classdef APP_Clinical_Research_CHI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFigure           matlab.ui.Figure
        Menu                 matlab.ui.container.Menu
        StartUp              matlab.ui.container.Menu
        RSMenu               matlab.ui.container.Menu
        LFPMenu              matlab.ui.container.Menu
        LFPMenu_2            matlab.ui.container.Menu
        Auto_Resize          matlab.ui.container.Menu
        Exit                 matlab.ui.container.Menu
        Functions            matlab.ui.container.Menu
        Current_info         matlab.ui.container.Menu
        Data_cut             matlab.ui.container.Menu
        Batch_Proc           matlab.ui.container.Menu
        Data_conp            matlab.ui.container.Menu
        Menu_3               matlab.ui.container.Menu
        Menu_8               matlab.ui.container.Menu
        Menu_9               matlab.ui.container.Menu
        Menu_10              matlab.ui.container.Menu
        Menu_13              matlab.ui.container.Menu
        Menu_15              matlab.ui.container.Menu
        Menu_16              matlab.ui.container.Menu
        Menu_17              matlab.ui.container.Menu
        Menu_11              matlab.ui.container.Menu
        Menu_14              matlab.ui.container.Menu
        Menu_18              matlab.ui.container.Menu
        Menu_19              matlab.ui.container.Menu
        Menu_20              matlab.ui.container.Menu
        Menu_21              matlab.ui.container.Menu
        Menu_22              matlab.ui.container.Menu
        Menu_23              matlab.ui.container.Menu
        Menu_29              matlab.ui.container.Menu
        Menu_30              matlab.ui.container.Menu
        Menu_31              matlab.ui.container.Menu
        Menu_25              matlab.ui.container.Menu
        Menu_32              matlab.ui.container.Menu
        Menu_33              matlab.ui.container.Menu
        Menu_34              matlab.ui.container.Menu
        ChineseMenu          matlab.ui.container.Menu
        EnglishMenu          matlab.ui.container.Menu
        data_management      matlab.ui.container.Panel
        save_data2workspace  matlab.ui.control.Button
        Save_Data            matlab.ui.control.Button
        out_put_select       matlab.ui.control.DropDown
        Researcher           matlab.ui.control.Label
        Researcher_Name      matlab.ui.control.EditField
        Open_File            matlab.ui.control.Button
        Save_Path            matlab.ui.control.TextArea
        Save_location        matlab.ui.control.Button
        Current_location     matlab.ui.control.Label
        Panel                matlab.ui.container.Panel
        current_IPGfile      matlab.ui.control.TextArea
        IPGLabel             matlab.ui.control.Label
        TFButton             matlab.ui.control.Button
        arti_detect          matlab.ui.control.CheckBox
        Label_4              matlab.ui.control.Label
        flip_page            matlab.ui.control.Label
        Change_to            matlab.ui.control.Label
        Chack_channel_now    matlab.ui.control.Label
        Plot_ALL_Channl      matlab.ui.control.Button
        Scales_But           matlab.ui.control.Button
        Right_Push           matlab.ui.control.Image
        Left_Push            matlab.ui.control.Image
        Current_data         matlab.ui.control.Label
        currentdata_txt      matlab.ui.control.TextArea
        Beta_Peakfind        matlab.ui.control.Button
        Auto_Reject          matlab.ui.control.CheckBox
        Auto_ECG             matlab.ui.control.CheckBox
        Auto_Stimu_Artifact  matlab.ui.control.CheckBox
        SameMeth             matlab.ui.control.CheckBox
        chi_choose           matlab.ui.control.DropDown
        Data_config          matlab.ui.control.Button
        Preview              matlab.ui.control.Button
        Research_report      matlab.ui.control.Button
        QUANLITY_INDEX       matlab.ui.control.UIAxes
        DATA_PSD             matlab.ui.control.UIAxes
        Data_Timedomine      matlab.ui.control.UIAxes
        data_load_pannel     matlab.ui.container.Panel
        IS_OLD_VERSON        matlab.ui.control.CheckBox
        More_info            matlab.ui.control.Button
        data_config          matlab.ui.control.Button
        UITable              matlab.ui.control.Table
        View_chi_DropDown    matlab.ui.control.DropDown
        Label_3              matlab.ui.control.Label
        Image                matlab.ui.control.Image
        infoACQ              matlab.ui.control.Label
        Label_2              matlab.ui.control.Label
        Label                matlab.ui.control.Label
        infoSTIM             matlab.ui.control.Label
        File_selection       matlab.ui.control.Button
        axis_pre             matlab.ui.control.UIAxes
        NNSRLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        waitting_NOW 
        Filename
        First_Filename
        data_path
        polar_info 
        Rec_Chi_info  
        Data_now  
        data_fs 
        data_time 
        deatiled = "请先进行数据解析" 
        Data_ori 
        line_all % PSD_LINES
        DFS_ISLOG = 0 
        RESULT_DATA_PSD_FREQ 
        data_extra_info
        data_acqs_info
        % net = load('model_Gry_3_0907')
        % net = load('NET_1D2D_V4_970')
        net = load('NET_1D2D_V5_940')
        % LOGO_Pic = '\1735974069763.jpg';
        IPG_ID = '未指定'; 
        IS_ECGS
        IS_STMS
        IS_MOVES
        
    end
    
    methods (Access = private)
    
    % function [IS_ECG, IS_STM, IS_MOVE] = P01_classify_multichannel_data_optimized(~, data, input_sampleRate, net,bpFilt)

    % function data = P01_formatForPrediction(~,dataCell)    % 格式化函数, 将合并的datastore转换为模型所需的输入格式

    % function processed_data = P01_cut_artifact_segments(~, data, fs, labels, del_mode, keep_mode)

    % function visualize_classification(app, IS_ECG, IS_STM, IS_MOVE)

    % function [qrs_amp_raw,qrs_i_raw,delay]=pan_tompkin(~,ecg,fs,gr)

    % function [clEEG,Temp]=EAS_cl_TP(app,rawEEG,ECG,fsEEG,fsECG,klength)

    % function Temp = get_temp(~,rawEEG,QRS_point,klength)

    % function clEEG =  remove_temp(~,rawEEG,QRS_point,Temp,klength)

    % function [Pxxmat,linemat,f] = F2a_welch_calcu_power(~,Data,nfft,fs,noverlap,window,ISLOG)
    
    % function temp = get_template_calculate(~,LFP, fdf)

    % P01_F1a_102_unpack_dir    % function 【1】, 102RS unpack
    
    % function 【2】, 106RS OLD dat unpack
    % function [Data_out,Data_fs,Stim_info,acq_info,Stim_ONOFF_info,Stim_Emode_info,Freq_Stim,Polar_info,Rec_Parameters_info,Lost] = F1a_106_unpack_dir(~,bit_data,IsaddNans,FILENAME) 

    % function 【3】, 106RS OLD data unpack %%%%
    % function [Data_out,Data_fs,Stim_info,acq_info,Stim_ONOFF_info,Stim_Emode_info,Freq_Stim,Polar_info,Rec_Parameters_info,Lost] = F2a_106_unpack_dir(~,bit_data,IsaddNans,FILENAME) 

    % function 【4】, 106RS NEW unpack %%%%
    % function [Data_out,Data_fs,Stim_info,acq_info,Stim_ONOFF_info,Stim_Emode_info,Freq_Stim,Polar_info,Rec_Parameters_info,other_INFO,Lost,IPG_ID] = F2b_106_unpack_dir_new(~,bit_data,IsaddNans,FILENAME) 
    %
    % Unpack_all data from all
    function [data_unpackeded,data_frequency,data_stim_info,data_acq_info,data_stim_ONOFF_info,data_stim_Emode_info,data_Freq_Stim_info,data_polar_info,data_Rec_Parameters_info,data_Lost,Dealtiled_para,UNPACK_IS_OK,DATA_DATE,DATA_TIME,IPG_ID] = Unpack_all_Kind_Ori(app,data,filenames,IsaddNans)
          data_unpackeded = [];
          DATA_DATE = 'NaN';
          DATA_TIME = 'NaN';
          data_frequency = 'NaN';
          data_stim_info = 'NaN';
          data_acq_info = 'NaN';
          data_stim_ONOFF_info = 'NaN';
          data_stim_Emode_info = 'NaN';
          data_Freq_Stim_info = 'NaN';
          data_polar_info = 'NaN';
          data_Rec_Parameters_info = 'NaN';
          data_Lost = [];
          UNPACK_IS_OK = '解析成功' ;
          istimeset = 0;
          Dealtiled_para = '解析失败, 无信息';
          IPG_ID = '未指定';
         if filenames((end-3):end) == ".dat" % unpack 102RS data and OLD 106RS data
                     if length(data) < 1000

                         UNPACK_IS_OK = '解析失败 数据长度过短';
                         % msgbox('文件长度过短','提示','warn')
                         set(app.MainFigure,'Pointer','arrow'); drawnow;
                         close(app.waitting_NOW)
                         return
                     end
                      try
                             try
                                [data_unpackeded,data_frequency,data_stim_info,data_acq_info,data_stim_ONOFF_info,data_stim_Emode_info,data_Freq_Stim_info,data_polar_info,data_Rec_Parameters_info,data_Lost] = P01_F1a_102_unpack_dir(app,data);
                                    if data_Lost < 4
                                       data_Lost = 0;
                                    end

                                if istimeset ~= 1
                                   try
                                        pattern = '(\d{4})-(\d{2})-(\d{2}) (\d{2})[:: ](\d{2})[:: ](\d{2})';
                                        tokens = regexp(filenames, pattern, 'tokens');
                                        year = tokens{1}{1};
                                        month = tokens{1}{2};
                                        day = tokens{1}{3};      DATA_DATE = [year,'年',month,'月',day,'日'];
                                        hour = tokens{1}{4};
                                        minute = tokens{1}{5};
                                        second = tokens{1}{6};   DATA_TIME = [hour,'时',minute,'分',second,'秒'];
                                        istimeset = 1;
                                   catch
                                        DATA_DATE = 'NaN';
                                        DATA_TIME = 'NaN';
                                   end
                                   try
                                     Dealtiled_para = {'※ 数据采集: 102RS';['※ 刺激模式: ',data_stim_info];['※ 采集模式: ',strjoin(data_acq_info,' ')];['※ 丢包参考数: ',num2str(data_Lost)];['* 采集时间',DATA_DATE,DATA_TIME]};
                                   catch
                                     Dealtiled_para = {'※ 数据采集: 102RS';['※ 刺激模式: ',data_stim_info];['※ 采集模式: ',data_acq_info,' '];['※ 丢包参考数: ',num2str(data_Lost)];['* 采集时间',DATA_DATE,DATA_TIME]};
                                   end
                                     IPG_ID = 'S_102RS';
                                end
                             catch

                                try
                                [data_unpackeded,data_frequency,data_stim_info,data_acq_info,data_stim_ONOFF_info,data_stim_Emode_info,data_Freq_Stim_info,data_polar_info,data_Rec_Parameters_info,data_Lost] = P01_F1a_106_unpack_dir(app,data,IsaddNans,filenames);
                                    if data_Lost < 4
                                       data_Lost = 0;
                                    end
                                if istimeset ~= 1
                                   try
                                        pattern = 'SourceData_(\d{4})(\d{2})(\d{2})(\d{2})(\d{2})(\d{2})';
                                        tokens = regexp(filenames, pattern, 'tokens');
                                        year = tokens{1}{1};
                                        month = tokens{1}{2};
                                        day = tokens{1}{3};      DATA_DATE = [year,'年',month,'月',day,'日'];
                                        hour = tokens{1}{4};
                                        minute = tokens{1}{5};
                                        second = tokens{1}{6};   DATA_TIME = [hour,'时',minute,'分',second,'秒'];
                                        istimeset = 1;
                                   catch
                                        DATA_DATE = 'NaN';
                                        DATA_TIME = 'NaN';
                                   end
                                   try
                                   Dealtiled_para = {'※ 数据采集: 106RS (code:02)';['※ 刺激模式: ',data_stim_info];['※  采集模式: ',strjoin(data_acq_info,' ')];['※ 丢包参考数: ',num2str(data_Lost)];['* 采集时间',DATA_DATE,DATA_TIME]};    
                                   catch
                                   Dealtiled_para = {'※ 数据采集: 106RS (code:02)';['※ 刺激模式: ',data_stim_info];['※  采集模式: ',data_acq_info,' '];['※ 丢包参考数: ',num2str(data_Lost)];['* 采集时间',DATA_DATE,DATA_TIME]};    
                                   end
                                   IPG_ID = 'S_106RS';
                                end

                                catch
                                      close(app.waitting_NOW)
                                      UNPACK_IS_OK = '解析失败 （.dat 1002）';
                                      Dealtiled_para = '解析失败, 无信息 (code 02)';
                                      set(app.MainFigure,'Pointer','arrow'); drawnow;
                                      return
                                end
                             end
                      catch
                          close(app.waitting_NOW)
                          set(app.MainFigure,'Pointer','arrow'); drawnow;
                          UNPACK_IS_OK = '解析失败 （.dat 1002）';
                      end
                            
                            if length(data_unpackeded(1,:)) < 4*(data_frequency)
                                UNPACK_IS_OK = '解析失败 .dat数据过短, 小于4个采样周期';
                                Dealtiled_para = '解析失败, 数据过短, 小于4个采样周期';
                                close(app.waitting_NOW)
                                return
                            end
                        
          elseif filenames((end-4):end) == ".data"
                                     if length(data) < 7000
                                         uialert(app.MainFigure,'数据长度过短, 可能无法进行后续分析, 请选择其他数据','提示','Icon','warning');
                                         set(app.MainFigure,'Pointer','arrow'); drawnow;
                                         close(app.waitting_NOW)
                                         return
                                     end

                                        try
                                           if app.IS_OLD_VERSON.Value  == 1
                                            % try
                                                 % assignin("base","data",data)
                                                [data_unpackeded,data_frequency,data_stim_info,data_acq_info,data_stim_ONOFF_info,data_stim_Emode_info,data_Freq_Stim_info,data_polar_info,data_Rec_Parameters_info,data_Lost] = P01_F2a_106_unpack_dir(app,data,IsaddNans,filenames);
                                                % data_unpackeded,data_frequency,data_stim_info,data_acq_info,data_stim_ONOFF_info,data_stim_Emode_info,data_Freq_Stim_info,data_polar_info,data_Rec_Parameters_info,data_Lostl
                                                % assignin("base","stim_info",app.stim_info)
                                                 % assignin("base","acq_info",app.acq_info)
                                                    % if app.Lost_index < 4
                                                    %    app.Lost_index = 0;
                                                    % end
                                                    data_Lost = data_Lost -5 ;
                                                    if data_Lost < 0
                                                       data_Lost = 0;
                                                    end
                                                    % uialert(app.MainFigure,{'106RS数据解包成功！';[app.stim_info,app.acq_info];['丢包数: ',num2str(app.Lost_index)]},'(已加载.data文件) 数据信息确认','Icon','success')
                                                    % msgbox(,'提示','warn');
                                                    % app.IS_106RS = 2;

                                                   try
                                                        pattern = '(\d{4})-(\d{2})-(\d{2}) (\d{2})_(\d{2})_(\d{2})';
                                                        tokens = regexp(filenames, pattern, 'tokens');
                                                        year = tokens{1}{1};
                                                        month = tokens{1}{2};
                                                        day = tokens{1}{3};      DATA_DATE = [year,'年',month,'月',day,'日'];
                                                        hour = tokens{1}{4};
                                                        minute = tokens{1}{5};
                                                        second = tokens{1}{6};   DATA_TIME = [hour,'时',minute,'分',second,'秒'];
                                                        istimeset = 1;
                                                   catch
                                                        DATA_DATE = 'NaN';
                                                        DATA_TIME = 'NaN';
                                                   end
                                                       IPG_ID = 'S_106RS';
                                                   try
                                                       Dealtiled_para = {'※ 数据采集: 106RS (code:04)';['※ 刺激参数: ',data_stim_info];['※ 采集参数: ',strjoin(data_acq_info,' ')];['※ 丢包参考数: ',num2str(data_Lost)];['* 采集时间',DATA_DATE,DATA_TIME];['※ IPG型号: ',IPG_ID]};
                                                   catch
                                                       Dealtiled_para = {'※ 数据采集: 106RS (code:04)';['※ 刺激参数: ',data_stim_info];['※ 采集参数: ',(data_acq_info)];['※ 丢包参考数: ',num2str(data_Lost)];['* 采集时间',DATA_DATE,DATA_TIME];['※ IPG型号: ',IPG_ID]};
                                                   end

                                                   % 判断数据
                                                        if length(data_unpackeded(1,:)) < 4*(data_frequency)
                                                            % uialert(app.MainFigure,'数据长度过短, 无法进行后续分析, 请选择其他数据','提示','Icon','warning');
                                                            UNPACK_IS_OK = '解析失败 .data数据过短, 小于4个采样周期';
                                                            % cla(app.axis_pre)
                                                            close(app.waitting_NOW)
                                                            return
                                                        end
                                            else
                                                 % assignin("base","data",data)
                                                try
                                                [data_unpackeded,data_frequency,data_stim_info,data_acq_info,data_stim_ONOFF_info,data_stim_Emode_info,data_Freq_Stim_info,data_polar_info,data_Rec_Parameters_info,otherINFO,data_Lost,IPG_ID] = P01_F2b_106_unpack_dir_new(app,data,IsaddNans,filenames);
                                                % data_unpackeded,data_frequency,data_stim_info,data_acq_info,data_stim_ONOFF_info,data_stim_Emode_info,data_Freq_Stim_info,data_polar_info,data_Rec_Parameters_info,data_Lost
                                                % assignin("base","stim_info",app.stim_info)
                                                % assignin("base","acq_info",app.acq_info)
                                                    % if app.Lost_index < 4
                                                    %    app.Lost_index = 0;
                                                    % end
                                                    % assignin("base","lost",app.Lost_index)
                                                    data_Lost = data_Lost -2;
                                                    if data_Lost < 0
                                                       data_Lost = 0;
                                                    end
                                                    % uialert(app.MainFigure,['106RS数据解包成功！',app.stim_info,app.acq_info,'丢包数: ',num2str(app.Lost_index)],'(已加载.data文件)数据信息确认','Icon','success')
                                                    % uialert(app.MainFigure,{'106RS数据解包成功！';[data_stim_info,data_acq_info];['丢包数: ',num2str(data_Lost)]},'(已加载.data文件) 数据信息确认','Icon','success')

                                                    % app.IS_106RS = 2;
                                                    try
                                                        pattern = '(\d+)年(\d{1,2})月(\d{1,2})日(\d{1,2})时(\d{1,2})分(\d{1,2})秒';tokens = regexp(otherINFO, pattern, 'tokens');
                                                        year = tokens{1}{1};
                                                        month = tokens{1}{2};
                                                        day = tokens{1}{3};       DATA_DATE = [year,'年',month,'月',day,'日'];
                                                        hour = tokens{1}{4};
                                                        minute = tokens{1}{5};
                                                        second = tokens{1}{6};     DATA_TIME = [hour,'时',minute,'分',second,'秒'];
                                                        istimeset = 1;
                                                    catch
                                                        DATA_DATE = 'NaN';
                                                        DATA_TIME = 'NaN';
                                                    end
                                                    % msgbox(,'提示','warn');
                                                    assignin("base","stim_info",data_stim_info)
                                                    assignin("base","acq_info",data_acq_info)
                                                    assignin("base","lost",['※ 丢包参考数: ',num2str(data_Lost)])
                                                    assignin("base","otherINFO",otherINFO)
                                                    try
                                                    Dealtiled_para = {'※ 数据采集: 106RS (NEW)';['※ 刺激参数: ',data_stim_info];['※ 采集参数: ',strjoin(data_acq_info,' ')];['※ 丢包参考数: ',num2str(data_Lost)];['※ 其他信息: ',otherINFO];['※ IPG编号: ',IPG_ID]};
                                                    catch
                                                    Dealtiled_para = {'※ 数据采集: 106RS (NEW)';['※ 刺激参数: ',data_stim_info];['※ 采集参数: ',data_acq_info,' '];['※ 丢包参考数: ',num2str(data_Lost)];['※ 其他信息: ',otherINFO];['※ IPG编号: ',IPG_ID]};
                                                    end
                                                 
                                                 catch
                                                      close(app.waitting_NOW)
                                                      % uialert(app.MainFigure,'.data文件解包失败 （提示: 如为旧版软件采集, 请手动勾选“旧版解析”）','提示','Icon','warning');
                                                      UNPACK_IS_OK = '解析失败: 请检查是否是旧版data文件';
                                                      Dealtiled_para = '解析失败, 请检查是否为旧版格式';
                                                      return
                                                 end
                                            end
                                            % end
                                            % 判断数据
                                            if length(data_unpackeded(1,:)) < 4*(data_frequency)
                                                % uialert(app.MainFigure,'数据长度过短, 无法进行后续分析, 请选择其他数据','提示','Icon','warning');
                                                UNPACK_IS_OK = '解析失败 .data数据过短, 小于4个采样周期';
                                                Dealtiled_para = '解析失败, 数据小于4个采样周期';
                                                % app.Data_config.Enable = "off";
                                                % cla(app.axis_pre)
                                                close(app.waitting_NOW)
                                                return
                                            end

                                        catch
                                             close(app.waitting_NOW)
                                             UNPACK_IS_OK = '解析失败: .data文件解析失败 请检查数据与版本';
                                             Dealtiled_para = '解析失败, 请检查版本';
                                             % uialert(app.MainFigure,'.data文件解析失败 请检查数据与版本 code:1004','提示','Icon','warning');
                                             return
                                             msgbox('.dat文件解包错误 -004','提示','warn')
                                        end
                                        
                elseif filenames((end-3):end) == ".mat"
                        try
                            % Load MAT file
                            U = string(app.First_Filename);
                            mat_data_struct = load(U);
                            
                            % Extract data from MAT structure
                            try
                                mat_data_in = mat_data_struct.data;
                                [temp_Data_ori,temp_Data_now,temp_data_fs,temp_stim_info,temp_acq_info,temp_Stim_ONOFF_info,temp_Stim_Emode_info,temp_Freq_Stim,temp_Polar_info,temp_Rec_Chi_info] = P02_F1b_load_mat(app,mat_data_in);
                                
                                % Map MAT data to function output variables
                                data_unpackeded = temp_Data_now;
                                data_frequency = temp_data_fs;
                                
                                % Handle string/cell array conversions for info fields
                                if iscell(temp_stim_info)
                                    data_stim_info = strjoin(temp_stim_info, ' ');
                                else
                                    data_stim_info = char(temp_stim_info);
                                end
                                
                                if iscell(temp_acq_info)
                                    data_acq_info = temp_acq_info;
                                else
                                    try
                                    data_acq_info = {char(temp_acq_info)};
                                    catch
                                       data_acq_info = sprintf('%s_%d_%s_%d', ...
                                                strjoin(temp_acq_info.channels, ','), ...
                                                temp_acq_info.samplerate, ...
                                                temp_acq_info.timestamp, ...
                                                temp_acq_info.data_length); 
                                    end
                                end
                                
                                % Set stimulation and acquisition parameters
                                data_stim_ONOFF_info = char(temp_Stim_ONOFF_info);
                                data_stim_Emode_info = char(temp_Stim_Emode_info);
                                data_Freq_Stim_info = char(temp_Freq_Stim);
                                data_polar_info = char(temp_Polar_info);
                                
                                if iscell(temp_Rec_Chi_info)
                                    data_Rec_Parameters_info = temp_Rec_Chi_info;
                                else
                                    data_Rec_Parameters_info = {char(temp_Rec_Chi_info)};
                                end
                                
                                % Set default values for MAT-specific fields
                                data_Lost = 0; % MAT files typically don't have packet loss info
                                DATA_DATE = 'NaN'; % Extract from filename if possible
                                DATA_TIME = 'NaN';
                                IPG_ID = 'MAT_FILE';
                                
                                % Try to extract date/time from filename
                                try
                                    % Try various common date patterns in MAT filenames
                                    pattern_date1 = '(\d{4})-(\d{2})-(\d{2})';
                                    pattern_date2 = '(\d{4})(\d{2})(\d{2})';
                                    tokens = regexp(filenames, pattern_date1, 'tokens');
                                    if isempty(tokens)
                                        tokens = regexp(filenames, pattern_date2, 'tokens');
                                    end
                                    if ~isempty(tokens)
                                        year = tokens{1}{1};
                                        month = tokens{1}{2};
                                        day = tokens{1}{3};
                                        DATA_DATE = [year,'年',month,'月',day,'日'];
                                    end
                                catch
                                    % Keep default NaN values
                                end
                                
                                % Create detailed parameter info for MAT files
                                try
                                    Dealtiled_para = {'※ 数据来源: MAT文件';
                                                     ['※ 采样频率: ',num2str(data_frequency),'Hz'];
                                                     ['※ 刺激信息: ',data_stim_info];
                                                     ['※ 采集信息: ',strjoin(data_acq_info,' ')];
                                                     ['※ 通道信息: ',strjoin(data_Rec_Parameters_info,' ')];
                                                     ['※ 数据长度: ',num2str(size(data_unpackeded,2)),'采样点'];
                                                     ['※ 文件类型: ',IPG_ID]};
                                catch
                                    Dealtiled_para = {'※ 数据来源: MAT文件';
                                                     ['※ 采样频率: ',num2str(data_frequency),'Hz'];
                                                     ['※ 刺激信息: ',data_stim_info];
                                                     ['※ 采集信息: ',char(data_acq_info)];
                                                     ['※ 文件类型: ',IPG_ID]};
                                end
                                
                                % Validate data length
                                if length(data_unpackeded(1,:)) < 4*data_frequency
                                    UNPACK_IS_OK = '解析失败 .mat数据过短, 小于4个采样周期';
                                    Dealtiled_para = '解析失败, MAT数据小于4个采样周期';
                                    close(app.waitting_NOW)
                                    return
                                end
                                
                                % Update app UI elements (maintain original functionality)
                                app.Data_ori = temp_Data_ori;
                                app.Data_now = temp_Data_now;
                                app.data_fs = temp_data_fs;
                                data_stim_info = temp_stim_info;
                                data_acq_info = temp_acq_info;
                                data_stim_ONOFF_info = temp_Stim_ONOFF_info;
                                data_stim_Emode_info = temp_Stim_Emode_info;
                                data_Freq_Stim_info = temp_Freq_Stim;
                                data_polar_info = temp_Polar_info;
                                data_Rec_Parameters_info = temp_Rec_Chi_info;
                                                                
                                % Set success status
                                UNPACK_IS_OK = '解析成功';
                                
                                % Display success message
                                uialert(app.MainFigure, strjoin(Dealtiled_para, '\n'), '(已加载.mat文件)数据信息确认','Icon','success');
                                
                            catch
                                close(app.waitting_NOW)
                                UNPACK_IS_OK = '解析失败: .mat文件内容残缺或格式不兼容';
                                Dealtiled_para = '解析失败, MAT文件内容残缺';
                                uialert(app.MainFigure,'.mat文件内容残缺','提示','Icon','warning');
                                return
                            end
                            
                        catch
                            close(app.waitting_NOW)
                            UNPACK_IS_OK = '解析失败: .mat文件访问失败';
                            Dealtiled_para = '解析失败, 文件访问失败';
                            uialert(app.MainFigure,'文件访问失败','提示','Icon','warning');
                            return
                        end


          elseif filenames((end-3):end) == ".txt"
                   samplingRate = 1;
                 try
                    filesssname = string(app.First_Filename_NOW);

                    fileID = fopen(filesssname, 'r');
                    if fileID == -1
                        uialert(app.MainFigure,'文件访问失败','提示','Icon','warning')
                    end

                    firstLine1 = fgetl(fileID); % READ 第一行
                    firstLine2 = fgetl(fileID); % READ 第二行
                    trysss = [firstLine1,firstLine2];
                    pattern_fs = '采样率: (\d+)';
                    match_fs = regexp(trysss, pattern_fs, 'tokens');

                    pattern_channel = '_(\w+\+\w+-)';
                    matche_channel = regexp(trysss, pattern_channel, 'tokens');


                    if ~isempty(match_fs)
                        samplingRate = str2double(match_fs{1}{1});
                    else
                        try
                            str = app.First_Filename_NOW;
                            pattern = '(\d+)Hz';
                            match_fs = regexp(str, pattern, 'tokens');
                            % frequency 
                            samplingRate = str2double(match_fs{1}{1}); % 将找到的字符串转换为数字
                        catch
                            answer = inputdlg({'请手动输入采样率: '}, '输入', [1 30],{'500'});
                            samplingRate =  str2double(answer); % 如果没有匹配到, 返回NaN
                        end
                    end

                    app.data_fs = samplingRate;

                    if isempty(matche_channel)
                       lineCounter = 0;
                        % 指定要读取的行号
                        targetLineNum = 3; % 以读取第3行为例
                        % 循环遍历文件直到到达目标行
                        while ~feof(fileID)
                            lineCounter = lineCounter + 1;
                            line = fgetl(fileID);
                            if lineCounter == targetLineNum
                                break;
                            end
                        end
                        numbers = str2num(line);
                        n = length(numbers); 
                        cells = cell(1, length(numbers)); % 创建一个1xn的cell数组
                        % 使用循环为每个单元赋值
                        for i = 1:n
                           cells{i} = num2str(i); % 将索引转换为字符串并赋值给相应的cell单元
                        end
                       app.Rec_Chi_info = cells;
                    else
                       app.Rec_Chi_info = [matche_channel{:}];
                    end

                       app.Data_ori = readmatrix(filesssname, 'NumHeaderLines', 6,'Delimiter', ',')';
                       assignin("base",'dataorri',app.Data_ori);

                       try
                           uniqueValues = unique(app.Data_ori(2,:));
                           if numel(uniqueValues)<3
                              app.Data_ori(1:2,:) = [];
                           end
                       end
                       
                       % app.Data_ori = app.Data_ori(~isnan(app.Data_ori));
                       fclose(fileID);
                       app.Data_now = app.Data_ori;
                       app.Dealtiled_para = {'※ 数据来源: TXT文档';['※ 采样率: ',num2str(samplingRate)];['※ 采集通道: ',cell2mat(app.Rec_Chi_info)]};
                       
                       % assignin("base",'Data_ori',app.Data_ori);
                      % 判断数据
                            if length(app.Data_now(1,:)) < 4*(app.data_fs)
                                uialert(app.MainFigure,'数据长度过短, 无法进行后续分析, 请选择其他数据','提示','Icon','warning');
                                app.infoACQ.Text = cell2mat([app.Polar_info,app.Rec_Chi_info]);
                                app.Data_config.Enable = "off";
                                cla(app.axis_pre)
                                close(app.waitting_NOW)
                                return
                            end
                       uialert(app.MainFigure,[app.Dealtiled_para],'(已加载.txt文件) 数据信息确认','Icon','success')

                       app.Stim_ONOFF_info = ' ';
                       app.Stim_Emode_info = ' ';
                       app.Freq_Stim = ' ';
                       % app.Rec_Parameters_info
                       app.Polar_info = ' ';
                     iii = app.View_chi_DropDown.Value;
                     app.infoACQ.Text = cell2mat([app.Polar_info,app.Rec_Chi_info]);
                     % iii = '3'
                     app.View_chi_DropDown.Items = app.Rec_Chi_info;
                     app.channel_choose_1.Items = app.Rec_Chi_info;
                     app.channel_choose_2.Items = app.Rec_Chi_info;
                     app.channel_Phase_spectrum.Items = app.Rec_Chi_info;
                     app.chi_calcu.Items = app.Rec_Chi_info;
                     app.chi_choose.Items = app.Rec_Chi_info; %set up main chi info
                     % assignin("base",'datanow',app.Data_now);
                     plot(app.axis_pre,app.Data_now(str2double(iii),500:end));
                     % 
                     app.Data_ori = app.Data_now;
                     % enable
                     app.View_chi_DropDown.Enable = "on";
                     app.Data_config.Enable = 'on';
                     app.filter_sample_rate.Value = num2str(app.data_fs);
                     app.samplefrequency_STM1.Value = app.data_fs;
                     app.Powercalcu_frequency.Value = num2str(app.data_fs);
                     app.save_data2workspace.Enable = "on";
                     app.Save_Data.Enable = "on";
                     
                 catch
                    % try
                    % fclose(fileID);
                    % end
                    % close(app.waitting_NOW)
                    % msgbox('文件访问错误','提示','warn')
                    uialert(app.MainFigure,'文件访问失败','提示','Icon','warning')
                    close(app.waitting_NOW)
                 end
         end
    end

    % function bandPower = P02_calculateBandPower(psd, freqs, bandRange)     % 计算指定频段的功率

    % function [peakFreq, peakPower] = P02_detectBetaPeak(psd, freqs, betaRange)
    
    % function P02_generateOverviewFigure(imgDir, dataMatrix, fs, imgSettings, TEXT)

    % function P02_generateChannelFigure(imgDir, ch, chName, signal, psd, freqs, peakFreq, peakPower, imgSettings, TEXT)

    % function [ECG_result, MOVE_result, STM_result, ECG_info, MOVE_info, STM_info] = detect_artifacts(~,IS_ECG, IS_MOVE, IS_STM) % function for detect if/not exist data

    % function [betaPeakFreq, betaPeakValue, betaPeakWidth] = detectBetaPeak(~,f, pxx) % 分析报告绘制函数

    % function [fileName, betaPeakFreq, betaPeakValue, betaPeakWidth, deltaP, thetaP, alphaP, betaP, gammaP, betaPeakBandP] = generateChannelAnalysisFigure(app,imgDir, ch, channelName, dataMatrix, data_fs, bands, colors, window_length, overlap, nfft, imgSettings, TEXT)

    % function fileName = generateArtifactDetectionFigure(~,imgDir, IS_ECG, IS_STM, IS_MOVE, imgSettings, TEXT)

    % function fileName = generateSignalOverviewFigure(app,imgDir, dataMatrix, data_fs, bands, colors, imgSettings, TEXT,app.IS_MOVES)

    % function fileName = generateSummaryChartFigure(~,imgDir, allDeltaP, allThetaP, allAlphaP, allBetaP, allGammaP, channel_montage, bandNames, colors, imgSettings, TEXT)
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 添加当前的路径
            appDir = fileparts(mfilename('fullpath'));
            addpath(appDir);
            
            screenSize = get(groot, 'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            appWidth = app.MainFigure.Position(3);
            appHeight = app.MainFigure.Position(4);
            horizontalCenter = (screenWidth - appWidth) / 2;
            verticalCenter = (screenHeight - appHeight) / 2;
            app.MainFigure.Position(1:2) = [horizontalCenter, verticalCenter];
            app.MainFigure.Name = 'NNSR脑电平台';
            app.waitting_NOW = uiprogressdlg(app.MainFigure,'Title','正在载入界面',...
            'Indeterminate','on');
            drawnow
            colorbacks = [0.88,0.92,0.94];
            text(app.DATA_PSD,0.1,0.5,'脑电数据离线分析系统','Color',[0.98,0.98,0.98],"BackgroundColor",colorbacks,"FontSize",50,"FontSmoothing","on","FontWeight","bold")
            % app.DATA_PSD.Color = [0.88,0.92,0.94];
            app.DATA_PSD.Color = [0.88,0.92,0.94];
            text(app.Data_Timedomine,0.27,0.5,'脑电数据离线分析系统','Color',[0.98,0.98,0.98],"BackgroundColor",colorbacks,"FontSize",30,"FontSmoothing","on","FontWeight","bold")
            app.Data_Timedomine.Color = [0.88,0.92,0.94];
            app.axis_pre.Color = [0.88,0.92,0.94];
            app.QUANLITY_INDEX.Color = [0.88,0.92,0.94];
            app.axis_pre.Interactions = [];
            close(app.waitting_NOW)

            msg = {'提示/Note：','该分析系统支持自动化的特征提取与分析，数据处理结果仅供研究使用。', ...
                       'The analysis system can perform automated feature extraction and analysis, and the data processing results are for research purposes only.'};
            % 显示消息对话框
            uialert(app.MainFigure, msg, '提示 / Notification', 'Icon', 'info');

            % add root path
            if isdeployed
                appPath = ctfroot;  % 使用ctfroot获取已部署应用的根路径
            else
                appPath = fileparts(mfilename('E:\05_Platform\Verson_0.2.0'));  % 在MATLAB中调试时使用
            end
            folderToAdd = fullfile(appPath, 'subfolder');  % 替换为你想添加的子文件夹路径
            if exist(folderToAdd, 'dir')
                addpath(folderToAdd);
            end

        end

        % Button pushed function: File_selection
        function File_selectionButtonPushed(app, event)
            % set(app.MainFigure,'Icon','icon_48.png');
            app.waitting_NOW = uiprogressdlg(app.MainFigure,'Title','正在获取文件夹',...
            'Indeterminate','on');
            drawnow
            app.Filename = [];
            close(app.waitting_NOW)
            [app.Filename,path] = uigetfile({'*.dat;*.mat;*.data;*.txt'},'打开LFP文件(.dat/.data/.mat/.txt)','MultiSelect','on');
            if isequal(app.Filename,0) || isequal(path,0)
             uialert(app.MainFigure,'用户取消了文件选择','提示','Icon','info');
            else
                try
                    app.UITable.Data = cell2table(app.Filename', 'VariableNames', {'Strings'});
                catch
                    % assignin('base','filename',app.Filename)
                    app.Filename = cellstr(app.Filename);
                    app.UITable.Data = app.Filename;
                end
                    app.First_Filename = app.Filename{1,1};
                if length(size(app.First_Filename)) <= 1 % Filename return
                    
                    uialert(app.MainFigure,'请选择有效文件','提示','Icon','warning');
                    app.data_config.Enable = "off";
                else
                    % app.filename.Value = app.First_Filename;
                    app.data_path = path;
                    app.data_config.Enable = "on";
                    cd(path) % 添加删除路径
                    app.Save_Path.Value = path;
                end
            end
            try
                filena = app.First_Filename;
                if filena(1:4) == "head" % unpack 102RS data and OLD 106RS data
                   app.IS_OLD_VERSON.Value = 1;
                else
                    app.IS_OLD_VERSON.Value = 0;
                end
            end
        end

        % Button pushed function: data_config
        function data_configButtonPushed(app, event)
            try
            app.waitting_NOW = uiprogressdlg(app.MainFigure,'Title','正在解包',...
            'Indeterminate','on');
            drawnow
            % app.processing_pannel.SelectedTab = app.PreProcess_Tool;
            % samplingRate = 1;
            % set(app.MainFigure,'Pointer','watch'); drawnow;
           list=dir([app.data_path,app.First_Filename]);
           %  assignin('base','file_id',app.Filename)
           file_id = fopen(list(1).name);
               % try 
           data= fread(file_id); 
           fclose(file_id);
           IsaddNans = 0;
           filenames = app.First_Filename;
           [app.Data_now,app.data_fs,app.data_extra_info,app.data_acqs_info,data_stim_ONOFF_info,data_stim_Emode_info,data_Freq_Stim_info,app.polar_info,app.Rec_Chi_info,data_Lost,Dealtiled_para,~,DATA_DATE,DATA_TIME,app.IPG_ID] = Unpack_all_Kind_Ori(app,data,filenames,IsaddNans);
           app.data_time = [DATA_DATE,DATA_TIME];
           app.deatiled = Dealtiled_para;
           % app.data_fs
           try
              uialert(app.MainFigure,{'数据解包成功！';[app.data_extra_info,(app.data_acqs_info)];['丢包参考数: ',num2str(data_Lost)]},'(已加载数据文件) 数据信息确认','Icon','success')
           catch
               try
                   uialert(app.MainFigure,{'数据解包成功！';[app.data_extra_info,cell2mat(app.data_acqs_info)];['丢包参考数: ',num2str(data_Lost)]},'(已加载数据文件) 数据信息确认','Icon','success')
               catch
                    uialert(app.MainFigure,'数据解包成功！具体信息未知','已加载数据','Icon','success');
               end
           end
               app.infoACQ.Text = cell2mat([app.polar_info,app.Rec_Chi_info]);
    
               % assignin('base','Data',app.Data_now);
               iii = app.View_chi_DropDown.Value;
               app.View_chi_DropDown.Items = app.Rec_Chi_info;
               app.chi_choose.Items = app.Rec_Chi_info; %set up main chi info
               plot(app.axis_pre,app.Data_now(str2double(iii),500:end));
               app.Data_ori = app.Data_now;
               % enable
               app.View_chi_DropDown.Enable = "on";
               app.Data_config.Enable = 'on';
               % app.filter_sample_rate.Value = num2str(app.data_fs);
               % app.samplefrequency_STM1.Value = app.data_fs;
               % app.Powercalcu_frequency.Value = num2str(app.data_fs);
               app.save_data2workspace.Enable = "on";
               app.Save_Data.Enable = "on";
                                 
               set(app.MainFigure,'Pointer','arrow'); drawnow;
               close(app.waitting_NOW)
               % app.Preview.Enable = "on";
               app.More_info.Enable = "on";
               app.Preview.Enable = "on";
               app.currentdata_txt.Value = app.First_Filename;
               app.current_IPGfile.Value = app.IPG_ID;
            catch
               % msgbox('执行错误','提示','warn')
               uialert(app.MainFigure,'执行失败','提示','Icon','warning')
               close(app.waitting_NOW)
               set(app.MainFigure,'Pointer','arrow'); drawnow;
               app.More_info.Enable = "off";
               app.Preview.Enable = "off";
            end
           % assignin("base",'ORI',app.Data_ori)
        end

        % Button pushed function: Data_config
        function Data_configButtonPushed(app, event)
            
        end

        % Image clicked function: Left_Push
        function Left_PushImageClicked(app, event)
            if (app.Data_Timedomine.XLim(1))/app.data_fs>0
               app.Data_Timedomine.XLim = app.Data_Timedomine.XLim - 10;
               app.Right_Push.Enable = "on";
            else
               app.Left_Push.Enable = "off";
            end
        end

        % Image clicked function: Right_Push
        function Right_PushImageClicked(app, event)
            if app.Data_Timedomine.XLim(2) < (length(app.Data_now(1,:)))/app.data_fs
               app.Data_Timedomine.XLim = app.Data_Timedomine.XLim + 10;
               app.Left_Push.Enable = "on";
            else
               app.Right_Push.Enable = "off";
            end
        end

        % Button pushed function: Preview
        function PreviewButtonPushed(app, event)
             try
             app.waitting_NOW = uiprogressdlg(app.MainFigure,'Title','正在执行预处理',...
             'Indeterminate','on');
             drawnow

             chinum = length(app.Rec_Chi_info);
             cla(app.DATA_PSD,'reset')
             app.DATA_PSD.Color = [0.88,0.92,0.94];
             colorbacks = [0.88,0.92,0.94];
             text(app.DATA_PSD,0.1,0.5,'脑电数据离线分析系统','Color',[0.98,0.98,0.98],"BackgroundColor",colorbacks,"FontSize",50,"FontSmoothing","on","FontWeight","bold")
             % 1. Processing first, band pass iir
             bpFilt_EEG = designfilt('bandpassiir','FilterOrder',8, 'HalfPowerFrequency1',1,'HalfPowerFrequency2',60, 'SampleRate',app.data_fs);
             Data_in_ori = app.Data_now';
             Data_out = filtfilt(bpFilt_EEG,Data_in_ori)';
             app.Data_now = Data_out;

             if app.arti_detect.Value == 1
                    filterOrder = 2;
                    lowCutoff = 0.5;      % 高通截止频率
                    highCutoff = 120;     % 低通截止频率
                    bpFilt = designfilt('bandpassiir', ...
                        'FilterOrder', filterOrder, ...
                        'HalfPowerFrequency1', lowCutoff, ...
                        'HalfPowerFrequency2', highCutoff, ...
                        'DesignMethod', 'butter', ...
                        'SampleRate', app.data_fs);

                     [app.IS_ECGS,app.IS_STMS,app.IS_MOVES] = P01_classify_multichannel_data_optimized(app, Data_in_ori', app.data_fs, app.net.net, bpFilt);
                     [ECG_result, MOVE_result, STM_result, ECG_info, MOVE_info, STM_info] = P01_detect_artifacts(app,app.IS_ECGS, app.IS_MOVES, app.IS_STMS);
                 try
                    P01_visualize_classification(app, app.IS_ECGS, app.IS_STMS, app.IS_MOVES);
                 end
             end
              
            if length(app.IS_ECGS(1,:)) <= 2
                uialert(app.MainFigure,'数据过短（少于半分钟），请更换数据段。','提示','Icon','warning')
                close(app.waitting_NOW)
                return
            end
             
             if app.Auto_Stimu_Artifact.Value == 1
                if sum(STM_result)>=1
                    for Js = 1:length(ECG_result)
                        
                        
                    end
                end
             end
             
             if app.Auto_ECG.Value == 1
                fsEEG = app.data_fs;
                fsECG = app.data_fs;
                length_win = 20;
                lengthS = 0;%自动取默认
                rawEEG = app.Data_now;
                EEG_OUT = rawEEG;
                if sum(ECG_result)>=1
                    for P = 1:length(ECG_result)
                        if ECG_result(P) == 1
                            ECG =  app.Data_now(P,:);
                            k = floor((size(rawEEG,2)/(fsEEG*length_win)));
                            for I = 1:k
                                rawEEG_now = rawEEG(P,((I-1)*(fsEEG*length_win)+1):I*(fsEEG*length_win));
                                ECG_now = ECG(:,((I-1)*(fsEEG*length_win)+1):I*(fsEEG*length_win));
                                [clEEG,Temp]=P01_EAS_cl_TP(app,rawEEG_now,ECG_now,fsEEG,fsECG,lengthS);
                                EEG_OUT(P,((I-1)*(fsEEG*length_win)+1):I*(fsEEG*length_win)) = clEEG;
                            end
                        end
                    end
                end
                app.Data_now = EEG_OUT;
             end

             if app.Auto_Reject.Value == 1
                if sum(MOVE_result)>=1
                   del_mode = 1; % 1 = deletsegements； 2 = delete thereholds
                   keep_mode = 2;%  1 = deleted ; 2 = set NaN;
                   Data_out = P01_cut_artifact_segments(app, app.Data_now, app.data_fs,app.IS_MOVES, del_mode, keep_mode);
                end
             end

             value = app.chi_choose.Value;
             i = value;
             cla(app.Data_Timedomine)
             plot(app.Data_Timedomine,(1:1:length(app.Data_ori(str2double(i),1000:end-600)))/app.data_fs,app.Data_ori(str2double(i),1000:end-600),"LineWidth",1.5);
             hold(app.Data_Timedomine,"on")
             plot(app.Data_Timedomine,(1:1:length(app.Data_now(str2double(i),1000:end-600)))/app.data_fs,app.Data_now(str2double(i),1000:end-600),"LineWidth",1.5);
             a = legend(app.Data_Timedomine,{'处理前','处理后'});
             set(a, 'FontSize', 10); % 将图例字体大小设置为14
             hold(app.Data_Timedomine,"off")
             
             app.line_all = [];

             for i = 1:chinum
                 if sum(ECG_result)>=1
                 datas_right_now = Data_out(i,app.data_fs*15:end-600);
                 else
                 datas_right_now = Data_out(i,1000:end-600);
                 end

                 datas_right_now(isnan(datas_right_now)) = [];
                 nfft = 10*app.data_fs;
                 fs = app.data_fs;
                 noverlap = 0.5*fs;
                 window = hanning(fs);
                 if app.DFS_ISLOG == 0
                     [~,linemat1,f1] = P01_F2a_welch_calcu_power(app,datas_right_now,nfft,fs,noverlap,window,0);
                 else
                     [~,linemat1,f1] = P01_F2a_welch_calcu_power(app,datas_right_now,nfft,fs,noverlap,window,1);
                 end
                 app.line_all = [app.line_all,linemat1];
             end
             app.RESULT_DATA_PSD_FREQ = f1;
             for num_cy1 = 1:(chinum)
                 plot(app.DATA_PSD,f1,(app.line_all(:,(num_cy1))),"LineWidth",3)
                 app.DATA_PSD.NextPlot = 'add';
             end
             app.DATA_PSD.Color = [0.88,0.92,0.94];

             a = legend(app.DATA_PSD,app.chi_choose.Items);
             set(a, 'FontSize', 10); % 将图例字体大小设置为14
             
             if chinum >= 6
                    customColors  = [
                        160, 160, 160;    % 浅灰色，曲线1 (代替深灰色)
                        80, 95, 120;      % 冷蓝灰色，曲线2 (增强蓝色)
                        160, 120, 90;     % 暖棕色调，曲线3 (带有橙红)
                        126, 142, 168;    % 亮灰色，曲线4
                        202, 215, 235;    % 浅蓝色，曲线5
                        45, 65, 50;       % 绿色调，曲线6
                        255, 153, 76;     % 柔和的橙色调，曲线7
                        200, 170, 210;    % 淡紫色，曲线8
                    ];
             elseif chinum <= 5 && chinum >= 3
                 customColors = [32, 37, 42;
                     128, 133, 138;
                    160, 165, 170;
                    192, 197, 202;
                     204, 209, 214;];
             else
                 customColors = [32, 37, 42;
                     140, 145, 150;
                     204, 209, 214;];
             end

             app.DATA_PSD.ColorOrder = customColors/255;
             % app.DATA_PSD.Color = [1,1,1];
             % app.Data_Timedomine.Color = [1,1,1];
             customColors = [160, 165, 170;32, 37, 42];
             app.Data_Timedomine.ColorOrder = customColors/255;
             app.DATA_PSD.YLabel.String = '能量(μV²/Hz)';
             app.DATA_PSD.XLabel.String = '频率(Hz)';
             
             app.Left_Push.Enable = "on";
             pp.Right_Push.Enable = "on";
             app.Left_Push.Enable = "on";
             app.Right_Push.Enable = "on";
             app.Scales_But.Enable = "on";
             app.chi_choose.Enable = "on";
             app.TFButton.Enable = "on";
             app.Plot_ALL_Channl.Enable = "on";
             app.Beta_Peakfind.Enable = "on";
             app.Data_Timedomine.XLim = [2,12];
             app.DATA_PSD.XLim = [5,60];
             app.Preview.Enable = "off";

             Beta_PeakfindButtonPushed(app)

             catch
                uialert(app.MainFigure,'执行失败,请重试','提示','Icon','warning')
             end
             close(app.waitting_NOW)
             
        end

        % Button pushed function: Scales_But
        function Scales_ButPushed(app, event)
           
            if app.Scales_But.Text == "查看全局"
                app.Data_Timedomine.XLim = [1,(length(app.Data_now(1,:)))/app.data_fs];
                app.Left_Push.Enable = "off";
                app.Right_Push.Enable = "off";
                app.Scales_But.Text = "查看数据帧";
            elseif app.Scales_But.Text == "查看数据帧"
                app.Data_Timedomine.XLim = [2,12];
                app.Left_Push.Enable = "on";
                app.Right_Push.Enable = "on";
                app.Scales_But.Text = "查看全局";
            end

        end

        % Value changed function: chi_choose
        function chi_chooseValueChanged(app, event)
            value = app.chi_choose.Value;
            i = value;
            temp = app.Data_Timedomine.XLim;
             cla(app.Data_Timedomine)
             plot(app.Data_Timedomine,(1:1:length(app.Data_ori(str2double(i),1000:end-600)))/app.data_fs,app.Data_ori(str2double(i),1000:end-600),"LineWidth",1.5);
             hold(app.Data_Timedomine,"on")
             plot(app.Data_Timedomine,(1:1:length(app.Data_now(str2double(i),1000:end-600)))/app.data_fs,app.Data_now(str2double(i),1000:end-600),"LineWidth",1.5);
             legend(app.Data_Timedomine,{'before','after'})
             hold(app.Data_Timedomine,"off")
             axis(app.Data_Timedomine, 'auto')
             if app.Scales_But.Text == "查看全局"
                app.Left_Push.Enable = "on";
                app.Right_Push.Enable = "on";
             elseif app.Scales_But.Text == "查看数据帧"
                app.Data_Timedomine.XLim = [2,12];
                app.Left_Push.Enable = "off";
                app.Right_Push.Enable = "off";
             end
               app.Data_Timedomine.XLim = temp;
               app.Data_Timedomine.YAxis.LimitsMode = 'auto';
        end

        % Button pushed function: Plot_ALL_Channl
        function Plot_ALL_ChannlButtonPushed(app, event)
               temp = app.Data_Timedomine.XLim;
            if app.Plot_ALL_Channl.Text == "全通道绘制"

               cla(app.Data_Timedomine)
               chinum = length(app.Rec_Chi_info);
               % wides = 10*chinum; % calcu adds
               pattern = '\d+'; % 匹配一个或多个数字
               numStr = regexp(app.Menu_20.Text, pattern, 'match');
                
                % 将字符串转换为数字
                num = str2double(numStr{1});

               for num_cy1 = 1:(chinum)
                     plot(app.Data_Timedomine,(1:1:length(app.Data_now((num_cy1),1000:end-600)))/app.data_fs,app.Data_now((num_cy1),1000:end-600)+num*num_cy1,"LineWidth",1)
                     app.Data_Timedomine.NextPlot = 'add';
               end

               legend(app.Data_Timedomine,app.Rec_Chi_info)
               app.chi_choose.Enable = "off";
               app.Plot_ALL_Channl.Text = "对比通道绘制";

             if chinum >= 6
                                     
                    customColors  = [
                        160, 160, 160;    % 浅灰色，曲线1 (代替深灰色)
                        80, 95, 120;      % 冷蓝灰色，曲线2 (增强蓝色)
                        160, 120, 90;     % 暖棕色调，曲线3 (带有橙红)
                        126, 142, 168;    % 亮灰色，曲线4
                        202, 215, 235;    % 浅蓝色，曲线5
                        45, 65, 50;       % 绿色调，曲线6
                        255, 153, 76;     % 柔和的橙色调，曲线7
                        200, 170, 210;    % 淡紫色，曲线8
                    ];
             elseif chinum <= 5 && chinum >= 3
                 customColors = [32, 37, 42;
                     128, 133, 138;
                    160, 165, 170;
                    192, 197, 202;
                     204, 209, 214;];
             else
                 customColors = [32, 37, 42;
                    140, 145, 150;
                     204, 209, 214;];
             end
             app.Data_Timedomine.ColorOrder = customColors/255;

            elseif app.Plot_ALL_Channl.Text == "对比通道绘制"
               value = app.chi_choose.Value;
               i = value;
               cla(app.Data_Timedomine)
               plot(app.Data_Timedomine,(1:1:length(app.Data_ori(str2double(i),1000:end-600)))/app.data_fs,app.Data_ori(str2double(i),1000:end-600),"LineWidth",1.5);
               hold(app.Data_Timedomine,"on")
               plot(app.Data_Timedomine,(1:1:length(app.Data_now(str2double(i),1000:end-600)))/app.data_fs,app.Data_now(str2double(i),1000:end-600),"LineWidth",1.5);
               legend(app.Data_Timedomine,{'before','after'})
    
             customColors = [160, 165, 170;32, 37, 42];
             app.Data_Timedomine.ColorOrder = customColors/255;

               hold(app.Data_Timedomine,"off")
               app.chi_choose.Enable = "on";
               app.Plot_ALL_Channl.Text = "全通道绘制";
            end
               app.Data_Timedomine.XLim = temp;
               app.Data_Timedomine.YAxis.LimitsMode = 'auto';

        end

        % Button pushed function: More_info
        function More_infoButtonPushed(app, event)
           uialert(app.MainFigure,app.deatiled,'详细信息','Icon','none')
        end

        % Menu selected function: Menu_21
        function Menu_21Selected(app, event)
            app.Menu_20.Text = "全通道间隔: 20";
        end

        % Menu selected function: Menu_22
        function Menu_22Selected(app, event)
            app.Menu_20.Text = "全通道间隔: 30";
        end

        % Menu selected function: Menu_23
        function Menu_23Selected(app, event)
            app.Menu_20.Text = "全通道间隔: 40";
        end

        % Menu selected function: Menu_29
        function Menu_29Selected(app, event)
            app.Menu_20.Text = "全通道间隔: 60";
        end

        % Menu selected function: Menu_30
        function Menu_30Selected(app, event)
            app.Menu_20.Text = "全通道间隔: 90";
        end

        % Menu selected function: Menu_31
        function Menu_31Selected(app, event)
            app.Menu_20.Text = "全通道间隔: 130";
        end

        % Double-clicked callback: UITable
        function UITableDoubleClicked(app, event)
            Row = event.InteractionInformation.DisplayRow;
            % displayColumn = event.InteractionInformation.DisplayColumn;
            % item = app.UITable.Data(displayRow);
            if Row <= height(app.UITable.Data)
            % app.filename.Value = 
               if istable(app.UITable.Data)
                  app.First_Filename = cell2mat(app.UITable.Data{Row,1});
               else
                  app.First_Filename = cell2mat(app.UITable.Data);
               end
            data_configButtonPushed(app, event)
            else
            end
        end

        % Button pushed function: Beta_Peakfind
        function Beta_PeakfindButtonPushed(app, event)
             app.waitting_NOW = uiprogressdlg(app.MainFigure,'Title','计算相关性中',...
            'Indeterminate','on');
            drawnow
            cla(app.DATA_PSD)
            try
                
                peakss = [];
                % Plot chi
                chinum = length(app.Rec_Chi_info);

                for num_cy1 = 1:(chinum)
                    plot(app.DATA_PSD,app.RESULT_DATA_PSD_FREQ,(app.line_all(:,(num_cy1))),"LineWidth",3)
                    app.DATA_PSD.NextPlot = 'add';
                end

                peaksValue = [];
                peaksFrequency = [];
            for i = 1:chinum
                x = app.RESULT_DATA_PSD_FREQ(50:40*10); % 
                y = (app.line_all(50:40*10,i)); % 

                fitType = fittype(@(a,b,c,x) a + b./(c+x), ...
                                   'independent', 'x', ...
                                   'dependent', 'y');

                [fitResult, ~] = fit(x, y, fitType, 'StartPoint', [1,1,1]);

                a = fitResult.a;
                b = fitResult.b;
                c = fitResult.c;
                xs = x;
                ys = a+b./(c+xs);
                OCI = y-ys;
                idx_freq = (x >= 13 & x <= 35);
                OCI_FIND = OCI(idx_freq);
                OCI_XDATA = x(idx_freq);
                ORI_OCI = y(idx_freq);
                
                [PKS,LOCS] = findpeaks(OCI_FIND);
                [~,IDX] = max(PKS);

                if isempty(PKS)
                   [~,IDX] = max(y);
                   LOCS = x(IDX+50);
                end

                maxpeak_locs = OCI_XDATA(round(LOCS(IDX)));
                maxpeak_pks = ORI_OCI(round(LOCS(IDX)));
    
                app.DATA_PSD.NextPlot = 'add';

                scatter(app.DATA_PSD,maxpeak_locs, maxpeak_pks, 50, 'o', 'MarkerEdgeColor', 'k', 'MarkerFaceColor', 'k', 'MarkerFaceAlpha', 0.5, 'HandleVisibility', 'off');
                % hold on;
                app.DATA_PSD.NextPlot = 'add';
                peakss = [peakss,[app.chi_choose.Items{i},':' , num2str(maxpeak_locs),'Hz ']];
                peaksValue = [peaksValue;maxpeak_pks];
                peaksFrequency = [peaksFrequency;maxpeak_locs];
                message = sprintf(['β震荡峰值频率识别: \n',num2str(peakss), '  \n 注:  结果由 1/f 以上部分拟合决定.']);

            end

            [Peakssss,loca] = max(peaksValue);
            locations = peaksFrequency(loca);
            line(app.DATA_PSD,[5 locations], [Peakssss Peakssss], 'LineStyle', '--', 'Color', 'k' ,'LineWidth', 2);

            line(app.DATA_PSD,[locations locations], [0 Peakssss], 'LineStyle', '--', 'Color', 'k' ,'LineWidth', 2);

            strsss = sprintf('  (%0.1fHz,%0.2fµV^{2}/Hz)', locations, Peakssss);
            text(app.DATA_PSD,locations, Peakssss, strsss, 'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'left', 'FontSize', 15, 'Color', 'k');

             a = legend(app.DATA_PSD,app.chi_choose.Items);
             set(a, 'FontSize', 10); % 将图例字体大小设置为14
             % Set New Color order
             [~, sorted_indices] = sort(peaksValue, 'descend');
             ranks = zeros(size(peaksValue));
             for rank = 1:length(sorted_indices)
                 ranks(sorted_indices(rank)) = rank - 1;
             end

             uiconfirm(app.MainFigure,message,'β震荡峰值识别结果','Icon','success');


             if chinum >= 6
                    customColors  = [
                        160, 160, 160;    % 浅灰色，曲线1 (代替深灰色)
                        80, 95, 120;      % 冷蓝灰色，曲线2 (增强蓝色)
                        160, 120, 90;     % 暖棕色调，曲线3 (带有橙红)
                        126, 142, 168;    % 亮灰色，曲线4
                        202, 215, 235;    % 浅蓝色，曲线5
                        45, 65, 50;       % 绿色调，曲线6
                        255, 153, 76;     % 柔和的橙色调，曲线7
                        200, 170, 210;    % 淡紫色，曲线8
                    ];
             elseif chinum <= 5 && chinum >= 3
                 customColors = [32, 37, 42;
                     128, 133, 138;
                    160, 165, 170;
                    192, 197, 202;
                     204, 209, 214;];
             else
                 customColors = [32, 37, 42;
                    140, 145, 150;
                     204, 209, 214;];
             end
             app.DATA_PSD.YLabel.String = '能量(μV²/Hz)';
             app.DATA_PSD.XLabel.String = '频率(Hz)';

             app.DATA_PSD.ColorOrder = customColors/255;

             app.DATA_PSD.YLim = [0,Peakssss*2];
             close(app.waitting_NOW)
            catch
             close(app.waitting_NOW)
            end

        end

        % Button pushed function: Research_report
        function Research_reportButtonPushed(app, event)
            try
                        % 1. Basic setup
                        app.waitting_NOW = uiprogressdlg(app.MainFigure,'Title','生成分析报告中，可能需要一定时间',...
                            'Indeterminate','on');
                        drawnow
                        
                        % 1 for English report, 0 for Chinese report
                        IS_ENG = 0;
                        
                        % 2. Subject and acquisition information
                        subject_name = cell2mat(app.current_IPGfile.Value);  % Subject name
                        handler_name = app.Researcher_Name.Value;  % Operator name
                        institution_name = 'NNSR分析工具生成';  % Institution name
                        project_name = 'DBS-LFP脑电信号分析（测试版）';  % Project name
                        
                        % 3. Channel information setup
                        channel_montage = app.Rec_Chi_info;
                        
                        % 4. Acquisition and stimulation information
                        acquisition_mode = app.data_acqs_info;
                        extra_info = app.data_extra_info;
                        
                        % 5. Preprocessing operation flags
                        IS_ECG_REMOVED = 1;
                        IS_PULSE_REMOVED = 1;
                        IS_MOVE_PROCESSED = 1;
                        IS_FILTERED = 1;
                        IS_NOTCH = 0;
                        IS_BASELINE_CORRECTED = 1;
                        
                        % 6. Data assignment and generation
                        dataMatrix = app.Data_now(:,2*app.data_fs:end);
                        data_FS = app.data_fs;
                        data_duration_seconds = size(dataMatrix, 2) / data_FS;
                        data_duration_minutes = data_duration_seconds / 60;
                        
                        % 7. Artifact detection results display
                        IS_ECG = app.IS_ECGS;
                        IS_STM = app.IS_STMS;
                        IS_MOVE = app.IS_MOVES;
                        
                        % 8. Frequency band ranges
                        delta_range = [2 4];
                        theta_range = [4 8];
                        alpha_range = [8 13];
                        beta_range = [13 30];
                        gamma_range = [30 80];
                        
                        % Color settings
                        color_delta = [153, 204, 255]/255;
                        color_theta = [51, 153, 255]/255;
                        color_alpha = [204, 102, 204]/255;
                        color_beta = [255, 153, 51]/255;
                        color_gamma = [255, 102, 102]/255;
                    
                        % Prepare text resources
                        TEXT = struct();
                        TEXT.preproc_operations = {};
                        if IS_ENG
                            % English text resources (keeping original English definitions)
                            % ... [Keep original English text section as is]
                        else
                            % Chinese text resources
                            TEXT.title = '信号分析报告';
                            TEXT.subject = '被试';
                            TEXT.handler = '操作员';
                            TEXT.institution = '神经信号分析实验室';
                            TEXT.project = '脑电信号分析项目';
                            TEXT.acquisition_mode = '采集方式';
                            TEXT.stimulus_info = '刺激信息';
                            
                            % Menu and titles
                            TEXT.menu = '目录';
                            TEXT.summary = '执行摘要';
                            TEXT.acquisition = '采集与刺激信息';
                            TEXT.preprocessing = '数据预处理';
                            TEXT.overview = '信号总览';
                            TEXT.analysis = '通道详细分析';
                            TEXT.methods = '计算方法说明';
                            TEXT.conclusion = '分析结论';
                            TEXT.appendix = '附录';
                            
                            % Channel related
                            TEXT.channel = '通道';
                            TEXT.channel_config = '通道配置';
                            TEXT.channel_num = '通道';
                            TEXT.electrode_config = '电极配置';
                            
                            % Acquisition information
                            TEXT.acq_info = '采集信息';
                            TEXT.acq_mode = '采集方式:';
                            TEXT.sampling_rate = '采样率:';
                            TEXT.data_length = '数据长度:';
                            TEXT.stim_info = '刺激信息';
                            
                            % Preprocessing
                            TEXT.preproc_steps = '原始数据经过以下预处理步骤以提高信号质量: ';
                            
                            % Artifact detection
                            TEXT.artifact_title = '伪迹识别结果';
                            TEXT.artifact_desc = '展示各通道在不同时间段的伪迹识别结果。绿色表示无伪迹，黄色表示心电或刺激伪迹，红色表示运动伪迹。';
                            TEXT.artifact_legend = '图例:';
                            TEXT.no_artifact = '无伪迹';
                            TEXT.ecg_stim_artifact = '心电/刺激伪迹';
                            TEXT.move_artifact = '运动伪迹';
                            TEXT.artifact_stats = '伪迹识别统计';
                            TEXT.artifact_type = '伪迹类型';
                            TEXT.segment_count = '片段数量';
                            TEXT.total = '总计';
                            
                            % Signal overview
                            TEXT.overview_desc = '下图展示了所有%d个通道的功率谱密度和时域信号。频段以不同颜色标记: Delta (%.1f-%.1f Hz)、Theta (%.1f-%.1f Hz)、Alpha (%.1f-%.1f Hz)、Beta (%.1f-%.1f Hz) 和 Gamma (%.1f-%.1f Hz)。';
                            TEXT.psd_overview = '各通道功率谱密度总览';
                            TEXT.time_overview = '各通道信号时域总览';
                            TEXT.overview_caption = '图2: 所有通道的频谱和时域数据总览。上图展示功率谱密度，下图展示归一化时域信号。';
                            TEXT.time_sec = '时间 (秒)';
                            TEXT.freq_hz = '频率 (Hz)';
                            TEXT.power_density = '功率密度 (dB/Hz)';
                            TEXT.norm_amp = '归一化幅度';
                            
                            % Channel analysis
                            TEXT.ch_analysis_desc = '本章节提供每个通道的详细分析，包括时域波形、频谱能量分布以及时频分析。计算了各频段的能量百分比和Beta频段的峰值特征。';
                            TEXT.ch_time_domain = '时域信号';
                            TEXT.ch_time_freq = '时频图';
                            TEXT.ch_psd = '功率谱密度';
                            TEXT.power_db = '功率 (dB)';
                            TEXT.norm_power = '归一化功率 (a.u.)';
                            TEXT.ch_analysis_caption = '图%d: 通道 %s 的详细分析，包括时域波形（上）、时频分析（中）和功率谱密度（下）。';
                            TEXT.band_energy = '通道 %s 频段能量分析';
                            TEXT.freq_band = '频段';
                            TEXT.freq_range = '频率范围 (Hz)';
                            TEXT.rel_energy = '相对能量 (%)';
                            TEXT.band_energy_caption = '表%d: 通道 %s 的频段能量分布。';
                            
                            % Beta peak
                            TEXT.beta_peak = '通道 %s 的Beta峰值检测';
                            TEXT.parameter = '参数';
                            TEXT.value = '值';
                            TEXT.beta_peak_freq = 'Beta峰值频率';
                            TEXT.beta_peak_power = 'Beta峰值功率';
                            TEXT.beta_peak_band = 'Beta峰值频带 (±2Hz)';
                            TEXT.beta_band_energy = 'Beta峰值频带能量占比';
                            TEXT.dominant_band = '主导频段';
                            TEXT.peak_band = 'Peak Band:';
                            TEXT.beta_peak_caption = '表%d: 通道 %s 的Beta频段特征。';
                            
                            % Methods description
                            TEXT.methods_desc = '本报告使用的主要分析方法和参数如下:';
                            TEXT.analysis_type = '分析类型';
                            TEXT.method = '方法';
                            TEXT.parameters = '参数';
                            TEXT.time_freq_analysis = '时频分析';
                            TEXT.stft = '短时傅里叶变换 (STFT)';
                            TEXT.window_overlap = '窗长: %d, 重叠: %d (75%%), FFT点数: %d';
                            TEXT.psd_method = '功率谱密度';
                            TEXT.welch_method = 'Welch方法';
                            TEXT.band_energy_method = '频段能量';
                            TEXT.band_integration = '频段积分';
                            TEXT.band_sum = '各频段PSD值求和';
                            TEXT.beta_peak_method = 'Beta峰值检测';
                            TEXT.background_fitting = '1/f背景拟合与峰值检测';
                            TEXT.fitting_function = '拟合函数: a + b/(c+x), 寻找残差最大值';
                            
                            % Band definitions
                            TEXT.band_definitions = '频段定义:';
                            TEXT.cognitive_state = '通常与之相关的认知/生理状态';
                            TEXT.delta_state = '与深度睡眠相关';
                            TEXT.theta_state = '与学习和记忆过程相关';
                            TEXT.alpha_state = '与运动任务和认知任务执行相关';
                            TEXT.beta_state = '在运动控制中起关键作用，与PD患者的STN区域β波活动相关';
                            TEXT.gamma_state = '与高级认知功能和感知处理相关';
                            
                            % Method details
                            TEXT.methods_detail = '本报告采用标准的信号处理方法对脑电数据进行分析。时频分析使用短时傅里叶变换，频域分析使用Welch方法估计功率谱密度。频段能量通过积分相应频率范围内的功率谱密度值计算。Beta峰值检测采用1/f背景拟合方法。';
                            
                            % Conclusion section
                            TEXT.conclusion_intro = '基于对%d个通道的信号分析，得出以下主要结论: ';
                            TEXT.dominant_band_conclusion = '1. 主导频段: 数据中主要表现为%s波（占总能量的%.2f%%）。';
                            TEXT.beta_energy_ranking = '2. 通道Beta能量排序: 各通道按Beta能量百分比从高到低排序如下: ';
                            TEXT.rank = '排名';
                            TEXT.beta_energy = 'Beta能量占比';
                            TEXT.beta_peak_band_pct = 'Beta峰值频带占比';
                            
                            % Hemisphere comparison
                            TEXT.hemisphere_comparison = '3. 左右脑对比:';
                            TEXT.left_max_beta = '左侧脑区Beta能量最高的是通道 %s, 占比为%.2f%%';
                            TEXT.right_max_beta = '右侧脑区Beta能量最高的是通道 %s, 占比为%.2f%%';
                            TEXT.left_higher = '左侧脑区平均Beta能量（%.2f%%）高于右侧脑区（%.2f%%）';
                            TEXT.right_higher = '右侧脑区平均Beta能量（%.2f%%）高于左侧脑区（%.2f%%）';
                            
                            % Signal quality conclusion
                            TEXT.signal_quality = '4. 信号质量评估: ';
                            TEXT.quality_high = '数据质量较高，无伪迹的片段占%.2f%%.';
                            TEXT.quality_medium = '数据质量中等，无伪迹的片段占%.2f%%.';
                            TEXT.quality_low = '数据质量较低，无伪迹的片段仅占%.2f%%.';
                            TEXT.movement_main = '运动伪迹是主要干扰源，占%.2f%%.';
                            TEXT.ecg_main = '心电伪迹是主要干扰源，占%.2f%%.';
                            TEXT.stim_main = '刺激伪迹是主要干扰源，占%.2f%%.';
                            
                            % Preprocessing explanation
                            TEXT.preproc_explanation = '5. 预处理说明: 本分析对原始数据进行了以下预处理: ';
                            
                            % Disclaimer
                            TEXT.disclaimer = '本报告提供的分析结果仅供研究参考，不适用于临床诊断。';
                            
                            % Spectrum distribution chart
                            TEXT.spectrum_caption = '各频段平均能量百分比分布。';
                            TEXT.avg_energy = '平均能量百分比 (%)';
                            TEXT.band_energy_dist = '各频段平均能量分布';
                            
                            % Appendix
                            TEXT.appendix_intro = '以下为全部分析数据的汇总表格:';
                            TEXT.avg_values = '平均值';
                            
                            % Report footer
                            TEXT.report_footer = '报告生成于 %s | %s | %s';
                            
                            % Additional fields
                            TEXT.references = '参考资料:';
                            TEXT.fig = '图';
                            TEXT.table = '表';
                            TEXT.second = '秒';
                            TEXT.minute = '分钟';
                            TEXT.hour = '小时';
                            TEXT.timestamp = 'yyyy-mm-dd HH:MM:SS';
                            TEXT.research_only = '仅供研究使用 · 不做临床建议';
                            
                            % Time and units
                            TEXT.time_stamp = '报告生成时间:';
                            TEXT.channel_count = '通道数量:';
                            TEXT.sample_count = '样本:';
                            TEXT.hz = 'Hz';
                            TEXT.seconds = '秒';
                            TEXT.minutes = '分钟';
                            
                            % Preprocessing operations
                            TEXT.preproc_operations = {};
                            if IS_ECG_REMOVED
                                TEXT.preproc_operations{end+1} = '心电伪迹去除: 模版匹配法';
                            end
                            if IS_PULSE_REMOVED
                                TEXT.preproc_operations{end+1} = '电脉冲伪迹去除: 滤波器 + 自适应滤波';
                            end
                            if IS_MOVE_PROCESSED
                                TEXT.preproc_operations{end+1} = '运动伪迹处理: 端点检测 + 片段剔除';
                            end
                            if IS_FILTERED
                                TEXT.preproc_operations{end+1} = '带通滤波: 0.5-80Hz, FIR滤波器, 阶数: 1000';
                            end
                            if IS_NOTCH
                                TEXT.preproc_operations{end+1} = '陷波滤波: 50Hz, Q因子: 35';
                            end
                            if IS_BASELINE_CORRECTED
                                TEXT.preproc_operations{end+1} = '基线漂移校正: 高通滤波, 截止频率: 0.5Hz';
                            end
                        end
                        
                        % Create color structure
                        colors = struct();
                        colors.delta = color_delta;
                        colors.theta = color_theta;
                        colors.alpha = color_alpha;
                        colors.beta = color_beta;
                        colors.gamma = color_gamma;
                        colors.line = lines(size(dataMatrix, 1));
                        
                        % Frequency band structure
                        bands = struct();
                        bands.delta = delta_range;
                        bands.theta = theta_range;
                        bands.alpha = alpha_range;
                        bands.beta = beta_range;
                        bands.gamma = gamma_range;
                        bands.names = {'Delta', 'Theta', 'Alpha', 'Beta', 'Gamma'};
                        
                        % Create output folder
                        outputDir = fullfile(app.Save_Path.Value{1}, 'signal_analysis_report');
                        if ~exist(outputDir, 'dir')
                            mkdir(outputDir);
                        end
                        
                        % Create image folder
                        imgDir = fullfile(outputDir, 'images');
                        if ~exist(imgDir, 'dir')
                            mkdir(imgDir);
                        end
                        
                        % Define image format and settings - white background with black text
                        imgSettings = struct();
                        imgSettings.resolution = '-r300';
                        imgSettings.format = '-dpng';
                        imgSettings.fontSizeTitle = 13;
                        imgSettings.fontSizeLabel = 11;
                        imgSettings.fontSizeText = 9;
                        imgSettings.lineWidth = 1.5;
                        imgSettings.backgroundColor = [1 1 1];  % White background
                        imgSettings.textColor = [0 0 0];        % Black text
                        
                        % Improve PSD resolution - increase FFT points
                        if data_duration_minutes > 20
                            window_length = round(data_FS * 2);
                            nfft = window_length * 4;  % Increase FFT points for better resolution
                            overlap = round(window_length * 0.75);
                        else
                            window_length = round(data_FS);
                            nfft = window_length * 4;  % Increase FFT points for better resolution
                            overlap = round(window_length * 0.75);
                        end
                        
                        % Initialize storage structure
                        analysisData = struct();
                        analysisData.metadata = struct();
                        analysisData.metadata.subject_name = subject_name;
                        analysisData.metadata.handler_name = handler_name;
                        analysisData.metadata.data_FS = data_FS;
                        analysisData.metadata.data_duration_seconds = data_duration_seconds;
                        analysisData.metadata.channel_montage = channel_montage;
                        analysisData.metadata.bands = bands;
                        analysisData.metadata.analysis_parameters = struct('window_length', window_length, 'nfft', nfft, 'overlap', overlap);
                        
                        % Pre-allocate arrays to store all channel data
                        num_channels = size(dataMatrix, 1);
                        allDeltaP = zeros(1, num_channels);
                        allThetaP = zeros(1, num_channels);
                        allAlphaP = zeros(1, num_channels);
                        allBetaP = zeros(1, num_channels);
                        allGammaP = zeros(1, num_channels);
                        allBetaPeakFreq = zeros(1, num_channels);
                        allBetaPeakAmp = zeros(1, num_channels);
                        allBetaPeakBandP = zeros(1, num_channels);
                        
                        % Store PSD and STFT data
                        analysisData.channels = cell(num_channels, 1);
                        
                        % Generate artifact detection and overview figures before processing channels
                        fprintf('Generating artifact detection figure...\n');
                        P01_generateArtifactDetectionFigure_Modified(app, imgDir, IS_ECG, IS_STM, IS_MOVE, imgSettings, TEXT);
                        
                        fprintf('Generating signal overview figure...\n');
                        P01_generateSignalOverviewFigure_Modified(app, imgDir, dataMatrix, data_FS, bands, colors, imgSettings, TEXT, app.IS_MOVES);
                        
                        % Create HTML report file
                        reportFileName = fullfile(outputDir, ['signal_analysis_report_', datestr(now, 'yyyymmdd_HHMMSS'), '.html']);
                        fid = fopen(reportFileName, 'w');
                        
                        % Write HTML header
                        fprintf(fid, '<!DOCTYPE html>\n');
                        fprintf(fid, '<html>\n');
                        fprintf(fid, '<head>\n');
                        fprintf(fid, '<meta charset="UTF-8">\n');
                        fprintf(fid, '<title>%s</title>\n', TEXT.title);
                        % CSS styles
                        fprintf(fid, '<style>\n');
                        fprintf(fid, 'body { font-family: Arial, sans-serif; margin: 40px; line-height: 1.6; color: #333; }\n');
                        fprintf(fid, 'h1 { color: #004B87; }\n');
                        fprintf(fid, 'h2 { color: #004B87; margin-top: 30px; border-bottom: 1px solid #eee; padding-bottom: 10px; }\n');
                        fprintf(fid, 'h3 { color: #3264A0; }\n');
                        fprintf(fid, 'h4 { color: #3264A0; margin-top: 20px; }\n');
                        fprintf(fid, 'table { border-collapse: collapse; width: 100%%; margin: 20px 0; }\n');
                        fprintf(fid, 'table, th, td { border: 1px solid #ddd; }\n');
                        fprintf(fid, 'th, td { padding: 10px; text-align: center; }\n');
                        fprintf(fid, 'th { background-color: #f2f2f2; }\n');
                        fprintf(fid, '.header { background-color: #f8f9fa; padding: 25px; margin-bottom: 30px; border-radius: 8px; box-shadow: 0 2px 5px rgba(0,0,0,0.1); position: relative; }\n');
                        fprintf(fid, '.figure { text-align: center; margin: 25px 0; }\n');
                        fprintf(fid, '.figure img { max-width: 100%%; box-shadow: 0 2px 8px rgba(0,0,0,0.1); border-radius: 5px; }\n');
                        fprintf(fid, '.figure-caption { font-style: italic; color: #666; margin-top: 10px; }\n');
                        fprintf(fid, '.conclusion { background-color: #f0f8ff; padding: 20px; border-radius: 8px; margin: 25px 0; box-shadow: 0 2px 5px rgba(0,0,0,0.05); }\n');
                        fprintf(fid, '.delta-bg { background-color: rgba(153, 204, 255, 0.3); }\n');
                        fprintf(fid, '.theta-bg { background-color: rgba(51, 153, 255, 0.3); }\n');
                        fprintf(fid, '.alpha-bg { background-color: rgba(204, 102, 204, 0.3); }\n');
                        fprintf(fid, '.beta-bg { background-color: rgba(255, 153, 51, 0.3); }\n');
                        fprintf(fid, '.gamma-bg { background-color: rgba(255, 102, 102, 0.3); }\n');
                        fprintf(fid, '</style>\n');
                        fprintf(fid, '</head>\n');
                        fprintf(fid, '<body>\n');
                        
                        % Add cover information
                        fprintf(fid, '<div class="header">\n');
                        fprintf(fid, '<h1 style="text-align: center;">%s</h1>\n', TEXT.title);
                        fprintf(fid, '<h3 style="text-align: center;">%s</h3>\n', project_name);
                        fprintf(fid, '<table style="width: 80%%; margin: 20px auto; border: 1px solid #ddd;">\n');
                        fprintf(fid, '<tr><td style="text-align: right; font-weight: bold;">%s:</td><td>%s</td></tr>\n', TEXT.subject, subject_name);
                        fprintf(fid, '<tr><td style="text-align: right; font-weight: bold;">%s:</td><td>%s</td></tr>\n', TEXT.handler, handler_name);
                        fprintf(fid, '<tr><td style="text-align: right; font-weight: bold;">%s</td><td>%s</td></tr>\n', '报告生成时间:', datestr(now, 'yyyy-mm-dd HH:MM:SS'));
                        fprintf(fid, '<tr><td style="text-align: right; font-weight: bold;">%s</td><td>%d</td></tr>\n', '通道数量:', size(dataMatrix, 1));
                        fprintf(fid, '<tr><td style="text-align: right; font-weight: bold;">%s</td><td>%d %s</td></tr>\n', TEXT.sampling_rate, data_FS, 'Hz');
                        fprintf(fid, '<tr><td style="text-align: right; font-weight: bold;">%s</td><td>%d %s (%.2f %s)</td></tr>\n', TEXT.data_length, size(dataMatrix, 2), '样本', size(dataMatrix, 2)/data_FS, '秒');
                        try
                            fprintf(fid, '<tr><td style="text-align: right; font-weight: bold;">%s</td><td>%s</td></tr>\n', TEXT.acq_mode, acquisition_mode);
                        catch
                            try
                            acquisition_mode = cell2mat(acquisition_mode);
                            fprintf(fid, '<tr><td style="text-align: right; font-weight: bold;">%s</td><td>%s</td></tr>\n', TEXT.acq_mode, acquisition_mode);
                            end
                        end
                        fprintf(fid, '</table>\n');
                        fprintf(fid, '<p style="text-align: center; color: #888; font-size: 12px;">仅供研究使用 · 不做临床建议</p>\n');
                        fprintf(fid, '</div>\n');
                        
                        % Add table of contents
                        fprintf(fid, '<h2>%s</h2>\n', TEXT.menu);
                        fprintf(fid, '<ul>\n');
                        fprintf(fid, '<li><a href="#summary">1. %s</a></li>\n', TEXT.summary);
                        fprintf(fid, '<li><a href="#acquisition">2. %s</a></li>\n', TEXT.acquisition);
                        fprintf(fid, '<li><a href="#preprocessing">3. %s</a></li>\n', TEXT.preprocessing);
                        fprintf(fid, '<li><a href="#overview">4. %s</a></li>\n', TEXT.overview);
                        fprintf(fid, '<li><a href="#analysis">5. %s</a>\n', TEXT.analysis);
                        fprintf(fid, '<ul>\n');
                        for ch = 1:size(dataMatrix, 1)
                            fprintf(fid, '<li><a href="#channel%d">5.%d %s %s %s</a></li>\n', ch, ch, TEXT.channel, channel_montage{ch}, TEXT.analysis);
                        end
                        fprintf(fid, '</ul>\n');
                        fprintf(fid, '</li>\n');
                        fprintf(fid, '<li><a href="#methods">6. %s</a></li>\n', TEXT.methods);
                        fprintf(fid, '<li><a href="#conclusion">7. %s</a></li>\n', TEXT.conclusion);
                        fprintf(fid, '<li><a href="#appendix">8. %s</a></li>\n', TEXT.appendix);
                        fprintf(fid, '</ul>\n');
                        
                        % Add executive summary
                        fprintf(fid, '<h2 id="summary">1. %s</h2>\n', TEXT.summary);
                        fprintf(fid, '<p>本报告分析了记录自%s的脑电信号数据。数据包含%d个通道，采样率为%d Hz，总计%.2f秒的记录。分析包括时域分析、频域分析和时频联合分析。各频段的能量分布已计算并显示在报告中。</p>\n', subject_name, size(dataMatrix, 1), data_FS, size(dataMatrix, 2)/data_FS);
                        
                        % Add acquisition and stimulation information section
                        fprintf(fid, '<h2 id="acquisition">2. %s</h2>\n', TEXT.acquisition);
                        fprintf(fid, '<div class="info-section">\n');
                        
                        % Channel configuration information
                        fprintf(fid, '<div class="info-column">\n');
                        fprintf(fid, '<div class="info-box">\n');
                        fprintf(fid, '<h4>%s</h4>\n', TEXT.channel_config);
                        fprintf(fid, '<table>\n');
                        fprintf(fid, '<tr><th>%s</th><th>%s</th></tr>\n', TEXT.channel_num, TEXT.electrode_config);
                        for ch = 1:length(channel_montage)
                            fprintf(fid, '<tr><td>%d</td><td>%s</td></tr>\n', ch, channel_montage{ch});
                        end
                        fprintf(fid, '</table>\n');
                        fprintf(fid, '</div>\n');
                        fprintf(fid, '</div>\n');
                        
                        % Acquisition information
                        fprintf(fid, '<div class="info-column">\n');
                        fprintf(fid, '<div class="info-box">\n');
                        fprintf(fid, '<h4>%s</h4>\n', TEXT.acq_info);
                        fprintf(fid, '<table>\n');
                        try
                        fprintf(fid, '<tr><td style="text-align: right; font-weight: bold;">%s</td><td>%s</td></tr>\n', TEXT.acq_mode, acquisition_mode);
                        end
                        fprintf(fid, '<tr><td style="text-align: right; font-weight: bold;">%s</td><td>%d %s</td></tr>\n', TEXT.sampling_rate, data_FS, TEXT.hz);
                        fprintf(fid, '<tr><td style="text-align: right; font-weight: bold;">%s</td><td>%.2f %s</td></tr>\n', TEXT.data_length, data_duration_minutes, TEXT.minutes);
                        fprintf(fid, '</table>\n');
                        fprintf(fid, '</div>\n');
                        
                        % Stimulation information
                        fprintf(fid, '<div class="info-box">\n');
                        fprintf(fid, '<h4>%s</h4>\n', TEXT.stim_info);
                        fprintf(fid, '<p>%s</p>\n', extra_info);
                        fprintf(fid, '</div>\n');
                        fprintf(fid, '</div>\n');
                        fprintf(fid, '</div>\n');
                        
                        % Add data preprocessing section
                        fprintf(fid, '<h2 id="preprocessing">3. %s</h2>\n', TEXT.preprocessing);
                        fprintf(fid, '<p>%s</p>\n', TEXT.preproc_steps);
                        fprintf(fid, '<div class="preprocessing-list">\n');
                        for i = 1:length(TEXT.preproc_operations)
                            fprintf(fid, '<div class="preprocessing-item"><strong>%d.</strong> %s</div>\n', i, TEXT.preproc_operations{i});
                        end
                        fprintf(fid, '</div>\n');
                        
                        % Add artifact identification results section
                        fprintf(fid, '<h3 id="artifact">%s</h3>\n', TEXT.artifact_title);
                        fprintf(fid, '<p>%s</p>\n', TEXT.artifact_desc);
                        
                        % Add artifact detection figure to HTML
                        fprintf(fid, '<div class="figure">\n');
                        fprintf(fid, '<img src="images/artifact_detection.png" alt="%s">\n', TEXT.artifact_title);
                        fprintf(fid, '<p class="figure-caption">%s1: %s</p>\n', TEXT.fig, TEXT.artifact_desc);
                        fprintf(fid, '</div>\n');
                        
                        % Add artifact statistics
                        fprintf(fid, '<h4>%s</h4>\n', TEXT.artifact_stats);
                        [numChannels_artifact, numSegments_artifact] = size(IS_ECG);
                        
                        % Create statistics table including all channels
                        fprintf(fid, '<table style="width: 90%%; margin: 0 auto;">\n');
                        fprintf(fid, '<tr><th>%s</th>', TEXT.artifact_type);
                        % Add header for each channel
                        for ch = 1:numChannels_artifact
                            fprintf(fid, '<th>%s %s</th>', TEXT.channel, channel_montage{ch});
                        end
                        fprintf(fid, '</tr>\n');
                        
                        % No artifact row
                        fprintf(fid, '<tr><td>%s</td>', TEXT.no_artifact);
                        for ch = 1:numChannels_artifact
                            clean_count = numSegments_artifact - sum(IS_ECG(ch, :)) - sum(IS_STM(ch, :)) - sum(IS_MOVE(ch, :));
                            fprintf(fid, '<td>%d</td>', clean_count);
                        end
                        fprintf(fid, '</tr>\n');
                        
                        % ECG artifact row
                        fprintf(fid, '<tr><td>ECG</td>');
                        for ch = 1:numChannels_artifact
                            fprintf(fid, '<td>%d</td>', sum(IS_ECG(ch, :)));
                        end
                        fprintf(fid, '</tr>\n');
                        
                        % Stimulation artifact row
                        fprintf(fid, '<tr><td>STM</td>');
                        for ch = 1:numChannels_artifact
                            fprintf(fid, '<td>%d</td>', sum(IS_STM(ch, :)));
                        end
                        fprintf(fid, '</tr>\n');
                        
                        % Movement artifact row
                        fprintf(fid, '<tr><td>%s</td>', TEXT.move_artifact);
                        for ch = 1:numChannels_artifact
                            fprintf(fid, '<td>%d</td>', sum(IS_MOVE(ch, :)));
                        end
                        fprintf(fid, '</tr>\n');
                        
                        % Total row
                        fprintf(fid, '<tr style="font-weight: bold;"><td>%s</td>', TEXT.total);
                        for ch = 1:numChannels_artifact
                            fprintf(fid, '<td>%d</td>', numSegments_artifact);
                        end
                        fprintf(fid, '</tr>\n');
                        fprintf(fid, '</table>\n');
                        
                        % Add overview section to HTML
                        fprintf(fid, '<h2 id="overview">4. %s</h2>\n', TEXT.overview);
                        fprintf(fid, '<p>%s</p>\n', sprintf(TEXT.overview_desc, size(dataMatrix, 1), bands.delta(1), bands.delta(2), bands.theta(1), bands.theta(2), bands.alpha(1), bands.alpha(2), bands.beta(1), bands.beta(2), bands.gamma(1), bands.gamma(2)));
                        
                        fprintf(fid, '<div class="figure">\n');
                        fprintf(fid, '<img src="images/overview.png" alt="%s">\n', TEXT.overview);
                        fprintf(fid, '<p class="figure-caption">%s</p>\n', TEXT.overview_caption);
                        fprintf(fid, '</div>\n');
                        
                        % Add detailed channel analysis section
                        fprintf(fid, '<h2 id="analysis">5. %s</h2>\n', TEXT.analysis);
                        fprintf(fid, '<p>%s</p>\n', TEXT.ch_analysis_desc);
                        
                        % Generate detailed analysis for each channel
                        for ch = 1:num_channels
                            fprintf('Processing channel %d (%s)...\n', ch, channel_montage{ch});
                            
                            % Add channel title to HTML
                            fprintf(fid, '<h3 id="channel%d">5.%d %s %s %s</h3>\n', ch, ch, TEXT.channel, channel_montage{ch}, TEXT.analysis);
                            
                            % Use modified channel analysis function
                            [channelFileName, betaPeakFreq, betaPeakValue, betaPeakWidth, deltaP, thetaP, alphaP, betaP, gammaP, betaPeakBandP, channelData] = P01_generateChannelAnalysisFigure_Modified(app, imgDir, ch, channel_montage{ch}, dataMatrix, data_FS, bands, colors, window_length, overlap, nfft, imgSettings, TEXT, app.IS_MOVES);
                            
                            % Save frequency band energy percentage data and Beta peak data
                            allDeltaP(ch) = deltaP;
                            allThetaP(ch) = thetaP;
                            allAlphaP(ch) = alphaP;
                            allBetaP(ch) = betaP;
                            allGammaP(ch) = gammaP;
                            allBetaPeakFreq(ch) = betaPeakFreq;
                            allBetaPeakAmp(ch) = betaPeakValue;
                            allBetaPeakBandP(ch) = betaPeakBandP;
                            
                            % Store channel data
                            analysisData.channels{ch} = channelData;
                            
                            % Add image to HTML
                            fprintf(fid, '<div class="figure">\n');
                            fprintf(fid, '<img src="images/channel_%d.png" alt="%s %s %s">\n', ch, TEXT.channel, channel_montage{ch}, TEXT.analysis);
                            fprintf(fid, '<p class="figure-caption">%s%d: %s</p>\n', TEXT.fig, ch+2, sprintf(TEXT.ch_analysis_caption, ch+2, channel_montage{ch}));
                            fprintf(fid, '</div>\n');
                            
                            % Add frequency band energy table
                            fprintf(fid, '<h4>%s</h4>\n', sprintf(TEXT.band_energy, channel_montage{ch}));
                            fprintf(fid, '<table>\n');
                            fprintf(fid, '<tr><th>%s</th><th>%s</th><th>%s</th></tr>\n', TEXT.freq_band, TEXT.freq_range, TEXT.rel_energy);
                            fprintf(fid, '<tr class="delta-bg"><td>Delta</td><td>%.1f-%.1f</td><td>%.2f%%</td></tr>\n', bands.delta(1), bands.delta(2), deltaP);
                            fprintf(fid, '<tr class="theta-bg"><td>Theta</td><td>%.1f-%.1f</td><td>%.2f%%</td></tr>\n', bands.theta(1), bands.theta(2), thetaP);
                            fprintf(fid, '<tr class="alpha-bg"><td>Alpha</td><td>%.1f-%.1f</td><td>%.2f%%</td></tr>\n', bands.alpha(1), bands.alpha(2), alphaP);
                            fprintf(fid, '<tr class="beta-bg"><td>Beta</td><td>%.1f-%.1f</td><td>%.2f%%</td></tr>\n', bands.beta(1), bands.beta(2), betaP);
                            fprintf(fid, '<tr class="gamma-bg"><td>Gamma</td><td>%.1f-%.1f</td><td>%.2f%%</td></tr>\n', bands.gamma(1), bands.gamma(2), gammaP);
                            fprintf(fid, '</table>\n');
                            fprintf(fid, '<p class="figure-caption">%s%d: %s</p>\n', TEXT.table, ch, sprintf(TEXT.band_energy_caption, ch, channel_montage{ch}));
                            
                            % Add Beta peak information
                            fprintf(fid, '<h4>%s</h4>\n', sprintf(TEXT.beta_peak, channel_montage{ch}));
                            fprintf(fid, '<table style="width: 70%%;">\n');
                            fprintf(fid, '<tr><th>%s</th><th>%s</th></tr>\n', TEXT.parameter, TEXT.value);
                            fprintf(fid, '<tr><td style="text-align: right; font-weight: bold;">%s</td><td style="text-align: left;">%.2f Hz</td></tr>\n', TEXT.beta_peak_freq, betaPeakFreq);
                            fprintf(fid, '<tr><td style="text-align: right; font-weight: bold;">%s</td><td style="text-align: left;">%.4e μV²/Hz</td></tr>\n', TEXT.beta_peak_power, betaPeakValue);
                            fprintf(fid, '<tr><td style="text-align: right; font-weight: bold;">%s</td><td style="text-align: left;">%.1f-%.1f Hz</td></tr>\n', TEXT.beta_peak_band, max(bands.beta(1), betaPeakFreq - 2), min(bands.beta(2), betaPeakFreq + 2));
                            fprintf(fid, '<tr><td style="text-align: right; font-weight: bold;">%s</td><td style="text-align: left;">%.2f%%</td></tr>\n', TEXT.beta_band_energy, betaPeakBandP);
                            fprintf(fid, '</table>\n');
                            fprintf(fid, '<p class="figure-caption">%s%d: %s</p>\n', TEXT.table, ch+1, sprintf(TEXT.beta_peak_caption, ch+1, channel_montage{ch}));
                        end
                        
                        % Store summary data
                        analysisData.summary = struct();
                        analysisData.summary.allDeltaP = allDeltaP;
                        analysisData.summary.allThetaP = allThetaP;
                        analysisData.summary.allAlphaP = allAlphaP;
                        analysisData.summary.allBetaP = allBetaP;
                        analysisData.summary.allGammaP = allGammaP;
                        analysisData.summary.allBetaPeakFreq = allBetaPeakFreq;
                        analysisData.summary.allBetaPeakAmp = allBetaPeakAmp;
                        analysisData.summary.allBetaPeakBandP = allBetaPeakBandP;
                        
                        % Save analysis data to .mat file
                        matFileName = fullfile(outputDir, ['analysis_data_', datestr(now, 'yyyymmdd_HHMMSS'), '.mat']);
                        save(matFileName, 'analysisData', '-v7.3');
                        
                        % Calculate average values
                        avgDeltaP = mean(allDeltaP);
                        avgThetaP = mean(allThetaP);
                        avgAlphaP = mean(allAlphaP);
                        avgBetaP = mean(allBetaP);
                        avgGammaP = mean(allGammaP);
                        avgBetaPeakFreq = mean(allBetaPeakFreq);
                        
                        % Find dominant frequency band
                        avgValues = [avgDeltaP, avgThetaP, avgAlphaP, avgBetaP, avgGammaP];
                        [maxAvgEnergy, maxAvgBandIdx] = max(avgValues);
                        bandNames = {'Delta', 'Theta', 'Alpha', 'Beta', 'Gamma'};
                        dominantBand = bandNames{maxAvgBandIdx};
                        
                        % Create spectrum summary chart
                        P01_generateSummaryChartFigure_Modified(app, imgDir, allDeltaP, allThetaP, allAlphaP, allBetaP, allGammaP, channel_montage, bandNames, colors, imgSettings, TEXT);
                        
                        % Add calculation methods section
                        fprintf(fid, '<h2 id="methods">6. %s</h2>\n', TEXT.methods);
                        fprintf(fid, '<p>%s</p>\n', TEXT.methods_desc);
                        
                        % Add analysis methods table
                        fprintf(fid, '<table>\n');
                        fprintf(fid, '<tr><th>%s</th><th>%s</th><th>%s</th></tr>\n', TEXT.analysis_type, TEXT.method, TEXT.parameters);
                        fprintf(fid, '<tr><td style="font-weight: bold;">%s</td><td>%s</td><td>%s</td></tr>\n', TEXT.time_freq_analysis, TEXT.stft, sprintf(TEXT.window_overlap, window_length, overlap, nfft));
                        fprintf(fid, '<tr><td style="font-weight: bold;">%s</td><td>%s</td><td>%s</td></tr>\n', TEXT.psd_method, TEXT.welch_method, sprintf(TEXT.window_overlap, round(data_FS/2), round(data_FS*0.75/2), round(data_FS/2)));
                        fprintf(fid, '<tr><td style="font-weight: bold;">%s</td><td>%s</td><td>%s</td></tr>\n', TEXT.band_energy_method, TEXT.band_integration, TEXT.band_sum);
                        fprintf(fid, '<tr><td style="font-weight: bold;">%s</td><td>%s</td><td>%s</td></tr>\n', TEXT.beta_peak_method, TEXT.background_fitting, TEXT.fitting_function);
                        fprintf(fid, '</table>\n');
                        
                        % Add frequency band definitions table
                        fprintf(fid, '<h3>%s</h3>\n', TEXT.band_definitions);
                        fprintf(fid, '<table>\n');
                        fprintf(fid, '<tr><th>%s</th><th>%s</th><th>%s</th></tr>\n', TEXT.freq_band, TEXT.freq_range, TEXT.cognitive_state);
                        fprintf(fid, '<tr class="delta-bg"><td style="font-weight: bold;">Delta</td><td>%.1f-%.1f Hz</td><td>%s</td></tr>\n', bands.delta(1), bands.delta(2), TEXT.delta_state);
                        fprintf(fid, '<tr class="theta-bg"><td style="font-weight: bold;">Theta</td><td>%.1f-%.1f Hz</td><td>%s</td></tr>\n', bands.theta(1), bands.theta(2), TEXT.theta_state);
                        fprintf(fid, '<tr class="alpha-bg"><td style="font-weight: bold;">Alpha</td><td>%.1f-%.1f Hz</td><td>%s</td></tr>\n', bands.alpha(1), bands.alpha(2), TEXT.alpha_state);
                        fprintf(fid, '<tr class="beta-bg"><td style="font-weight: bold;">Beta</td><td>%.1f-%.1f Hz</td><td>%s</td></tr>\n', bands.beta(1), bands.beta(2), TEXT.beta_state);
                        fprintf(fid, '<tr class="gamma-bg"><td style="font-weight: bold;">Gamma</td><td>%.1f-%.1f Hz</td><td>%s</td></tr>\n', bands.gamma(1), bands.gamma(2), TEXT.gamma_state);
                        fprintf(fid, '</table>\n');
                        
                        % Add detailed method description
                        fprintf(fid, '<p>%s</p>\n', TEXT.methods_detail);
                        
                        % Add analysis conclusion section
                        fprintf(fid, '<h2 id="conclusion">7. %s</h2>\n', TEXT.conclusion);
                        fprintf(fid, '<div class="conclusion">\n');
                        
                        % Generate conclusion text
                        fprintf(fid, '<p>%s</p>\n', sprintf(TEXT.conclusion_intro, num_channels));
                        
                        % Dominant band conclusion
                        fprintf(fid, '<p><strong>%s</strong></p>\n', sprintf(TEXT.dominant_band_conclusion, dominantBand, maxAvgEnergy));
                        
                        % Beta energy ranking
                        % Create array containing channel information and Beta energy
                        betaChannelInfo = zeros(num_channels, 2);
                        for ch = 1:num_channels
                            betaChannelInfo(ch, 1) = ch; % Channel number
                            betaChannelInfo(ch, 2) = allBetaP(ch); % Beta energy
                        end
                        
                        % Sort by Beta energy in descending order
                        betaChannelInfo = sortrows(betaChannelInfo, -2);
                        
                        % Add Beta energy ranking conclusion
                        fprintf(fid, '<p><strong>%s</strong></p>\n', TEXT.beta_energy_ranking);
                        fprintf(fid, '<table style="width: 50%%; margin: 0 auto;">\n');
                        fprintf(fid, '<tr><th>%s</th><th>%s</th><th>%s</th><th>%s</th></tr>\n', TEXT.rank, TEXT.channel, TEXT.beta_energy, TEXT.beta_peak_band_pct);
                        for i = 1:num_channels
                            ch = betaChannelInfo(i, 1);
                            fprintf(fid, '<tr><td>%d</td><td>%s</td><td>%.2f%%</td><td>%.2f%%</td></tr>\n', i, channel_montage{ch}, betaChannelInfo(i, 2), allBetaPeakBandP(ch));
                        end
                        fprintf(fid, '</table>\n');
                        
                        % Hemisphere comparison (if channel count >= 8 and even configuration)
                        if num_channels >= 8 && mod(num_channels, 2) == 0
                            % Assume left hemisphere channels are first half, right hemisphere channels are second half
                            leftChannels = 1:(num_channels/2);
                            rightChannels = (num_channels/2+1):num_channels;
                            
                            % Calculate left and right hemisphere Beta energy
                            leftBetaP = allBetaP(leftChannels);
                            rightBetaP = allBetaP(rightChannels);
                            
                            % Find channels with highest Beta energy in left and right hemispheres
                            [maxLeftBetaP, maxLeftIdx] = max(leftBetaP);
                            [maxRightBetaP, maxRightIdx] = max(rightBetaP);
                            
                            fprintf(fid, '<p><strong>%s</strong></p>\n', TEXT.hemisphere_comparison);
                            fprintf(fid, '<ul>\n');
                            fprintf(fid, '<li>%s</li>\n', sprintf(TEXT.left_max_beta, channel_montage{leftChannels(maxLeftIdx)}, maxLeftBetaP));
                            fprintf(fid, '<li>%s</li>\n', sprintf(TEXT.right_max_beta, channel_montage{rightChannels(maxRightIdx)}, maxRightBetaP));
                            
                            if mean(leftBetaP) > mean(rightBetaP)
                                fprintf(fid, '<li>%s</li>\n', sprintf(TEXT.left_higher, mean(leftBetaP), mean(rightBetaP)));
                            else
                                fprintf(fid, '<li>%s</li>\n', sprintf(TEXT.right_higher, mean(rightBetaP), mean(leftBetaP)));
                            end
                            fprintf(fid, '</ul>\n');
                        end
                        
                        % Signal quality assessment conclusion
                        [numChannels_qual, numSegments_qual] = size(IS_ECG);
                        total_segments = numChannels_qual * numSegments_qual;
                        ecg_segments = sum(sum(IS_ECG));
                        stm_segments = sum(sum(IS_STM));
                        move_segments = sum(sum(IS_MOVE));
                        clean_segments = total_segments - ecg_segments - stm_segments - move_segments;
                        
                        fprintf(fid, '<p><strong>%s</strong>', TEXT.signal_quality);
                        if clean_segments/total_segments > 0.9
                            fprintf(fid, '%s', sprintf(TEXT.quality_high, clean_segments/total_segments*100));
                        elseif clean_segments/total_segments > 0.7
                            fprintf(fid, '%s', sprintf(TEXT.quality_medium, clean_segments/total_segments*100));
                        else
                            fprintf(fid, '%s', sprintf(TEXT.quality_low, clean_segments/total_segments*100));
                        end
                        
                        if move_segments > 0
                            fprintf(fid, '%s', sprintf(TEXT.movement_main, move_segments/total_segments*100));
                        elseif ecg_segments > stm_segments
                            fprintf(fid, '%s', sprintf(TEXT.ecg_main, ecg_segments/total_segments*100));
                        elseif stm_segments > 0
                            fprintf(fid, '%s', sprintf(TEXT.stim_main, stm_segments/total_segments*100));
                        end
                        fprintf(fid, '</p>\n');
                        
                        % Add preprocessing content
                        fprintf(fid, '<p><strong>%s</strong></p>\n', TEXT.preproc_explanation);
                        fprintf(fid, '<ul>\n');
                        for i = 1:length(TEXT.preproc_operations)
                            fprintf(fid, '<li>%s</li>\n', TEXT.preproc_operations{i});
                        end
                        fprintf(fid, '</ul>\n');
                        
                        % Add disclaimer
                        fprintf(fid, '<p style="font-style: italic; color: #666; text-align: center; margin-top: 20px;">%s</p>\n', TEXT.disclaimer);
                        
                        fprintf(fid, '</div>\n');
                        
                        % Add spectrum distribution chart
                        fprintf(fid, '<div class="figure">\n');
                        fprintf(fid, '<img src="images/summary_chart.png" alt="%s" style="max-width: 80%%;">\n', TEXT.band_energy_dist);
                        fprintf(fid, '<p class="figure-caption">%s</p>\n', TEXT.spectrum_caption);
                        fprintf(fid, '</div>\n');
                        
                        % Add appendix section
                        fprintf(fid, '<h2 id="appendix">8. %s</h2>\n', TEXT.appendix);
                        fprintf(fid, '<p>%s</p>\n', TEXT.appendix_intro);
                        
                        % Add data summary table
                        fprintf(fid, '<table>\n');
                        fprintf(fid, '<tr><th>%s</th><th>Delta (%%)</th><th>Theta (%%)</th><th>Alpha (%%)</th><th>Beta (%%)</th><th>Gamma (%%)</th><th>Beta %s (Hz)</th></tr>\n', TEXT.channel, TEXT.beta_peak_freq);
                        
                        for ch = 1:num_channels
                            % Row background color setting (alternating rows)
                            if mod(ch, 2) == 1
                                fprintf(fid, '<tr style="background-color: rgb(245, 245, 245);">');
                            else
                                fprintf(fid, '<tr>');
                            end
                            fprintf(fid, '<td>%s</td><td>%.2f</td><td>%.2f</td><td>%.2f</td><td>%.2f</td><td>%.2f</td><td>%.2f</td></tr>\n', channel_montage{ch}, allDeltaP(ch), allThetaP(ch), allAlphaP(ch), allBetaP(ch), allGammaP(ch), allBetaPeakFreq(ch));
                        end
                        
                        % Add average value row
                        fprintf(fid, '<tr style="font-weight: bold; background-color: rgb(220, 230, 240);">');
                        fprintf(fid, '<td>%s</td><td>%.2f</td><td>%.2f</td><td>%.2f</td><td>%.2f</td><td>%.2f</td><td>%.2f</td></tr>\n', TEXT.avg_values, avgDeltaP, avgThetaP, avgAlphaP, avgBetaP, avgGammaP, avgBetaPeakFreq);
                        fprintf(fid, '</table>\n');
                        fprintf(fid, '<p class="figure-caption">%s: %s</p>\n', TEXT.table, TEXT.appendix_intro);
                        
                        % Add references
                        fprintf(fid, '<h3>%s</h3>\n', TEXT.references);
                        fprintf(fid, '<ol>\n');
                        fprintf(fid, '<li>Welch, P. (1967). The use of fast Fourier transform for the estimation of power spectra. IEEE Transactions on Audio and Electroacoustics, 15(2), 70-73.</li>\n');
                        fprintf(fid, '<li>Cohen, M. X. (2014). Analyzing neural time series data: Theory and practice. MIT press.</li>\n');
                        fprintf(fid, '<li>Buzsáki, G. (2006). Rhythms of the Brain. Oxford University Press.</li>\n');
                        fprintf(fid, '<li>Nunez, P. L., & Srinivasan, R. (2006). Electric fields of the brain: The neurophysics of EEG. Oxford University Press.</li>\n');
                        fprintf(fid, '</ol>\n');
                        
                        % Add footer
                        fprintf(fid, '<div class="footer">\n');
                        fprintf(fid, '<p>%s</p>\n', sprintf(TEXT.report_footer, datestr(now, 'yyyy-mm-dd'), institution_name, project_name));
                        fprintf(fid, '<p>Generated by NNSR Analysis Toolbox</p>\n');
                        fprintf(fid, '</div>\n');
                        
                        % Close HTML file
                        fprintf(fid, '</body>\n');
                        fprintf(fid, '</html>\n');
                        fclose(fid);
                        
                        fprintf('HTML report generated successfully: %s\n', reportFileName);
                        fprintf('Data file saved: %s\n', matFileName);
                        
                        % Automatically open generated HTML file
                        if exist(reportFileName, 'file')
                            if ispc
                                system(['start "" "', reportFileName, '"']);
                            elseif ismac
                                system(['open "', reportFileName, '"']);
                            else
                                system(['xdg-open "', reportFileName, '"']);
                            end
                        end
                        
                        uialert(app.MainFigure,'报告生成完成','提示','Icon','info');
                        fprintf('Script execution completed\n');
                        close(app.waitting_NOW)
                        
                    catch ME
                        close(app.waitting_NOW)
                        fprintf('Error: %s\n', ME.message);
                        uialert(app.MainFigure, ME.message, '错误', 'Icon', 'error');
                    end
                    
                    %% Modified channel analysis function
                    function [channelFileName, betaPeakFreq, betaPeakValue, betaPeakWidth, deltaP, thetaP, alphaP, betaP, gammaP, betaPeakBandP, channelData] = P01_generateChannelAnalysisFigure_Modified(app, imgDir, ch, channelName, dataMatrix, data_FS, bands, colors, window_length, overlap, nfft, imgSettings, TEXT, IS_MOVES)
                        
                        % Use complete data directly without data segment selection
                        signal = dataMatrix(ch, :);
                        
                        % Remove NaN values
                        signal(isnan(signal)) = [];
                        
                        time = (0:length(signal)-1) / data_FS;
                        
                        % Initialize channel data storage structure
                        channelData = struct();
                        channelData.channel_name = channelName;
                        channelData.signal = signal;
                        channelData.time = time;
                        
                        % Create figure - white background with black text
                        fig = figure('Visible', 'off', 'Position', [100, 100, 1200, 900], 'Color', imgSettings.backgroundColor);
                        
                        % Subplot 1: Time domain signal
                        subplot(3, 1, 1);
                        plot(time, signal, 'Color', colors.line(ch, :), 'LineWidth', imgSettings.lineWidth);
                        xlabel(TEXT.time_sec, 'FontSize', imgSettings.fontSizeLabel, 'Color', imgSettings.textColor);
                        ylabel('幅度 (μV)', 'FontSize', imgSettings.fontSizeLabel, 'Color', imgSettings.textColor);
                        title([TEXT.ch_time_domain, ' - ', channelName], 'FontSize', imgSettings.fontSizeTitle, 'Color', imgSettings.textColor);
                        grid on;
                        set(gca, 'Color', imgSettings.backgroundColor, 'XColor', imgSettings.textColor, 'YColor', imgSettings.textColor);
                        
                        % Calculate time domain features
                        channelData.time_domain = struct();
                        channelData.time_domain.mean = mean(signal);
                        channelData.time_domain.std = std(signal);
                        channelData.time_domain.rms = rms(signal);
                        channelData.time_domain.peak_to_peak = max(signal) - min(signal);
                        channelData.time_domain.skewness = skewness(signal);
                        channelData.time_domain.kurtosis = kurtosis(signal);
                        
                        % Subplot 2: Time-frequency analysis (STFT)
                        subplot(3, 1, 2);
                        
                        % Use shorter window for STFT to avoid excessive length
                        stft_window = min(round(data_FS), length(signal)/10);
                        stft_overlap = round(stft_window * 0.75);
                        stft_nfft = min(nfft, stft_window * 4);
                        
                        [S, F, T] = spectrogram(signal, stft_window, stft_overlap, stft_nfft, data_FS);
                        
                        % Store STFT data
                        channelData.stft = struct();
                        channelData.stft.S = S;
                        channelData.stft.F = F;
                        channelData.stft.T = T;
                        channelData.stft.window_length = stft_window;
                        channelData.stft.overlap = stft_overlap;
                        channelData.stft.nfft = stft_nfft;
                        
                        % Plot time-frequency diagram
                        imagesc(T, F, 10*log10(abs(S).^2));
                        axis xy;
                        colormap(gca, 'jet');
                        colorbar;
                        xlabel(TEXT.time_sec, 'FontSize', imgSettings.fontSizeLabel, 'Color', imgSettings.textColor);
                        ylabel(TEXT.freq_hz, 'FontSize', imgSettings.fontSizeLabel, 'Color', imgSettings.textColor);
                        title([TEXT.ch_time_freq, ' - ', channelName], 'FontSize', imgSettings.fontSizeTitle, 'Color', imgSettings.textColor);
                        ylim([0, 80]);
                        set(gca, 'Color', imgSettings.backgroundColor, 'XColor', imgSettings.textColor, 'YColor', imgSettings.textColor);
                        
                        % Subplot 3: Power spectral density (PSD) - using reference code parameters
                        subplot(3, 1, 3);
                        
                        % Use Hanning window
                        window_psd = hanning(round(data_FS));
                        
                        % Calculate high-resolution PSD - using reference code parameters
                        [psd, freq] = pwelch(signal, window_psd, round(0.5*data_FS), nfft, data_FS);
                        
                        % Store PSD data
                        channelData.psd = struct();
                        channelData.psd.psd = psd;
                        channelData.psd.freq = freq;
                        channelData.psd.window_type = 'hanning';
                        channelData.psd.window_length = length(window_psd);
                        channelData.psd.overlap = round(0.5*data_FS);
                        channelData.psd.nfft = nfft;
                        
                        % Use improved Beta peak detection method
                        freq_range_5_40 = freq >= 5 & freq <= 40;
                        x = freq(freq_range_5_40);
                        y = 10*log10(psd(freq_range_5_40));
                        
                        % 1/f fitting
                        fitType = fittype(@(a,b,c,x) a + b./(c+x), 'independent', 'x', 'dependent', 'y');
                        [fitResult, ~] = fit(x, y, fitType, 'StartPoint', [1,1,1]);
                        
                        % Extract fitting parameters
                        a = fitResult.a;
                        b = fitResult.b;
                        c = fitResult.c;
                        
                        % Calculate fitted curve and residual
                        ys = a + b./(c + x);
                        residual = y - ys;
                        
                        % Find peaks within Beta frequency band
                        beta_freq_idx = (x >= bands.beta(1) & x <= bands.beta(2));
                        residual_beta = residual(beta_freq_idx);
                        x_beta = x(beta_freq_idx);
                        y_beta = y(beta_freq_idx);
                        psd_beta = psd(freq_range_5_40);
                        psd_beta = psd_beta(beta_freq_idx);
                        
                        % Find peaks
                        [peaks, locs] = findpeaks(residual_beta);
                        
                        if ~isempty(peaks)
                            [~, max_idx] = max(peaks);
                            betaPeakFreq = x_beta(locs(max_idx));
                            betaPeakValue = psd_beta(locs(max_idx));  % Use linear scale PSD values
                        else
                            % If no peaks found, use maximum value within Beta frequency band
                            [~, max_idx] = max(psd_beta);
                            betaPeakFreq = x_beta(max_idx);
                            betaPeakValue = psd_beta(max_idx);
                        end
                        
                        % Calculate Beta peak band energy (±2Hz range around the detected peak)
                        % Define the frequency range as betaPeakFreq ± 2Hz, constrained within beta band limits
                        beta_peak_band_lower = max(bands.beta(1), betaPeakFreq - 2);
                        beta_peak_band_upper = min(bands.beta(2), betaPeakFreq + 2);
                        
                        beta_peak_band_idx = (freq >= beta_peak_band_lower) & (freq <= beta_peak_band_upper);
                        beta_peak_band_power = trapz(freq(beta_peak_band_idx), psd(beta_peak_band_idx));
                        
                        % Store Beta peak detection results
                        channelData.beta_peak = struct();
                        channelData.beta_peak.frequency = betaPeakFreq;
                        channelData.beta_peak.power = betaPeakValue;
                        channelData.beta_peak.band_range = [beta_peak_band_lower, beta_peak_band_upper];  % Store the ±2Hz range
                        channelData.beta_peak.fit_parameters = struct('a', a, 'b', b, 'c', c);
                        channelData.beta_peak.residual = residual;
                        channelData.beta_peak.fitted_curve = ys;
                        
                        betaPeakWidth = 2;  % Simply set to 2Hz
                        
                        % Plot PSD - adjust y-axis range to focus around beta frequency range
                        semilogy(freq, psd, 'Color', colors.line(ch, :), 'LineWidth', imgSettings.lineWidth);
                        xlabel(TEXT.freq_hz, 'FontSize', imgSettings.fontSizeLabel, 'Color', imgSettings.textColor);
                        ylabel(TEXT.power_db, 'FontSize', imgSettings.fontSizeLabel, 'Color', imgSettings.textColor);
                        title([TEXT.ch_psd, ' - ', channelName], 'FontSize', imgSettings.fontSizeTitle, 'Color', imgSettings.textColor);
                        grid on;
                        xlim([5, 80]); % Start display from 5Hz
                        
                        % Adjust y-axis range to center around beta frequency band for better visualization
                        % Focus on the frequency range around beta (8-40 Hz) for y-axis calculation
                        focus_freq_idx = (freq >= 8 & freq <= 40);
                        focus_psd = psd(focus_freq_idx);
                        
                        if ~isempty(focus_psd) && any(focus_psd > 0)
                            % Calculate percentiles for better range setting
                            psd_median = median(focus_psd);
                            psd_75 = prctile(focus_psd, 75);
                            psd_25 = prctile(focus_psd, 25);
                            
                            % Set y-axis limits based on the distribution of PSD values in focus range
                            ylim_upper = max(psd_75 * 5, betaPeakValue * 2);  % Use beta peak or 75th percentile
                            ylim_lower = min(psd_25 / 5, min(focus_psd) / 2);  % Use 25th percentile or minimum
                            
                            % Ensure the range is reasonable
                            if ylim_upper / ylim_lower > 1e6
                                ylim_upper = ylim_lower * 1e5;  % Limit the range to avoid extreme values
                            end
                            
                            ylim([ylim_lower, ylim_upper]);
                        end
                        
                        set(gca, 'Color', imgSettings.backgroundColor, 'XColor', imgSettings.textColor, 'YColor', imgSettings.textColor);
                        
                        % Add frequency band markers
                        hold on;
                        ylims = ylim;
                        % Delta
                        patch([bands.delta(1) bands.delta(2) bands.delta(2) bands.delta(1)], ...
                              [ylims(1) ylims(1) ylims(2) ylims(2)], colors.delta, 'FaceAlpha', 0.2, 'EdgeColor', 'none');
                        % Theta  
                        patch([bands.theta(1) bands.theta(2) bands.theta(2) bands.theta(1)], ...
                              [ylims(1) ylims(1) ylims(2) ylims(2)], colors.theta, 'FaceAlpha', 0.2, 'EdgeColor', 'none');
                        % Alpha
                        patch([bands.alpha(1) bands.alpha(2) bands.alpha(2) bands.alpha(1)], ...
                              [ylims(1) ylims(1) ylims(2) ylims(2)], colors.alpha, 'FaceAlpha', 0.2, 'EdgeColor', 'none');
                        % Beta
                        patch([bands.beta(1) bands.beta(2) bands.beta(2) bands.beta(1)], ...
                              [ylims(1) ylims(1) ylims(2) ylims(2)], colors.beta, 'FaceAlpha', 0.2, 'EdgeColor', 'none');
                        % Gamma
                        patch([bands.gamma(1) bands.gamma(2) bands.gamma(2) bands.gamma(1)], ...
                              [ylims(1) ylims(1) ylims(2) ylims(2)], colors.gamma, 'FaceAlpha', 0.2, 'EdgeColor', 'none');
                        
                        % Plot peak point
                        plot(betaPeakFreq, betaPeakValue, 'ro', 'MarkerSize', 8, 'MarkerFaceColor', 'r');
                        
                        hold off;
                        
                        % Calculate frequency band energy
                        % Total energy (0.5-80Hz)
                        total_freq_idx = (freq >= 0.5 & freq <= 80);
                        total_power = trapz(freq(total_freq_idx), psd(total_freq_idx));
                        
                        % Individual frequency band energy
                        delta_idx = (freq >= bands.delta(1) & freq <= bands.delta(2));
                        theta_idx = (freq >= bands.theta(1) & freq <= bands.theta(2));
                        alpha_idx = (freq >= bands.alpha(1) & freq <= bands.alpha(2));
                        beta_idx = (freq >= bands.beta(1) & freq <= bands.beta(2));
                        gamma_idx = (freq >= bands.gamma(1) & freq <= bands.gamma(2));
                        
                        delta_power = trapz(freq(delta_idx), psd(delta_idx));
                        theta_power = trapz(freq(theta_idx), psd(theta_idx));
                        alpha_power = trapz(freq(alpha_idx), psd(alpha_idx));
                        beta_power = trapz(freq(beta_idx), psd(beta_idx));
                        gamma_power = trapz(freq(gamma_idx), psd(gamma_idx));
                        
                        % Calculate percentages
                        deltaP = (delta_power / total_power) * 100;
                        thetaP = (theta_power / total_power) * 100;
                        alphaP = (alpha_power / total_power) * 100;
                        betaP = (beta_power / total_power) * 100;
                        gammaP = (gamma_power / total_power) * 100;
                        betaPeakBandP = (beta_peak_band_power / total_power) * 100;
                        
                        % Store frequency band power data
                        channelData.band_power = struct();
                        channelData.band_power.delta = struct('power', delta_power, 'percentage', deltaP);
                        channelData.band_power.theta = struct('power', theta_power, 'percentage', thetaP);
                        channelData.band_power.alpha = struct('power', alpha_power, 'percentage', alphaP);
                        channelData.band_power.beta = struct('power', beta_power, 'percentage', betaP);
                        channelData.band_power.gamma = struct('power', gamma_power, 'percentage', gammaP);
                        channelData.band_power.total = total_power;
                        channelData.band_power.beta_peak_band = struct('power', beta_peak_band_power, 'percentage', betaPeakBandP);
                        
                        % Save image
                        channelFileName = fullfile(imgDir, ['channel_', num2str(ch), '.png']);
                        print(fig, channelFileName, imgSettings.format, imgSettings.resolution);
                        close(fig);
                    end
                    
                    %% Modified artifact detection figure generation function
                    function P01_generateArtifactDetectionFigure_Modified(app, imgDir, IS_ECG, IS_STM, IS_MOVE, imgSettings, TEXT)
                        % Create artifact detection visualization figure - white background with black text
                        fig = figure('Visible', 'off', 'Position', [100, 100, 1000, 600], 'Color', imgSettings.backgroundColor);
                        
                        [numChannels, numSegments] = size(IS_ECG);
                        
                        % Create artifact status matrix
                        artifactMatrix = zeros(numChannels, numSegments);
                        
                        % Set artifact status: 1=no artifact, 2=ECG/STM artifact, 3=movement artifact
                        for ch = 1:numChannels
                            for seg = 1:numSegments
                                if IS_MOVE(ch, seg)
                                    artifactMatrix(ch, seg) = 3;  % Movement artifact has highest priority
                                elseif IS_ECG(ch, seg) || IS_STM(ch, seg)
                                    artifactMatrix(ch, seg) = 2;  % ECG/STM artifact
                                else
                                    artifactMatrix(ch, seg) = 1;  % No artifact
                                end
                            end
                        end
                        
                        % Create custom color mapping
                        customColors = [0.2 0.8 0.2;  % Green - no artifact
                                        1.0 0.8 0.0;  % Yellow - ECG/STM artifact
                                        0.8 0.2 0.2]; % Red - movement artifact
                        
                        % Plot artifact matrix
                        imagesc(artifactMatrix);
                        colormap(customColors);
                        caxis([1 3]);
                        
                        % Set coordinate axes
                        xlabel('时间段', 'FontSize', imgSettings.fontSizeLabel, 'Color', imgSettings.textColor);
                        ylabel('通道', 'FontSize', imgSettings.fontSizeLabel, 'Color', imgSettings.textColor);
                        title(TEXT.artifact_title, 'FontSize', imgSettings.fontSizeTitle, 'Color', imgSettings.textColor);
                        
                        % Set y-axis labels
                        if isfield(app, 'Rec_Chi_info') && length(app.Rec_Chi_info) == numChannels
                            set(gca, 'YTick', 1:numChannels, 'YTickLabel', app.Rec_Chi_info);
                        else
                            set(gca, 'YTick', 1:numChannels, 'YTickLabel', arrayfun(@(x) sprintf('Ch%d', x), 1:numChannels, 'UniformOutput', false));
                        end
                        
                        set(gca, 'Color', imgSettings.backgroundColor, 'XColor', imgSettings.textColor, 'YColor', imgSettings.textColor);
                        
                        % Save image
                        artifactFileName = fullfile(imgDir, 'artifact_detection.png');
                        print(fig, artifactFileName, imgSettings.format, imgSettings.resolution);
                        close(fig);
                    end
                    
                    %% Modified signal overview figure generation function
                    function P01_generateSignalOverviewFigure_Modified(app, imgDir, dataMatrix, data_FS, bands, colors, imgSettings, TEXT, IS_MOVES)
                        % Create signal overview figure - white background with black text
                        fig = figure('Visible', 'off', 'Position', [100, 100, 1200, 800], 'Color', imgSettings.backgroundColor);
                        
                        num_channels = size(dataMatrix, 1);
                        
                        % Subplot 1: PSD overview
                        subplot(2, 1, 1);
                        hold on;
                        
                        % Use reference code PSD parameters
                        nfft = 10 * data_FS;
                        window_psd = hanning(data_FS);
                        overlap_psd = round(0.5 * data_FS);
                        
                        for ch = 1:num_channels
                            % Use complete data directly, remove data segment selection logic
                            signal_ch = dataMatrix(ch, :);
                            signal_ch(isnan(signal_ch)) = [];  % Remove NaN values
                            
                            % Calculate PSD
                            [psd, freq] = pwelch(signal_ch, window_psd, overlap_psd, nfft, data_FS);
                            
                            % Plot PSD
                            semilogy(freq, psd, 'Color', colors.line(ch, :), 'LineWidth', imgSettings.lineWidth);
                        end
                        
                        xlabel(TEXT.freq_hz, 'FontSize', imgSettings.fontSizeLabel, 'Color', imgSettings.textColor);
                        ylabel(TEXT.power_density, 'FontSize', imgSettings.fontSizeLabel, 'Color', imgSettings.textColor);
                        title(TEXT.psd_overview, 'FontSize', imgSettings.fontSizeTitle, 'Color', imgSettings.textColor);
                        xlim([5, 80]); % Start display from 5Hz
                        grid on;
                        set(gca, 'Color', imgSettings.backgroundColor, 'XColor', imgSettings.textColor, 'YColor', imgSettings.textColor);
                        
                        % Add frequency band markers
                        ylims = ylim;
                        % Delta
                        patch([bands.delta(1) bands.delta(2) bands.delta(2) bands.delta(1)], ...
                              [ylims(1) ylims(1) ylims(2) ylims(2)], colors.delta, 'FaceAlpha', 0.2, 'EdgeColor', 'none');
                        % Theta  
                        patch([bands.theta(1) bands.theta(2) bands.theta(2) bands.theta(1)], ...
                              [ylims(1) ylims(1) ylims(2) ylims(2)], colors.theta, 'FaceAlpha', 0.2, 'EdgeColor', 'none');
                        % Alpha
                        patch([bands.alpha(1) bands.alpha(2) bands.alpha(2) bands.alpha(1)], ...
                              [ylims(1) ylims(1) ylims(2) ylims(2)], colors.alpha, 'FaceAlpha', 0.2, 'EdgeColor', 'none');
                        % Beta
                        patch([bands.beta(1) bands.beta(2) bands.beta(2) bands.beta(1)], ...
                              [ylims(1) ylims(1) ylims(2) ylims(2)], colors.beta, 'FaceAlpha', 0.2, 'EdgeColor', 'none');
                        % Gamma
                        patch([bands.gamma(1) bands.gamma(2) bands.gamma(2) bands.gamma(1)], ...
                              [ylims(1) ylims(1) ylims(2) ylims(2)], colors.gamma, 'FaceAlpha', 0.2, 'EdgeColor', 'none');
                        
                        hold off;
                        
                        % Subplot 2: Time domain signal overview - display entire signal starting from 3 seconds
                        subplot(2, 1, 2);
                        hold on;
                        
                        % Start displaying from 3 seconds
                        start_time_display = 3; % 3 seconds
                        start_sample_display = round(start_time_display * data_FS);
                        
                        % Ensure starting point is valid
                        start_sample_display = max(1, min(start_sample_display, size(dataMatrix, 2)-1));
                        
                        % Display data from 3 seconds to end
                        display_samples = start_sample_display:size(dataMatrix, 2);
                        time_display = (display_samples - 1) / data_FS;
                        
                        % Calculate appropriate vertical spacing to avoid overlap
                        vertical_spacing = 0;
                        for ch = 1:num_channels
                            signal_segment = dataMatrix(ch, display_samples);
                            signal_rms = rms(signal_segment);
                            vertical_spacing = max(vertical_spacing, signal_rms * 6); % Use 6 times RMS as spacing
                        end
                        
                        for ch = 1:num_channels
                            signal_segment = dataMatrix(ch, display_samples);
                            % Normalize and add vertical offset
                            signal_norm = signal_segment / max(abs(signal_segment)) * vertical_spacing/2;
                            plot(time_display, signal_norm + ch*vertical_spacing, 'Color', colors.line(ch, :), 'LineWidth', imgSettings.lineWidth);
                        end
                        
                        xlabel(TEXT.time_sec, 'FontSize', imgSettings.fontSizeLabel, 'Color', imgSettings.textColor);
                        ylabel(TEXT.norm_amp, 'FontSize', imgSettings.fontSizeLabel, 'Color', imgSettings.textColor);
                        title(TEXT.time_overview, 'FontSize', imgSettings.fontSizeTitle, 'Color', imgSettings.textColor);
                        
                        % Set y-axis labels as channel names
                        if isfield(app, 'Rec_Chi_info') && length(app.Rec_Chi_info) == num_channels
                            ytick_positions = (1:num_channels) * vertical_spacing;
                            set(gca, 'YTick', ytick_positions, 'YTickLabel', app.Rec_Chi_info);
                        end
                        
                        grid on;
                        set(gca, 'Color', imgSettings.backgroundColor, 'XColor', imgSettings.textColor, 'YColor', imgSettings.textColor);
                        
                        hold off;
                        
                        % Save image
                        overviewFileName = fullfile(imgDir, 'overview.png');
                        print(fig, overviewFileName, imgSettings.format, imgSettings.resolution);
                        close(fig);
                    end
                    
                    %% Modified spectrum summary chart generation function
                    function P01_generateSummaryChartFigure_Modified(app, imgDir, allDeltaP, allThetaP, allAlphaP, allBetaP, allGammaP, channel_montage, bandNames, colors, imgSettings, TEXT)
                        % Create spectrum distribution summary chart - white background with black text
                        fig = figure('Visible', 'off', 'Position', [100, 100, 800, 600], 'Color', imgSettings.backgroundColor);
                        
                        % Calculate average values
                        avgValues = [mean(allDeltaP), mean(allThetaP), mean(allAlphaP), mean(allBetaP), mean(allGammaP)];
                        
                        % Create bar chart colors
                        bar_colors = [colors.delta; colors.theta; colors.alpha; colors.beta; colors.gamma];
                        
                        % Plot bar chart
                        b = bar(avgValues);
                        b.FaceColor = 'flat';
                        for i = 1:length(avgValues)
                            b.CData(i, :) = bar_colors(i, :);
                        end
                        
                        % Set figure properties
                        xlabel(TEXT.freq_band, 'FontSize', imgSettings.fontSizeLabel, 'Color', imgSettings.textColor);
                        ylabel(TEXT.avg_energy, 'FontSize', imgSettings.fontSizeLabel, 'Color', imgSettings.textColor);
                        title(TEXT.band_energy_dist, 'FontSize', imgSettings.fontSizeTitle, 'Color', imgSettings.textColor);
                        
                        % Set x-axis labels
                        set(gca, 'XTickLabel', bandNames);
                        set(gca, 'Color', imgSettings.backgroundColor, 'XColor', imgSettings.textColor, 'YColor', imgSettings.textColor);
                        
                        % Add value labels
                        for i = 1:length(avgValues)
                            text(i, avgValues(i) + max(avgValues)*0.02, sprintf('%.1f%%', avgValues(i)), ...
                                'HorizontalAlignment', 'center', 'FontSize', imgSettings.fontSizeText, 'Color', imgSettings.textColor);
                        end
                        
                        grid on;
                        ylim([0, max(avgValues)*1.1]);
                        
                        % Save image
                        summaryFileName = fullfile(imgDir, 'summary_chart.png');
                        print(fig, summaryFileName, imgSettings.format, imgSettings.resolution);
                        close(fig);
                    end
        end

        % Close request function: MainFigure
        function MainFigureCloseRequest(app, event)
            % button=questdlg('您确定退出吗？（注意！未储存的数据将被删除）','退出离线分析工具？','是','否','是'); %内容, 标题, 选项, 默认选项
            selection = uiconfirm(app.MainFigure, '您确定退出吗？（注意！未储存的数据将被删除）', '退出离线分析工具？','Options',{'直接退出', '返回主菜单' ,'取消'},'DefaultOption', '取消', 'CancelOption', '直接退出');
            if strcmp(selection,'直接退出')
               delete(app);
            elseif strcmp(selection,'返回主菜单')
               APP_to_start_0_1
               delete(app);
            end
        end

        % Button pushed function: save_data2workspace
        function save_data2workspaceButtonPushed(app, event)
            uialert(app.MainFigure,'已发送（该功能在matlab授权运行时可用）','提示','Icon','info');
            name_mid = app.First_Filename;
            name = ['Data_',name_mid(isstrprop(name_mid,'digit'))];
            assignin('base',name,app.Data_now);
%           savedate_2 = ;
        end

        % Button pushed function: TFButton
        function TFButtonPushed(app, event)
                % plot TF 
                try
                    Nfft = app.data_fs;
                    OverLap = round(0.5 * app.data_fs);
                    Window_Length = app.data_fs;
                    
                    % 通道数量和通道名称
                    num_channels = size(app.Data_now, 1); % 假设Data_now的行数表示通道数
                    channel_names = app.chi_choose.Items; % 获取通道名称
                    
                    % 创建figure并设置背景为白色
                    figure('Color', 'w');
                    
                    % 绘制每个通道的时频图
                    for ch = 1:num_channels
                        % 获取当前通道数据
                        Data_nowss = app.Data_ori(ch, 500:end);
                        
                        % 使用 spectrogram 计算时频图
                        [B, f, t] = spectrogram(Data_nowss, Window_Length, OverLap, Nfft, app.data_fs);
                        
                        % 在子图中绘制时频图
                        subplot(num_channels, 1, ch);
                        imagesc(t, f, 10 * log10(abs(B)));
                        axis xy; % 调整y轴朝上
                        ylim([0 60]); % 设置y轴范围为0-90 Hz
                        xlabel('Time (s)');
                        ylabel('Frequency (Hz)');
                        
                        % 添加通道名称作为标题
                        if ch <= length(channel_names)
                            title(['Channel: ' channel_names{ch}]);
                        else
                            title(['Channel: ' num2str(ch)]);
                        end
                        
                        % 添加颜色条
                        colorbar;
                        colormap jet; % 使用jet配色
                    end
                    
                    % 调整子图间距
                    sgtitle('Multi-Channel Time-Frequency Spectrogram'); % 总标题
                end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MainFigure = uifigure('Visible', 'off');
            app.MainFigure.Name = 'MATLAB App';
            app.MainFigure.Icon = fullfile(pathToMLAPP, 'icon_48.png');
            app.MainFigure.CloseRequestFcn = createCallbackFcn(app, @MainFigureCloseRequest, true);
            app.Menu = uimenu(app.MainFigure);
            app.Menu.Text = '菜单';
            app.StartUp = uimenu(app.Menu);
            app.StartUp.Text = '启动菜单';
            app.RSMenu = uimenu(app.Menu);
            app.RSMenu.Text = '106RS在线分析系统';
            app.LFPMenu = uimenu(app.Menu);
            app.LFPMenu.Text = 'LFP离线分析工具平台';
            app.LFPMenu_2 = uimenu(app.Menu);
            app.LFPMenu_2.Text = 'LFP临床研究分析工具';
            app.Auto_Resize = uimenu(app.Menu);
            app.Auto_Resize.Text = '控件大小自适应：关';
            app.Exit = uimenu(app.Menu);
            app.Exit.Text = '退出';
            app.Functions = uimenu(app.MainFigure);
            app.Functions.Text = '功能';
            app.Current_info = uimenu(app.Functions);
            app.Current_info.Text = '当前数据信息';
            app.Data_cut = uimenu(app.Functions);
            app.Data_cut.Text = '数据剪裁工具';
            app.Batch_Proc = uimenu(app.Functions);
            app.Batch_Proc.Text = '批处理工具';
            app.Data_conp = uimenu(app.Functions);
            app.Data_conp.Text = '数据对比工具';
            app.Menu_3 = uimenu(app.MainFigure);
            app.Menu_3.Text = '帮助';
            app.Menu_8 = uimenu(app.Menu_3);
            app.Menu_8.Text = '帮助';
            app.Menu_9 = uimenu(app.MainFigure);
            app.Menu_9.Text = '预设';
            app.Menu_10 = uimenu(app.Menu_9);
            app.Menu_10.Text = '数据处理预设';
            app.Menu_13 = uimenu(app.Menu_10);
            app.Menu_13.Text = '基线漂移处理：是';
            app.Menu_15 = uimenu(app.Menu_10);
            app.Menu_15.Text = '心电伪迹检测与处理：是';
            app.Menu_16 = uimenu(app.Menu_10);
            app.Menu_16.Text = '自动数据段筛除：是';
            app.Menu_17 = uimenu(app.Menu_10);
            app.Menu_17.Text = '所有通道执行相同操作：是';
            app.Menu_11 = uimenu(app.Menu_9);
            app.Menu_11.Text = '数据绘制预设';
            app.Menu_14 = uimenu(app.Menu_11);
            app.Menu_14.Text = '特征归一化：关闭';
            app.Menu_18 = uimenu(app.Menu_11);
            app.Menu_18.Text = '功率谱对数：关闭';
            app.Menu_19 = uimenu(app.Menu_11);
            app.Menu_19.Text = '功率谱范围：常规';
            app.Menu_20 = uimenu(app.Menu_11);
            app.Menu_20.Text = '全通道间隔：30';
            app.Menu_21 = uimenu(app.Menu_20);
            app.Menu_21.MenuSelectedFcn = createCallbackFcn(app, @Menu_21Selected, true);
            app.Menu_21.Text = '20';
            app.Menu_22 = uimenu(app.Menu_20);
            app.Menu_22.MenuSelectedFcn = createCallbackFcn(app, @Menu_22Selected, true);
            app.Menu_22.Text = '30';
            app.Menu_23 = uimenu(app.Menu_20);
            app.Menu_23.MenuSelectedFcn = createCallbackFcn(app, @Menu_23Selected, true);
            app.Menu_23.Text = '40';
            app.Menu_29 = uimenu(app.Menu_20);
            app.Menu_29.MenuSelectedFcn = createCallbackFcn(app, @Menu_29Selected, true);
            app.Menu_29.Text = '60';
            app.Menu_30 = uimenu(app.Menu_20);
            app.Menu_30.MenuSelectedFcn = createCallbackFcn(app, @Menu_30Selected, true);
            app.Menu_30.Text = '90';
            app.Menu_31 = uimenu(app.Menu_20);
            app.Menu_31.MenuSelectedFcn = createCallbackFcn(app, @Menu_31Selected, true);
            app.Menu_31.Text = '130';
            app.Menu_25 = uimenu(app.Menu_9);
            app.Menu_25.Enable = 'off';
            app.Menu_25.Text = '分析报告预设';
            app.Menu_32 = uimenu(app.Menu_25);
            app.Menu_32.Text = '时频分析结果';
            app.Menu_33 = uimenu(app.Menu_25);
            app.Menu_33.Text = '特征分析结果';
            app.Menu_34 = uimenu(app.Menu_9);
            app.Menu_34.Text = '语言选择：中文';
            app.ChineseMenu = uimenu(app.Menu_34);
            app.ChineseMenu.Text = '中文/Chinese';
            app.EnglishMenu = uimenu(app.Menu_34);
            app.EnglishMenu.Text = '英文/English';
            app.NNSRLabel = uilabel(app.MainFigure);
            app.NNSRLabel.Text = 'NNSR 离线分析';
            app.data_load_pannel = uipanel(app.MainFigure);
            app.data_load_pannel.Title = 'LFP导入';
            app.axis_pre = uiaxes(app.data_load_pannel);
            zlabel(app.axis_pre, 'Z')
            app.axis_pre.XTick = [];
            app.axis_pre.YTick = [];
            app.File_selection = uibutton(app.data_load_pannel, 'push');
            app.File_selection.ButtonPushedFcn = createCallbackFcn(app, @File_selectionButtonPushed, true);
            app.File_selection.Text = '数据选择';
            app.infoSTIM = uilabel(app.data_load_pannel);
            app.infoSTIM.Visible = 'off';
            app.infoSTIM.Text = 'no info detected';
            app.Label = uilabel(app.data_load_pannel);
            app.Label.Visible = 'off';
            app.Label.Text = '数据刺激参数：';
            app.Label_2 = uilabel(app.data_load_pannel);
            app.Label_2.Text = '数据采集参数：';
            app.infoACQ = uilabel(app.data_load_pannel);
            app.infoACQ.Text = 'no info detected';
            app.Image = uiimage(app.data_load_pannel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Electrode_ORI.jpg');
            app.Label_3 = uilabel(app.data_load_pannel);
            app.Label_3.Text = '预览通道：';
            app.View_chi_DropDown = uidropdown(app.data_load_pannel);
            app.View_chi_DropDown.Items = {'1+2-', '3+4-'};
            app.View_chi_DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.View_chi_DropDown.Enable = 'off';
            app.View_chi_DropDown.Value = '1';
            app.UITable = uitable(app.data_load_pannel);
            app.UITable.ColumnName = {'文件列表'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.DoubleClickedFcn = createCallbackFcn(app, @UITableDoubleClicked, true);
            app.UITable.Multiselect = 'off';
            app.data_config = uibutton(app.data_load_pannel, 'push');
            app.data_config.ButtonPushedFcn = createCallbackFcn(app, @data_configButtonPushed, true);
            app.data_config.Enable = 'off';
            app.data_config.Text = '数据解析';
            app.More_info = uibutton(app.data_load_pannel, 'push');
            app.More_info.ButtonPushedFcn = createCallbackFcn(app, @More_infoButtonPushed, true);
            app.More_info.Text = '数据信息';
            app.IS_OLD_VERSON = uicheckbox(app.data_load_pannel);
            app.IS_OLD_VERSON.Text = '旧版解析';
            app.Panel = uipanel(app.MainFigure);
            app.Panel.Title = '数据报告';
            app.Data_Timedomine = uiaxes(app.Panel);
            title(app.Data_Timedomine, '时域信息')
            xlabel(app.Data_Timedomine, '时间(s)')
            ylabel(app.Data_Timedomine, '振幅(uv)')
            zlabel(app.Data_Timedomine, 'Z')
            app.DATA_PSD = uiaxes(app.Panel);
            title(app.DATA_PSD, '频域信息')
            xlabel(app.DATA_PSD, '频率(Hz)')
            ylabel(app.DATA_PSD, '能量(μV²/Hz)')
            zlabel(app.DATA_PSD, 'Z')
            app.DATA_PSD.XAxisLocation = 'origin';
            app.DATA_PSD.LineStyleOrder = {'-'};
            app.QUANLITY_INDEX = uiaxes(app.Panel);
            app.Research_report = uibutton(app.Panel, 'push');
            app.Research_report.ButtonPushedFcn = createCallbackFcn(app, @Research_reportButtonPushed, true);
            app.Research_report.Text = '生成数据分析报告 (测试版)';
            app.Preview = uibutton(app.Panel, 'push');
            app.Preview.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.Preview.Enable = 'off';
            app.Preview.Text = '数据预览/处理';
            app.Data_config = uibutton(app.Panel, 'push');
            app.Data_config.ButtonPushedFcn = createCallbackFcn(app, @Data_configButtonPushed, true);
            app.Data_config.Enable = 'off';
            app.Data_config.Text = '数据确认';
            app.chi_choose = uidropdown(app.Panel);
            app.chi_choose.Items = {'1+3-', '2+4-'};
            app.chi_choose.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.chi_choose.ValueChangedFcn = createCallbackFcn(app, @chi_chooseValueChanged, true);
            app.chi_choose.Enable = 'off';
            app.chi_choose.Value = '1';
            app.SameMeth = uicheckbox(app.Panel);
            app.SameMeth.Enable = 'off';
            app.SameMeth.Text = '所有通道执行相同操作';
            app.SameMeth.Value = true;
            app.Auto_Stimu_Artifact = uicheckbox(app.Panel);
            app.Auto_Stimu_Artifact.Text = '基线与刺激伪迹处理';
            app.Auto_Stimu_Artifact.Value = true;
            app.Auto_ECG = uicheckbox(app.Panel);
            app.Auto_ECG.Text = '心电自动处理';
            app.Auto_ECG.Value = true;
            app.Auto_Reject = uicheckbox(app.Panel);
            app.Auto_Reject.Text = '自动数据段筛除';
            app.Auto_Reject.Value = true;
            app.Beta_Peakfind = uibutton(app.Panel, 'push');
            app.Beta_Peakfind.ButtonPushedFcn = createCallbackFcn(app, @Beta_PeakfindButtonPushed, true);
            app.Beta_Peakfind.Enable = 'off';
            app.Beta_Peakfind.Text = 'β峰值识别';
            app.currentdata_txt = uitextarea(app.Panel);
            app.currentdata_txt.Editable = 'off';
            app.currentdata_txt.Value = {'请进行"数据解析"'};
            app.Current_data = uilabel(app.Panel);
            app.Current_data.Text = '数据：';
            app.Left_Push = uiimage(app.Panel);
            app.Left_Push.ImageClickedFcn = createCallbackFcn(app, @Left_PushImageClicked, true);
            app.Left_Push.Enable = 'off';
            app.Left_Push.ImageSource = fullfile(pathToMLAPP, 'ICONS', 'Left_Push.jpg');
            app.Right_Push = uiimage(app.Panel);
            app.Right_Push.ImageClickedFcn = createCallbackFcn(app, @Right_PushImageClicked, true);
            app.Right_Push.Enable = 'off';
            app.Right_Push.ImageSource = fullfile(pathToMLAPP, 'ICONS', 'Right_Push.jpg');
            app.Scales_But = uibutton(app.Panel, 'push');
            app.Scales_But.ButtonPushedFcn = createCallbackFcn(app, @Scales_ButPushed, true);
            app.Scales_But.Enable = 'off';
            app.Scales_But.Text = '查看全局';
            app.Plot_ALL_Channl = uibutton(app.Panel, 'push');
            app.Plot_ALL_Channl.ButtonPushedFcn = createCallbackFcn(app, @Plot_ALL_ChannlButtonPushed, true);
            app.Plot_ALL_Channl.Enable = 'off';
            app.Plot_ALL_Channl.Text = '全通道绘制';
            app.Chack_channel_now = uilabel(app.Panel);
            app.Chack_channel_now.Text = '当前查看通道：';
            app.Change_to = uilabel(app.Panel);
            app.Change_to.Text = '查看模式切换为：';
            app.flip_page = uilabel(app.Panel);
            app.flip_page.Text = '翻页：';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = {'质量'; '指示：'};
            app.arti_detect = uicheckbox(app.Panel);
            app.arti_detect.Text = '伪迹识别';
            app.arti_detect.Value = true;
            app.TFButton = uibutton(app.Panel, 'push');
            app.TFButton.ButtonPushedFcn = createCallbackFcn(app, @TFButtonPushed, true);
            app.TFButton.Text = 'TF';
            app.IPGLabel = uilabel(app.Panel);
            app.IPGLabel.Text = 'IPG编号：';
            app.current_IPGfile = uitextarea(app.Panel);
            app.current_IPGfile.Editable = 'off';
            app.current_IPGfile.Value = {'导入数据以获取'};
            app.data_management = uipanel(app.MainFigure);
            app.data_management.Title = '数据管理';
            app.Current_location = uilabel(app.data_management);
            app.Current_location.Text = '当前数据路径：';
            app.Save_location = uibutton(app.data_management, 'push');
            app.Save_location.Text = '修改路径';
            app.Save_Path = uitextarea(app.data_management);
            app.Open_File = uibutton(app.data_management, 'push');
            app.Open_File.Text = '打开路径';
            app.Researcher_Name = uieditfield(app.data_management, 'text');
            app.Researcher_Name.Value = 'Default Researcher';
            app.Researcher = uilabel(app.data_management);
            app.Researcher.Text = '研究人员：';
            app.out_put_select = uidropdown(app.data_management);
            app.out_put_select.Items = {'Matlab(格式)', 'TXT(格式)', 'EDF(格式)'};
            app.out_put_select.ItemsData = {'1', '3', '2'};
            app.out_put_select.Value = '1';
            app.Save_Data = uibutton(app.data_management, 'push');
            app.Save_Data.Enable = 'off';
            app.Save_Data.Text = '存数据';
            app.save_data2workspace = uibutton(app.data_management, 'push');
            app.save_data2workspace.ButtonPushedFcn = createCallbackFcn(app, @save_data2workspaceButtonPushed, true);
            app.save_data2workspace.Enable = 'off';
            app.save_data2workspace.Text = '工作区';
            % Show the figure after all components are created
            app.MainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_Clinical_Research_CHI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFigure)
        end
    end
end