classdef APP_anylisis_Data_CUT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        AUTO_SIZE            matlab.ui.control.StateButton
        Label_9              matlab.ui.control.Label
        cut_method_dropdown  matlab.ui.control.DropDown
        Label_6              matlab.ui.control.Label
        giveup_and_return    matlab.ui.control.Button
        samplerate           matlab.ui.control.NumericEditField
        Label_5              matlab.ui.control.Label
        CheckBox             matlab.ui.control.CheckBox
        chi_choose           matlab.ui.control.DropDown
        Label_4              matlab.ui.control.Label
        data_cut_mannual     matlab.ui.container.Panel
        data_cut_in          matlab.ui.control.DropDown
        Label_8              matlab.ui.control.Label
        data_cut_meths       matlab.ui.control.DropDown
        Label_7              matlab.ui.control.Label
        wd_cut_step          matlab.ui.control.Button
        cut_data             matlab.ui.control.Button
        select_cut_parts     matlab.ui.control.Button
        data_cut_outofbound  matlab.ui.container.Panel
        Button_9             matlab.ui.control.Button
        Button_8             matlab.ui.control.Button
        DropDown_3           matlab.ui.control.DropDown
        Label_3              matlab.ui.control.Label
        DropDown_2           matlab.ui.control.DropDown
        Label_2              matlab.ui.control.Label
        DropDown             matlab.ui.control.DropDown
        Label                matlab.ui.control.Label
        confirm_cut_result   matlab.ui.control.Button
        NERCNLabel           matlab.ui.control.Label
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes1              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Data % savedatafrom_main
        Data_last_step % save last step data
        Data_now % save data_now data
        fs % save sample rate
        chi % save chinnel name
        CUT_X % cut_points
        From_where % from what platform = 1/ Main ； = 2/ Batch
    end
    
    properties (Access = public)
        APP_anylysis_CHI_0_1 % main app
        APP_anylysis_Data_batch_CHI % main app 2 
    end
    
    methods (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, APP_anylysis_CHI_0_1, DATA, fs, chi, TYPE)
            set(app.UIFigure,'Icon','icon_48.png');
            app.UIFigure.Name = 'NERCN脑电平台-数据裁剪工具';
            screenSize = get(groot, 'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
            horizontalCenter = (screenWidth - appWidth) / 2;
            verticalCenter = (screenHeight - appHeight) / 2;
            app.UIFigure.Position(1:2) = [horizontalCenter, verticalCenter];
            try
            % set(app.UIFigure,'Icon','icon_48.png');
            app.UIFigure.Resize = "off";
            if TYPE == 1
            app.APP_anylysis_CHI_0_1 = APP_anylysis_CHI_0_1;
            elseif TYPE ==2
            app.APP_anylysis_Data_batch_CHI = APP_anylysis_CHI_0_1;
            end
            app.Data = DATA;
            app.fs = fs;
            app.chi = chi;
            app.From_where = TYPE;
            app.Data_last_step = app.Data;
            app.Data_now = app.Data_last_step;
            app.chi_choose.Items = app.chi;
            app.samplerate.Value = app.fs;
            
            screenSize = get(groot, 'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
            horizontalCenter = (screenWidth - appWidth) / 2;
            verticalCenter = (screenHeight - appHeight) / 2;
            app.UIFigure.Position(1:2) = [horizontalCenter, verticalCenter];

            plot(app.UIAxes1,app.Data_now(1,500:end));
            app.UIAxes1.XLim = [1 length(app.Data_now(1,500:end))];
            app.fs = app.samplerate.Value;
            [B,f,t] = specgram(app.Data_now(1,500:end),round(app.fs),round(app.fs),round(app.fs),round(app.fs*0.75));
            imagesc(app.UIAxes2,t,f,20*log10(abs(B)));

            app.UIAxes2.YDir = "normal";
            app.UIAxes2.Colormap=jet;
            app.UIAxes2.YLim = [0 180];
            app.UIAxes2.XLim = [t(1) t(end)];
            app.UIAxes1.Color = [0.76,0.73,0.82];
            end 
        end

        % Button pushed function: select_cut_parts
        function select_cut_partsPushed(app, event)
    
        % Check Matlab version, throw error if prior to r2020b
        assert(~verLessThan('Matlab', '9.9'), 'ginput not supported prior to Matlab r2020b.')  
        % Set up figure handle visibility, run ginput, and return state
        fhv = app.UIFigure.HandleVisibility;        % Current status
        app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
        set(0, 'CurrentFigure', app.UIFigure)       % Make fig current
        if str2double(app.data_cut_meths.Value) == 1
        [x,~] = ginput(2);    % run ginput
        value = app.chi_choose.Value;
        i = value;
        cla(app.UIAxes1)
        plot(app.UIAxes1,app.Data_now(str2double(i),500:end));
        app.UIAxes1.XLim = [1 length(app.Data_now(1,500:end))];
        hold(app.UIAxes1,"on")
        xline(app.UIAxes1,x(1),LineWidth=2)
        hold(app.UIAxes1,"on")
        xline(app.UIAxes1,x(2),LineWidth=2)
        hold(app.UIAxes1,"off")
        app.CUT_X = sort(x);
        app.UIFigure.HandleVisibility = fhv;        % return original state
        else
        [x,~] = ginput(1);    % run ginput
        value = app.chi_choose.Value;
        i = value;
        cla(app.UIAxes1)
        plot(app.UIAxes1,app.Data_now(str2double(i),500:end));
        app.UIAxes1.XLim = [1 length(app.Data_now(1,500:end))];
        hold(app.UIAxes1,"on")
        xline(app.UIAxes1,x(1),LineWidth=2)
        hold(app.UIAxes1,"off")
        app.CUT_X = sort(x);
        app.UIFigure.HandleVisibility = fhv;        % return original state
        end
        
        app.cut_data.Enable = "on";
        app.select_cut_parts.Enable = "off"; 
        end

        % Value changed function: chi_choose
        function chi_chooseValueChanged(app, event)
            value = app.chi_choose.Value;
            i = value;
            plot(app.UIAxes1,app.Data_now(str2double(i),500:end));
            app.UIAxes1.XLim = [1 length(app.Data_now(1,500:end))];
            app.fs = app.samplerate.Value;
            [B,f,t] = specgram(app.Data_now(str2double(i),500:end),round(app.fs),round(app.fs),round(app.fs),round(app.fs*0.75));
            imagesc(app.UIAxes2,t,f,20*log10(abs(B)));
            app.UIAxes2.YDir = "normal";
            app.UIAxes2.Colormap=jet;
            app.UIAxes2.YLim = [0 180];
            app.UIAxes2.XLim = [t(1) t(end)];
%             Data1 = app.Data_now(str2double(i),500:end);

        end

        % Button pushed function: cut_data
        function cut_dataPushed(app, event)
        
        if str2double(app.data_cut_in.Value) == 1
            if str2double(app.data_cut_meths.Value) == 1
               app.Data_now(:,app.CUT_X(1):app.CUT_X(2))=[];
            elseif str2double(app.data_cut_meths.Value) == 2
               app.Data_now(:,1:app.CUT_X(1))=[];
            elseif str2double(app.data_cut_meths.Value) == 3
               app.Data_now(:,app.CUT_X(1):end)=[];
            end
        elseif str2double(app.data_cut_in.Value) == 2
            if str2double(app.data_cut_meths.Value) == 1
               app.Data_now(:,app.CUT_X(1):app.CUT_X(2))=0;
            elseif str2double(app.data_cut_meths.Value) == 2
               app.Data_now(:,1:app.CUT_X(1))=0;
            elseif str2double(app.data_cut_meths.Value) == 3
               app.Data_now(:,app.CUT_X(1):end)=0;
            end
        elseif str2double(app.data_cut_in.Value) ==3
            if str2double(app.data_cut_meths.Value) == 1
               app.Data_now(:,app.CUT_X(1):app.CUT_X(2))=NaN;
            elseif str2double(app.data_cut_meths.Value) == 2
               app.Data_now(:,1:app.CUT_X(1))=NaN;
            elseif str2double(app.data_cut_meths.Value) == 3
               app.Data_now(:,app.CUT_X(1):end)=NaN;
            end
        end
            value = app.chi_choose.Value;
            i = value;
            cla(app.UIAxes1)
            cla(app.UIAxes2)
            plot(app.UIAxes1,app.Data_now(str2double(i),1:end));
            app.UIAxes1.XLim = [1 length(app.Data_now(1,1:end))];
            app.fs = app.samplerate.Value;
            [B,f,t] = specgram(app.Data_now(str2double(i),1:end),app.fs,app.fs,app.fs,round(app.fs*0.75));
            imagesc(app.UIAxes2,t,f,20*log10(abs(B)));
            app.UIAxes2.YDir = "normal";
            app.UIAxes2.Colormap=jet;
            app.UIAxes2.YLim = [0 180];
            app.UIAxes2.XLim = [t(1) t(end)];

            app.wd_cut_step.Enable ="on";
%           app.finish_data_cut.Enable ="on";
            app.select_cut_parts.Enable = "on";
        end

        % Value changed function: cut_method_dropdown
        function cut_method_dropdownValueChanged(app, event)
            value = str2double(app.cut_method_dropdown.Value);
            if value == 1
                app.data_cut_mannual.Visible = "on";
                app.data_cut_outofbound.Visible = "off";
            elseif value == 2
                app.data_cut_mannual.Visible = "off";
                app.data_cut_outofbound.Visible = "on";
            end
        end

        % Button pushed function: confirm_cut_result
        function confirm_cut_resultPushed(app, event)
            if app.From_where == 1
                Data_out = app.Data_now;
                app.APP_anylysis_CHI_0_1.closeDialog(); % 调用App A的方法来关闭对话框
                update_cut_data(app.APP_anylysis_CHI_0_1,Data_out);
                delete(app)
            elseif app.From_where == 2
                Data_out = app.Data_now;
                app.APP_anylysis_Data_batch_CHI.closeDialog_batch(); % 调用App A的方法来关闭对话框
                 update_cut_data_batch(app.APP_anylysis_Data_batch_CHI,Data_out);
                delete(app)
            end
        end

        % Button pushed function: giveup_and_return
        function giveup_and_returnPushed(app, event)
            if app.From_where == 1
                Data_out = app.Data;
                app.APP_anylysis_CHI_0_1.closeDialog(); % 调用App A的方法来关闭对话框
                update_cut_data(app.APP_anylysis_CHI_0_1,Data_out);
                delete(app)
            elseif app.From_where == 2
                Data_out = app.Data;
                app.APP_anylysis_Data_batch_CHI.closeDialog_batch(); % 调用App A的方法来关闭对话框
                 update_cut_data_batch(app.APP_anylysis_Data_batch_CHI,Data_out);
                delete(app)
            end
        end

        % Button pushed function: wd_cut_step
        function wd_cut_stepButtonPushed(app, event)
            
            app.Data_now = app.Data_last_step;
            value = app.chi_choose.Value;
            i = value;
            plot(app.UIAxes1,app.Data_now(str2double(i),500:end));
            app.UIAxes1.XLim = [1 length(app.Data_now(1,500:end))];
            app.fs = app.samplerate.Value;
            [B,f,t] = specgram(app.Data_now(str2double(i),500:end),app.fs,app.fs,app.fs,round(app.fs*0.75));
            imagesc(app.UIAxes2,t,f,20*log10(abs(B)));
            app.UIAxes2.YDir = "normal";
            app.UIAxes2.Colormap=jet;
            app.UIAxes2.YLim = [0 180];
            app.UIAxes2.XLim = [t(1) t(end)];
            app.wd_cut_step.Enable = "off";
            app.cut_data.Enable = "off";
            app.select_cut_parts.Enable = "on";

        end

        % Value changed function: AUTO_SIZE
        function AUTO_SIZEValueChanged(app, event)
            
            value = app.AUTO_SIZE.Value;
            if value == 1
                app.AUTO_SIZE.Text = 'autosize off';
                app.UIFigure.Resize = "off";
            elseif value == 0
                app.AUTO_SIZE.Text = 'autosize on';      
                app.UIFigure.Resize = "on";
            end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection = uiconfirm(app.UIFigure, '您确定退出吗？注意！直接退出将不进行数据保存', '退出？','Options',{'是', '否'},'DefaultOption', '否', 'CancelOption', '是');
            if strcmp(selection,'是')
                try
                    if app.From_where == 1
                       app.APP_anylysis_CHI_0_1.closeDialog(); % 调用App A的方法来关闭对话框
                    elseif app.From_where == 2
                       app.APP_anylysis_Data_batch_CHI.closeDialog_batch(); % 调用App B的方法来关闭对话框
                    end
                end
               delete(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes1 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes1, '数据点数')
            ylabel(app.UIAxes1, '振幅')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, '时间（s）')
            ylabel(app.UIAxes2, '频率')
            zlabel(app.UIAxes2, 'Z')
            app.NERCNLabel = uilabel(app.UIFigure);
            app.NERCNLabel.Text = 'NERCN 离线分析平台';
            app.confirm_cut_result = uibutton(app.UIFigure, 'push');
            app.confirm_cut_result.ButtonPushedFcn = createCallbackFcn(app, @confirm_cut_resultPushed, true);
            app.confirm_cut_result.Text = '确认并返回';
            app.data_cut_outofbound = uipanel(app.UIFigure);
            app.data_cut_outofbound.Title = '阈值裁剪';
            app.data_cut_outofbound.Visible = 'off';
            app.Label = uilabel(app.data_cut_outofbound);
            app.Label.Text = '阈值上限';
            app.DropDown = uidropdown(app.data_cut_outofbound);
            app.Label_2 = uilabel(app.data_cut_outofbound);
            app.Label_2.Text = '阈值下限';
            app.DropDown_2 = uidropdown(app.data_cut_outofbound);
            app.Label_3 = uilabel(app.data_cut_outofbound);
            app.Label_3.Text = '单位长度';
            app.DropDown_3 = uidropdown(app.data_cut_outofbound);
            app.Button_8 = uibutton(app.data_cut_outofbound, 'push');
            app.Button_8.Text = '裁剪数据';
            app.Button_9 = uibutton(app.data_cut_outofbound, 'push');
            app.Button_9.Text = '撤销';
            app.data_cut_mannual = uipanel(app.UIFigure);
            app.data_cut_mannual.Title = '手动裁剪';
            app.select_cut_parts = uibutton(app.data_cut_mannual, 'push');
            app.select_cut_parts.ButtonPushedFcn = createCallbackFcn(app, @select_cut_partsPushed, true);
            app.select_cut_parts.Text = '选择裁剪区域';
            app.cut_data = uibutton(app.data_cut_mannual, 'push');
            app.cut_data.ButtonPushedFcn = createCallbackFcn(app, @cut_dataPushed, true);
            app.cut_data.Enable = 'off';
            app.cut_data.Text = '裁剪数据';
            app.wd_cut_step = uibutton(app.data_cut_mannual, 'push');
            app.wd_cut_step.ButtonPushedFcn = createCallbackFcn(app, @wd_cut_stepButtonPushed, true);
            app.wd_cut_step.Enable = 'off';
            app.wd_cut_step.Text = '撤回';
            app.Label_7 = uilabel(app.data_cut_mannual);
            app.Label_7.Text = '选择方式';
            app.data_cut_meths = uidropdown(app.data_cut_mannual);
            app.data_cut_meths.Items = {'所夹区域', '选点以前', '选点以后'};
            app.data_cut_meths.ItemsData = {'1', '2', '3'};
            app.data_cut_meths.Value = '1';
            app.Label_8 = uilabel(app.data_cut_mannual);
            app.Label_8.Text = '裁剪方式';
            app.data_cut_in = uidropdown(app.data_cut_mannual);
            app.data_cut_in.Items = {'删除', '置0'};
            app.data_cut_in.ItemsData = {'1', '2', '3'};
            app.data_cut_in.Value = '1';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '选择通道';
            app.chi_choose = uidropdown(app.UIFigure);
            app.chi_choose.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.chi_choose.ValueChangedFcn = createCallbackFcn(app, @chi_chooseValueChanged, true);
            app.chi_choose.Value = '1';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Enable = 'off';
            app.CheckBox.Text = '所有通道同步剪裁';
            app.CheckBox.Value = true;
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '采样率';
            app.samplerate = uieditfield(app.UIFigure, 'numeric');
            app.giveup_and_return = uibutton(app.UIFigure, 'push');
            app.giveup_and_return.ButtonPushedFcn = createCallbackFcn(app, @giveup_and_returnPushed, true);
            app.giveup_and_return.Text = '撤销并返回';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '剪裁方法';
            app.cut_method_dropdown = uidropdown(app.UIFigure);
            app.cut_method_dropdown.Items = {'手动剪裁'};
            app.cut_method_dropdown.ItemsData = 1;
            app.cut_method_dropdown.ValueChangedFcn = createCallbackFcn(app, @cut_method_dropdownValueChanged, true);
            app.cut_method_dropdown.Value = 1;
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '-数据裁剪工具';
            app.AUTO_SIZE = uibutton(app.UIFigure, 'state');
            app.AUTO_SIZE.ValueChangedFcn = createCallbackFcn(app, @AUTO_SIZEValueChanged, true);
            app.AUTO_SIZE.Text = 'autosize off';
            app.AUTO_SIZE.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_anylisis_Data_CUT(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end