classdef APP_anylysis_CHI_0_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFigure                     matlab.ui.Figure
        Menu_main                      matlab.ui.container.Menu
        Menu_7                         matlab.ui.container.Menu
        RSMenu                         matlab.ui.container.Menu
        LFPMenu                        matlab.ui.container.Menu
        allage                         matlab.ui.container.Menu
        AUTO_RESIZE                    matlab.ui.container.Menu
        Menu_exit                      matlab.ui.container.Menu
        Menu_functions                 matlab.ui.container.Menu
        Menu_current_INFO              matlab.ui.container.Menu
        Menu_data_CUT                  matlab.ui.container.Menu
        Menu_batch_Processing          matlab.ui.container.Menu
        Menu_data_Compare              matlab.ui.container.Menu
        Data_copy                      matlab.ui.container.Menu
        Clear_all_set                  matlab.ui.container.Menu
        Set_Theme_Color                matlab.ui.container.Menu
        Defult                         matlab.ui.container.Menu
        White                          matlab.ui.container.Menu
        Black                          matlab.ui.container.Menu
        PercptPCMenu                   matlab.ui.container.Menu
        Menu_help                      matlab.ui.container.Menu
        illustrate                     matlab.ui.container.Menu
        Language                       matlab.ui.container.Menu
        ChineseMenu                    matlab.ui.container.Menu
        EniglisMenu                    matlab.ui.container.Menu
        Is_Log_Plot                    matlab.ui.control.CheckBox
        data_management                matlab.ui.container.Panel
        Open_File                      matlab.ui.control.Button
        out_put_select                 matlab.ui.control.DropDown
        Save_Data                      matlab.ui.control.Button
        save_data2workspace            matlab.ui.control.Button
        Save_Path                      matlab.ui.control.TextArea
        Save_location                  matlab.ui.control.Button
        Current_location               matlab.ui.control.Label
        processing_pannel              matlab.ui.container.TabGroup
        PreProcess_Tool                matlab.ui.container.Tab
        current_IPGfile                matlab.ui.control.TextArea
        IPGLabel                       matlab.ui.control.Label
        Batch_Procecces                matlab.ui.control.Button
        Comparation_TOOL_2             matlab.ui.control.Button
        Range_freq                     matlab.ui.control.RangeSlider
        Label_54                       matlab.ui.control.Label
        Current_data                   matlab.ui.control.Label
        currentdata_txt                matlab.ui.control.TextArea
        Slider_Plot                    matlab.ui.control.RangeSlider
        Label_50                       matlab.ui.control.Label
        back                           matlab.ui.control.Button
        Data_re_finish                 matlab.ui.control.Button
        Panel_Removal                  matlab.ui.container.Panel
        Panel_Removal_in               matlab.ui.container.TabGroup
        Tab_for_detrend                matlab.ui.container.Tab
        correction_filterdesign_Panel  matlab.ui.container.Panel
        fs_2                           matlab.ui.control.EditField
        Label_fs_2                     matlab.ui.control.Label
        Label_fs_1                     matlab.ui.control.Label
        Label_samplerate               matlab.ui.control.Label
        Label_filterorder              matlab.ui.control.Label
        design_high_pass               matlab.ui.control.Button
        comfirm_detrend2               matlab.ui.control.Button
        highpass_apply                 matlab.ui.control.Button
        fs_1                           matlab.ui.control.EditField
        filter_sample_rate             matlab.ui.control.EditField
        filterorder                    matlab.ui.control.EditField
        filter_methed_2                matlab.ui.control.DropDown
        FIRCheckBox                    matlab.ui.control.CheckBox
        IIRCheckBox                    matlab.ui.control.CheckBox
        filter_methed_1                matlab.ui.control.DropDown
        Label_15                       matlab.ui.control.Label
        correction_detrend_Panel       matlab.ui.container.Panel
        detrend_confirm                matlab.ui.control.Button
        apply_detrend1                 matlab.ui.control.Button
        continuous                     matlab.ui.control.DropDown
        Label_14                       matlab.ui.control.Label
        NaNDropDown                    matlab.ui.control.DropDown
        NaNDropDownLabel               matlab.ui.control.Label
        Num_of_order                   matlab.ui.control.DropDown
        Label_13                       matlab.ui.control.Label
        detrend_function_chiose        matlab.ui.control.DropDown
        DropDown_6Label                matlab.ui.control.Label
        filter_simptom2                matlab.ui.control.UIAxes
        filter_simptom1                matlab.ui.control.UIAxes
        Tab_for_stim_artifacts         matlab.ui.container.Tab
        STMartifactremove_filter       matlab.ui.container.Panel
        frequency1                     matlab.ui.control.NumericEditField
        frequency2                     matlab.ui.control.NumericEditField
        frequency3                     matlab.ui.control.NumericEditField
        frequency4                     matlab.ui.control.NumericEditField
        frequency2_text                matlab.ui.control.Label
        frequency1_text                matlab.ui.control.Label
        frequency3_text                matlab.ui.control.Label
        frequency4_text                matlab.ui.control.Label
        samplefrequency_STM1           matlab.ui.control.NumericEditField
        EditField_7Label               matlab.ui.control.Label
        filter_design_function         matlab.ui.control.Button
        filter_order_STM               matlab.ui.control.NumericEditField
        Label_9                        matlab.ui.control.Label
        STMartifactremove_confirm      matlab.ui.control.Button
        apply_lowpass_filter           matlab.ui.control.Button
        filter_file_choose             matlab.ui.control.Button
        spicified_design               matlab.ui.control.Button
        filter_methed_FIR              matlab.ui.control.DropDown
        filter_methed_IIR              matlab.ui.control.DropDown
        FIRCheckBox_fir                matlab.ui.control.CheckBox
        IIRCheckBox_iir                matlab.ui.control.CheckBox
        filter_method_dropdown         matlab.ui.control.DropDown
        Label_7                        matlab.ui.control.Label
        STMartifactremove_stract       matlab.ui.container.Panel
        STMartifact_re_length          matlab.ui.control.EditField
        Label_42                       matlab.ui.control.Label
        STMartifact_num_select         matlab.ui.control.NumericEditField
        STMartifact_remove             matlab.ui.control.Button
        Label_32                       matlab.ui.control.Label
        STMartifactremove_confirm_2    matlab.ui.control.Button
        artifact_denoise               matlab.ui.control.DropDown
        Label_12                       matlab.ui.control.Label
        filter_simptom2_2              matlab.ui.control.UIAxes
        filter_simptom1_2              matlab.ui.control.UIAxes
        Tab_for_ECG_artifacts          matlab.ui.container.Tab
        EASPanel                       matlab.ui.container.Panel
        IS_ALL_CHANNEL                 matlab.ui.control.CheckBox
        EAS_set_pice                   matlab.ui.control.DropDown
        Label_46                       matlab.ui.control.Label
        Label_44                       matlab.ui.control.Label
        Load_ECG                       matlab.ui.control.Button
        is_ECG_avi                     matlab.ui.control.DropDown
        Label_43                       matlab.ui.control.Label
        confirm_EAS                    matlab.ui.control.Button
        apply_EAS                      matlab.ui.control.Button
        K_length                       matlab.ui.control.EditField
        Label_27                       matlab.ui.control.Label
        DropDown_7                     matlab.ui.control.DropDown
        DropDown_7Label                matlab.ui.control.Label
        Temp_figure                    matlab.ui.control.UIAxes
        all_chi                        matlab.ui.control.CheckBox
        data_cut_tool                  matlab.ui.control.Button
        chi_choose                     matlab.ui.control.DropDown
        Data_config                    matlab.ui.control.Button
        DATA_PSD                       matlab.ui.control.UIAxes
        axis_preprocessing_now         matlab.ui.control.UIAxes
        FeatureProcess_Tool            matlab.ui.container.Tab
        TabGroup3                      matlab.ui.container.TabGroup
        PSD_analisis                   matlab.ui.container.Tab
        Comparation_TOOL               matlab.ui.control.Button
        PSD_YSlider                    matlab.ui.control.RangeSlider
        Label_53                       matlab.ui.control.Label
        Is_shadow                      matlab.ui.control.CheckBox
        out_put_select2                matlab.ui.control.DropDown
        Beta_Peakfind                  matlab.ui.control.Button
        Slider_PSD                     matlab.ui.control.RangeSlider
        Label_51                       matlab.ui.control.Label
        save_PSD_2File                 matlab.ui.control.Button
        TO_3                           matlab.ui.control.Label
        TO_2                           matlab.ui.control.Label
        CtrlLabel                      matlab.ui.control.Label
        Window_length                  matlab.ui.control.EditField
        Label_47                       matlab.ui.control.Label
        Powercalcu_frequency           matlab.ui.control.EditField
        Label_21                       matlab.ui.control.Label
        plot_freq_up                   matlab.ui.control.NumericEditField
        TO                             matlab.ui.control.Label
        plot_freq_down                 matlab.ui.control.NumericEditField
        EditField_7Label_2             matlab.ui.control.Label
        welch_overlap_2                matlab.ui.control.EditField
        Label_25                       matlab.ui.control.Label
        chi_calcu                      matlab.ui.control.ListBox
        Label_33                       matlab.ui.control.Label
        save_PSD_2Work_space           matlab.ui.control.Button
        save_PSD_Fig                   matlab.ui.control.Button
        Plot_PSD                       matlab.ui.control.Button
        overlap                        matlab.ui.control.EditField
        Label_23                       matlab.ui.control.Label
        FFT_Number                     matlab.ui.control.EditField
        Label_22                       matlab.ui.control.Label
        PSD_method                     matlab.ui.control.DropDown
        Label_20                       matlab.ui.control.Label
        PSD_figure                     matlab.ui.control.UIAxes
        Phase_Value_analisis           matlab.ui.container.Tab
        BandPowerSelect                matlab.ui.control.DropDown
        Label_55                       matlab.ui.control.Label
        SAVE_PLV_FIG                   matlab.ui.control.Button
        PLV_length                     matlab.ui.control.EditField
        Label_45                       matlab.ui.control.Label
        result                         matlab.ui.control.NumericEditField
        PLV_calcu                      matlab.ui.control.Button
        channel_choose_2               matlab.ui.control.DropDown
        Label_31                       matlab.ui.control.Label
        channel_choose_1               matlab.ui.control.DropDown
        DropDown_15Label               matlab.ui.control.Label
        SAVE_PHASE_FIG                 matlab.ui.control.Button
        samplerate_phase               matlab.ui.control.NumericEditField
        EditField_3Label               matlab.ui.control.Label
        channel_Phase_spectrum         matlab.ui.control.DropDown
        Label_37                       matlab.ui.control.Label
        plot_Phase_spectrum            matlab.ui.control.Button
        FFTEditField                   matlab.ui.control.NumericEditField
        FFTEditFieldLabel              matlab.ui.control.Label
        Phase_method                   matlab.ui.control.DropDown
        Label_26                       matlab.ui.control.Label
        PLV_plot                       matlab.ui.control.UIAxes
        Phase_feature                  matlab.ui.control.UIAxes
        Panel_4                        matlab.ui.container.Panel
        Plot_Changes                   matlab.ui.control.Button
        out_put_select3                matlab.ui.control.DropDown
        Y_range_Slide                  matlab.ui.control.RangeSlider
        SliderLabel                    matlab.ui.control.Label
        Time_range_Slide               matlab.ui.control.RangeSlider
        Label_52                       matlab.ui.control.Label
        Color_range_Slide              matlab.ui.control.RangeSlider
        Label_49                       matlab.ui.control.Label
        save_T_F_MAP_Workspace         matlab.ui.control.Button
        save_T_F_MAP                   matlab.ui.control.Button
        TO_4                           matlab.ui.control.Label
        T_M_NFFT                       matlab.ui.control.EditField
        FFTEditField_2Label            matlab.ui.control.Label
        T_M_WindowsLength              matlab.ui.control.EditField
        Label_41                       matlab.ui.control.Label
        samplarate                     matlab.ui.control.EditField
        Label_40                       matlab.ui.control.Label
        T_M_overlap                    matlab.ui.control.EditField
        Label_39                       matlab.ui.control.Label
        T_M_Save                       matlab.ui.control.Button
        T_M_chi                        matlab.ui.control.DropDown
        DropDown_15Label_2             matlab.ui.control.Label
        T_M_plot                       matlab.ui.control.Button
        TIME_FREQ_meth                 matlab.ui.control.DropDown
        Label_19                       matlab.ui.control.Label
        TIME_FREQUENCY                 matlab.ui.control.UIAxes
        data_load_pannel               matlab.ui.container.Panel
        IS_OLD_VERSON                  matlab.ui.control.CheckBox
        More_info                      matlab.ui.control.Button
        data_config                    matlab.ui.control.Button
        InseartNAN                     matlab.ui.control.CheckBox
        UITable                        matlab.ui.control.Table
        View_chi_DropDown              matlab.ui.control.DropDown
        Label_5                        matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        infoACQ                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        infoSTIM                       matlab.ui.control.Label
        File_selection                 matlab.ui.control.Button
        axis_pre                       matlab.ui.control.UIAxes
        NNSRLabel                      matlab.ui.control.Label
    end

    
    properties (Access = private)
        Data_now % Save Latest Data
        Data_ori % Save ori data(unpacked)
        Filename % Save filename
        data_path
        data_fs % Samplerate
        stim_info % Stimulation infos
        acq_info
        Data_last_step % Now Processing
        detrened_data % Description
        filter_design_highpass % Description
        filter_design_artifact % Description
        Stim_ONOFF_info % Description
        Stim_Emode_info % Description
        Freq_Stim % Description
        Polar_info % Description
        mat_data % Description
        Lost_index % DescriptionF1a_unpack
        IS_detrend_finish % Description
        RESULT_DATA_PSD_POWER % Description
        RESULT_DATA_PSD_FREQ 
        RESULT_DATA_TIME_FREQUENCY_POWER
        RESULT_DATA_PLV_VALUE
        RESULT_DATA_TIME_FREQUENCY_POWER_f % Description
        Filename2 % Description
        isfilter_design % Description
        First_Filename % Description
        History % Description
        IS_106RS % Description
        current_Path % Description
        isautoresize % Resize
        waitting_now % waiting figure
        Channel_Use_PSD % PSD channel use
        IsaddNans % Weaser add nan to group
        Caxis % Description
        RESULT_DATA_TIME_FREQUENCY_POWER_t; % 
        Dealtiled_para % Detailed collection/stimulation information, etc
        defult_date % acq date
        defult_time % acq time
        Dialog % Execute dialog in another app
        noverlap % Description
        % Save defult mat 
        % lang_any = load('language_any.mat');
        AppInstallDir       % Application installation directory
        SettingsDir         % Set storage directory
        SettingsFile        % Set file path
        AppVersion = '0.2'  
    end
    
    properties (Access = public)
        CUTapp % Description
        data_for_cut % cut data fun
        Rec_Parameters_info
        app_anylisis_Data_CUT % sub app
        app_anylysis_Data_batch_CHI
        % APP_analysis_Data_batch_CHI % sub app2
        PreferenceGroup = 'P01_anylysis'  %
    end
    
    methods (Access = private)
      % function[Data_out,Data_fs,Stim_info,acq_info,Stim_ONOFF_info,Stim_Emode_info,Freq_Stim,Polar_info,Rec_Parameters_info,Lost] = F1A_unpack_dir(~,bit_data) 

      % function [Data_out,Data_fs,Stim_info,acq_info,Stim_ONOFF_info,Stim_Emode_info,Freq_Stim,Polar_info,Rec_Parameters_info,Lost] = P02_F3a_unpack_dir(~,bit_data,IsaddNans,FILENAME) 

      % function [Data_out,Data_fs,Stim_info,acq_info,Stim_ONOFF_info,Stim_Emode_info,Freq_Stim,Polar_info,Rec_Parameters_info,Lost] = P02_F2a_unpack_dir(~,bit_data,IsaddNans,FILENAME)     

      % function [Data_out1,Data_out2,Data_fs,Stim_info,acq_info,Stim_ONOFF_info,Stim_Emode_info,Freq_Stim,Polar_info,Rec_Parameters_info] = P02_F1b_load_mat(app,mat_data)        

      % function varargout = P02_F1c_Loadedf(~,varargin)
      
      % function DBS_Data = P02_Save_NERCN_Format(app,DBS_Data_In, para, Starttime, Fs_LFP, Data_LFP, Processed_LFP, Channel_LFP, Fs_ACC, Data_ACC, Processed_ACC, Channel_ACC, Fs_C_EEG, Data_C_EEG, Processed_C_EEG, Channel_C_EEG, Savetime, appendMode)

      % function value = P02_validateInput(~,inputValue, defaultValue)        

      % function value = P02_convertToString(~,inputValue)

      % Data Preprocessing
      % function cl = P02_F3a_LFPdenoise_DBS(app,raw,channel,set_divfs,set_win)

      % function x = P02_ClearDBSStartEnd(~,x)  

      % function temp = P02_get_template_calculate(~,LFP1, fdf)

      % function [qrs_amp_raw,qrs_i_raw,delay] = P02_pan_tompkin(~,ecg,fs,gr) 

      % function fdf = P02_getfdf(~,LFP1,dotlist,finaldot,method,set_divfs)

      % function [num_seg,right] = P02_getnum_seg(~,num_pks,A)

      % function temp = P02_Template_timerelocate(~,tempraw, fdf , locsleft , finalnode, methodtime)

      % function coefs = P02_F2c_cwt_cmor(~,z,Fb,Fc,f,fs)

      % function [processed_LFP,Alltemplate] = F4a_LFPdenoise_ECG_EAS_win(app,LFP,fsLFP,win)

      % function [clEEG,Temp] = P02_EAS_cl_TP(app,rawEEG,ECG,fsEEG,fsECG,klength)

      % function Temp = P02_get_temp(~,rawEEG,QRS_point,klength) 

      % function clEEG =  remove_temp(~,rawEEG,QRS_point,Temp,klength)

      % Data feature calcu      
      % function plv = P02_F2b_PLV(~, phase_sig1, phase_sig2 )
      
      % function file_count = P02_collect_files(~,source_folder, target_folder, file_format)

      % function P02_confirmSelection(app,confirmDlg, source_folder, target_folder, fileFormatDropDown, customFormatEditField)

      % function P02_handleCustomFormatSelection(~,fileFormatDropDown, customFormatEditField)

      % function [Pxxmat,linemat,f] = P02_F2a_welch_calcu_power(app,Data,nfft,fs,noverlap,window)

      % function P02_change_color_platform(app, INPUT)
        function initAppPaths(app)

            if isdeployed

                [appDir, ~, ~] = fileparts(ctfroot);
                app.AppInstallDir = appDir;

                app.SettingsDir = fullfile(app.AppInstallDir, 'Settings');
            else

                app.AppInstallDir = fileparts(mfilename('fullpath'));
                app.SettingsDir = fullfile(app.AppInstallDir, 'Settings');
            end

            if ~exist(app.SettingsDir, 'dir')
                try
                    mkdir(app.SettingsDir);
                catch 

                    warning('无法在应用目录创建设置文件夹: %s');

                    app.SettingsDir = fullfile(getenv('USERPROFILE'), 'Documents', 'YourAppName');
                    if ~exist(app.SettingsDir, 'dir')
                        mkdir(app.SettingsDir);
                    end
                end
            end

            app.SettingsFile = fullfile(app.SettingsDir, 'app_settings.mat');

            if ~isdeployed
                disp(['应用目录: ' app.AppInstallDir]);
                disp(['设置目录: ' app.SettingsDir]);
            end
        end

        function saveSettings(app)
            try

                settings = struct();

                settings.version = app.AppVersion;

                settings.Is_Log_Plot_Value = app.Is_Log_Plot.Value;
                settings.IIRCheckBox_Value = app.IIRCheckBox.Value;
                settings.FIRCheckBox_Value = app.FIRCheckBox.Value;
                settings.IIRCheckBox_iir_Value = app.IIRCheckBox_iir.Value;
                settings.FIRCheckBox_fir_Value = app.FIRCheckBox_fir.Value;
                settings.InseartNAN_Value = app.InseartNAN.Value;
                settings.AUTO_RESIZE_Value = app.isautoresize;
                settings.Is_shadow_Value = app.Is_shadow.Value;

                save(app.SettingsFile, 'settings');

                if ~isdeployed
                    disp(['设置已保存到: ' app.SettingsFile]);
                end
            catch 
                warning('保存设置失败');

                if exist(app.SettingsDir, 'dir') && contains(app.SettingsDir, app.AppInstallDir)
                    try

                        backupDir = fullfile(getenv('USERPROFILE'), 'Documents', 'YourAppName');
                        if ~exist(backupDir, 'dir')
                            mkdir(backupDir);
                        end
                        backupFile = fullfile(backupDir, 'app_settings.mat');

                        save(backupFile, 'settings');

                        app.SettingsDir = backupDir;
                        app.SettingsFile = backupFile;

                        if ~isdeployed
                            disp(['已改为保存到用户目录: ' app.SettingsFile]);
                        end
                    catch 
                        warning('备用保存失败');
                    end
                end
            end
        end

        function loadSettings(app)
            try

                if exist(app.SettingsFile, 'file')

                    data = load(app.SettingsFile);
                    settings = data.settings;

                    if isfield(settings, 'version') && strcmp(settings.version, app.AppVersion)

                        if isfield(settings, 'Is_Log_Plot_Value')
                            app.Is_Log_Plot.Value = settings.Is_Log_Plot_Value;
                        end
                        
                        if isfield(settings, 'IIRCheckBox_Value')
                            app.IIRCheckBox.Value = settings.IIRCheckBox_Value;
                        end

                        if isfield(settings, 'FIRCheckBox_Value')
                            app.FIRCheckBox.Value = settings.FIRCheckBox_Value;
                        end

                        if isfield(settings, 'IIRCheckBox_iir_Value')
                            app.IIRCheckBox_iir.Value = settings.IIRCheckBox_iir_Value;
                        end

                        if isfield(settings, 'FIRCheckBox_fir_Value')
                            app.FIRCheckBox_fir.Value = settings.FIRCheckBox_fir_Value;
                        end
                        
                        if isfield(settings, 'InseartNAN_Value')
                            app.InseartNAN.Value = settings.InseartNAN_Value;
                        end

                        if isfield(settings, 'AUTO_RESIZE_Value')
                            app.isautoresize = settings.AUTO_RESIZE_Value;
                        end

                        if isfield(settings, 'Is_shadow_Value')
                            app.Is_shadow.Value = settings.Is_shadow_Value;
                        end

                        if ~isdeployed
                            disp('设置加载成功');
                        end
                    else
                        warning('设置文件版本不匹配，使用默认值');
                    end
                else

                    backupDir = fullfile(getenv('USERPROFILE'), 'Documents', 'YourAppName');
                    backupFile = fullfile(backupDir, 'app_settings.mat');
                    
                    if exist(backupFile, 'file')

                        app.SettingsDir = backupDir;
                        app.SettingsFile = backupFile;
                        app.loadSettings(); 
                    else

                        if ~isdeployed
                            disp('未找到设置文件，使用默认值');
                        end
                    end
                end
            catch
                warning('加载设置失败: %s');
            end
        end
    
        function applyDefaultSettings(app)

            if isprop(app, 'Is_Log_Plot') && isvalid(app.Is_Log_Plot)
                app.Is_Log_Plot.Value = 1; 
            end

            if isprop(app, 'IIRCheckBox') && isvalid(app.IIRCheckBox)
                app.IIRCheckBox.Value = 1; 
            end

            if isprop(app, 'FIRCheckBox') && isvalid(app.FIRCheckBox)
                app.FIRCheckBox.Value = 0; 
            end

            if isprop(app, 'IIRCheckBox_iir') && isvalid(app.IIRCheckBox_iir)
                app.IIRCheckBox_iir.Value = 1; 
            end

            if isprop(app, 'FIRCheckBox_fir') && isvalid(app.FIRCheckBox_fir)
                app.FIRCheckBox_fir.Value = 0; 
            end

            if isprop(app, 'InseartNAN') && isvalid(app.InseartNAN)
                app.InseartNAN.Value = 0; 
            end

            if isprop(app, 'Is_shadow') && isvalid(app.Is_shadow)
                app.Is_shadow.Value = 1;
            end

            if ~isdeployed
                disp('应用控件已重置为默认值。');
            end

        end

    end

    methods (Access = public)
        
        function  update_cut_data(app, Data_cutted)
            app.Data_last_step = Data_cutted;
            app.Data_now = Data_cutted;
            value = app.chi_choose.Value;
            i = value;
            plot(app.axis_preprocessing_now,app.Data_now(str2double(i),500:end));
            Data1 = app.Data_now(str2double(i),500:end);
            nfft = 5*app.data_fs;
            fs = app.data_fs;
            noverlap = 0.5*fs; 
            [~,linemat1,f1] = P02_F2a_welch_calcu_power(app,Data1,nfft,fs,noverlap,hanning(fs));
            plot(app.DATA_PSD,f1,linemat1);
        end
            
        function closeDialog(app)
            close(app.Dialog);
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            appDir = fileparts(mfilename('fullpath'));
            addpath(appDir);

            screenSize = get(groot, 'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            appWidth = app.MainFigure.Position(3);
            appHeight = app.MainFigure.Position(4);
            horizontalCenter = (screenWidth - appWidth) / 2;
            verticalCenter = (screenHeight - appHeight) / 2;
            app.MainFigure.Position(1:2) = [horizontalCenter, verticalCenter];
            app.MainFigure.Name = 'NNSR脑电平台';
            app.waitting_now = uiprogressdlg(app.MainFigure,'Title','正在载入界面',...
            'Indeterminate','on');
            drawnow
            set(app.MainFigure,'Pointer','watch'); drawnow;
            colorbacks = [0.93,0.93,0.96];
            % text(app.axis_pre,0.1,0.5,'脑电数据离线分析系统','Color',[0.98,0.98,0.98],"BackgroundColor",[0.76,0.73,0.82],"FontSize",18,"FontSmoothing","on","FontWeight","bold")
            text(app.axis_pre,0.1,0.5,'脑电数据离线分析系统','Color',[0.98,0.98,0.98],"BackgroundColor",colorbacks,"FontSize",18,"FontSmoothing","on","FontWeight","bold")
            app.axis_pre.Color = [0.93,0.93,0.96];
            app.axis_pre.Toolbar.Visible = 'off';
            text(app.axis_preprocessing_now,0.1,0.5,'脑电数据离线分析系统','Color',[0.98,0.98,0.98],"BackgroundColor",colorbacks,"FontSize",27,"FontSmoothing","on","FontWeight","bold")
            app.axis_preprocessing_now.Color = [0.93,0.93,0.96];
            text(app.DATA_PSD,0.1,0.5,'脑电数据离线分析系统','Color',[0.98,0.98,0.98],"BackgroundColor",colorbacks,"FontSize",13,"FontSmoothing","on","FontWeight","bold")
            app.DATA_PSD.Color = [0.93,0.93,0.96];
            
            text(app.PSD_figure,0.12,0.5,'脑电数据离线分析系统','Color',[0.98,0.98,0.98],"BackgroundColor",colorbacks,"FontSize",22,"FontSmoothing","on","FontWeight","bold")
            app.PSD_figure.Color = [0.93,0.93,0.96];

            text(app.Phase_feature,0.1,0.5,'脑电数据离线分析系统','Color',[0.98,0.98,0.98],"BackgroundColor",colorbacks,"FontSize",18,"FontSmoothing","on","FontWeight","bold")
            app.Phase_feature.Color = [0.93,0.93,0.96];

            text(app.TIME_FREQUENCY,0.18,0.5,'脑电数据离线分析系统','Color',[0.98,0.98,0.98],"BackgroundColor",colorbacks,"FontSize",34,"FontSmoothing","on","FontWeight","bold")
            app.TIME_FREQUENCY.Color = [0.93,0.93,0.96];
            colorbar(app.TIME_FREQUENCY,"northoutside");
            colormap(app.TIME_FREQUENCY, jet); 
            
            text(app.PLV_plot,0.1,0.5,'脑电数据离线分析系统','Color',[0.98,0.98,0.98],"BackgroundColor",colorbacks,"FontSize",18,"FontSmoothing","on","FontWeight","bold")
            
            app.PLV_plot.Color = [0.93,0.93,0.96];
            app.filter_simptom1.Color = [0.93,0.93,0.96];
            app.filter_simptom2.Color = [0.93,0.93,0.96];
            app.filter_simptom1_2.Color = [0.93,0.93,0.96];
            app.filter_simptom2_2.Color = [0.93,0.93,0.96];
            app.Temp_figure.Color = [0.93,0.93,0.96];
            
            msg = {'提示/Note：',' ','该分析系统支持手动预处理与特征提取，数据处理结果仅供研究使用。请从LFP数据导入开始。', ...
                       'The analysis system supports manual preprocessing and feature extraction, and the data processing results are for research purposes only. Please start from the import of LFP data.'};
             uialert(app.MainFigure, msg, '提示 / Notification', 'Icon', 'info');

            app.IsaddNans = 0; % Nan
            dir1 = cd;
            app.Save_Path.Value = dir1;
            set(app.MainFigure,'Pointer','arrow'); drawnow;
            app.isautoresize = 0;
            close(app.waitting_now)
            app.MainFigure.Resize = "off";
            app.Dealtiled_para = '请先进行数据解包';
            % load preset
            initAppPaths(app);
            loadSettings(app);

        end

        % Button pushed function: spicified_design
        function spicified_designPushed(app, event)

        end

        % Button pushed function: File_selection
        function File_selectionButtonPushed(app, event)
            % set(app.MainFigure,'Icon','icon_48.png');
            app.waitting_now = uiprogressdlg(app.MainFigure,'Title','正在获取文件夹',...
            'Indeterminate','on');
            drawnow
            app.Filename = [];
            close(app.waitting_now)
            [app.Filename,path] = uigetfile({'*.dat;*.mat;*.data;*.txt'},'打开LFP文件(.dat/.data/.mat/.txt)','MultiSelect','on');

            if isequal(app.Filename,0) || isequal(path,0)
             uialert(app.MainFigure,'用户取消了文件选择','提示','Icon','info');
            else
                try
                    app.UITable.Data = cell2table(app.Filename', 'VariableNames', {'Strings'});
                catch
                    % assignin('base','filename',app.Filename)
                    app.Filename = cellstr(app.Filename);
                    app.UITable.Data = app.Filename;
                end
                    app.First_Filename = app.Filename{1,1};
                    % assignin('base','filename',app.Filename)
                if length(size(app.First_Filename)) <= 1 % Filename return
                    
                    uialert(app.MainFigure,'请选择有效文件','提示','Icon','warning');
                    app.data_config.Enable = "off";
                else
                    % app.filename.Value = app.First_Filename;
                    app.data_path = path;
                    app.data_config.Enable = "on";
                    cd(path) % 
                    app.Save_Path.Value = path;
                end
            end

            try
                filena = app.First_Filename;
                if filena(1:4) == "head" % unpack 102RS data and OLD 106RS data
                   app.IS_OLD_VERSON.Value = 1;
                else
                    app.IS_OLD_VERSON.Value = 0;
                end
            end
        end

        % Button pushed function: data_config
        function data_configPushed(app, event)
           try
            app.waitting_now = uiprogressdlg(app.MainFigure,'Title','正在解包',...
            'Indeterminate','on');
            drawnow
          list=dir([app.data_path,app.First_Filename]);
          file_id = fopen(list(1).name);
          istimeset = 0;

          if app.First_Filename((end-3):end) == ".dat" 
                     try
                     data= fread(file_id); 
                     fclose(file_id);
                     catch
                         close(app.waitting_now)    
                         uialert(app.MainFigure,'文件访问失败','提示','Icon','warning');
                         close(app.waitting_now)
                     end
                     if length(data) < 1000
                         uialert(app.MainFigure,'数据长度过短或识别失败','提示','Icon','warning');
                         % msgbox('文件长度过短','提示','warn')
                         set(app.MainFigure,'Pointer','arrow'); drawnow;
                         close(app.waitting_now)
                         return
                     end

                      try
                             try
                                [app.Data_now,app.data_fs,app.stim_info,app.acq_info,app.Stim_ONOFF_info,app.Stim_Emode_info,app.Freq_Stim,app.Polar_info,app.Rec_Parameters_info,app.Lost_index] = P02_F1A_unpack_dir(app,data);
                                    if app.Lost_index < 4
                                       app.Lost_index = 0;
                                    end
                                uialert(app.MainFigure,{'102RS数据解包成功！';[app.stim_info,app.acq_info];['丢包参考数：',num2str(app.Lost_index)]},'(已加载.dat文件) 数据信息确认','Icon','success')
                                app.IS_106RS = 0;
                                if istimeset ~= 1
                                   try
                                        pattern = '(\d{4})-(\d{2})-(\d{2}) (\d{2})[:：](\d{2})[:：](\d{2})';
                                        tokens = regexp(app.First_Filename, pattern, 'tokens');
                                        year = tokens{1}{1};
                                        month = tokens{1}{2};
                                        day = tokens{1}{3};      app.defult_date = [year,'年',month,'月',day,'日'];
                                        hour = tokens{1}{4};
                                        minute = tokens{1}{5};
                                        second = tokens{1}{6};   app.defult_time = [hour,'时',minute,'分',second,'秒'];
                                        istimeset = 1;
                                   catch
                                        app.defult_date = 'NaN';
                                        app.defult_time = 'NaN';
                                   end
                                app.Dealtiled_para = {'※ 数据采集：102RS';['※ 刺激模式：',app.stim_info];['※ 采集模式：',app.acq_info];['※ 丢包参考数: ',num2str(app.Lost_index)];['* 采集时间',app.defult_date,app.defult_time]};
                                end
                             catch

                                try
                                [app.Data_now,app.data_fs,app.stim_info,app.acq_info,app.Stim_ONOFF_info,app.Stim_Emode_info,app.Freq_Stim,app.Polar_info,app.Rec_Parameters_info,app.Lost_index] = P02_F2a_unpack_dir(app,data,app.IsaddNans);
                                    % if app.Lost_index < 4
                                    %    app.Lost_index = 0;
                                    % end
                                    app.Lost_index = app.Lost_index -2 ;
                                % assignin("base",'dadadada',cell2mat(app.acq_info))
                                uialert(app.MainFigure,{'106RS数据解包成功！';[cell2mat(app.acq_info)];['丢包参考数：',num2str(app.Lost_index)]},'(已加载.dat文件) 数据信息确认','Icon','success')
                                app.IS_106RS = 1;

                                if istimeset ~= 1
                                   try
                                        pattern = 'SourceData_(\d{4})(\d{2})(\d{2})(\d{2})(\d{2})(\d{2})';
                                        tokens = regexp(app.First_Filename, pattern, 'tokens');
                                        year = tokens{1}{1};
                                        month = tokens{1}{2};
                                        day = tokens{1}{3};      app.defult_date = [year,'年',month,'月',day,'日'];
                                        hour = tokens{1}{4};
                                        minute = tokens{1}{5};
                                        second = tokens{1}{6};   app.defult_time = [hour,'时',minute,'分',second,'秒'];
                                        istimeset = 1;
                                   catch
                                        app.defult_date = 'NaN';
                                        app.defult_time = 'NaN';
                                   end
                                   app.Dealtiled_para = {'※ 数据采集：106RS (code:02)';['※ 刺激模式：',app.stim_info];['※  采集模式：',string(app.acq_info)];['※ 丢包参考数: ',num2str(app.Lost_index)];['* 采集时间',app.defult_date,app.defult_time]};    
                                end

                                catch
                                      close(app.waitting_now)
                                      % msgbox('.dat文件解包错误 -1002','提示','warn')
                                      uialert(app.MainFigure,'.dat文件解包失败 code:1002','提示','Icon','warning');
                                      set(app.MainFigure,'Pointer','arrow'); drawnow;
                                      return
                                end
                             end
                      catch
                          close(app.waitting_now)
                          set(app.MainFigure,'Pointer','arrow'); drawnow;
                          uialert(app.MainFigure,'.dat文件解包失败 code:1003','提示','Icon','warning');
                      end
                            
                            if length(app.Data_now(1,:)) < 4*(app.data_fs)
                                uialert(app.MainFigure,'数据长度过短，无法进行后续分析，请选择其他数据','提示','Icon','warning');
                                app.Data_config.Enable = "off";
                                cla(app.axis_pre)
                                close(app.waitting_now)
                                return
                            end
                             app.infoACQ.Text = cell2mat([app.Polar_info,app.Rec_Parameters_info]);
                            % assignin('base','Data',app.Data_now);
                             iii = app.View_chi_DropDown.Value;
                             app.View_chi_DropDown.Items = app.Rec_Parameters_info;
                             app.channel_choose_1.Items = app.Rec_Parameters_info;
                             app.channel_choose_2.Items = app.Rec_Parameters_info;
                             app.channel_Phase_spectrum.Items = app.Rec_Parameters_info;
                             app.chi_calcu.Items = app.Rec_Parameters_info;
                             app.chi_choose.Items = app.Rec_Parameters_info; %set up main chi info
                             plot(app.axis_pre,app.Data_now(str2double(iii),500:end));
                             app.Data_ori = app.Data_now;
                            % enable
                             app.View_chi_DropDown.Enable = "on";
                             app.Data_config.Enable = 'on';
                             app.filter_sample_rate.Value = num2str(app.data_fs);
                             app.samplefrequency_STM1.Value = app.data_fs;
                             app.Powercalcu_frequency.Value = num2str(app.data_fs);
                             app.save_data2workspace.Enable = "on";
                             app.Save_Data.Enable = "on";
                             
          elseif app.First_Filename((end-4):end) == ".data"     
                              % if app.First_Filename(1:4) == "head"
                                     try
                                     data= fread(file_id); 
                                     fclose(file_id);
                                     catch
                                         close(app.waitting_now)
                                         set(app.MainFigure,'Pointer','arrow'); drawnow;
                                         uialert(app.MainFigure,'文件访问失败','提示','Icon','warning');
                                         % msgbox('文件访问错误','提示','warn')
                                     end

                                     if length(data) < 9000
                                         uialert(app.MainFigure,'数据长度过短，可能无法进行后续分析，请选择其他数据','提示','Icon','warning');
                                         % msgbox('文件长度错误','提示','warn')
                                         app.Data_config.Enable = "off";
                                         set(app.MainFigure,'Pointer','arrow'); drawnow;
                                         close(app.waitting_now)
                                         return
                                     end

                                        try
                                           if app.IS_OLD_VERSON.Value  == 1
                                            % try
                                                 % assignin("base","data",data)
                                                [app.Data_now,app.data_fs,app.stim_info,app.acq_info,app.Stim_ONOFF_info,app.Stim_Emode_info,app.Freq_Stim,app.Polar_info,app.Rec_Parameters_info,app.Lost_index] = P02_F3a_unpack_dir(app,data,app.IsaddNans,app.First_Filename);
                                                 % assignin("base","stim_info",app.stim_info)
                                                 % assignin("base","acq_info",app.acq_info)
                                                    % if app.Lost_index < 4
                                                    %    app.Lost_index = 0;
                                                    % end
                                                    app.Lost_index = app.Lost_index -5 ;
                                                    if app.Lost_index < 0
                                                       app.Lost_index = 0;
                                                    end
                                                    uialert(app.MainFigure,{'106RS数据解包成功！';[app.stim_info,app.acq_info];['丢包参考数：',num2str(app.Lost_index)]},'(已加载.data文件) 数据信息确认','Icon','success')

                                                    % msgbox(,'提示','warn');
                                                    app.IS_106RS = 2;

                                                   try
                                                        pattern = '(\d{4})-(\d{2})-(\d{2}) (\d{2})_(\d{2})_(\d{2})';
                                                        tokens = regexp(app.First_Filename, pattern, 'tokens');
                                                        year = tokens{1}{1};
                                                        month = tokens{1}{2};
                                                        day = tokens{1}{3};      app.defult_date = [year,'年',month,'月',day,'日'];
                                                        hour = tokens{1}{4};
                                                        minute = tokens{1}{5};
                                                        second = tokens{1}{6};   app.defult_time = [hour,'时',minute,'分',second,'秒'];
                                                        istimeset = 1;
                                                   catch
                                                        app.defult_date = 'NaN';
                                                        app.defult_time = 'NaN';
                                                   end
                                                  app.Dealtiled_para = {'※ 数据采集：106RS (code:04)';['※ 刺激参数：',app.stim_info];['※ 采集参数：',app.acq_info];['※ 丢包参考数: ',num2str(app.Lost_index)];['* 采集时间',app.defult_date,app.defult_time]};
                                                        % 判断数据
                                                        if length(app.Data_now(1,:)) < 4*(app.data_fs)
                                                            uialert(app.MainFigure,'数据长度过短，无法进行后续分析，请选择其他数据','提示','Icon','warning');
                                                            app.Data_config.Enable = "off";
                                                            cla(app.axis_pre)
                                                            close(app.waitting_now)
                                                            return
                                                        end

                                            else
                                                 assignin("base","data",data)
                                                try
                                                [app.Data_now,app.data_fs,app.stim_info,app.acq_info,app.Stim_ONOFF_info,app.Stim_Emode_info,app.Freq_Stim,app.Polar_info,app.Rec_Parameters_info,otherINFO,app.Lost_index] = P02_F3a_unpack_dir_new(app,data,app.IsaddNans);
                                                % [app.Data_now,app.data_fs,app.stim_info,app.acq_info,app.Stim_ONOFF_info,app.Stim_Emode_info,app.Freq_Stim,app.Polar_info,app.Rec_Parameters_info,otherINFO,app.Lost_index,IPG_ID] = P01_F2b_106_unpack_dir_new(app,data,IsaddNans,filenames);
                                                                                    % assignin("base","stim_info",app.stim_info)
                                                % assignin("base","acq_info",app.acq_info)
                                                    % if app.Lost_index < 4
                                                    %    app.Lost_index = 0;
                                                    % end
                                                    % assignin("base","lost",app.Lost_index)
                                                    app.Lost_index = app.Lost_index -2;
                                                    if app.Lost_index < 0
                                                       app.Lost_index = 0;
                                                    end
                                                    % uialert(app.MainFigure,['106RS数据解包成功！',app.stim_info,app.acq_info,'丢包数：',num2str(app.Lost_index)],'(已加载.data文件)数据信息确认','Icon','success')
                                                    uialert(app.MainFigure,{'106RS数据解包成功！';[app.stim_info,app.acq_info];['丢包参考数：',num2str(app.Lost_index)]},'(已加载.data文件) 数据信息确认','Icon','success')

                                                    app.IS_106RS = 2;
                                                    try
                                                        pattern = '(\d+)年(\d{1,2})月(\d{1,2})日(\d{1,2})时(\d{1,2})分(\d{1,2})秒';tokens = regexp(otherINFO, pattern, 'tokens');
                                                        year = tokens{1}{1};
                                                        month = tokens{1}{2};
                                                        day = tokens{1}{3};       app.defult_date = [year,'年',month,'月',day,'日'];
                                                        hour = tokens{1}{4};
                                                        minute = tokens{1}{5};
                                                        second = tokens{1}{6};     app.defult_time = [hour,'时',minute,'分',second,'秒'];
                                                        istimeset = 1;
                                                    catch
                                                        app.defult_date = 'NaN';
                                                        app.defult_time = 'NaN';
                                                    end
                                                    % msgbox(,'提示','warn');
                                                    app.Dealtiled_para = {'※ 数据采集：106RS (NEW)';['※ 刺激参数：',app.stim_info];['※ 采集参数：',app.acq_info];['※ 丢包参考数: ',num2str(app.Lost_index)];['※ 其他信息: ',otherINFO]};
                                                catch
                                                      close(app.waitting_now)
                                                      uialert(app.MainFigure,'.data文件解包失败 （提示：如为旧版软件采集，请手动勾选“旧版解析”）','提示','Icon','warning');
                                                      return
                                                end
                                            end
                                            % end
                                            % 判断数据
                                            if length(app.Data_now(1,:)) < 4*(app.data_fs)
                                                uialert(app.MainFigure,'数据长度过短，无法进行后续分析，请选择其他数据','提示','Icon','warning');
                                                app.Data_config.Enable = "off";
                                                cla(app.axis_pre)
                                                close(app.waitting_now)
                                                return
                                            end

                                       catch
                                             close(app.waitting_now)
                                             uialert(app.MainFigure,'.data文件解析失败 请检查数据与版本 code:1004','提示','Icon','warning');
                                             return
                                             % msgbox('.dat文件解包错误 -004','提示','warn')
                                        end
                                             % app.infoSTIM.Text = [app.Stim_ONOFF_info,app.Stim_Emode_info,app.Freq_Stim];
                                             app.infoACQ.Text = cell2mat([app.Polar_info,app.Rec_Parameters_info]);
                                             % assignin('base','Data',app.Data_now);
                                             iii = app.View_chi_DropDown.Value;
                                             app.View_chi_DropDown.Items = app.Rec_Parameters_info;
                                             app.channel_choose_1.Items = app.Rec_Parameters_info;
                                             app.channel_choose_2.Items = app.Rec_Parameters_info;
                                             app.channel_Phase_spectrum.Items = app.Rec_Parameters_info;
                                
                                             app.chi_calcu.Items = app.Rec_Parameters_info;
                                             app.chi_choose.Items = app.Rec_Parameters_info; %set up main chi info
                                             plot(app.axis_pre,app.Data_now(str2double(iii),500:end));
                                             app.Data_ori = app.Data_now;
                                             % enable
                                             app.View_chi_DropDown.Enable = "on";
                                             app.Data_config.Enable = 'on';
                                             app.filter_sample_rate.Value = num2str(app.data_fs);
                                             app.samplefrequency_STM1.Value = app.data_fs;
                                             app.Powercalcu_frequency.Value = num2str(app.data_fs);
                                             app.save_data2workspace.Enable = "on";
                                             app.Save_Data.Enable = "on";

          elseif app.First_Filename((end-3):end) == ".mat"

                 try
                    U = string(app.First_Filename);
                   % assignin('base','U',U)
                    app.mat_data = load(U);
                 catch
                    close(app.waitting_now)
                    % msgbox('文件访问错误','提示','warn')
                    uialert(app.MainFigure,'文件访问失败','提示','Icon','warning')
                 end
    
                 try
                    mat_data_in = app.mat_data.data;
                    % assignin("base","mat_data_in",mat_data_in)
                    [app.Data_ori,app.Data_now,app.data_fs,app.stim_info,app.acq_info,app.Stim_ONOFF_info,app.Stim_Emode_info,app.Freq_Stim,app.Polar_info,app.Rec_Parameters_info] = P02_F1b_load_mat(app,mat_data_in);
                    % msgbox([app.stim_info,app.acq_info],'(已加载.mat文件)数据信息确认','提示','warn');
                    try
                         uialert(app.MainFigure,[app.stim_info,app.acq_info],'(已加载.mat文件)数据信息确认','Icon','success')
                    catch
                        try
                            STR2 = sprintf('%s_%d_%s_%d', ...
                                    strjoin(app.acq_info.channels, ','), ...
                                    app.acq_info.samplerate, ...
                                    app.acq_info.timestamp, ...
                                    app.acq_info.data_length); 
                            uialert(app.MainFigure,[app.stim_info,STR2],'(已加载.mat文件)数据信息确认','Icon','success')
                        end
                    end
                 catch
                    close(app.waitting_now)
                    uialert(app.MainFigure,'.mat文件内容残缺','提示','Icon','warning')
                    % msgbox('.mat文件内容残缺','提示','warn')
                 end
    
                 % app.infoSTIM.Text = [app.Stim_ONOFF_info,app.Stim_Emode_info,app.Freq_Stim];
                 app.infoACQ.Text = cell2mat([app.Polar_info,app.Rec_Parameters_info]);
                 % assignin('base','Data',app.Data_now);
                 iii = app.View_chi_DropDown.Value;
                 app.View_chi_DropDown.Items = app.Rec_Parameters_info;
                 app.channel_Phase_spectrum.Items = app.Rec_Parameters_info;
                 app.chi_calcu.Items = app.Rec_Parameters_info;
                 app.chi_choose.Items = app.Rec_Parameters_info; %set up main chi info
                 
                 axis(app.axis_pre, 'auto')
                 % app.Data_ori = app.Data_now;
                 % enable
                 app.View_chi_DropDown.Enable = "on";
                 app.Data_config.Enable = 'on';
                 app.filter_sample_rate.Value = num2str(app.data_fs);
                 app.samplefrequency_STM1.Value = app.data_fs;
                 app.Powercalcu_frequency.Value = num2str(app.data_fs);
                 app.save_data2workspace.Enable = "on";
                 app.Save_Data.Enable = "on";
                 % assignin("base",'data',app.Data_ori(str2double(iii),500:end))
                 plot(app.axis_pre,app.Data_ori(str2double(iii),500:end));
                 app.defult_date = 'NaN';
                 app.defult_time = 'NaN';

          elseif app.First_Filename((end-3):end) == ".edf"
              try
                   U = string(app.First_Filename);
                   edfF = cellstr(U);
                   U = edfF{1,1};
                   % assignin("base","U",U)
                   [hdr,sig_info,data] = P02_F1c_Loadedf(app,U);
                   % assignin("base","hdr",hdr)
                   % assignin("base","sig_info",sig_info)
                   % assignin("base","data",data)
                   Patient_ID = hdr.patient_id;
                   Equipments = hdr.local_rec_id;
                   recording_start_date = hdr.recording_startdate;
                   recording_start_time = hdr.recording_starttime;
                   channels_num =  hdr.num_signals - 1;
                   app.Rec_Parameters_info = {};
                   app.Data_ori = [];
                   app.Data_now = [];
                   for i = 1:channels_num
                   app.Rec_Parameters_info = [app.Rec_Parameters_info;sig_info(i,1).signal_labels(5:8)];
                   app.Data_ori = [app.Data_ori;data{i}];
                   app.Data_now = [app.Data_now;data{i+channels_num}];
                   end
                   str1 = sig_info(1,1).reserve_2;
                   app.data_fs = str1((strfind(str1,':')+1):strfind(str1,'Hz')-1);

                   app.Stim_ONOFF_info = ' ';
                   app.Stim_Emode_info = ' ';
                   app.Freq_Stim = ' ';
                   app.Polar_info = '记录';
                   % app.Rec_Parameters_info
                   app.Polar_info = '';
                   app.infoACQ.Text = cell2mat([app.Polar_info,app.Rec_Parameters_info]);
                   app.infoACQ.Text = cell2mat([app.Polar_info,app.Rec_Parameters_info]);
                   % assignin('base','Data',app.Data_now);
                   iii = app.View_chi_DropDown.Value;
                   app.View_chi_DropDown.Items = app.Rec_Parameters_info;
                   app.channel_Phase_spectrum.Items = app.Rec_Parameters_info;
                   app.chi_calcu.Items = app.Rec_Parameters_info;
                   app.chi_choose.Items = app.Rec_Parameters_info; %set up main chi info
                   plot(app.axis_pre,app.Data_now(str2double(iii),500:end));
                   app.View_chi_DropDown.Enable = "on";
                   app.Data_config.Enable = 'on';
                   app.filter_sample_rate.Value = num2str(app.data_fs);
                   app.samplefrequency_STM1.Value = app.data_fs;
                   app.Powercalcu_frequency.Value = num2str(app.data_fs);
                   app.save_data2workspace.Enable = "on";
                   app.Save_Data.Enable = "on";

              catch
                   close(app.waitting_now)
                   uialert(app.MainFigure,'.edf文件格式不支持','提示','Icon','warning')
                   % msgbox('.edf文件格式不支持','提示','warn')
              end  

          elseif app.First_Filename((end-3):end) == ".txt"
                   samplingRate = 1;
                 try
                    filesssname = string(app.First_Filename);

                    fileID = fopen(filesssname, 'r');
                    if fileID == -1
                        uialert(app.MainFigure,'文件访问失败','提示','Icon','warning')
                    end

                    firstLine1 = fgetl(fileID); % READ 第一行
                    firstLine2 = fgetl(fileID); % READ 第二行
                    trysss = [firstLine1,firstLine2];
                    pattern_fs = '采样率：(\d+)';
                    match_fs = regexp(trysss, pattern_fs, 'tokens');

                    pattern_channel = '_(\w+\+\w+-)';
                    matche_channel = regexp(trysss, pattern_channel, 'tokens');


                    if ~isempty(match_fs)
                        samplingRate = str2double(match_fs{1}{1});
                    else
                        try
                            str = app.First_Filename;
                            pattern = '(\d+)Hz';
                            match_fs = regexp(str, pattern, 'tokens');
                            % frequency 
                            samplingRate = str2double(match_fs{1}{1}); % 将找到的字符串转换为数字
                        catch
                            answer = inputdlg({'请手动输入采样率：'}, '输入', [1 30],{'500'});
                            samplingRate =  str2double(answer); % 如果没有匹配到，返回NaN
                        end
                    end

                    app.data_fs = samplingRate;

                    if isempty(matche_channel)
                       lineCounter = 0;
                        % 指定要读取的行号
                        targetLineNum = 3; % 以读取第3行为例
                        % 循环遍历文件直到到达目标行
                        while ~feof(fileID)
                            lineCounter = lineCounter + 1;
                            line = fgetl(fileID);
                            if lineCounter == targetLineNum
                                break;
                            end
                        end
                        numbers = str2num(line);
                        n = length(numbers); 
                        cells = cell(1, length(numbers)); % 创建一个1xn的cell数组
                        % 使用循环为每个单元赋值
                        for i = 1:n
                           cells{i} = num2str(i); % 将索引转换为字符串并赋值给相应的cell单元
                        end
                       app.Rec_Parameters_info = cells;
                    else
                       app.Rec_Parameters_info = [matche_channel{:}];
                    end

                       app.Data_ori = readmatrix(filesssname, 'NumHeaderLines', 6,'Delimiter', ',')';
                       assignin("base",'dataorri',app.Data_ori);

                       try
                           uniqueValues = unique(app.Data_ori(2,:));
                           if numel(uniqueValues)<3
                              app.Data_ori(1:2,:) = [];
                           end
                       end
                       
                       % app.Data_ori = app.Data_ori(~isnan(app.Data_ori));
                       fclose(fileID);
                       app.Data_now = app.Data_ori;
                       app.Dealtiled_para = {'※ 数据来源：TXT文档';['※ 采样率: ',num2str(samplingRate)];['※ 采集通道: ',cell2mat(app.Rec_Parameters_info)]};
                       
                       % assignin("base",'Data_ori',app.Data_ori);
                      % 判断数据
                            if length(app.Data_now(1,:)) < 4*(app.data_fs)
                                uialert(app.MainFigure,'数据长度过短，无法进行后续分析，请选择其他数据','提示','Icon','warning');
                                app.infoACQ.Text = cell2mat([app.Polar_info,app.Rec_Parameters_info]);
                                app.Data_config.Enable = "off";
                                cla(app.axis_pre)
                                close(app.waitting_now)
                                return
                            end
                       uialert(app.MainFigure,[app.Dealtiled_para],'(已加载.txt文件) 数据信息确认','Icon','success')

                       app.Stim_ONOFF_info = ' ';
                       app.Stim_Emode_info = ' ';
                       app.Freq_Stim = ' ';
                       % app.Rec_Parameters_info
                       app.Polar_info = ' ';
                     iii = app.View_chi_DropDown.Value;
                     app.infoACQ.Text = cell2mat([app.Polar_info,app.Rec_Parameters_info]);
                     % iii = '3'
                     app.View_chi_DropDown.Items = app.Rec_Parameters_info;
                     app.channel_choose_1.Items = app.Rec_Parameters_info;
                     app.channel_choose_2.Items = app.Rec_Parameters_info;
                     app.channel_Phase_spectrum.Items = app.Rec_Parameters_info;
                     app.chi_calcu.Items = app.Rec_Parameters_info;
                     app.chi_choose.Items = app.Rec_Parameters_info; %set up main chi info
                     % assignin("base",'datanow',app.Data_now);
                     plot(app.axis_pre,app.Data_now(str2double(iii),500:end));
                     % 
                     app.Data_ori = app.Data_now;
                     % enable
                     app.View_chi_DropDown.Enable = "on";
                     app.Data_config.Enable = 'on';
                     app.filter_sample_rate.Value = num2str(app.data_fs);
                     app.samplefrequency_STM1.Value = app.data_fs;
                     app.Powercalcu_frequency.Value = num2str(app.data_fs);
                     app.save_data2workspace.Enable = "on";
                     app.Save_Data.Enable = "on";
                     
                 catch
                    try
                    fclose(fileID);
                    end
                    % close(app.waitting_now)
                    % msgbox('文件访问错误','提示','warn')
                    uialert(app.MainFigure,'文件访问失败','提示','Icon','warning')
                    close(app.waitting_now)
                 end

          end
           set(app.MainFigure,'Pointer','arrow'); drawnow;
           close(app.waitting_now)
           catch
               % msgbox('执行错误','提示','warn')
               uialert(app.MainFigure,'执行失败','提示','Icon','warning')
               close(app.waitting_now)
               set(app.MainFigure,'Pointer','arrow'); drawnow;
           end
           % assignin("base",'ORI',app.Data_ori)
        end

        % Button pushed function: Save_location
        function Save_locationPushed(app, event)
            try
            fild = uigetdir;
            cd (fild)
            path = cd;
            app.Save_Path.Value = path;
            app.current_Path = path;
            catch
                uialert(app.MainFigure,'请选择路径','提示','Icon','warning')
                % msgbox('请选择路径','提示','warn')
            end
        end

        % Value changed function: View_chi_DropDown
        function View_chi_DropDownValueChanged(app, event)
            
            value = app.View_chi_DropDown.Value;
            i = value;
            plot(app.axis_pre,app.Data_now(str2double(i),500:end));
            axis(app.axis_pre, 'auto')
        end

        % Button pushed function: STMartifactremove_confirm
        function STMartifactremove_confirmPushed(app, event)
            app.Data_last_step = app.Data_now;
            value = app.chi_choose.Value;
            i = value;
            cla(app.axis_preprocessing_now)
            plot(app.axis_preprocessing_now,app.Data_last_step(str2double(i),2000:end));
            Data1 = app.Data_last_step(str2double(i),2000:end);
            cla(app.DATA_PSD)
            nfft = 5*app.data_fs;
            fs = app.data_fs;
            noverlap = 0.5*fs;
            window = hanning(fs);
            [~,linemat1,f1] = P02_F2a_welch_calcu_power(app,Data1,nfft,fs,noverlap,window);
            cla(app.DATA_PSD)
            plot(app.DATA_PSD,f1,linemat1);
            legend(app.DATA_PSD,{'now'})
            
        end

        % Button pushed function: Data_re_finish
        function Data_re_finishPushed(app, event)

            confirm_i = uiconfirm(app.MainFigure,'确认伪迹已去除','确认对话框','Icon','warning');
            if confirm_i == "OK"

            app.Data_last_step = app.Data_now;
            app.IS_detrend_finish = 1;
            %设置第二页的默认值
            app.FFT_Number.Value = num2str(2*app.data_fs);
            app.overlap.Value = num2str(round(app.data_fs*0.75));
            app.T_M_overlap.Value = num2str(round(app.data_fs*0.75));
            app.T_M_NFFT.Value = num2str(app.data_fs);
            app.samplarate.Value = num2str(app.data_fs);
            app.T_M_WindowsLength.Value = num2str(app.data_fs);
            app.T_M_chi.Enable = "on"; 
            app.processing_pannel.SelectedTab = app.FeatureProcess_Tool;
            FeatureProcess_ToolButtonDown(app, event)
            elseif confirm_i == "Cancel"

            end

        end

        % Button pushed function: Data_config
        function Data_configPushed(app, event)
            
            app.waitting_now = uiprogressdlg(app.MainFigure,'Title','正在进行数据确认与功率谱密度估计',...
            'Indeterminate','on');
            drawnow
            value = app.chi_choose.Value;
            i = value;
            app.Data_now = app.Data_ori;
            
            app.Data_now(isnan(app.Data_now)) = 0;
            plot(app.axis_preprocessing_now,app.Data_now(str2double(i),500:end));
            app.Slider_Plot.Limits = [1,length(app.Data_now(str2double(i),500:end))];
            app.Slider_Plot.Value = app.Slider_Plot.Limits;
            app.axis_preprocessing_now.XLim = app.Slider_Plot.Value;

            app.Range_freq.Limits = [1,(app.data_fs)/2];
            app.Range_freq.Value = [6,app.Range_freq.Limits(2)];

            cla(app.DATA_PSD)
            
            app.axis_preprocessing_now.XLimMode = "auto";
            app.axis_preprocessing_now.YLimMode = "auto";
            app.DATA_PSD.XLimMode = "auto";
            app.DATA_PSD.YLimMode = "auto";

            Data1 = app.Data_now(str2double(app.chi_choose.Value),500:end);
            nfft = 5*app.data_fs;
            fs = app.data_fs;
            if length(Data1)>= 5*60*fs
               app.noverlap = round(0.75*fs);
            else 
               app.noverlap = round(0.25*fs);
            end
            win = hanning(fs);
            app.Window_length.Value = num2str(fs);
            [~,linemat1,f1] = P02_F2a_welch_calcu_power(app,Data1(200:end-200),nfft,fs,app.noverlap,win);
            plot(app.DATA_PSD,f1,linemat1,'LineWidth',1.2);
            
            app.DATA_PSD.XLim = app.Range_freq.Value;
            % Turn pannel1 on
            app.chi_choose.Enable = "on";
%           app.IIRCheckBox.Enable = "on";
            app.FIRCheckBox.Enable = 'on';
            app.Num_of_order.Enable = 'on';
            app.NaNDropDown.Enable = "on";
            app.continuous.Enable = "on";
            app.apply_detrend1.Enable = "on";
            % Turn pannel2 on
%           app.IIRCheckBox_iir.Enable = "on";
%           app.filter_methed_FIR.Enable = "on";
            app.frequency1.Enable = "on";
            app.frequency2.Enable = "on";
            app.data_cut_tool.Enable = "on";
            app.Menu_data_CUT.Enable = "on";
            app.out_put_select.Enable = "on";
            app.Save_Data.Enable = "on";
            app.save_data2workspace.Enable = "on";
%           app.Plot_PSD.Enable = "on";
            app.STMartifact_remove.Enable = "on";
            app.IIRCheckBox.Enable = "on";
            app.filter_methed_1.Enable = "on";
            app.PLV_length.Enable = "on";
            app.apply_EAS.Enable = "on";
%           app.save_PSD_Fig.Enable = "on";
            app.Data_last_step = app.Data_now;
            
            app.filterorder.Enable = "on";
            app.filter_sample_rate.Enable = "on";
            app.fs_1.Enable = "on";
            app.fs_2.Enable = "on";

            app.IIRCheckBox_iir.Enable = "on";
            app.FIRCheckBox_fir.Enable = "on";
            app.filter_design_function.Enable = "on";
            app.design_high_pass.Enable = "on";
            app.filter_methed_IIR.Enable = "on";
            app.currentdata_txt.Value = app.First_Filename;
            
            close(app.waitting_now)
 
        end

        % Value changed function: chi_choose
        function chi_chooseValueChanged(app, event)
            
            set(app.MainFigure,'Pointer','watch'); drawnow;

            value = app.chi_choose.Value;
            i = value;
            plot(app.axis_preprocessing_now,app.Data_now(str2double(i),500:end));
            
            Data1 = app.Data_now(str2double(i),500:end);
            nfft = 5*app.data_fs;
            fs = app.data_fs;
            % noverlap = 0.5*fs;
            window = hanning(fs);
            [~,linemat1,f1] = P02_F2a_welch_calcu_power(app,Data1,nfft,fs,app.noverlap,window);
            plot(app.DATA_PSD,f1,linemat1);
            axis(app.axis_preprocessing_now, 'auto')
            axis(app.DATA_PSD, 'auto')
            app.detrend_confirm.Enable = "on";
            set(app.MainFigure,'Pointer','arrow'); drawnow;
        end

        % Button pushed function: apply_detrend1
        function apply_detrend1Pushed(app, event)
            
            if app.all_chi.Value == 1
                data = app.Data_last_step;
            else
                Chi = app.chi_choose.Value;
                data = app.Data_last_step(Chi,:);
            end

            detrened = detrend(data',str2double(app.Num_of_order.Value),app.NaNDropDown.Value,'Continuous',str2double(app.continuous.Value));
            app.Data_now = detrened';
            app.detrened_data = detrened';
            cla(app.axis_preprocessing_now)
            a = plot(app.axis_preprocessing_now,app.Data_last_step(str2double(app.chi_choose.Value),500:end));
            a.Color(4) = 0.2;% visibility
            hold(app.axis_preprocessing_now,'on')
            plot(app.axis_preprocessing_now,app.detrened_data(str2double(app.chi_choose.Value),500:end));
            legend(app.axis_preprocessing_now,{'before','after'})
            hold(app.axis_preprocessing_now,'off')
            
            Data2 = app.detrened_data(str2double(app.chi_choose.Value),500:end);
            Data1 = app.Data_ori(str2double(app.chi_choose.Value),500:end);
            cla(app.DATA_PSD)
            nfft = 5*app.data_fs;
            fs = app.data_fs;
            % noverlap = 0.5*fs;
            window = hanning(fs);
            [~,linemat1,f1] = P02_F2a_welch_calcu_power(app,Data1,nfft,fs,app.noverlap,window);
            [~,linemat2,f2] = P02_F2a_welch_calcu_power(app,Data2,nfft,fs,app.noverlap,window);
            b = plot(app.DATA_PSD,f1,linemat1);
            b.Color(4) = 0.2;
            hold(app.DATA_PSD,"on")
            plot(app.DATA_PSD,f2,linemat2);
            legend(app.DATA_PSD,{'before','after'})
            % set(gca,xlim,[0 fs/4])
            hold(app.DATA_PSD,"off")
            app.detrend_confirm.Enable = "on";

            app.Data_now = detrened';
        end

        % Button pushed function: design_high_pass
        function design_high_passPushed(app, event)
        app.waitting_now = uiprogressdlg(app.MainFigure,'Title','创建滤波器中',...
            'Indeterminate','on');
            drawnow
        set(app.MainFigure,'Pointer','watch'); drawnow;
        try
        if app.IIRCheckBox.Value == 1 && app.FIRCheckBox.Value == 0
            name11 = "butter";
            name12 = "cheby1";
          if app.filter_methed_1.Value == name11
          app.filter_design_highpass = designfilt('highpassiir','DesignMethod',app.filter_methed_1.Value, ...
                'FilterOrder',str2double(app.filterorder.Value), ...
                'HalfPowerFrequency',str2double(app.fs_1.Value), ...
                'SampleRate',str2double(app.filter_sample_rate.Value));
          elseif app.filter_methed_1.Value == name12
          app.filter_design_highpass = designfilt('highpassiir','DesignMethod',app.filter_methed_1.Value, ...
                'FilterOrder',str2double(app.filterorder.Value), ...
                'PassbandFrequency',str2double(app.fs_1.Value), ...
                'PassbandRipple',str2double(app.fs_2.Value), ...
                'SampleRate',str2double(app.filter_sample_rate.Value));
          end

        elseif app.IIRCheckBox.Value == 0 && app.FIRCheckBox.Value == 1
            name21 = "equiripple";
            name22 = "ls";
          if app.filter_methed_2.Value == name21
          
              if str2double(app.filterorder.Value)<=2
                 uialert(app.MainFigure,'此种设计方法滤波器阶次至少为3阶','错误','Icon','warning');
              else
          app.filter_design_highpass = designfilt('highpassfir','DesignMethod',app.filter_methed_2.Value, ...
                'FilterOrder',str2double(app.filterorder.Value), ...
                'StopbandFrequency',str2double(app.fs_2.Value), ...
                'PassbandFrequency',str2double(app.fs_1.Value), ...
                'SampleRate',str2double(app.filter_sample_rate.Value));
              end 
          elseif app.filter_methed_2.Value == name22
          app.filter_design_highpass = designfilt('highpassfir','DesignMethod',app.filter_methed_2.Value, ...
                'FilterOrder',str2double(app.filterorder.Value), ...
                'StopbandFrequency',str2double(app.fs_2.Value), ...
                'PassbandFrequency',str2double(app.fs_1.Value), ...
                'SampleRate',str2double(app.filter_sample_rate.Value));
          end
        end

        [H,w] = freqz(app.filter_design_highpass,512,str2double(app.filter_sample_rate.Value));
        app.filter_simptom1.Visible = "on";
        app.filter_simptom2.Visible = "on";
        plot(app.filter_simptom1,w,10*log10(abs(H)));
        cla(app.filter_simptom2)
        plot(app.filter_simptom2,w,angle(H));
        legend(app.filter_simptom1,{'幅频特征'})
        legend(app.filter_simptom2,{'相频特征'})
        app.highpass_apply.Enable = "on";

        catch
            % msgbox('请检查设置参数')
            uialert(app.MainFigure,'请检查设置参数','提示','Icon','warning');
        end
        set(app.MainFigure,'Pointer','arrow'); drawnow;
        close(app.waitting_now)
        % freqz(app.filter_design_highpass,512,str2double(app.filter_sample_rate.Value))
        end

        % Value changed function: detrend_function_chiose
        function detrend_function_chioseValueChanged(app, event)
            value = app.detrend_function_chiose.Value;
               app.correction_detrend_Panel.Visible = "on";
               app.correction_filterdesign_Panel.Visible = "on";
            if str2double(value) == 1
               app.correction_detrend_Panel.Visible = "off";
               
            elseif str2double(value) == 2
               app.correction_filterdesign_Panel.Visible = "off";
            end
        end

        % Value changed function: IIRCheckBox
        function IIRCheckBoxValueChanged(app, event)
            value = app.IIRCheckBox.Value;
            if value == 1
                app.filterorder.Enable = "on";
                app.filter_sample_rate.Enable = "on";
                app.fs_1.Enable = "on";
                app.design_high_pass.Enable = "on";

                
                app.FIRCheckBox.Value = 0;
                app.filter_methed_1.Enable = "on";
                app.filter_methed_2.Enable = "off";

                app.fs_2.Visible = "off";
                app.Label_fs_2.Visible = "off";
                app.filterorder.Value = '2';
            elseif value == 0
                app.FIRCheckBox.Value = 1;
                app.filter_methed_1.Enable = "off";
                app.filter_methed_2.Enable = "on";
                app.Label_fs_1.Text = "截止频率";
                app.Label_fs_2.Text = "通带频率";
                app.filter_methed_2.Value = 'equiripple';
                app.fs_2.Visible = "on";
                app.Label_fs_2.Visible = "on";
                app.fs_2.Value = '0.5';
                app.filterorder.Value = '3';
            end
        end

        % Value changed function: FIRCheckBox
        function FIRCheckBoxValueChanged(app, event)
            value = app.FIRCheckBox.Value;
            if value == 1
                app.filterorder.Enable = "on";
                app.filter_sample_rate.Enable = "on";
                app.fs_1.Enable = "on";
                app.IIRCheckBox.Value = 0;
                app.filter_methed_1.Enable = "off";
                app.filter_methed_2.Enable = "on";
                app.Label_fs_1.Text = "截止频率";
                app.Label_fs_2.Text = "通带频率";
                app.filter_methed_2.Value = 'equiripple';
                app.fs_2.Visible = "on";
                app.Label_fs_2.Visible = "on";
                app.fs_2.Value = '0.5';
                app.filterorder.Value = '100';
                
            elseif value == 0
                app.IIRCheckBox.Value = 1;
                app.filter_methed_1.Enable = "on";
                app.filter_methed_2.Enable = "off";
                app.fs_2.Visible = "off";
                app.Label_fs_2.Visible = "off";
                app.filterorder.Value = '2';
            end
        end

        % Button pushed function: highpass_apply
        function highpass_applyButtonPushed(app, event)
            app.waitting_now = uiprogressdlg(app.MainFigure,'Title','正在计算',...
            'Indeterminate','on');
            drawnow
            
            set(app.MainFigure,'Pointer','watch'); drawnow;
            Data_in = app.Data_last_step';
            Data_out = filtfilt(app.filter_design_highpass,Data_in);
            app.Data_now = Data_out';
            value = app.chi_choose.Value;
            i = value;
            cla(app.axis_preprocessing_now)
            plot(app.axis_preprocessing_now,app.Data_last_step(str2double(i),2000:end));
            hold(app.axis_preprocessing_now,"on")
            plot(app.axis_preprocessing_now,app.Data_now(str2double(i),2000:end));
            legend(app.axis_preprocessing_now,{'before','after'})
            hold(app.axis_preprocessing_now,"off")
            
            Data1 = app.Data_last_step(str2double(i),2000:end);
            Data2 = app.Data_now(str2double(i),2000:end);
            cla(app.DATA_PSD)
            nfft = 5*app.data_fs;
            fs = app.data_fs;
            % noverlap = 0.5*fs;
            window = hanning(fs);
            [~,linemat1,f1] = P02_F2a_welch_calcu_power(app,Data1,nfft,fs,app.noverlap,window);
            [~,linemat2,f2] = P02_F2a_welch_calcu_power(app,Data2,nfft,fs,app.noverlap,window);
            b = plot(app.DATA_PSD,f1,linemat1);
            b.Color(4) = 0.2;
            hold(app.DATA_PSD,"on")
            plot(app.DATA_PSD,f2,linemat2);
            legend(app.DATA_PSD,{'before','after'})
            % set(gca,xlim,[0 fs/4])
            hold(app.DATA_PSD,"off")
            app.comfirm_detrend2.Enable = "on";
            set(app.MainFigure,'Pointer','arrow'); drawnow;
            close(app.waitting_now)
        end

        % Button pushed function: comfirm_detrend2
        function comfirm_detrend2ButtonPushed2(app, event)
            app.Data_last_step = app.Data_now;
            value = app.chi_choose.Value;
            i = value;
            cla(app.axis_preprocessing_now)
            plot(app.axis_preprocessing_now,app.Data_last_step(str2double(i),2000:end));
            Data1 = app.Data_last_step(str2double(i),2000:end);
            cla(app.DATA_PSD)
            nfft = 5*app.data_fs;
            fs = app.data_fs;
            % noverlap = 0.5*fs;
            window = hanning(fs);
            [~,linemat1,f1] = P02_F2a_welch_calcu_power(app,Data1,nfft,fs,app.noverlap,window);
            cla(app.DATA_PSD)
            plot(app.DATA_PSD,f1,linemat1);
            legend(app.DATA_PSD,{'now'})
%             app.apply_lowpass_filter.Enable = "on";
            app.filter_design_function.Enable ="on";
            app.IIRCheckBox_iir.Enable = "on";
            app.FIRCheckBox_fir.Enable = "on";
            app.samplefrequency_STM1.Enable = "on";
            app.filter_order_STM.Enable = "on";
            app.frequency1.Enable = "on";
        end

        % Button pushed function: apply_lowpass_filter
        function apply_lowpass_filterPushed(app, event)
            
            set(app.MainFigure,'Pointer','watch'); drawnow;
            app.waitting_now = uiprogressdlg(app.MainFigure,'Title','创建滤波器中',...
            'Indeterminate','on','Cancelable','on');
            drawnow
            Data_in = app.Data_last_step';
            if app.isfilter_design == 1
            Data_out = filtfilt(app.filter_design_artifact,Data_in);
            else
            Data_out = eval([app.Filename2(1:end-2),'(Data_in);']);
            end
            app.Data_now = Data_out';
            value = app.chi_choose.Value;
            i = value;
            cla(app.axis_preprocessing_now)
            plot(app.axis_preprocessing_now,app.Data_last_step(str2double(i),2000:end));
            hold(app.axis_preprocessing_now,"on")
            plot(app.axis_preprocessing_now,app.Data_now(str2double(i),2000:end));
            legend(app.axis_preprocessing_now,{'before','after'})
            hold(app.axis_preprocessing_now,"off")
            
            Data1 = app.Data_last_step(str2double(i),2000:end);
            Data2 = app.Data_now(str2double(i),2000:end);
            cla(app.DATA_PSD)
            nfft = 5*app.data_fs;
            fs = app.data_fs;
            % noverlap = 0.5*fs;
            window = hanning(fs);
            [~,linemat1,f1] = P02_F2a_welch_calcu_power(app,Data1,nfft,fs,app.noverlap,window);
            [~,linemat2,f2] = P02_F2a_welch_calcu_power(app,Data2,nfft,fs,app.noverlap,window);
            b_map=plot(app.DATA_PSD,f1,linemat1);
            b_map.Color(4) = 0.2;
            hold(app.DATA_PSD,"on")
            plot(app.DATA_PSD,f2,linemat2);
            legend(app.DATA_PSD,{'before','after'})
            % set(gca,xlim,[0 fs/4])
            hold(app.DATA_PSD,"off")
            app.STMartifactremove_confirm.Enable = "on";
%           set(app.MainFigure,'Pointer','watch'); drawnow;
            set(app.MainFigure,'Pointer','arrow'); drawnow;
            close(app.waitting_now)
        end

        % Button pushed function: filter_design_function
        function filter_design_functionButtonPushed(app, event)
            app.waitting_now = uiprogressdlg(app.MainFigure,'Title','创建滤波器中',...
            'Indeterminate','on');
            drawnow
        if app.IIRCheckBox_iir.Value == 1 
            if str2double(app.filter_method_dropdown.Value) == 1 
               DESIGN_METH = 'lowpassiir';%%%%%%%%%%%%%%%%%%%%%

               Design_meth1 = 'butter';
               Design_meth2 = 'cheby1';
               Design_meth3 = 'cheby2';
               if str2double(app.filter_methed_IIR.Value) == 1
               app.filter_design_artifact = designfilt(DESIGN_METH,'DesignMethod',Design_meth1, ...
                  'FilterOrder',app.filter_order_STM.Value, ...
                  'HalfPowerFrequency',app.frequency1.Value, ...
                  'SampleRate',app.samplefrequency_STM1.Value);
               elseif str2double(app.filter_methed_IIR.Value) == 2
               app.filter_design_artifact = designfilt(DESIGN_METH,'DesignMethod',Design_meth2, ...
                  'FilterOrder',app.filter_order_STM.Value, ...
                  'PassbandFrequency',app.frequency1.Value, ...
                  'PassbandRipple',app.frequency2.Value, ...
                  'SampleRate',app.samplefrequency_STM1.Value);
               elseif str2double(app.filter_methed_IIR.Value) == 3
               app.filter_design_artifact = designfilt(DESIGN_METH,'DesignMethod',Design_meth3, ...
                  'FilterOrder',app.filter_order_STM.Value, ...
                  'StopbandFrequency',app.frequency1.Value, ...
                  'StopbandAttenuation',app.frequency2.Value, ...
                  'SampleRate',app.samplefrequency_STM1.Value);
               end

            elseif str2double(app.filter_method_dropdown.Value) == 2
               DESIGN_METH = 'bandpassiir';%%%%%%%%%%%%%%%%%%%%%

               Design_meth1 = 'butter';
               Design_meth2 = 'cheby1';
               Design_meth3 = 'cheby2';
               
               if str2double(app.filter_methed_IIR.Value) == 1
               app.filter_design_artifact = designfilt(DESIGN_METH,'DesignMethod',Design_meth1, ...
                  'FilterOrder',app.filter_order_STM.Value, ...
                  'HalfPowerFrequency1',app.frequency1.Value, ...
                  'HalfPowerFrequency2',app.frequency2.Value, ...
                  'SampleRate',app.samplefrequency_STM1.Value);
               elseif str2double(app.filter_methed_IIR.Value) == 2
               app.filter_design_artifact = designfilt(DESIGN_METH,'DesignMethod',Design_meth2, ...
                  'FilterOrder',app.filter_order_STM.Value, ...
                  'StopbandFrequency',app.frequency1.Value, ...
                  'StopbandAttenuation',app.frequency2.Value, ...
                  'SampleRate',app.samplefrequency_STM1.Value);
               elseif app.filter_methed_IIR.Value == 3
               app.filter_design_artifact = designfilt(DESIGN_METH,'DesignMethod',Design_meth3, ...
                  'FilterOrder',app.filter_order_STM.Value, ...
                  'PassbandFrequency',app.frequency1.Value, ...
                  'PassbandRipple',app.frequency2.Value, ...
                  'SampleRate',app.samplefrequency_STM1.Value);
               end

            elseif str2double(app.filter_method_dropdown.Value) == 3
               DESIGN_METH = 'bandstopiir';
               app.filter_design_artifact = designfilt(DESIGN_METH,'DesignMethod',app.filter_methed_1.Value, ...
                  'FilterOrder',str2double(app.filterorder.Value), ...
                  'HalfPowerFrequency',str2double(app.fs_1.Value), ...
                  'SampleRate',str2double(app.filter_sample_rate.Value));

            elseif str2double(app.filter_method_dropdown.Value) == 4
               DESIGN_METH = 'highpassiir';
                

               app.filter_design_artifact = designfilt(DESIGN_METH,'DesignMethod',app.filter_methed_1.Value, ...
                  'FilterOrder',str2double(app.filterorder.Value), ...
                  'HalfPowerFrequency',str2double(app.fs_1.Value), ...
                  'SampleRate',str2double(app.filter_sample_rate.Value));
            end
       elseif app.IIRCheckBox_iir.Value == 0 && app.FIRCheckBox_fir.Value == 1
            if str2double(app.filter_method_dropdown.Value) == 1 
               DESIGN_METH = 'lowpassfir';
%              DESIGN_METH = 'lowpassiir';%%%%%%%%%%%%%%%%%%%%%

               Design_meth1 = 'equiripple';
               Design_meth2 = 'ls';
               Design_meth3 = 'maxflat';
               if str2double(app.filter_methed_FIR.Value) == 1

               app.filter_design_artifact = designfilt(DESIGN_METH,'DesignMethod',Design_meth1, ...
                  'FilterOrder',app.filter_order_STM.Value, ...
                  'PassbandFrequency',app.frequency1.Value, ...
                  'StopbandFrequency',app.frequency2.Value, ...
                  'SampleRate',app.samplefrequency_STM1.Value);
               elseif str2double(app.filter_methed_FIR.Value) == 2
               app.filter_design_artifact = designfilt(DESIGN_METH,'DesignMethod',Design_meth2, ...
                  'FilterOrder',app.filter_order_STM.Value, ...
                  'PassbandFrequency',app.frequency1.Value, ...
                  'StopbandFrequency',app.frequency2.Value, ...
                  'SampleRate',app.samplefrequency_STM1.Value);
               elseif str2double(app.filter_methed_FIR.Value) == 3
               app.filter_design_artifact = designfilt(DESIGN_METH,'DesignMethod',Design_meth3, ...
                  'FilterOrder',app.filter_order_STM.Value, ...
                  'HalfPowerFrequency',app.frequency1.Value, ...
                  'SampleRate',app.samplefrequency_STM1.Value);
               end
            
            elseif str2double(app.filter_method_dropdown.Value) == 2
               DESIGN_METH = 'bandpassfir';
               app.filter_design_artifact = designfilt(DESIGN_METH,'DesignMethod',app.filter_methed_1.Value, ...
                  'FilterOrder',str2double(app.filterorder.Value), ...
                  'HalfPowerFrequency',str2double(app.fs_1.Value), ...
                  'SampleRate',str2double(app.filter_sample_rate.Value));

            elseif str2double(app.filter_method_dropdown.Value) == 3
               DESIGN_METH = 'bandstopfir';
               app.filter_design_artifact = designfilt(DESIGN_METH,'DesignMethod',app.filter_methed_1.Value, ...
                  'FilterOrder',str2double(app.filterorder.Value), ...
                  'HalfPowerFrequency',str2double(app.fs_1.Value), ...
                  'SampleRate',str2double(app.filter_sample_rate.Value));

            elseif str2double(app.filter_method_dropdown.Value) == 4
               DESIGN_METH = 'highpassfir';
               app.filter_design_artifact = designfilt(DESIGN_METH,'DesignMethod',app.filter_methed_1.Value, ...
                  'FilterOrder',str2double(app.filterorder.Value), ...
                  'HalfPowerFrequency',str2double(app.fs_1.Value), ...
                  'SampleRate',str2double(app.filter_sample_rate.Value));
            end
            
         end
         [H,w] = freqz(app.filter_design_artifact,512,app.samplefrequency_STM1.Value);
          app.filter_simptom1_2.Visible = "on";
            app.filter_simptom2_2.Visible = "on";
            
         cla(app.filter_simptom1_2)
%        assignin('base','base',w)
         app.apply_lowpass_filter.Enable = 1;
         plot(app.filter_simptom1_2,w,10*log10(abs(H)));
         cla(app.filter_simptom2_2)
         plot(app.filter_simptom2_2,w,angle(H));
         legend(app.filter_simptom1_2,{'幅频特征'})
         legend(app.filter_simptom2_2,{'相频特征'})
         app.isfilter_design = 1;
         close(app.waitting_now)
        end

        % Button pushed function: save_data2workspace
        function save_data2workspacePushed(app, event)
            % msgbox('已发送（该功能在matlab授权运行时可用）')
            uialert(app.MainFigure,'已发送（该功能在matlab授权运行时可用）','提示','Icon','info');
            name_mid = app.First_Filename;
            name = ['Data_',name_mid(isstrprop(name_mid,'digit'))];
            assignin('base',name,app.Data_now);
%           savedate_2 = ;
            
%             value = app.out_put_select.Value;
%             data_saved_time = datestr(clock);
%             saved_time = datestr(now,30);
%             if length(app.acq_info) >= 2
%                chi_info = ['DATA_ori','Data_Processed','samplerate','stim_info','acq_info','ori_dataname','save_time','processdate']; % data saved order
%                data = {app.Data_ori,app.Data_now,app.data_fs,app.stim_info,app.acq_info,app.Filename,data_saved_time,chi_info}; % save data mat
%             elseif length(app.acq_info) < 2
%                chi_info = ['DATA_ori','Data_Processed','samplerate','save_time'];
%                data = {app.Data_ori,app.Data_now,app.data_fs,data_saved_time,chi_info};
%             end
%             if str2double(value) == 1
%                uisave({'data',''},saved_time)
%             end
        end

        % Value changed function: filter_methed_1
        function filter_methed_1ValueChanged(app, event)
            value = app.filter_methed_1.Value;
            middle_data_IIR1 = "butter";
            middle_data_IIR2 = "cheby1";
            middle_data_IIR3 = "cheby2";
            if value == middle_data_IIR1
               % butter on
               app.filterorder.Visible = "on";
               app.filter_sample_rate.Visible = "on";
               app.fs_1.Visible = "on";
               app.Label_filterorder.Visible = "on"; 
               app.Label_samplerate.Visible = "on";
               app.Label_fs_1.Visible = "on";
               % cheby1 off
               app.Label_fs_2.Visible = "off";
               app.fs_2.Visible = "off";
               app.Label_fs_1.Text = "截止频率";
            elseif value == middle_data_IIR2
               % cheby1 on
               app.Label_fs_2.Visible = "on";
               app.fs_2.Visible = "on";
               app.Label_fs_1.Text = "通带频率";
                % butter off
               app.filterorder.Visible = "on";
               app.filter_sample_rate.Visible = "on";
               app.fs_1.Visible = "on";
               app.Label_filterorder.Visible = "on";
               app.Label_samplerate.Visible = "on";
               app.Label_fs_1.Visible = "on";
            elseif value == middle_data_IIR3
                
            end
        end

        % Menu selected function: Menu_7
        function Menu_7Selected(app, event)
            selection = uiconfirm(app.MainFigure, '您确定退出吗？（注意！未储存的数据将被删除）', '退出离线分析工具？','Options',{'是', '否'},'DefaultOption', '否', 'CancelOption', '是');
        if strcmp(selection,'是')
           delete(app);
           APP_to_start_0_1
        end

        end

        % Value changed function: artifact_denoise
        function artifact_denoiseValueChanged(app, event)
            value = app.artifact_denoise.Value;
            if str2double(value) == 1
               app.STMartifactremove_filter.Visible = "on";
               app.STMartifactremove_stract.Visible = "off";
            elseif str2double(value) == 2
               app.STMartifactremove_filter.Visible = "off";
               app.STMartifactremove_stract.Visible = "on";
            end
        end

        % Value changed function: filter_methed_IIR
        function filter_methed_IIRValueChanged(app, event)
            value1 = str2double(app.filter_methed_IIR.Value);
            value2 = str2double(app.filter_method_dropdown.Value);
            if value2 == 1
                if value1 == 1
                  app.frequency1.Visible = "on";
                  app.frequency2.Visible = "off";
                  app.frequency1_text.Text = "截止频率";
                  app.frequency2_text.Visible = "off";
                  app.frequency1.Value = 60;
                  app.filter_order_STM.Value = 2;
                elseif value1 == 2
                  app.frequency1.Visible = "on";
                  app.frequency2.Visible = "on";
                  app.frequency2_text.Visible = "on";
                  app.frequency1_text.Text = "通带频率"; %'PassbandFrequency'
                  app.frequency2_text.Text = "通带波动";
                elseif value1 == 3
                  app.frequency1.Visible = "on";
                  app.frequency2.Visible = "on";
                  app.frequency2_text.Visible = "on";
                  app.frequency1_text.Text = "阻带频率"; %'PassbandFrequency'
                  app.frequency2_text.Text = "阻带衰减";
                end
            elseif value2 == 2
                if value1 == 1
                  app.frequency1.Visible = "on";
                  app.frequency2.Visible = "off";
                  app.frequency1_text.Text = "截止频率";
                  app.frequency2_text.Visible = "off";
                elseif value1 == 2
                  app.frequency1.Visible = "on";
                  app.frequency2.Visible = "on";
                  app.frequency2_text.Visible = "on";
                  app.frequency1_text.Text = "通带频率"; %'PassbandFrequency'
                  app.frequency2_text.Text = "通带波动";
                elseif value1 == 3
                  app.frequency1.Visible = "on";
                  app.frequency2.Visible = "on";
                  app.frequency2_text.Visible = "on";
                  app.frequency1_text.Text = "阻带频率"; %'PassbandFrequency'
                  app.frequency2_text.Text = "阻带衰减";
                end
            end
        end

        % Value changed function: IIRCheckBox_iir
        function IIRCheckBox_iirValueChanged(app, event)

            value = app.IIRCheckBox_iir.Value;
            if value == 1
                app.FIRCheckBox_fir.Value = 0;
                app.IIRCheckBox_iir.Value = 1;
                app.filter_methed_IIR.Enable = "on";
                app.filter_methed_FIR.Enable = "off";
                app.filter_methed_IIR.Value = "1";
                app.frequency1_text.Visible = "on";
                app.frequency1_text.Enable = "on";
                app.frequency1_text.Text = "截止频率";
                app.frequency2.Visible = "off";
                app.frequency2_text.Visible = "off";
                app.filter_order_STM.Value = 6;
            elseif value == 0
                app.FIRCheckBox_fir.Value = 1;
                app.IIRCheckBox_iir.Value = 0;
                app.filter_methed_IIR.Enable = "off";
                app.filter_methed_FIR.Enable = "on";
            end
        end

        % Value changed function: FIRCheckBox_fir
        function FIRCheckBox_firValueChanged(app, event)
            
            value = app.FIRCheckBox_fir.Value;
            if value == 0
                app.FIRCheckBox_fir.Value = 0;
                app.IIRCheckBox_iir.Value = 1;
                app.filter_methed_FIR.Enable = "off";
                app.filter_methed_IIR.Enable = "on";
            elseif value == 1
                app.FIRCheckBox_fir.Value = 1;
                app.IIRCheckBox_iir.Value = 0;
                app.filter_methed_IIR.Enable = "off";
                app.filter_methed_FIR.Enable = "on";
                app.filter_methed_FIR.Value = '1';
                app.frequency1_text.Text = "通带频率";
                app.frequency2_text.Text = "阻带频率";
                app.frequency2_text.Visible = "on";
                app.frequency2_text.Enable = "on";
                app.frequency2.Visible = "on";
                app.frequency2.Enable = "on";
                app.frequency1_text.Visible = "on";
                app.frequency1_text.Enable = "on";
                app.filter_order_STM.Value = 100;
                app.frequency1.Value = 60;
                app.frequency2.Value = 80;
                
            end
        end

        % Value changed function: filter_methed_2
        function filter_methed_2ValueChanged(app, event)
            value = app.filter_methed_2.Value;
            if value == "equiripple"
               app.Label_fs_2.Text = "通带频率";
               app.Label_fs_2.Visible = "on";
               app.fs_2.Visible = "on";
               app.fs_2.Value = '0.5';
               app.Label_fs_1.Text = "截止频率";
               app.fs_1.Value = '1';
               app.Label_fs_1.Visible = "on";
               app.fs_1.Visible = "on";

            elseif value == "ls"
               app.Label_fs_2.Text = "通带频率";
               app.Label_fs_2.Visible = "on";
               app.fs_2.Visible = "on";
               app.fs_2.Value = '0.5';
               app.Label_fs_1.Text = "截止频率";
               app.fs_1.Value = '1';
               app.Label_fs_1.Visible = "on";
               app.fs_1.Visible = "on";
            elseif value == "cls"

            elseif value == "kaiserwin"

            elseif value == "maxflat"

            end
        end

        % Button pushed function: data_cut_tool
        function data_cut_toolButtonPushed(app, event)
            
            app.data_cut_tool.Enable = 'off';
            app.Menu_data_CUT.Enable = "off";
            %  app.waitting_now = uiprogressdlg(app.MainFigure,'Title','请在新界面中操作',...
            % 'Indeterminate','on');
            % drawnow
            app.Dialog = uiprogressdlg(app.MainFigure,'Title','请在裁剪工具箱中继续操作',...
            'Indeterminate','on');

            DATA = app.Data_now;
            fs = app.data_fs;
            chi_input = app.Rec_Parameters_info; 
            app.app_anylisis_Data_CUT = APP_anylisis_Data_CUT(app,DATA,fs,chi_input,1);
            app.data_cut_tool.Enable = 'on';
            app.Menu_data_CUT.Enable = "on";
            % close(app.waitting_now)
            
        end

        % Button pushed function: Plot_PSD
        function Plot_PSDButtonPushed(app, event)
        try 
            % Display progress dialog for PSD calculation
            app.waitting_now = uiprogressdlg(app.MainFigure,'Title','计算功率谱密度',...
                'Indeterminate','on');
            drawnow
            
            % Initialize PSD figure
            cla(app.PSD_figure,'reset');
            app.DATA_PSD.XLimMode = "auto";
            app.DATA_PSD.YLimMode = "auto";
            app.PSD_figure.Color = [0.93,0.93,0.96];
            app.PSD_figure.NextPlot = 'add';
            
            % Set Y-axis label based on plot type (logarithmic or linear)
            value = app.Is_Log_Plot.Value;
            if value == 1
               app.PSD_figure.YLabel.String = "Power(dB/Hz)";
            elseif value == 0
                app.PSD_figure.YLabel.String = "Power(uv^{2}/Hz)";
            end
            
            app.PSD_figure.FontSize = 9;
            
            % Method 1: Welch's method for PSD calculation
            if str2double(app.PSD_method.Value) == 1
                % Get parameters for Welch's method
                nfft = str2double(app.FFT_Number.Value);           % Number of FFT points
                fs = str2double(app.Powercalcu_frequency.Value);   % Sampling frequency
                noverlap = str2double(app.overlap.Value);          % Number of overlap points
                chinum = str2double(app.chi_calcu.Value);          % Channel numbers
                window = hanning(str2double(app.Window_length.Value)); % Window function
                PSD_Power = [];
                
                % Store colors for each channel
                colors = lines(length(chinum));
                
                % Calculate PSD for each channel
                for num_cy = 1:length(chinum)
                    % Calculate PSD using Welch's method with 95% confidence intervals
                    [PXX22, f, Pxx_CI] = pwelch(app.Data_now(chinum(num_cy),400:end-400), window, noverlap, nfft, fs, 'ConfidenceLevel', 0.95);
                    PSD_Power = [PSD_Power, PXX22];
                    
                    % Plot based on logarithmic or linear scale
                    if app.Is_Log_Plot.Value == 1
                        % Logarithmic plot
                        logPSD = 10*log10(abs(PXX22));
                        % Note: Confidence intervals code commented out for performance
                        % logCI_upper = 10*log10(abs(Pxx_CI(:,2)));
                        % logCI_lower = 10*log10(abs(Pxx_CI(:,1)));
                        % fill(app.PSD_figure, [f; flipud(f)], [logCI_upper; flipud(logCI_lower)], colors(num_cy,:), 'FaceAlpha', 0.2, 'EdgeColor', 'none');
                        plot(app.PSD_figure, f, logPSD, 'LineWidth', 1.2, 'Color', colors(num_cy,:));
                        
                    elseif app.Is_Log_Plot.Value == 0
                        % Linear plot
                        % linearCI_upper = Pxx_CI(:,2);
                        % linearCI_lower = Pxx_CI(:,1);
                        logPSD = (abs(PXX22));
                        % fill(app.PSD_figure, [f; flipud(f)], [linearCI_upper; flipud(linearCI_lower)], colors(num_cy,:), 'FaceAlpha', 0.2, 'EdgeColor', 'none');
                        plot(app.PSD_figure, f, logPSD, 'LineWidth', 1.2, 'Color', colors(num_cy,:));
                    end
                    
                    app.PSD_figure.NextPlot = 'add';
                end
                
                % Set legend and enable controls
                legend_chi = app.Rec_Parameters_info(chinum);
                app.Channel_Use_PSD = legend_chi;
                legend(app.PSD_figure, legend_chi);
                
                % Set frequency range if specified
                try
                    app.PSD_figure.XLim = [app.plot_freq_down.Value, app.plot_freq_up.Value];
                end
                
                app.PSD_figure.NextPlot = 'replacechildren';
                app.save_PSD_Fig.Enable = "on";
                app.save_PSD_2Work_space.Enable = "on";
                app.RESULT_DATA_PSD_POWER = PSD_Power;
                app.RESULT_DATA_PSD_FREQ = f;
                
                % Update sliders
                try
                    app.Slider_PSD.Value = [app.plot_freq_down.Value, app.plot_freq_up.Value];
                    app.Slider_PSD.Limits = [0, app.data_fs/2];
                    app.PSD_YSlider.Value = app.PSD_figure.XLim;
                end
                
                % Adaptive axis adjustment for DATA_PSD
                try
                    % Find indices corresponding to 15Hz and 60Hz for focus range
                    [~, idx_8Hz] = min(abs(f - 15));
                    [~, idx_80Hz] = min(abs(f - 60));
                    
                    % Ensure indices are within valid range
                    idx_8Hz = max(1, idx_8Hz);
                    idx_80Hz = min(length(f), idx_80Hz);
                    
                    % Set Y-axis range adaptively based on PSD data in 15-60Hz range
                    if app.Is_Log_Plot.Value == 1
                        % Logarithmic scale Y-axis range
                        log_values = 10*log10(abs(PSD_Power));
                        range_values = log_values(idx_8Hz:idx_80Hz, :);
                        y_min = min(range_values(:)) - 5; % Add bottom margin
                        y_max = max(range_values(:)) + 5; % Add top margin
                        app.DATA_PSD.YLim = [y_min, y_max];
                    else
                        % Linear scale Y-axis range
                        y_min = 0; % Power spectrum typically starts from 0
                        range_values = abs(PSD_Power(idx_8Hz:idx_80Hz, :));
                        y_max = max(range_values(:)) * 1.1; % Add 10% top margin
                        app.DATA_PSD.YLim = [y_min, y_max];
                    end
                    
                    % Set X-axis range while preserving user custom settings
                    if isempty(app.plot_freq_down.Value) || isempty(app.plot_freq_up.Value)
                        % Auto-set to full frequency range if not specified
                        app.DATA_PSD.XLim = [min(f), max(f)];
                    else
                        % Use user-specified range
                        app.DATA_PSD.XLim = [app.plot_freq_down.Value, app.plot_freq_up.Value];
                    end
                    
                    % Apply DATA_PSD settings to PSD_figure
                    if isequal(app.DATA_PSD.XLimMode, "auto") && isequal(app.DATA_PSD.YLimMode, "auto")
                        % No additional setting needed if both are auto mode
                    else
                        % Apply DATA_PSD settings to PSD_figure
                        if ~isequal(app.DATA_PSD.XLimMode, "auto")
                            app.PSD_figure.XLim = app.DATA_PSD.XLim;
                        end
                        if ~isequal(app.DATA_PSD.YLimMode, "auto")
                            app.PSD_figure.YLim = app.DATA_PSD.YLim;
                        end
                    end
                catch
                    % If adaptive adjustment fails, keep original settings
                    disp('Adaptive axis adjustment failed, using default settings');
                end
                
                app.Beta_Peakfind.Enable = "on";
                
            % Method 2: Periodogram method for PSD calculation    
            elseif str2double(app.PSD_method.Value) == 2
                % Get parameters for periodogram method
                nfft = str2double(app.FFT_Number.Value);
                noverlap = str2double(app.Powercalcu_frequency.Value);
                chinum = str2double(app.chi_calcu.Value);
                PSD_Power = [];
                fs = str2double(app.Powercalcu_frequency.Value); % Sampling frequency
                N = length(app.Data_now(chinum(1),:));
                Windowsss = N;
                
                % Calculate periodogram for each channel
                for num_cy = 1:length(chinum)
                    [fft_result, f] = periodogram(app.Data_now(chinum(num_cy),:), hanning(Windowsss), nfft, fs, "onesided", 'psd');
                    PSD_Power = [PSD_Power, fft_result];
                end
                
                % Plot PSD for each channel
                for num_cy2 = 1:length(chinum)
                    if app.Is_Log_Plot.Value == 1
                        plot(app.PSD_figure, f, 10*log10(abs(PSD_Power(:, (num_cy2)))), 'LineWidth', 1.2);
                    elseif app.Is_Log_Plot.Value == 0
                        plot(app.PSD_figure, f, abs(PSD_Power(:, (num_cy2))), 'LineWidth', 1.2);
                    end
                    app.PSD_figure.NextPlot = 'add';
                end
                
                % Set frequency range if specified
                try
                    app.PSD_figure.XLim = [app.plot_freq_down.Value, app.plot_freq_up.Value];
                end
                
                app.PSD_figure.NextPlot = 'replacechildren';
                app.save_PSD_Fig.Enable = "on";
                app.save_PSD_2Work_space.Enable = "on";
                app.RESULT_DATA_PSD_POWER = PSD_Power;
                app.RESULT_DATA_PSD_FREQ = f;
                
                % Update sliders
                try
                    app.Slider_PSD.Value = [app.plot_freq_down.Value, app.plot_freq_up.Value];
                    app.Slider_PSD.Limits = [0, app.data_fs/2];
                    app.PSD_YSlider.Value = app.PSD_figure.XLim;
                end
                
                % Adaptive axis adjustment for DATA_PSD (periodogram method)
                try
                    % Find indices corresponding to 15Hz and 60Hz for focus range
                    [~, idx_8Hz] = min(abs(f - 15));
                    [~, idx_80Hz] = min(abs(f - 60));
                    
                    % Ensure indices are within valid range
                    idx_8Hz = max(1, idx_8Hz);
                    idx_80Hz = min(length(f), idx_80Hz);
                    
                    % Set Y-axis range adaptively based on PSD data in 15-60Hz range
                    if app.Is_Log_Plot.Value == 1
                        % Logarithmic scale Y-axis range
                        log_values = 10*log10(abs(PSD_Power));
                        range_values = log_values(idx_8Hz:idx_80Hz, :);
                        y_min = min(range_values(:)) - 5; % Add bottom margin
                        y_max = max(range_values(:)) + 5; % Add top margin
                        app.DATA_PSD.YLim = [y_min, y_max];
                    else
                        % Linear scale Y-axis range
                        y_min = 0; % Power spectrum typically starts from 0
                        range_values = abs(PSD_Power(idx_8Hz:idx_80Hz, :));
                        y_max = max(range_values(:)) * 1.1; % Add 10% top margin
                        app.DATA_PSD.YLim = [y_min, y_max];
                    end
                    
                    % Set X-axis range while preserving user custom settings
                    if isempty(app.plot_freq_down.Value) || isempty(app.plot_freq_up.Value)
                        % Auto-set to full frequency range if not specified
                        app.DATA_PSD.XLim = [min(f), max(f)];
                    else
                        % Use user-specified range
                        app.DATA_PSD.XLim = [app.plot_freq_down.Value, app.plot_freq_up.Value];
                    end
                    
                    % Apply DATA_PSD settings to PSD_figure
                    if isequal(app.DATA_PSD.XLimMode, "auto") && isequal(app.DATA_PSD.YLimMode, "auto")
                        % No additional setting needed if both are auto mode
                    else
                        % Apply DATA_PSD settings to PSD_figure
                        if ~isequal(app.DATA_PSD.XLimMode, "auto")
                            app.PSD_figure.XLim = app.DATA_PSD.XLim;
                        end
                        if ~isequal(app.DATA_PSD.YLimMode, "auto")
                            app.PSD_figure.YLim = app.DATA_PSD.YLim;
                        end
                    end
                catch
                    % If adaptive adjustment fails, keep original settings
                    disp('Adaptive axis adjustment failed, using default settings');
                end
                
                app.Beta_Peakfind.Enable = "on";
            end
            
            % Add frequency band shadows (preserve original logic)
            if app.Is_shadow.Value == 1
                % Color palette for different frequency bands
                colorList_band = [245 117 71; 214 064 078; 158 3 66; 78 98 171; 70 158 180; 135 207 164] ./ 256;
                YLM = get(app.PSD_figure, 'ylim');
                app.PSD_figure.NextPlot = 'add';
                
                % Define y-coordinates for shadow areas
                y = [min(abs(PSD_Power(:)))-100 min(abs(PSD_Power(:)))-100 max(abs(PSD_Power(:)))+100 max(abs(PSD_Power(:)))+100];
                
                % Delta band (1-4 Hz)
                x = [1 4 4 1];
                H1 = fill(app.PSD_figure, x, y, colorList_band(1,:), 'EdgeColor', 'none', 'FaceAlpha', 0.28, 'HandleVisibility', 'off');
                uistack(H1, 'bottom');
                app.PSD_figure.NextPlot = 'add';
                
                % Theta band (5-8 Hz)
                x = [5 8 8 5];
                H2 = fill(app.PSD_figure, x, y, colorList_band(2,:), 'EdgeColor', 'none', 'FaceAlpha', 0.28, 'HandleVisibility', 'off');
                uistack(H2, 'bottom');
                app.PSD_figure.NextPlot = 'add';
                
                % Alpha band (9-13 Hz)
                x = [9 13 13 9];
                H3 = fill(app.PSD_figure, x, y, colorList_band(3,:), 'EdgeColor', 'none', 'FaceAlpha', 0.28, 'HandleVisibility', 'off');
                uistack(H3, 'bottom');
                app.PSD_figure.NextPlot = 'add';
                
                % Beta1 band (14-20 Hz)
                x = [14 20 20 14];
                H4 = fill(app.PSD_figure, x, y, colorList_band(4,:), 'EdgeColor', 'none', 'FaceAlpha', 0.28, 'HandleVisibility', 'off');
                uistack(H4, 'bottom');
                app.PSD_figure.NextPlot = 'add';
                
                % Beta2 band (21-35 Hz)
                x = [21 35 35 21];
                H5 = fill(app.PSD_figure, x, y, colorList_band(5,:), 'EdgeColor', 'none', 'FaceAlpha', 0.28, 'HandleVisibility', 'off');
                uistack(H5, 'bottom');
                app.PSD_figure.NextPlot = 'add';
                
                % Gamma band (36+ Hz)
                x = [36 1000 1000 36];
                H6 = fill(app.PSD_figure, x, y, colorList_band(6,:), 'EdgeColor', 'none', 'FaceAlpha', 0.28, 'HandleVisibility', 'off');
                uistack(H6, 'bottom');
                app.PSD_figure.YLim = YLM;
                
                % Ensure DATA_PSD settings are maintained after adding shadows
                try
                    if ~isequal(app.DATA_PSD.XLimMode, "auto")
                        app.PSD_figure.XLim = app.DATA_PSD.XLim;
                    end
                    if ~isequal(app.DATA_PSD.YLimMode, "auto")
                        app.PSD_figure.YLim = app.DATA_PSD.YLim;
                    end
                catch
                    % Silent error handling
                end
            end
            
            % Enable save functions and close progress dialog
            app.save_PSD_2File.Enable = 'on';
            close(app.waitting_now)
        
        catch
            % Error handling: show alert and disable functions
            uialert(app.MainFigure,'请修改参数后重试','错误','Icon','warning');
            app.Beta_Peakfind.Enable = "off";
            app.save_PSD_2File.Enable = 'off';
            app.save_PSD_Fig.Enable = "off";
            app.save_PSD_2Work_space.Enable = "off";
            close(app.waitting_now);
        end
        end

        % Button pushed function: Save_Data
        function Save_DataButtonPushed(app, event)
            % 数据格式保存选择
            try
            app.waitting_now = uiprogressdlg(app.MainFigure,'Title','正在保存，请勿关闭',...
           'Indeterminate','on');
            drawnow
            % assignin("base","data_finename",app.First_Filename)
            value = app.out_put_select.Value;
            data_saved_time = datestr(now);
            saved_time = datestr(now,30);

            if length(app.acq_info) >= 2
               chi_info = {'DATA_ori','Data_Processed','samplerate','stim_info','acq_info','Stim_onoff_info','stim_electrode_info','stim_frequency_info','recoding_parameter_info','Record_Channel','ori_dataname','processdate','save_type'}; % data saved order
               data = {app.Data_ori,app.Data_now,app.data_fs,app.stim_info,app.acq_info,app.Stim_ONOFF_info,app.Stim_Emode_info,app.Freq_Stim,app.Polar_info,app.Rec_Parameters_info,app.First_Filename,data_saved_time,'full'}; % save data mat
            elseif length(app.acq_info) < 2
               chi_info = {'DATA_ori','Data_Processed','samplerate','save_time','save_type'};
               data = {app.Data_ori,app.Data_now,app.data_fs,data_saved_time,'only_data'};
            end
            
            if str2double(value) == 1
                if isempty(chi_info) == 1 && isempty(data) == 1
                   close(app.waitting_now)
                   % msgbox('请计算后再存储')
                   uialert(app.MainFigure,'请计算后再存储','错误','Icon','warning');
                else
                   uisave({'data','chi_info'},saved_time)
                   close(app.waitting_now)
                   % uialert(app.MainFigure,['数据存储成功，位置：',app.Save_Path.Value],'保存提示','Icon','success');
                end
            elseif str2double(value) == 2
                if isempty(chi_info) == 1 && isempty(data) == 1
                   % msgbox('请计算后再存储')
                   uialert(app.MainFigure,'请计算后再存储','错误','Icon','warning');
                else
                   filenameedf = [saved_time,'.edf'];
                   hdr = edfheader("EDF+");
                   % sigdata = data;
                        prompt = {'输入患者姓名:','采集日期：','采集时刻：','采集时长：','存储文件名：'};
                        dlgtitle = '确认信息';
                        dims = [1 35];
                        Record_Time = ' ';
                        if app.IS_106RS == 0
                        dt = datetime(app.First_Filename(3:10), 'InputFormat', 'yy-MM-dd');
                        app.defult_date = datestr(dt, 'dd.mm.yy');
                        dt = datetime(app.First_Filename(12:19), 'InputFormat', 'HH：mm：ss');
                        app.defult_time  = datestr(dt, 'HH.MM.SS');
                        % channelNum_Data_Records = numel(app.Rec_Parameters_info)+numel(app.Rec_Parameters_info);
                        Record_Time = length(app.Data_ori)/app.data_fs;
                        record_setup = "G102RS";
                        name_str = 'defult';
                        % ------ 
                        elseif app.IS_106RS == 1
                        app.defult_date = ' ';
                        app.defult_time = ' ';
                        % channelNum_Data_Records = numel(app.Rec_Parameters_info)+numel(app.Rec_Parameters_info);                 
                        Record_Time = length(app.Data_ori)/app.data_fs;
                        record_setup = "G106RS";
                        name_str = 'defult';
                        elseif app.IS_106RS == 2
                        % find = strfind(app.First_Filename,'_');
                        name_str = app.Patient_ID;
                        % name_str = app.First_Filename((find(1)+1):(find(2)-1));
                        find2 = strfind(app.First_Filename,'Hz')+2;

                        date_str = app.First_Filename((find2+1):(find2+10));
                        time_str = app.First_Filename((find2+12):(find2+19));
                        
                        dt = datetime(date_str, 'InputFormat', 'yy-MM-dd');
                        app.defult_date = datestr(dt, 'dd.mm.yy');

                        dt = datetime(time_str, 'InputFormat', 'HH_mm_ss');
                        app.defult_time  = datestr(dt, 'HH.MM.SS');           
                        Record_Time = length(app.Data_ori)/app.data_fs;
                        record_setup = "G106RS";
                        end
                        definput = {name_str, app.defult_date, app.defult_time,num2str(Record_Time),filenameedf};
                        answer = inputdlg(prompt, dlgtitle, dims, definput);
                        if ~isempty(answer)
                            value_Patient = answer{1};
                            value_Recorddate = answer{2};
                            value_Recordtime = answer{3};
                            value_Data_Length = answer{4};
                            filenameedf = answer{5};
                            value_Recording = record_setup;
                            hdr.Patient = string(value_Patient);
                            hdr.StartDate = string(value_Recorddate);
                            hdr.StartTime = string(value_Recordtime);
                            hdr.Recording = string(value_Recording);
                            seconds = str2double(value_Data_Length);
                            hdr.DataRecordDuration = duration(0, 0, seconds);
                            hdr.NumSignals = length(app.Data_ori(:,1))+length(app.Data_now(:,1));
                            sigdata = [app.Data_ori;app.Data_now];
                            num_ori1 = [];
                            num_ori2 = [];
                        for i= 1:(hdr.NumSignals)
                            num_ori1 = [num_ori1,min(sigdata(i,:))];
                            num_ori2 = [num_ori2,max(sigdata(i,:))];
                        end
                            hdr.DigitalMin = int32(num_ori1-1);
                            hdr.DigitalMax = int32(num_ori2+1);                        
                            hdr.PhysicalMin = int32(num_ori1-1);
                            hdr.PhysicalMax = int32(num_ori2+1);
   
                            ori = {};
                            processed = {};
                            Sample_Rate = {};
                        for pp = 1:(hdr.NumSignals/2)
                        ori = [ori,['Ori ',app.Rec_Parameters_info{pp}]];
                        processed = [processed,['Proces ',app.Rec_Parameters_info{pp}]];
                        Sample_Rate = [Sample_Rate,['Sample_rate:',num2str(app.data_fs),'Hz']];
                        end
                        hdr.SignalReserved = [Sample_Rate,Sample_Rate];
                        hdr.SignalLabels = [ori,processed];
                        hdr.NumDataRecords = 1;
                        [filess, pathss] = uiputfile(filenameedf, '保存EDF文件为...');
                        if filess ~= 0
                            fid = fopen(fullfile(pathss, filess), 'w');
                            edfwrite([pathss,filenameedf],hdr,sigdata');
                            fclose(fid);
                        end
                        close(app.waitting_now)
                        else
                            uialert(app.MainFigure,'用户取消保存','保存提示','Icon','info');
                            % msgbox('用户取消保存','保存提示','warn')
                            close(app.waitting_now)
                        end
                end
            elseif str2double(value) == 3
                if isempty(chi_info) == 1 && isempty(data) == 1
                   uialert(app.MainFigure,'请计算后再存储','提示','Icon','warning')
                else
                   defult_title = regexprep(app.First_Filename, '[^a-zA-Z0-9]', '');
                   % assignin('base',"defult_title",defult_title)
                   [filess, pathss] = uiputfile([defult_title,'_ori.txt'], '存储为TXT格式'); % 让用户选择保存位置和文件名
                       if isequal(filess,0) || isequal(pathss,0)
                           uialert(app.MainFigure,'用户取消了选择','提示','Icon','info')
                        else
                           filename_nows = fullfile(pathss, filess); % 完整的文件路径
                           fileID = fopen(filename_nows, 'w'); % 创建文件
                           % fprintf(fileID, '%s', app.currentdata_txt.Value); % 写入文本区域的内容到文件
                           headerLine1 = ['采样率：',num2str(app.data_fs),' 丢包参考数：',num2str(app.Lost_index),'个 时间：',app.defult_date,app.defult_time];
                           modifiedCells = cellfun(@(x) ['CHI_' x], app.Rec_Parameters_info, 'UniformOutput', false);
                           headerLine2 = strjoin(modifiedCells, ' ');
                           headerMAT3 = app.Data_ori;
                           fprintf(fileID, '%s\n', headerLine1);
                           fprintf(fileID, '%s\n', headerLine2);
                           % assignin('base',"Data_ori",app.Data_ori)
                           % assignin('base',"modifiedCells",modifiedCells)
                           T = array2table(headerMAT3', 'VariableNames', modifiedCells);
                           writetable(T, filename_nows, 'Delimiter', ',', 'WriteMode', 'append');
                           %  [nRows,~] = size(headerMAT3);
                           %  formatStr = [repmat('%f\t', 1, nRows) '\n'];
                           %  for i = 1:nRows
                           %      fprintf(fileID, formatStr, headerMAT3(i, :));
                           %  end
                           % fclose(fileID); 
                       end
                   % uisave({'data','chi_info'},saved_time)
                   close(app.waitting_now)
                   uialert(app.MainFigure,['数据存储成功，位置：',pathss],'保存提示','Icon','success');
                end

           elseif str2double(value) == 5
                if isempty(chi_info) == 1 && isempty(data) == 1
                   % msgbox('请计算后再存储')
                   uialert(app.MainFigure,'请计算后再存储','提示','Icon','warning')
                else
                   
                   uisave({'data','chi_info'},saved_time)
                   close(app.waitting_now)
                end
            elseif str2double(value) == 6
               % chi_info = {'DATA_ori','Data_Processed','samplerate','stim_info','acq_info','Stim_onoff_info','stim_electrode_info','stim_frequency_info','recoding_parameter_info','Record_Channel','ori_dataname','processdate','save_type'}; % data saved order
               % data = {app.Data_ori,app.Data_now,app.data_fs,app.stim_info,app.acq_info,app.Stim_ONOFF_info,app.Stim_Emode_info,app.Freq_Stim,app.Polar_info,app.Rec_Parameters_info,app.First_Filename,data_saved_time,'full'}; % save data ma
               % chi_info = {'DATA_ori','Data_Processed','samplerate','save_time','save_type'};
               % data = {app.Data_ori,app.Data_now,app.data_fs,data_saved_time,'only_data'};
               
               DBS_Data_In = 0;
               para.acq_info1 = app.stim_info;
               para.acq_info2 = app.acq_info;
               para.Stim_onoff_info = app.Stim_ONOFF_info;
               para.ori_dataname = app.First_Filename;
               para.save_type = 'Realtime'; % 1\Realtime;2\PostBack;3\Merging
               para.IPG_NUM = 'No Infos';
               Starttime = app.First_Filename;
               Data_LFP = app.Data_ori;
               Processed_LFP = app.Data_now;
               Channel_LFP = app.Rec_Parameters_info;
               Fs_ACC = ['No data'];
               Data_ACC = ['No data'];
               Processed_ACC = ['No data'];
               Channel_ACC = ['No data'];
               Fs_C_EEG = ['No data'];
               Data_C_EEG = ['No data'];
               Processed_C_EEG = ['No data'];
               Channel_C_EEG = ['No data'];
               appdendMode = 0;
               [DBS_Data] = P02_Save_NERCN_Format(app,DBS_Data_In,para, Starttime, app.data_fs, Data_LFP, Processed_LFP, Channel_LFP, ...
                                                Fs_ACC, Data_ACC, Processed_ACC, Channel_ACC, ...
                                                Fs_C_EEG, Data_C_EEG, Processed_C_EEG, Channel_C_EEG,  saved_time,appdendMode);

               currentTime = datestr(now, 'yyyy-mm-dd_HH-MM-SS'); % 当前时间格式：'2024-12-25_15-30-00'
               fileName = sprintf('DBS_Data_%s_%s.mat', DBS_Data.IPG_NUM, currentTime);
               uisave('DBS_Data', fileName); % 'DBS_Data' 是变量名，'DBS_Data.mat' 是默认文件名
               close(app.waitting_now)
            end
            catch
               close(app.waitting_now)
               % msgbox('存储错误','保存提示','warn')
               uialert(app.MainFigure,'存储失败','保存提示','Icon','warning');
            end
              
        end

        % Button down function: FeatureProcess_Tool
        function FeatureProcess_ToolButtonDown(app, event)
            
            if app.IS_detrend_finish == 1
               app.PSD_method.Enable = "on";
               app.FFT_Number.Enable = "on";
               app.overlap.Enable = "on";
               app.welch_overlap_2.Enable = "on";
               app.Powercalcu_frequency.Enable = "on";
               app.Phase_method.Enable = "on";
               app.FFTEditField.Enable = "on";
               app.samplerate_phase.Enable = "on";
               app.channel_choose_1.Enable = "on";
               app.channel_choose_2.Enable = "on";
               app.PLV_calcu.Enable = "on";
               app.TIME_FREQ_meth.Enable = "on";
               app.T_M_overlap.Enable = "on";
               app.samplarate.Enable = "on";
               app.T_M_NFFT.Enable = "on";
               app.T_M_plot.Enable = "on";
               app.T_M_WindowsLength.Enable = "on";
               app.chi_calcu.Enable = "on";
               app.plot_freq_down.Enable = "on";
               app.plot_freq_up.Enable = "on";
               app.channel_Phase_spectrum.Enable = "on";
               app.T_M_chi.Items = app.Rec_Parameters_info;
               app.FFTEditField.Value = app.data_fs;
               app.Plot_PSD.Enable = "on";
               app.plot_Phase_spectrum.Enable = "on";
               app.Window_length.Enable = "on";
               app.BandPowerSelect.Enable = "on";
               try
                   if app.data_fs/2 < 65
                      app.plot_freq_up.Value = (app.data_fs/2);
                      app.Slider_PSD.Limits = [0,app.plot_freq_up.Value];
                   else
                       app.Slider_PSD.Limits = [0,130];
                       app.plot_freq_up.Value = 65;
                   end
                   app.Time_range_Slide.Limits = [0,round(length(app.Data_now(1,:))/app.data_fs)];
                   app.Time_range_Slide.Value = [0,round(length(app.Data_now(1,:))/app.data_fs)];
               end
               
            else
               app.PSD_method.Enable = "off";
               app.FFT_Number.Enable = "off";
               app.overlap.Enable = "off";
               app.welch_overlap_2.Enable = "off";
               app.Powercalcu_frequency.Enable = "off";
               app.Phase_method.Enable = "off";
               app.FFTEditField.Enable = "off";
               app.samplerate_phase.Enable = "off";
               app.channel_choose_1.Enable = "off";
               app.channel_choose_2.Enable = "off";
               app.PLV_calcu.Enable = "off";
               app.TIME_FREQ_meth.Enable = "off";
               app.T_M_overlap.Enable = "off";
               app.samplarate.Enable = "off";
               app.T_M_NFFT.Enable = "off";
               app.T_M_plot.Enable = "off";
               app.Plot_Changes.Enable = "off";
               app.T_M_WindowsLength.Enable = "off";
               app.chi_calcu.Enable = "off";
               app.plot_freq_down.Enable = "off";
               app.plot_freq_up.Enable = "off";
               app.channel_Phase_spectrum.Enable = "off";
               app.Plot_PSD.Enable = "off";
               app.plot_Phase_spectrum.Enable = "off";
               app.BandPowerSelect.Enable = "off";
               try
                   if app.data_fs/2 < 65
                      app.plot_freq_up.Value = (app.data_fs/2);
                      app.Slider_PSD.Value = [0,app.plot_freq_up.Value];
                   else
                       app.Slider_PSD.Value = [0,130];
                       app.plot_freq_up.Value = 65;
                   end
                   app.Time_range_Slide.Limits = [0,length(app.Data_now(1,:)/app.data_fs)];
                   app.Time_range_Slide.Value = [0,length(app.Data_now(1,:)/app.data_fs)];
               end

%              app.T_M_chi.Items = app.Rec_Parameters_info;
            end
        end

        % Button pushed function: T_M_plot
        function T_M_plotButtonPushed(app, event)
            try
            app.waitting_now = uiprogressdlg(app.MainFigure,'Title','正在计算',...
            'Indeterminate','on');
            drawnow
            app.TIME_FREQUENCY.XLimMode = "auto";
            app.TIME_FREQUENCY.YLimMode = "auto";
            fs = app.data_fs;
            Plot_chi = str2double(app.T_M_chi.Value);
            
            if str2double(app.TIME_FREQ_meth.Value) == 1
                % STFT
                Nfft = str2double(app.T_M_NFFT.Value);
                OverLap = str2double(app.T_M_overlap.Value);
                Window_Length = str2double(app.T_M_WindowsLength.Value);
                %  assignin("base","OverLap",OverLap);
                %  assignin("base","Nfft",Nfft);
                %  assignin("base","Window_Length",Window_Length);
                %  assignin("base","Data_now",app.Data_now(Plot_chi,500:end));
                [B,f,t] = specgram(app.Data_now(Plot_chi,500:end),Nfft,fs,Window_Length,OverLap);
                imagesc(app.TIME_FREQUENCY,t,f,10*log10(abs(B)));
                app.TIME_FREQUENCY.YDir = 'normal';
                % app.TIME_FREQUENCY.Colormap= jet;
                % app.TIME_FREQUENCY.YLim = [0,round(app.data_fs/2)];
                app.TIME_FREQUENCY.YLim = [1,80];
                app.TIME_FREQUENCY.XLim = [t(1),t(end)];
                app.RESULT_DATA_TIME_FREQUENCY_POWER = B;
                app.RESULT_DATA_TIME_FREQUENCY_POWER_f = f;
                app.RESULT_DATA_TIME_FREQUENCY_POWER_t = t;
                app.Caxis = app.TIME_FREQUENCY.CLim;
                try
                app.Color_range_Slide.Value = app.Caxis;
                app.Y_range_Slide.Limits = [0,round(app.data_fs/2)];
                app.Y_range_Slide.Value = app.TIME_FREQUENCY.YLim;
                end
                % app.Color_range_Slide.Limits = [app.Caxis(1)-range/2,app.Caxis(2)+range/2];
            elseif str2double(app.TIME_FREQ_meth.Value) == 2
                % CWT
                %  wname_coef = { '2.6-3' '4-3.2' '4.7-4.7' '5.6-5.5'
                %  '6.4-6.4' '7.2-7.1' '7.9-7.8' '8.0-8.0' '8.5-9.0'}; %小波系数
                cmor_coef = [2.6 3 4 3.2 4.7 4.7 5.6 5.5 6.4 6.4 7.2 7.1 7.9 7.8 8.0 8.0 8.5 9.0];
                if app.data_fs == 1000
                   calcu_data = resample(app.Data_now(Plot_chi,500:end),500,1000);
                elseif app.data_fs == 250
                   calcu_data = resample(app.Data_now(Plot_chi,500:end),500,250);
                elseif app.data_fs == 500
                   calcu_data = app.Data_now(Plot_chi,500:end);
                else
                   calcu_data = resample(app.Data_now(Plot_chi,500:end),500,app.data_fs);
                end
                
                fs = 500;
                data_seg_after_filter = calcu_data;
                for j = 1:9
                fmin=10*(j+1)-10;
                fmax=10*(j+1);
                df=1;
                %  totalscal=(fmax-fmin)/df;
                f=fmin:df:fmax-df;
                %  wcf=centfrq(['cmor',wname_coef{1,j}]);%central frequency
                %   scal=fs*wcf./f;
                data_data_seg_after_filter_vector = data_seg_after_filter(:); % 转成列向量
                data_seg_cwt_10Hz=P02_F2c_cwt_cmor(app,data_data_seg_after_filter_vector,cmor_coef(2*j-1),cmor_coef(2*j),f,fs); %CWT
                for channel = 1:1
                data_seg_cwt((channel-1)*70+10*j-9:(channel-1)*70+10*j,:) = abs(data_seg_cwt_10Hz(:,length(data_seg_after_filter)*(channel-1)+1:length(data_seg_after_filter)+length(data_seg_after_filter)*(channel-1)));
                end
                
                imagesc(app.TIME_FREQUENCY,1/fs:(1/fs):(length(data_seg_after_filter)/fs),1:1:70,data_seg_cwt); 
                app.TIME_FREQUENCY.YDir = "normal";
                % app.TIME_FREQUENCY.Colormap= jet;
                app.TIME_FREQUENCY.YLim = [0,80];
                app.TIME_FREQUENCY.XLim = [1/fs,(length(data_seg_after_filter)/fs)];
                app.RESULT_DATA_TIME_FREQUENCY_POWER = data_seg_cwt;
                app.RESULT_DATA_TIME_FREQUENCY_POWER_f = (1:1:90)';
                app.RESULT_DATA_TIME_FREQUENCY_POWER_t = (1/fs:(1/fs):(length(data_seg_after_filter)/fs))';
                
                try
                app.Caxis = app.TIME_FREQUENCY.CLim;
                app.Color_range_Slide.Value = app.Caxis;
                app.Y_range_Slide.Limits = app.TIME_FREQUENCY.YLim;
                app.Y_range_Slide.Value = app.TIME_FREQUENCY.YLim;
                end
                
                end
            end
            
            app.T_M_Save.Enable = "on";
            app.save_T_F_MAP.Enable = "on";
            app.save_T_F_MAP_Workspace.Enable = "on";
            close(app.waitting_now)
            app.Plot_Changes.Enable = "on";
            catch
            % msgbox('请修改参数后重试','错误','warn')
            uialert(app.MainFigure,'请修改参数后重试','错误','Icon','warning');
            close(app.waitting_now)
            end

        end

        % Button pushed function: plot_Phase_spectrum
        function plot_Phase_spectrumButtonPushed(app, event)

             try
                app.waitting_now = uiprogressdlg(app.MainFigure,'Title','计算相关性中',...
                    'Indeterminate','on');
                drawnow
                app.Phase_feature.XLimMode = "auto";
                app.Phase_feature.YLimMode = "auto";
                value = app.IS_detrend_finish;
                if (value) == 1
                    T_F_frequency = app.data_fs;
                    nfft = app.FFTEditField.Value;
                    datax1 = app.Data_now(str2double(app.channel_Phase_spectrum.Value),:);
                    
                    % % 确保nfft至少等于信号长度
                    % if nfft < length(datax1)
                    %     nfft = 2^nextpow2(length(datax1));
                    % end
                    
                    % 计算FFT
                    X1_FD = fft(datax1, nfft);
                    
                    % 提取相位并展开
                    xphase = unwrap(angle(X1_FD));
                    
                    % 对实信号，只取前一半频谱
                    nfft_half = ceil((nfft+1)/2); % 对于偶数和奇数nfft都适用
                    xphase = xphase(1:nfft_half);
                    
                    % 正确计算频率轴 (对实信号)
                    f = T_F_frequency * (0:(nfft_half-1)) / nfft;
                    
                    % 绘制相位谱
                    plot(app.Phase_feature, f, xphase);
                end
                app.SAVE_PHASE_FIG.Enable = "on";
                close(app.waitting_now)
            catch
                uialert(app.MainFigure,'计算错误，请检查参数','错误','Icon','warning');   
                close(app.waitting_now)
            end

        end

        % Button pushed function: PLV_calcu
        function PLV_calcuButtonPushed(app, event)
            % calcu phase lock value
            try
             app.waitting_now = uiprogressdlg(app.MainFigure,'Title','计算相关性中',...
            'Indeterminate','on');
            drawnow
            if app.BandPowerSelect.Value == "1"
                d = designfilt('bandpassfir', 'FilterOrder', 120, ...    
                               'CutoffFrequency1', 4, 'CutoffFrequency2', 8, ...    
                               'SampleRate', app.data_fs, 'DesignMethod', 'window', ...    
                               'Window', 'hamming');          
            elseif app.BandPowerSelect.Value == "2"
                d = designfilt('bandpassfir', 'FilterOrder', 120, ...    
                               'CutoffFrequency1', 1, 'CutoffFrequency2', 3, ...    
                               'SampleRate', app.data_fs, 'DesignMethod', 'window', ...    
                               'Window', 'hamming');     
            elseif app.BandPowerSelect.Value == "3"
                d = designfilt('bandpassfir', 'FilterOrder', 120, ...    
                               'CutoffFrequency1', 13, 'CutoffFrequency2', 35, ...    
                               'SampleRate', app.data_fs, 'DesignMethod', 'window', ...    
                               'Window', 'hamming');     
            elseif app.BandPowerSelect.Value == "4"
                d = designfilt('bandpassfir', 'FilterOrder', 120, ...    
                               'CutoffFrequency1', 9, 'CutoffFrequency2', 12, ...    
                               'SampleRate', app.data_fs, 'DesignMethod', 'window', ...    
                               'Window', 'hamming');     
            elseif app.BandPowerSelect.Value == "5"
                d = designfilt('bandpassfir', 'FilterOrder', 120, ...    
                               'CutoffFrequency1', 35, 'CutoffFrequency2', 50, ...    
                               'SampleRate', app.data_fs, 'DesignMethod', 'window', ...    
                               'Window', 'hamming');     
            end

            data1 = app.Data_now(str2double(app.channel_choose_1.Value),:);
            data2 = app.Data_now(str2double(app.channel_choose_2.Value),:);
            data1_new = filtfilt(d,data1);
            data2_new = filtfilt(d,data2);

            phase_sig1 = angle(hilbert(data1_new)); 
            phase_sig2 = angle(hilbert(data2_new)); 

            % calcu PLV
            [plv] = P02_F2b_PLV(app,phase_sig1,phase_sig2);
            app.result.Value = plv;

            % calcu a duration PLV value
            phase_sigA = phase_sig1; 
            phase_sigB = phase_sig2;
            Data_length = app.data_fs*str2double(app.PLV_length.Value);

            Plv = zeros(1,floor(length(phase_sigA)/Data_length));
            for i = 1:floor(length(phase_sigA)/Data_length)
                calcuA = phase_sigA(((i-1)*Data_length+1):(i*Data_length));
                calcuB = phase_sigB(((i-1)*Data_length+1):(i*Data_length));
                [plv_now] = P02_F2b_PLV(app,calcuA,calcuB);
                Plv(i) = plv_now;
            end
            cla(app.PLV_plot)
            plot(app.PLV_plot,Plv);
            app.axis_preprocessing_now.Color = [0.76,0.73,0.82];
            app.SAVE_PLV_FIG.Enable = "on";
            close(app.waitting_now)
            catch
            uialert(app.MainFigure,'计算特征失败，请调整 code: 2001','错误','Icon','warning');      
            % msgbox({'error - 00203';' -计算相关性特征错误'})
            close(app.waitting_now)
            end
        end

        % Value changed function: T_M_chi
        function T_M_chiValueChanged(app, event)
            value = str2double(app.T_M_chi.Value);
            Plot_chi = value;
            value_meth = str2double(app.TIME_FREQ_meth.Value);
%             assignin('base','data_plot',app.Data_now)
%             assignin('base','Plot_chi',Plot_chi)
            if str2double(value_meth) == 1
                % STFT
                Nfft = str2double(app.T_M_NFFT.Value);
                OverLap = str2double(app.T_M_overlap.Value);
                Window_Length = str2double(app.T_M_WindowsLength.Value);
                fs = str2double(app.samplarate.Value);
                [B,f,t] = specgram(app.Data_now(Plot_chi,500:end),Nfft,fs,OverLap,Window_Length);
                imagesc(app.TIME_FREQUENCY,t,f,10*log10(abs(B)));
%               app.TIME_FREQUENCY.FontSize = 10; 
                app.TIME_FREQUENCY.YDir = "normal";
                app.TIME_FREQUENCY.Colormap=jet;
                app.TIME_FREQUENCY.YLim = [0 180];
                app.TIME_FREQUENCY.XLim = [t(1) t(end)];
            elseif str2double(value_meth) == 2
                % CWT
                % wname_coef = { '2.6-3' '4-3.2' '4.7-4.7' '5.6-5.5' '6.4-6.4' '7.2-7.1' '7.9-7.8' '8.0-8.0' '8.5-9.0'};
                cmor_coef = [2.6 3 4 3.2 4.7 4.7 5.6 5.5 6.4 6.4 7.2 7.1 7.9 7.8 8.0 8.0 8.5 9.0];
                if app.data_fs == 1000
                   calcu_data = resample(app.Data_now(Plot_chi,500:end),500,1000);
                elseif app.data_fs == 250
                   calcu_data = resample(app.Data_now(Plot_chi,500:end),500,250);
                elseif app.data_fs == 500
                   calcu_data = app.Data_now(Plot_chi,500:end);
                else
                   calcu_data = resample(app.Data_now(Plot_chi,500:end),500,app.data_fs); % 重采样
                end
                
                fs = 500;
                data_seg_after_filter = calcu_data;
                for j = 1:9
                fmin=10*(j+1)-10;
                fmax=10*(j+1);
                df=1;
%                 totalscal=(fmax-fmin)/df;
                f=fmin:df:fmax-df;
%                 wcf=centfrq(['cmor',wname_coef{1,j}]);% central frequency
%                 scal=fs*wcf./f;
                data_data_seg_after_filter_vector = data_seg_after_filter(:);% 转成列向量
                data_seg_cwt_10Hz=P02_F2c_cwt_cmor(app,data_data_seg_after_filter_vector,cmor_coef(2*j-1),cmor_coef(2*j),f,fs); %CWT
                for channel = 1:1
                data_seg_cwt((channel-1)*70+10*j-9:(channel-1)*70+10*j,:) = abs(data_seg_cwt_10Hz(:,length(data_seg_after_filter)*(channel-1)+1:length(data_seg_after_filter)+length(data_seg_after_filter)*(channel-1)));
                end
                
                imagesc(app.TIME_FREQUENCY,1/fs:(1/fs):(length(data_seg_after_filter)/fs),1:1:70,data_seg_cwt); 
                app.TIME_FREQUENCY.YDir = "normal";
                app.TIME_FREQUENCY.Colormap=jet;
                app.TIME_FREQUENCY.YLim = [0 90];
                app.TIME_FREQUENCY.XLim = [1/fs (length(data_seg_after_filter)/fs)];
                end
            elseif str2double(value) == 3
                % HHT
                
            end
        end

        % Button pushed function: STMartifact_remove
        function STMartifact_removePushed(app, event)
            try
            app.waitting_now = uiprogressdlg(app.MainFigure,'Title','计算中（模板法预计会占用更久时间）',...
            'Indeterminate','on');
            drawnow
            set(app.MainFigure,'Pointer','watch'); drawnow;
            raw = app.Data_now;
            set_divfs = (app.STMartifact_num_select.Value);
            %  set_divfs = 1;
            set_win = str2double(app.STMartifact_re_length.Value);
            channel = size(raw,1);
            %  assignin("base","raw",raw);
            %  assignin("base","set_divfs",set_divfs);
            %  assignin("base","set_win",set_win);
            clean_LFP = P02_F3a_LFPdenoise_DBS(app,raw,channel,set_divfs,set_win);
            app.Data_now = clean_LFP;
            % value = app.chi_choose.Value;
            i = '1';
            cla(app.axis_preprocessing_now)
            plot(app.axis_preprocessing_now,app.Data_last_step(str2double(i),2000:end));
            hold(app.axis_preprocessing_now,"on")
            plot(app.axis_preprocessing_now,app.Data_now(str2double(i),2000:end));
            legend(app.axis_preprocessing_now,{'before','after'})
            hold(app.axis_preprocessing_now,"off")

            Data1 = app.Data_last_step(str2double(i),2000:end);
            Data2 = app.Data_now(str2double(i),2000:end);
            cla(app.DATA_PSD)
            nfft = 5*app.data_fs;
            fs = app.data_fs;
            noverlap = 0.5*fs;
            window = hanning(fs);
            [~,linemat1,f1] = P02_F2a_welch_calcu_power(app,Data1,nfft,fs,noverlap,window);
            [~,linemat2,f2] = P02_F2a_welch_calcu_power(app,Data2,nfft,fs,noverlap,window);
            b = plot(app.DATA_PSD,f1,linemat1);
            b.Color(4) = 0.2;
            hold(app.DATA_PSD,"on")
            plot(app.DATA_PSD,f2,linemat2);
            legend(app.DATA_PSD,{'before','after'})
            % set(gca,xlim,[0 fs/4])
            hold(app.DATA_PSD,"off")
            set(app.MainFigure,'Pointer','arrow'); drawnow;
            close(app.waitting_now)
            app.STMartifactremove_confirm_2.Enable = "on";
            catch
                set(app.MainFigure,'Pointer','arrow'); drawnow;
                close(app.waitting_now)
                uialert(app.MainFigure,'计算失败,请调整参数 code: 2002','错误','Icon','warning');    
                % msgbox({'error - 00202';' -方法失效，伪迹形态异常'})
            end

        end

        % Button pushed function: apply_EAS
        function apply_EASButtonPushed(app, event)
            try
            set(app.MainFigure,'Pointer','watch'); drawnow;
            app.waitting_now = uiprogressdlg(app.MainFigure,'Title','正在计算',...
            'Indeterminate','on');
            drawnow
            rawEEG = app.Data_last_step;
            if str2double(app.is_ECG_avi.Value) == 1 
               
               length = str2double(app.K_length.Value );
               fsEEG = app.data_fs;
               fsECG = app.data_fs;
               length_win = str2double(app.EAS_set_pice.Value);
               m = size(rawEEG,1);
               n = fsEEG*length_win*floor((size(rawEEG,2)/(fsEEG*length_win)));
%              assignin("base","m",m);
%              assignin("base","n",n);
               EEG_OUT = ones(m,n);
               k = floor((size(rawEEG,2)/(fsEEG*length_win)));
               if app.IS_ALL_CHANNEL.Value == 1
                 for P = 1:m
                         ECG =  app.Data_now(P,:);
                     for I = 1:k
                         rawEEG_now = rawEEG(P,((I-1)*(fsEEG*length_win)+1):I*(fsEEG*length_win));
                         ECG_now = ECG(:,((I-1)*(fsEEG*length_win)+1):I*(fsEEG*length_win));
                         [clEEG,Temp]=P02_EAS_cl_TP(app,rawEEG_now,ECG_now,fsEEG,fsECG,length);
                         EEG_OUT(P,((I-1)*(fsEEG*length_win)+1):I*(fsEEG*length_win)) = clEEG;
                     end
                 end
               elseif app.IS_ALL_CHANNEL.Value == 0
                   for I = 1:k
                       rawEEG_now = rawEEG(1,((I-1)*(fsEEG*length_win)+1):I*(fsEEG*length_win));
                       ECG_now = ECG(:,((I-1)*(fsEEG*length_win)+1):I*(fsEEG*length_win));
                       [clEEG,Temp]=P02_EAS_cl_TP(app,rawEEG_now,ECG_now,fsEEG,fsECG,length);
                       EEG_OUT(1,((I-1)*(fsEEG*length_win)+1):I*(fsEEG*length_win)) = clEEG;
                   end
               end
               app.Data_now = EEG_OUT;
               cla(app.axis_preprocessing_now)
               plot(app.axis_preprocessing_now,app.Data_last_step(1,2000:end));
               hold(app.axis_preprocessing_now,"on")
               plot(app.axis_preprocessing_now,app.Data_now(1,2000:end));
               legend(app.axis_preprocessing_now,{'before','after'})
               hold(app.axis_preprocessing_now,"off") 
               plot(app.Temp_figure,Temp)
               app.confirm_EAS.Enable = "on";
               
               Data1 = app.Data_last_step(1,2000:end);
               Data2 = app.Data_now(1,2000:end);
               cla(app.DATA_PSD)
               nfft = 5*app.data_fs;
               fs = app.data_fs;
               % noverlap = 0.5*fs;
               window = hanning(fs);
               [~,linemat1,f1] = P02_F2a_welch_calcu_power(app,Data1,nfft,fs,app.noverlap,window);
               [~,linemat2,f2] = P02_F2a_welch_calcu_power(app,Data2,nfft,fs,app.noverlap,window);
               b = plot(app.DATA_PSD,f1,linemat1);
               b.Color(4) = 0.2;
               hold(app.DATA_PSD,"on")
               plot(app.DATA_PSD,f2,linemat2);
               legend(app.DATA_PSD,{'before','after'})
               % set(gca,xlim,[0 fs/4])
               hold(app.DATA_PSD,"off")
            elseif str2double(app.is_ECG_avi.Value) == 2
               ECG = app.ECG;
               length = str2double(app.K_length.Value);
               fsEEG = app.data_fs;
               fsECG = app.data_fs;
               [clEEG,Temp]=P02_EAS_cl_TP(app,rawEEG,ECG,fsEEG,fsECG,length);
               plot(app.axis_preprocessing_now,clEEG(1,:));
               plot(app.Temp_figure,Temp)
            end
            close(app.waitting_now)
            app.Temp_figure.Visible = "on";
%           set(app.MainFigure,'Pointer','watch'); drawnow;
            set(app.MainFigure,'Pointer','arrow'); drawnow;
            catch
                app.Data_now = app.Data_last_step;
                uialert(app.MainFigure,'方法失效，请检查参数或心电形态 code: 2003','错误','Icon','warning');    
                % msgbox({'error -00301';'方法失效，请检查参数或心电形态'})
                close(app.waitting_now)
                set(app.MainFigure,'Pointer','arrow'); drawnow;
            end
        end

        % Button pushed function: back
        function backButtonPushed(app, event)

           % button=questdlg('您确定清空并重置界面吗？（注意！未储存的数据将被删除）','重置界面？','是','否','是'); %内容，标题，选项，默认选项
           button = uiconfirm(app.MainFigure, '您确定清空并重置界面吗？（注意！未储存的数据将被删除）', '重置界面？','Options',{'是', '否'},'DefaultOption', '否', 'CancelOption', '是');
           set(app.MainFigure,'Pointer','watch'); drawnow;
        if strcmp(button,'是')
            app.Data_last_step = [];
            app.filterorder.Enable = 0;
            app.filter_sample_rate.Enable = 0;
            app.fs_1.Enable = 0;

            cla(app.axis_preprocessing_now);
            text(app.axis_preprocessing_now,0.1,0.5,'脑电数据离线分析系统','Color',[0.98,0.98,0.98],"BackgroundColor",[0.76,0.73,0.82],"FontSize",27,"FontSmoothing","on","FontWeight","bold");
            legend(app.axis_preprocessing_now,'off');
            app.axis_preprocessing_now.XLim = [0,1];
            app.axis_preprocessing_now.YLim = [0,1];
            app.axis_preprocessing_now.Color = [0.76,0.73,0.82];
            cla(app.DATA_PSD);
            text(app.DATA_PSD,0.1,0.5,'脑电数据离线分析系统','Color',[0.98,0.98,0.98],"BackgroundColor",[0.76,0.73,0.82],"FontSize",13,"FontSmoothing","on","FontWeight","bold");
            legend(app.DATA_PSD,'off');
            app.DATA_PSD.XLim = [0,1];
            app.DATA_PSD.YLim = [0,1];
            app.DATA_PSD.Color = [0.76,0.73,0.82];

            cla(app.PSD_figure);
            text(app.PSD_figure,0.1,0.5,'脑电数据离线分析系统','Color',[0.98,0.98,0.98],"BackgroundColor",[0.76,0.73,0.82],"FontSize",16,"FontSmoothing","on","FontWeight","bold");
            legend(app.PSD_figure,'off');
            app.PSD_figure.XLim = [0,1];
            app.PSD_figure.YLim = [0,1];
            app.PSD_figure.Color = [0.76,0.73,0.82];
            cla(app.Phase_feature);
            text(app.Phase_feature,0.1,0.5,'脑电数据离线分析系统','Color',[0.98,0.98,0.98],"BackgroundColor",[0.76,0.73,0.82],"FontSize",18,"FontSmoothing","on","FontWeight","bold");
            legend(app.Phase_feature,'off');
            app.Phase_feature.XLim = [0,1];
            app.Phase_feature.YLim = [0,1];
            app.Phase_feature.Color = [0.76,0.73,0.82];
            cla(app.filter_simptom1);
            cla(app.filter_simptom2);
            legend(app.filter_simptom2,'off');
            legend(app.filter_simptom1,'off');
            cla(app.filter_simptom1_2);
            cla(app.filter_simptom2_2);
            legend(app.filter_simptom2_2,'off');
            legend(app.filter_simptom1_2,'off');
            app.Menu_data_CUT.Enable = "off";
            app.data_cut_tool.Enable = "off";
            cla(app.TIME_FREQUENCY);
            cla(app.Phase_feature);
            cla(app.PLV_plot);
            text(app.TIME_FREQUENCY,0.18,0.5,'脑电数据离线分析系统','Color',[0.98,0.98,0.98],"BackgroundColor",[0.76,0.73,0.82],"FontSize",34,"FontSmoothing","on","FontWeight","bold");
            legend(app.TIME_FREQUENCY,'off');
            app.TIME_FREQUENCY.XLim = [0,1];
            app.TIME_FREQUENCY.YLim = [0,1];
            app.TIME_FREQUENCY.Color = [0.76,0.73,0.82];
            colorbar(app.TIME_FREQUENCY,"northoutside");
            colormap(app.TIME_FREQUENCY, jet); 
            set(app.axis_preprocessing_now);
            text(app.PLV_plot,0.1,0.5,'脑电数据离线分析系统','Color',[0.98,0.98,0.98],"BackgroundColor",[0.76,0.73,0.82],"FontSize",18,"FontSmoothing","on","FontWeight","bold");
            app.PLV_plot.XLim = [0,1];
            app.PLV_plot.YLim = [0,1];
            legend(app.PLV_plot,'off');
            %app.processing_pannel.SelectedTab = app.PreProcess_Tool;
            app.Panel_Removal_in.SelectedTab = app.Tab_for_detrend;
            app.highpass_apply.Enable = "off";
            app.comfirm_detrend2.Enable = "off";
            app.Save_Data.Enable = "off";
            app.filter_simptom1.Visible = "off";
            app.filter_simptom2.Visible = "off";
            app.filter_simptom1_2.Visible = "off";
            app.filter_simptom2_2.Visible = "off";
            app.filter_design_function.Enable = "off";
            app.apply_lowpass_filter.Enable = "off";
            app.STMartifactremove_confirm.Enable = "off";
            app.apply_EAS.Enable = "off";
            app.confirm_EAS.Enable = "off";
            app.IS_detrend_finish = 0; % 第二页起始值
            app.IIRCheckBox.Enable = "off";
            app.FIRCheckBox.Enable = "off";
            app.plot_Phase_spectrum.Enable = "off";
            app.SAVE_PHASE_FIG.Enable;
            cla(app.Temp_figure);
            app.Temp_figure.Visible = "off";
            app.design_high_pass.Enable = "off";
            app.STMartifactremove_confirm_2.Enable = "off";
            app.STMartifact_remove.Enable = "off";
            app.save_PSD_2File.Enable = "off";
            app.save_T_F_MAP.Enable = "off";
            app.save_T_F_MAP_Workspace.Enable = "off";
            app.Plot_PSD.Enable = "off";
            app.Beta_Peakfind.Enable = "off";
            app.Dealtiled_para = '请先进行数据解包';
            app.currentdata_txt.Value = '请进行"数据确认"';
            
        end
           set(app.MainFigure,'Pointer','arrow'); drawnow;
        end

        % Button pushed function: confirm_EAS
        function confirm_EASButtonPushed(app, event)
            app.Data_last_step = app.Data_now;
            value = app.chi_choose.Value;
            i = value;
            cla(app.axis_preprocessing_now)
            plot(app.axis_preprocessing_now,app.Data_last_step(str2double(i),2000:end));
            Data1 = app.Data_last_step(str2double(i),2000:end);
            cla(app.DATA_PSD)
            nfft = 5*app.data_fs;
            fs = app.data_fs;
            % noverlap = 0.5*fs;
            window = hanning(fs);
            [~,linemat1,f1] = P02_F2a_welch_calcu_power(app,Data1,nfft,fs,app.noverlap,window);
            cla(app.DATA_PSD)
            plot(app.DATA_PSD,f1,linemat1);
            legend(app.DATA_PSD,{'now'})
        end

        % Menu selected function: illustrate
        function illustrateMenuSelected(app, event)

            uialert(app.MainFigure,{'说明：本程序为LFP数据离线分析工具，仅支持【科学研究使用】。';'使用说明请参见：说明文档与使用指南';'版本：V0.1.8';'问题联系：glx22@mails.tsinghua.edu.cn'},'帮助','Icon','none');

        end

        % Button pushed function: save_PSD_Fig
        function save_PSD_FigButtonPushed(app, event)

            selection = uiconfirm(app.MainFigure, ...
                '选择保存格式：', '保存选项', ...
                'Options', {'可编辑格式(.fig)', '图片格式'}, ...
                'DefaultOption', 2, 'CancelOption', 2);

            try
                if ~isempty(app.First_Filename)
                    origin_names = app.First_Filename(1:end-4);
                    validFileName = regexprep(origin_names, '[\.\-\\/:*?"<>|]', '');
                    saved_name = [validFileName, 'PSD'];
                else
                    saved_name = 'PSD_figure';
                end
            catch
                saved_name = 'PSD_figure';
            end
            
            if strcmp(selection, '可编辑格式(.fig)')
                new_f_handle = figure('visible', 'on', 'Color', 'white');
                new_axes = copyobj(app.PSD_figure, new_f_handle);
                set(new_axes, 'units', 'normalized', 'position', [0.1 0.1 0.8 0.8], ...
                    'Color', 'white', 'FontSize', 14, 'XColor', 'black', 'YColor', 'black');

                xlabel(new_axes, 'Frequency (Hz)', 'FontSize', 16, 'Color', 'black', 'FontWeight', 'bold');

                ylabel_handle = get(new_axes, 'YLabel');
                if ~isempty(ylabel_handle)
                    set(ylabel_handle, 'FontSize', 16, 'Color', 'black', 'FontWeight', 'bold');
                end

                title_handle = get(new_axes, 'Title');
                if ~isempty(title_handle)
                    set(title_handle, 'FontSize', 18, 'FontWeight', 'bold', 'Color', 'black');
                end

                if isfield(app, 'Channel_Use_PSD') && ~isempty(app.Channel_Use_PSD)
                    legend_handle = legend(new_axes, app.Channel_Use_PSD);
                    set(legend_handle, 'Color', 'white', 'EdgeColor', 'black', ...
                        'FontSize', 12, 'TextColor', 'black');
                else

                    orig_legend = findobj(get(app.PSD_figure, 'Parent'), 'Type', 'legend');
                    if ~isempty(orig_legend)
                        legend_strings = get(orig_legend, 'String');
                        if ~isempty(legend_strings)
                            legend_handle = legend(new_axes, legend_strings);
                            set(legend_handle, 'Color', 'white', 'EdgeColor', 'black', ...
                                'FontSize', 12, 'TextColor', 'black');
                        end
                    end
                end
                
                [filename, pathname] = uiputfile('*.fig', '保存为可编辑格式', [saved_name, '.fig']);
                if filename ~= 0
                    fullpath = fullfile(pathname, filename);
                    savefig(new_f_handle, fullpath);
                    uialert(app.MainFigure, ['图像已保存到: ' fullpath newline '双击.fig文件即可在MATLAB中打开编辑'], '保存成功', 'Icon', 'success');
                end
                delete(new_f_handle);
                
            else

                new_f_handle = figure('visible', 'off', 'Color', 'white');
                new_axes = copyobj(app.PSD_figure, new_f_handle);
                set(new_axes, 'units', 'normalized', 'position', [0.1 0.1 0.8 0.8], ...
                    'Color', 'white', 'FontSize', 14, 'XColor', 'black', 'YColor', 'black');

                xlabel(new_axes, 'Frequency (Hz)', 'FontSize', 16, 'Color', 'black', 'FontWeight', 'bold');

                ylabel_handle = get(new_axes, 'YLabel');
                if ~isempty(ylabel_handle)
                    set(ylabel_handle, 'FontSize', 16, 'Color', 'black', 'FontWeight', 'bold');
                end

                title_handle = get(new_axes, 'Title');
                if ~isempty(title_handle)
                    set(title_handle, 'FontSize', 18, 'FontWeight', 'bold', 'Color', 'black');
                end

                if isfield(app, 'Channel_Use_PSD') && ~isempty(app.Channel_Use_PSD)
                    legend_handle = legend(new_axes, app.Channel_Use_PSD);
                    set(legend_handle, 'Color', 'white', 'EdgeColor', 'black', ...
                        'FontSize', 12, 'TextColor', 'black');
                else

                    orig_legend = findobj(get(app.PSD_figure, 'Parent'), 'Type', 'legend');
                    if ~isempty(orig_legend)
                        legend_strings = get(orig_legend, 'String');
                        if ~isempty(legend_strings)
                            legend_handle = legend(new_axes, legend_strings);
                            set(legend_handle, 'Color', 'white', 'EdgeColor', 'black', ...
                                'FontSize', 12, 'TextColor', 'black');
                        end
                    end
                end
                
                [filename, pathname, fileindex] = uiputfile(...
                    {'*.jpg'; '*.png'; '*.bmp'; '*.pdf'; '*.eps'}, 'save picture as', saved_name);
                
                if filename ~= 0
                    file = fullfile(pathname, filename);
                    exportgraphics(new_f_handle, file, 'Resolution', 300);
                    uialert(app.MainFigure, ['图像已保存到: ' file], '保存成功', 'Icon', 'success');
                end
                delete(new_f_handle);
            end

        end

        % Value changed function: TIME_FREQ_meth
        function TIME_FREQ_methValueChanged(app, event)
            value = str2double(app.TIME_FREQ_meth.Value);
            if value == 1
                app.T_M_overlap.Enable = "on";
                app.T_M_NFFT.Enable = "on";
                app.T_M_WindowsLength.Enable = "on";
            elseif value == 2
                app.T_M_overlap.Enable = "off";
                app.T_M_NFFT.Enable = "off";
                app.T_M_WindowsLength.Enable = "off";
            end
        end

        % Button pushed function: save_PSD_2Work_space
        function save_PSD_2Work_spaceButtonPushed(app, event)
            % save 2 workspace                 app.RESULT_DATA_PSD_POWER =
            % PSD_Power;                 app.RESULT_DATA_PSD_FREQ = 1:1:app.data_fs;
            %           name_mid = app.Filename;
            %           name = ['Data_',name_mid(isstrprop(name_mid,'digit'))];
            %           assignin('base',name,app.Data_now);
            
            assignin("base","RESULT_DATA_PSD_POWER",app.RESULT_DATA_PSD_POWER);
            assignin("base","RESULT_DATA_PSD_FREQ",app.RESULT_DATA_PSD_FREQ);
            uialert(app.MainFigure,'已发送（该功能在matlab授权运行时可用）','提示','Icon','info');
        end

        % Button pushed function: T_M_Save
        function T_M_SaveButtonPushed(app, event)
        selection = uiconfirm(app.MainFigure, ...
            '选择保存格式：', '保存选项', ...
            'Options', {'可编辑格式(.fig)', '图片格式'}, ...
            'DefaultOption', 2, 'CancelOption', 2);

        try
            if ~isempty(app.First_Filename)
                origin_names = app.First_Filename(1:end-4);
                validFileName = regexprep(origin_names, '[\.\-\\/:*?"<>|]', '');
                saved_name = [validFileName, 'TF'];
            else
                saved_name = 'TF_figure';
            end
        catch
            saved_name = 'TF_figure';
        end
        
        if strcmp(selection, '可编辑格式(.fig)')

            new_f_handle = figure('visible', 'on', 'Color', 'white');
            new_axes = copyobj(app.TIME_FREQUENCY, new_f_handle);
            set(new_axes, 'units', 'normalized', 'position', [0.1 0.1 0.7 0.8], ...
                'Color', 'white', 'FontSize', 14, 'XColor', 'black', 'YColor', 'black');
            

            set(new_f_handle, 'Position', [100, 100, 900, 600]);
            
            xlabel(new_axes, 'Time (s)', 'FontSize', 16, 'Color', 'black', 'FontWeight', 'bold');
            ylabel(new_axes, 'Frequency (Hz)', 'FontSize', 16, 'Color', 'black', 'FontWeight', 'bold');

            title_handle = get(new_axes, 'Title');
            if ~isempty(title_handle)
                set(title_handle, 'FontSize', 18, 'FontWeight', 'bold', 'Color', 'black');
            end

            colorbar_handle = findobj(new_f_handle, 'Type', 'colorbar');
            if ~isempty(colorbar_handle)
                set(colorbar_handle, 'FontSize', 12, 'Color', 'black');
            else

                try
                    cb = colorbar(new_axes);
                    set(cb, 'FontSize', 12, 'Color', 'black');
                catch

                end
            end
            
            [filename, pathname] = uiputfile('*.fig', '保存为可编辑格式', [saved_name, '.fig']);
            if filename ~= 0
                fullpath = fullfile(pathname, filename);
                savefig(new_f_handle, fullpath);
                uialert(app.MainFigure, ['时频图已保存到: ' fullpath newline '双击.fig文件即可在MATLAB中打开编辑'], '保存成功', 'Icon', 'success');
            end
            delete(new_f_handle);
            
        else

            new_f_handle = figure('visible', 'off', 'Color', 'white');
            new_axes = copyobj(app.TIME_FREQUENCY, new_f_handle);
            set(new_axes, 'units', 'normalized', 'position', [0.1 0.1 0.7 0.8], ...
                'Color', 'white', 'FontSize', 14, 'XColor', 'black', 'YColor', 'black');

            set(new_f_handle, 'Position', [100, 100, 900, 600]);

            xlabel(new_axes, 'Time (s)', 'FontSize', 16, 'Color', 'black', 'FontWeight', 'bold');
            ylabel(new_axes, 'Frequency (Hz)', 'FontSize', 16, 'Color', 'black', 'FontWeight', 'bold');

            title_handle = get(new_axes, 'Title');
            if ~isempty(title_handle)
                set(title_handle, 'FontSize', 18, 'FontWeight', 'bold', 'Color', 'black');
            end

            colorbar_handle = findobj(new_f_handle, 'Type', 'colorbar');
            if ~isempty(colorbar_handle)
                set(colorbar_handle, 'FontSize', 12, 'Color', 'black');
            else

                try
                    cb = colorbar(new_axes);
                    set(cb, 'FontSize', 12, 'Color', 'black');
                catch

                end
            end
            
            [filename, pathname, fileindex] = uiputfile(...
                {'*.jpg'; '*.png'; '*.bmp'; '*.pdf'; '*.eps'}, 'save time-frequency plot as', saved_name);
            
            if filename ~= 0
                file = fullfile(pathname, filename);
                exportgraphics(new_f_handle, file, 'Resolution', 300);
                uialert(app.MainFigure, ['时频图已保存到: ' file], '保存成功', 'Icon', 'success');
            end
            delete(new_f_handle);
        end
        end

        % Button pushed function: SAVE_PHASE_FIG
        function SAVE_PHASE_FIGButtonPushed(app, event)
        selection = uiconfirm(app.MainFigure, ...
            '选择保存格式：', '保存选项', ...
            'Options', {'可编辑格式(.fig)', '图片格式'}, ...
            'DefaultOption', 2, 'CancelOption', 2);
        
        try
            if isfield(app, 'First_Filename') && ~isempty(app.First_Filename)
                origin_names = app.First_Filename(1:end-4);
                validFileName = regexprep(origin_names, '[\.\-\\/:*?"<>|]', '');
                saved_name = [validFileName, 'Phase'];
            else
                saved_name = 'Phase_figure';
            end
        catch
            saved_name = 'Phase_figure';
        end
        
        if strcmp(selection, '可编辑格式(.fig)')
            % 保存为.fig格式（可见figure）
            new_f_handle = figure('visible', 'on', 'Color', 'white');
            new_axes = copyobj(app.Phase_feature, new_f_handle);
            set(new_axes, 'units', 'normalized', 'position', [0.1 0.1 0.8 0.8], ...
                'Color', 'white', 'FontSize', 14, 'XColor', 'black', 'YColor', 'black');
            
            % 设置X轴标签
            xlabel(new_axes, 'Frequency (Hz)', 'FontSize', 16, 'Color', 'black', 'FontWeight', 'bold');
            
            % 保持Y轴标签不变，但调整字体
            ylabel_handle = get(new_axes, 'YLabel');
            if ~isempty(ylabel_handle)
                set(ylabel_handle, 'FontSize', 16, 'Color', 'black', 'FontWeight', 'bold');
            end
            
            % 调整标题字体
            title_handle = get(new_axes, 'Title');
            if ~isempty(title_handle)
                set(title_handle, 'FontSize', 18, 'FontWeight', 'bold', 'Color', 'black');
            end
            
            % 从原图复制legend
            orig_legend = findobj(get(app.Phase_feature, 'Parent'), 'Type', 'legend');
            if ~isempty(orig_legend)
                legend_strings = get(orig_legend, 'String');
                if ~isempty(legend_strings)
                    legend_handle = legend(new_axes, legend_strings);
                    set(legend_handle, 'Color', 'white', 'EdgeColor', 'black', ...
                        'FontSize', 12, 'TextColor', 'black');
                end
            end
            
            [filename, pathname] = uiputfile('*.fig', '保存为可编辑格式', [saved_name, '.fig']);
            if filename ~= 0
                fullpath = fullfile(pathname, filename);
                savefig(new_f_handle, fullpath);
                uialert(app.MainFigure, ['Phase特征图已保存到: ' fullpath newline '双击.fig文件即可在MATLAB中打开编辑'], '保存成功', 'Icon', 'success');
            end
            delete(new_f_handle);
            
        else
            % 保存为图片格式（带白色背景）
            new_f_handle = figure('visible', 'off', 'Color', 'white');
            new_axes = copyobj(app.Phase_feature, new_f_handle);
            set(new_axes, 'units', 'normalized', 'position', [0.1 0.1 0.8 0.8], ...
                'Color', 'white', 'FontSize', 14, 'XColor', 'black', 'YColor', 'black');
            
            % 设置X轴标签
            xlabel(new_axes, 'Frequency (Hz)', 'FontSize', 16, 'Color', 'black', 'FontWeight', 'bold');
            
            % 保持Y轴标签不变，但调整字体
            ylabel_handle = get(new_axes, 'YLabel');
            if ~isempty(ylabel_handle)
                set(ylabel_handle, 'FontSize', 16, 'Color', 'black', 'FontWeight', 'bold');
            end
            
            % 调整标题字体
            title_handle = get(new_axes, 'Title');
            if ~isempty(title_handle)
                set(title_handle, 'FontSize', 18, 'FontWeight', 'bold', 'Color', 'black');
            end
            
            % 从原图复制legend
            orig_legend = findobj(get(app.Phase_feature, 'Parent'), 'Type', 'legend');
            if ~isempty(orig_legend)
                legend_strings = get(orig_legend, 'String');
                if ~isempty(legend_strings)
                    legend_handle = legend(new_axes, legend_strings);
                    set(legend_handle, 'Color', 'white', 'EdgeColor', 'black', ...
                        'FontSize', 12, 'TextColor', 'black');
                end
            end
            
            [filename, pathname, fileindex] = uiputfile(...
                {'*.jpg'; '*.png'; '*.bmp'; '*.pdf'; '*.eps'}, 'save phase feature as', saved_name);
            
            if filename ~= 0
                file = fullfile(pathname, filename);
                exportgraphics(new_f_handle, file, 'Resolution', 300);
                uialert(app.MainFigure, ['Phase特征图已保存到: ' file], '保存成功', 'Icon', 'success');
            end
            delete(new_f_handle);
        end
        end

        % Button pushed function: SAVE_PLV_FIG
        function SAVE_PLV_FIGButtonPushed(app, event)
            selection = uiconfirm(app.MainFigure, ...
                '选择保存格式：', '保存选项', ...
                'Options', {'可编辑格式(.fig)', '图片格式'}, ...
                'DefaultOption', 2, 'CancelOption', 2);
            
            % 生成基于原始文件名的保存名称
            try
                if isfield(app, 'First_Filename') && ~isempty(app.First_Filename)
                    origin_names = app.First_Filename(1:end-4);
                    validFileName = regexprep(origin_names, '[\.\-\\/:*?"<>|]', '');
                    saved_name = [validFileName, 'PLV'];
                else
                    saved_name = 'PLV_figure';
                end
            catch
                saved_name = 'PLV_figure';
            end
            
            if strcmp(selection, '可编辑格式(.fig)')
                % 保存为.fig格式（可见figure）
                new_f_handle = figure('visible', 'on', 'Color', 'white');
                new_axes = copyobj(app.PLV_plot, new_f_handle);
                set(new_axes, 'units', 'normalized', 'position', [0.1 0.1 0.8 0.8], ...
                    'Color', 'white', 'FontSize', 14, 'XColor', 'black', 'YColor', 'black');
                
                % 设置X轴标签
                xlabel(new_axes, 'Frequency (Hz)', 'FontSize', 16, 'Color', 'black', 'FontWeight', 'bold');
                
                % 保持Y轴标签不变，但调整字体
                ylabel_handle = get(new_axes, 'YLabel');
                if ~isempty(ylabel_handle)
                    set(ylabel_handle, 'FontSize', 16, 'Color', 'black', 'FontWeight', 'bold');
                end
                
                % 调整标题字体
                title_handle = get(new_axes, 'Title');
                if ~isempty(title_handle)
                    set(title_handle, 'FontSize', 18, 'FontWeight', 'bold', 'Color', 'black');
                end
                
                % 从原图复制legend
                orig_legend = findobj(get(app.PLV_plot, 'Parent'), 'Type', 'legend');
                if ~isempty(orig_legend)
                    legend_strings = get(orig_legend, 'String');
                    if ~isempty(legend_strings)
                        legend_handle = legend(new_axes, legend_strings);
                        set(legend_handle, 'Color', 'white', 'EdgeColor', 'black', ...
                            'FontSize', 12, 'TextColor', 'black');
                    end
                end
                
                [filename, pathname] = uiputfile('*.fig', '保存为可编辑格式', [saved_name, '.fig']);
                if filename ~= 0
                    fullpath = fullfile(pathname, filename);
                    savefig(new_f_handle, fullpath);
                    uialert(app.MainFigure, ['PLV图已保存到: ' fullpath newline '双击.fig文件即可在MATLAB中打开编辑'], '保存成功', 'Icon', 'success');
                end
                delete(new_f_handle);
                
            else
                % 保存为图片格式（带白色背景）
                new_f_handle = figure('visible', 'off', 'Color', 'white');
                new_axes = copyobj(app.PLV_plot, new_f_handle);
                set(new_axes, 'units', 'normalized', 'position', [0.1 0.1 0.8 0.8], ...
                    'Color', 'white', 'FontSize', 14, 'XColor', 'black', 'YColor', 'black');
                
                % 设置X轴标签
                xlabel(new_axes, 'Frequency (Hz)', 'FontSize', 16, 'Color', 'black', 'FontWeight', 'bold');
                
                % 保持Y轴标签不变，但调整字体
                ylabel_handle = get(new_axes, 'YLabel');
                if ~isempty(ylabel_handle)
                    set(ylabel_handle, 'FontSize', 16, 'Color', 'black', 'FontWeight', 'bold');
                end
                
                % 调整标题字体
                title_handle = get(new_axes, 'Title');
                if ~isempty(title_handle)
                    set(title_handle, 'FontSize', 18, 'FontWeight', 'bold', 'Color', 'black');
                end
                
                % 从原图复制legend
                orig_legend = findobj(get(app.PLV_plot, 'Parent'), 'Type', 'legend');
                if ~isempty(orig_legend)
                    legend_strings = get(orig_legend, 'String');
                    if ~isempty(legend_strings)
                        legend_handle = legend(new_axes, legend_strings);
                        set(legend_handle, 'Color', 'white', 'EdgeColor', 'black', ...
                            'FontSize', 12, 'TextColor', 'black');
                    end
                end
                
                [filename, pathname, fileindex] = uiputfile(...
                    {'*.jpg'; '*.png'; '*.bmp'; '*.pdf'; '*.eps'}, 'save PLV plot as', saved_name);
                
                if filename ~= 0
                    file = fullfile(pathname, filename);
                    exportgraphics(new_f_handle, file, 'Resolution', 300);
                    uialert(app.MainFigure, ['PLV图已保存到: ' file], '保存成功', 'Icon', 'success');
                end
                delete(new_f_handle);
            end
        end

        % Value changed function: filter_method_dropdown
        function filter_method_dropdownValueChanged(app, event)
            value1 = str2double(app.filter_methed_FIR.Value);
            value2 = str2double(app.filter_method_dropdown.Value);
            if value2 == 1
                if value1 == 1
                  app.frequency2.Visible = "on";
                  app.frequency2.Enable = "on";
                  app.frequency1_text.Text = "通带频率";
                  app.frequency1.Visible = "on";
                  app.frequency1.Enable = "on";
                  app.frequency2_text.Text = "阻带频率";
                  app.frequency2_text.Visible = "on";
                elseif value1 == 2
                  app.frequency1.Visible = "on";
                  app.frequency1.Enable = "on";
                  app.frequency2.Visible = "on";
                  app.frequency2.Enable = "on";
                  app.frequency2_text.Visible = "on";
                  app.frequency1_text.Text = "通带频率"; %'PassbandFrequency'
                  app.frequency2_text.Text = "阻带频率";
                elseif value1 == 3
                  app.frequency1.Visible = "on";
                  app.frequency2.Visible = "off";
                  app.frequency2_text.Visible = "off";
                  app.frequency1_text.Text = "阻带频率"; %'PassbandFrequency'
%                 app.frequency2_text.Text = "阻带衰减";
                end
            elseif value2 == 2
                if value1 == 1
                  app.frequency1.Visible = "on";
                  app.frequency2.Visible = "off";
                  app.frequency1_text.Text = "截止频率";
                  app.frequency2_text.Visible = "off";
                elseif value1 == 2
                  app.frequency1.Visible = "on";
                  app.frequency2.Visible = "on";
                  app.frequency2_text.Visible = "on";
                  app.frequency1_text.Text = "通带频率"; %'PassbandFrequency'
                  app.frequency2_text.Text = "通带波动";
                elseif value1 == 3
                  app.frequency1.Visible = "on";
                  app.frequency2.Visible = "on";
                  app.frequency2_text.Visible = "on";
                  app.frequency1_text.Text = "阻带频率"; %'PassbandFrequency'
                  app.frequency2_text.Text = "阻带衰减";
                end
            end
        end

        % Value changed function: filter_methed_FIR
        function filter_methed_FIRValueChanged(app, event)
            value1 = str2double(app.filter_methed_FIR.Value);
            value2 = str2double(app.filter_method_dropdown.Value);
            if value2 == 1
                if value1 == 1
                  app.frequency2.Visible = "on";
                  app.frequency2.Enable = "on";
                  app.frequency1_text.Text = "通带频率";
                  app.frequency1.Visible = "on";
                  app.frequency1.Enable = "on";
                  app.frequency2_text.Text = "阻带频率";
                  app.frequency2_text.Visible = "on";
                  app.filter_order_STM.Value = 100;
                  app.frequency1.Value = 60;
                  app.frequency2.Value = 80;
                elseif value1 == 2
                  app.frequency1.Visible = "on";
                  app.frequency1.Enable = "on";
                  app.frequency2.Visible = "on";
                  app.frequency2.Enable = "on";
                  app.frequency2_text.Visible = "on";
                  app.frequency1_text.Text = "通带频率"; %'PassbandFrequency'
                  app.frequency2_text.Text = "阻带频率";
                  app.filter_order_STM.Value = 100;
                  app.frequency1.Value = 60;
                  app.frequency2.Value = 80;
                elseif value1 == 3
                  app.frequency1.Visible = "on";
                  app.frequency2.Visible = "off";
                  app.frequency2_text.Visible = "off";
                  app.frequency1_text.Text = "阻带频率"; %'PassbandFrequency'
%                   app.frequency2_text.Text = "阻带衰减";
                  app.filter_order_STM.Value = 6;
                  app.frequency1.Value = 60;
                end
            elseif value2 == 2
                if value1 == 1
                  app.frequency1.Visible = "on";
                  app.frequency2.Visible = "off";
                  app.frequency1_text.Text = "截止频率";
                  app.frequency2_text.Visible = "off";
                elseif value1 == 2
                  app.frequency1.Visible = "on";
                  app.frequency2.Visible = "on";
                  app.frequency2_text.Visible = "on";
                  app.frequency1_text.Text = "通带频率"; %'PassbandFrequency'
                  app.frequency2_text.Text = "通带波动";
                elseif value1 == 3
                  app.frequency1.Visible = "on";
                  app.frequency2.Visible = "on";
                  app.frequency2_text.Visible = "on";
                  app.frequency1_text.Text = "阻带频率"; %'PassbandFrequency'
                  app.frequency2_text.Text = "阻带衰减";
                end
            end
        end

        % Button pushed function: filter_file_choose
        function filter_file_chooseButtonPushed(app, event)
            % get data
            app.isfilter_design = 0;
            app.Filename2 = [];
            [app.Filename2,path2] = uigetfile('*.m','选择滤波器函数文件');
            
            if length(app.Filename) < 1 % Filename return
                % msgbox('请选择有效文件')
                uialert(app.MainFigure,'请选择有效文件','提示','Icon','warning')
                app.data_config.Enable = "off";
            else
                app.filename.Value = app.Filename;
                app.data_path = path2;
                cd(path2)
                app.Save_Path.Value = path2;
                app.apply_lowpass_filter.Enable = "on";
            end
        end

        % Button down function: Phase_Value_analisis
        function Phase_Value_analisisButtonDown(app, event)
            % msgbox()
            % uialert(app.Phase_Value_analisis,'请注意滤波器对相位分析的影响！检查是否采用线性相位滤波器。','提示','Icon','warning')
            try
            app.samplerate_phase.Value = app.data_fs;
            end
            uialert(app.MainFigure,'请注意滤波器对相位分析的影响','提示','Icon','info')
            app.TabGroup3.SelectedTab = app.Phase_Value_analisis;
        end

        % Close request function: MainFigure
        function MainFigureCloseRequest(app, event)
           
            % button=questdlg('您确定退出吗？（注意！未储存的数据将被删除）','退出离线分析工具？','是','否','是'); %内容，标题，选项，默认选项
            selection = uiconfirm(app.MainFigure, '您确定退出吗？（注意！未储存的数据将被删除）', '退出离线分析工具？','Options',{'直接退出', '返回主菜单' ,'取消'},'DefaultOption', '取消', 'CancelOption', '直接退出');
            if strcmp(selection,'直接退出')
               saveSettings(app);
               delete(app);
            elseif strcmp(selection,'返回主菜单')
               APP_to_start_0_1
               saveSettings(app);
               delete(app);
            end
        end

        % Menu selected function: RSMenu
        function RSMenuSelected(app, event)
        % button=questdlg('Leave current page？Make sure you have saved all you need!','warning','YES','NO','YES'); %内容，标题，选项，默认选项
        selection = uiconfirm(app.MainFigure, '您确定离开当前页面吗？（注意！退出前请保存您的工作）', '退出离线分析工具？','Options',{'是', '否'},'DefaultOption', '否', 'CancelOption', '是');
        if strcmp(selection,'是')
           delete(app);
           APP_acq_CHI_0_1
        end
        end

        % Menu selected function: Menu_exit
        function Menu_exitSelected(app, event)
            selection = uiconfirm(app.MainFigure, '您确定退出吗？（注意！未储存的数据将被删除）', '退出离线分析工具？','Options',{'是', '否'},'DefaultOption', '否', 'CancelOption', '是');
        if strcmp(selection,'是')
           delete(app);
        end
        end

        % Button pushed function: Open_File
        function Open_FileButtonPushed(app, event)
            path = app.Save_Path.Value;
%           assignin("base","path",path)
            winopen(path{1,1}); 
        end

        % Value changed function: is_ECG_avi
        function is_ECG_aviValueChanged(app, event)
            value = app.is_ECG_avi.Value;
            if value == "1"
                app.Load_ECG.Enable = "off";
            elseif value == "2"
                app.Load_ECG.Enable = "on";
                % msgbox(['注意，请在实验时采集与LFP数据等长、同步的心电数据。'])
                uialert(app.MainFigure,'注意，请在实验时采集与LFP数据等长、同步的心电数据。','提示','Icon','info')
            end
        end

        % Button pushed function: Load_ECG
        function Load_ECGButtonPushed(app, event)
            try
            [app.Filename_ISECG,path_ISECG] = uigetfile({'*.dat;*.mat;*.data'},'打开同步ECG文件');
            catch
            uialert(app.MainFigure,'导入失败','提示','Icon','info')
            end
            load([path_ISECG,app.Filename_ISECG]);
            % file_id = fopen(list(1).name);
        end

        % Menu selected function: AUTO_RESIZE
        function AUTO_RESIZESelected(app, event)

            if app.isautoresize == 1
            app.AUTO_RESIZE.Text = "控件大小自适应：关" ;
            app.MainFigure.Resize = "off";
%             app.MainFigure.AutoResizeChildren = "off";
            app.isautoresize = 0;
            elseif app.isautoresize == 0
            app.AUTO_RESIZE.Text = "控件大小自适应：开";
            app.MainFigure.Resize = "on";
%             app.MainFigure.AutoResizeChildren = "on";
            app.isautoresize = 1;
            end
            
        end

        % Button pushed function: detrend_confirm
        function detrend_confirmButtonPushed(app, event)
            app.Data_last_step = app.Data_now;
            value = app.chi_choose.Value;
            i = value;
            cla(app.axis_preprocessing_now)
            plot(app.axis_preprocessing_now,app.Data_last_step(str2double(i),2000:end));
            Data1 = app.Data_last_step(str2double(i),2000:end);
            cla(app.DATA_PSD)
            nfft = 5*app.data_fs;
            fs = app.data_fs;
            % noverlap = 0.5*fs;
            window = hanning(fs);
            [~,linemat1,f1] = P02_F2a_welch_calcu_power(app,Data1,nfft,fs,app.noverlap,window);
            cla(app.DATA_PSD)
            plot(app.DATA_PSD,f1,linemat1);
            legend(app.DATA_PSD,{'now'})
        end

        % Button pushed function: STMartifactremove_confirm_2
        function STMartifactremove_confirm_2ButtonPushed(app, event)
            
            app.Data_last_step = app.Data_now;
            value = app.chi_choose.Value;
            i = value;
            cla(app.axis_preprocessing_now)
            plot(app.axis_preprocessing_now,app.Data_last_step(str2double(i),2000:end));
            Data1 = app.Data_last_step(str2double(i),2000:end);
            cla(app.DATA_PSD)
            nfft = 5*app.data_fs;
            fs = app.data_fs;
            % noverlap = 0.5*fs;
            window = hanning(fs);
            [~,linemat1,f1] = P02_F2a_welch_calcu_power(app,Data1,nfft,fs,app.noverlap,window);
            cla(app.DATA_PSD)
            plot(app.DATA_PSD,f1,linemat1);
            legend(app.DATA_PSD,{'now'})

        end

        % Button pushed function: save_PSD_2File
        function save_PSD_2FileButtonPushed(app, event)
                values = app.out_put_select2.Value;
                origin_names = app.First_Filename(1:end-4);
                validFileName = regexprep(origin_names, '[\.\-\\/:*?"<>|]', '');
                saved_name = [validFileName,'PSD'];

                if isempty(app.RESULT_DATA_PSD_POWER) == 1 && isempty(app.RESULT_DATA_PSD_FREQ) == 1
                   % close(app.d)
                   % msgbox('请计算后再存储')
                   uialert(app.MainFigure,'请计算后再存储','提示','Icon','warning')
                else
                    try
                    power = app.RESULT_DATA_PSD_POWER;
                    logpower = 10*log10(power);
                    freq = app.RESULT_DATA_PSD_FREQ;
                    channel = app.Channel_Use_PSD;
                    if str2double(app.PSD_method.Value) == 1
                        meth = 'Welch';
                    elseif str2double(app.PSD_method.Value) == 2
                        meth = 'fft';
                    end
                    if str2double(values) == 2
                        Info = {'FFT_num_PSD:',app.FFT_Number.Value,'Overlap_PSD',app.overlap.Value,'Sample_Rate_PSD',app.Powercalcu_frequency.Value,'Meth_PSD',meth};
                        uisave({'power','logpower','freq','channel','Info'},saved_name)
                       app.waitting_now = uiprogressdlg(app.MainFigure,'Title','正在写入.mat文件',...
                       'Indeterminate','on');
                       drawnow

                       uialert(app.MainFigure,'操作完成','保存提示','Icon','success');
                    elseif str2double(values) == 1
                        [fileName, pathName] = uiputfile('*.xlsx', '存储为EXCEL格式', saved_name);
                       app.waitting_now = uiprogressdlg(app.MainFigure,'Title','正在写入excel文件',...
                       'Indeterminate','on');
                       drawnow
                        if isequal(fileName,0) || isequal(pathName,0)
                            uialert(app.MainFigure,'用户取消了选择','提示','Icon','info')
                        else
                            Info = {'FFT_num_PSD:',app.FFT_Number.Value,'Overlap_PSD',app.overlap.Value,'Sample_Rate_PSD',app.Powercalcu_frequency.Value,'Meth_PSD',meth};
                            fullPath = fullfile(pathName, fileName);
                            % 第一个 sheet 的数据
                            T1 = array2table(freq);
                            T2 = array2table(power);
                            T3 = array2table(logpower);
                            T4 = cell2table(Info);
                            T5 = cell2table(channel);
                            % 第二个 sheet 的数据
                            writetable([T1,T2], fullPath, 'Sheet', '通道能量');
                            % writetable(T2, fullPath, 'Sheet', 'ChannelPower');
                            writetable([T1,T3], fullPath, 'Sheet', '通道对数能量（db）');
                            writetable(T4, fullPath, 'Sheet', '计算方法');
                            writetable(T5, fullPath, 'Sheet', '通道');
                        end
                        uialert(app.MainFigure,['数据存储成功，位置：',pathName],'保存提示','Icon','success');

                    elseif str2double(values) == 3
                       [fileName, pathName] = uiputfile('*.txt', '存储为TXT格式', saved_name);
                       app.waitting_now = uiprogressdlg(app.MainFigure,'Title','正在写入.txt文件',...
                       'Indeterminate','on');
                       drawnow
                        if isequal(fileName,0) || isequal(pathName,0)
                            uialert(app.MainFigure,'用户取消了选择','提示','Icon','info')
                        else
                            Info = {'FFT_num_PSD',app.FFT_Number.Value,'Overlap_PSD',app.overlap.Value,'Sample_Rate_PSD',app.Powercalcu_frequency.Value,'Meth_PSD',meth};
                            fullPath = fullfile(pathName, fileName);
                            % 第一个 sheet 的数据
                            T1 = array2table(freq);
                            T2 = array2table(power);
                            % T3 = array2table(logpower);
                            T4 = cell2table(Info);
                            T5 = cell2table(channel);
                            % 第二个 sheet 的数据
                            TEMPS = table2array(T4);
                            Table_to_write1 = strjoin(TEMPS, ', ');
                            TEMPS = table2array(T5);
                            Table_to_write2 = strjoin(TEMPS, ', ');
                            Table_to_write3 = [T1,T2];
                            fileID = fopen(fullPath, 'w');
                            fprintf(fileID, '%s\n', ['方法：',Table_to_write1]);
                            fprintf(fileID, '%s\n', ['通道：',Table_to_write2]);
                            fprintf(fileID, '%s\n', '数据能量：');   
                            % 使用 writetable 将表格写入到文件，指定 'WriteMode' 为 'append' 以追加到文件
                            writetable(Table_to_write3, fullPath, 'Delimiter', '\t', 'WriteMode', 'append');
                            uialert(app.MainFigure,['数据存储成功，位置：',pathName],'保存提示','Icon','success');
                        end

                    end
                    catch
                       % msgbox('存储错误')
                       uialert(app.MainFigure,'存储失败','提示','Icon','warning')
                       close(app.waitting_now)
                    end
                    close(app.waitting_now)
                end
        end

        % Button pushed function: save_T_F_MAP_Workspace
        function save_T_F_MAP_WorkspaceButtonPushed(app, event)
        %   assignin('base',name,app.Data_now);
            assignin("base","RESULT_DATA_TIME_FREQUENCY_POWER",app.RESULT_DATA_TIME_FREQUENCY_POWER);
            assignin("base","RESULT_DATA_TIME_FREQUENCY_POWER_f",app.RESULT_DATA_TIME_FREQUENCY_POWER_f);
            uialert(app.MainFigure,'已发送（该功能在matlab授权运行时可用）','提示','Icon','info');
        end

        % Button pushed function: save_T_F_MAP
        function save_T_F_MAPButtonPushed(app, event)
                
                values = app.out_put_select3.Value;
                origin_names = app.First_Filename(1:end-4);
                validFileName = regexprep(origin_names, '[\.\-\\/:*?"<>|]', '');
                
                saved_name = [validFileName,'TIME_FREQ'];
                if  isempty(app.RESULT_DATA_TIME_FREQUENCY_POWER) == 1 && isempty(app.RESULT_DATA_TIME_FREQUENCY_POWER_f) == 1
                    % msgbox('请计算后再存储')
                    uialert(app.MainFigure,'请计算后再存储','提示','Icon','warning')
                else
                    power = abs(app.RESULT_DATA_TIME_FREQUENCY_POWER);
                    logpower = 10*log10(power);
                    freq = app.RESULT_DATA_TIME_FREQUENCY_POWER_f;
                    channel =  app.T_M_chi.Items(str2double(app.T_M_chi.Value));
                    times = app.RESULT_DATA_TIME_FREQUENCY_POWER_t;
                    
                    if str2double(app.TIME_FREQ_meth.Value) == 1
                        meth = 'STFT';
                    elseif str2double(app.TIME_FREQ_meth.Value) == 2
                        meth = 'CWT,Cmor';
                    end

                    if str2double(values) == 1
                       app.waitting_now = uiprogressdlg(app.MainFigure,'Title','正在写入.mat文件','Indeterminate','on');
                       Info = {'FFT_num_TF:',app.T_M_NFFT.Value,'Overlap_TF',app.T_M_overlap.Value,'Sample_Rate_TF',app.samplarate.Value,'Meth_TF',meth};
                       uisave({'power','logpower','times','freq','channel','Info'},saved_name)
                       drawnow
                       close(app.waitting_now)
                       % uialert(app.MainFigure,['数据存储成功'],'保存提示','Icon','success');
                    elseif str2double(values) == 2
                       if str2double(app.TIME_FREQ_meth.Value) == 1
                          try
                          app.waitting_now = uiprogressdlg(app.MainFigure,'Title','正在写入excel文件(通常写入过程较慢，请等待)',...
                          'Indeterminate','on');
                          drawnow
                          [fileName, pathName] = uiputfile('*.xlsx', '存储为EXCEL格式',saved_name);
                          if isequal(fileName,0) || isequal(pathName,0)
                              uialert(app.MainFigure,'用户取消了选择','提示','Icon','info')
                              close(app.waitting_now)
                              return
                          else
                              Info = {'FFT_num_TF:',app.T_M_NFFT.Value,'Overlap_TF',app.T_M_overlap.Value,'Sample_Rate_TF',app.samplarate.Value,'Meth_TF',meth};
                              fullPath = fullfile(pathName, fileName);
                              % 第一个 sheet 的数据
                              T1 = array2table(freq);
                              T2 = array2table(power);
                              T3 = array2table(times');
                              T4 = cell2table(Info);
                              T5 = cell2table(channel);
                 
                              temppppp = [T1,T2];
                                % 去掉T11 列名称
                                % remov = table2array(temppppp); 
                                % removed1 = array2table(remov); % 将数组转换回表格，但这时没有列名
                                
                                % 添加T2 第一列
                                timeColumn = "time";
                                Temp= addvars(T3,timeColumn, 'Before', 1, 'NewVariableNames', {'NewFirstColumn'});
                                
                                % 去掉Temp的列名称
                                columns = table2array(Temp); 
                                Tenped = array2table(columns); % 将数组转换回表格，但这时没有列名
                    
                                columns = table2array(temppppp); 
                                removed1 = array2table(columns); % 将数组转换回表格，但这时没有列名                                
                                ALL_mat_pow = [Tenped;removed1];

                              % 第二个 sheet 的数据
                              writetable(ALL_mat_pow, fullPath, 'Sheet', '能量');
                              % writetable(T2, fullPath, 'Sheet', 'Power');
                              writetable(T3, fullPath, 'Sheet', '时间');
                              writetable(T4, fullPath, 'Sheet', '计算参数');
                              writetable(T5, fullPath, 'Sheet', '通道信息');
                              uialert(app.MainFigure,['数据存储成功，位置：',pathName],'保存提示','Icon','success');
                          end
                          catch
                            % msgbox('计算结果文件过大，超出Excel限制，请选择Matlab存储格式')
                            uialert(app.MainFigure,'计算结果文件过大，超出Excel限制，请选择其他存储格式','提示','Icon','warning')
                            close(app.waitting_now)
                          end
                          close(app.waitting_now)
                       else
                           uialert(app.MainFigure,'小波变换结果较大，暂不支持.excel输出','提示','Icon','info')
                       end

                    elseif str2double(values) == 3
                       [fileName, pathName] = uiputfile('*.txt', '存储为TXT格式', saved_name);
                       app.waitting_now = uiprogressdlg(app.MainFigure,'Title','正在写入.txt文件',...
                       'Indeterminate','on');
                       drawnow
                        if isequal(fileName,0) || isequal(pathName,0)
                            uialert(app.MainFigure,'用户取消了选择','提示','Icon','info')
                            close(app.waitting_now)
                            return
                        else
                            Info = {'FFT_num_PSD',app.FFT_Number.Value,'Overlap_PSD',app.overlap.Value,'Sample_Rate_PSD',app.Powercalcu_frequency.Value,'Meth_PSD',meth};
                            fullPath = fullfile(pathName, fileName);
                            % 第一个 sheet 的数据
                              T1 = array2table(freq);
                              T2 = array2table(power);
                              T3 = array2table(times');
                              T4 = cell2table(Info);
                              T5 = cell2table(channel);
                            % 第二个 sheet 的数据
                            TEMPS = table2array(T4);
                            Table_to_write1 = strjoin(TEMPS, ', ');
                            TEMPS = table2array(T5);
                            Table_to_write2 = strjoin(TEMPS, ', ');
                             % Table_to_write3 = [T1,T2];
                             % assignin("base","T1",T1)
                             % assignin("base","T2",T2)
                            temppppp = [T1,T2];
                                
                                % 添加T2 第一列
                                timeColumn = "time";
                                Temp= addvars(T3,timeColumn, 'Before', 1, 'NewVariableNames', {'NewFirstColumn'});
                                
                                % 去掉Temp的列名称
                                columns = table2array(Temp); 
                                Tenped = array2table(columns); % 将数组转换回表格，但这时没有列名
                                columns = table2array(temppppp); 
                                removed1 = array2table(columns); % 将数组转换回表格，但这时没有列名                                
                                Table_to_write3 = [Tenped;removed1];

                            fileID = fopen(fullPath, 'w');
                            fprintf(fileID, '%s\n', ['方法：',Table_to_write1]);
                            fprintf(fileID, '%s\n', ['通道：',Table_to_write2]);
                            fprintf(fileID, '%s\n', '数据能量：');
                            % 使用 writetable 将表格写入到文件，指定 'WriteMode' 为 'append' 以追加到文件
                            writetable(Table_to_write3, fullPath, 'Delimiter', ',', 'WriteMode', 'append');
                            close(app.waitting_now)
                            uialert(app.MainFigure,['数据存储成功，位置：',pathName],'保存提示','Icon','success');
                        end
                    end
                end


        end

        % Value changed function: InseartNAN
        function InseartNANValueChanged(app, event)
            value = app.InseartNAN.Value;
            if value ==1
               app.IsaddNans = 1;
            else
               app.IsaddNans = 0;
            end
        end

        % Value changing function: Slider_PSD
        function Slider_PSDValueChanging(app, event)
            value = event.Value;
            try
            app.PSD_figure.XLim = value;
            catch
               % msg = ('请重新选择范围');
               % uialert(app.Phase_Value_analisis,msg,'提示','Icon','warning')
               % msgbox(msg)
            end
        end

        % Value changing function: Time_range_Slide
        function Time_range_SlideValueChanging(app, event)
            changingValue = event.Value;
            try
            app.TIME_FREQUENCY.XLim = changingValue;
            catch
               % msg = ('请重新选择范围');
               % uialert(app.Phase_Value_analisis,msg,'提示','Icon','warning')
            end
        end

        % Value changing function: Slider_Plot
        function Slider_PlotValueChanging(app, event)
            changingValue = event.Value;
            try
            app.axis_preprocessing_now.XLim = changingValue;
            catch
               % msg = ('请重新选择范围');
               % uialert(app.Phase_Value_analisis,msg,'提示','Icon','warning')
            end
        end

        % Value changing function: Y_range_Slide
        function Y_range_SlideValueChanging(app, event)
            changingValue = event.Value;
            try
            app.TIME_FREQUENCY.YLim = changingValue;
            catch
               % msg = ('请重新选择范围');
               % uialert(app.Phase_Value_analisis,msg,'提示','Icon','warning')
            end
        end

        % Value changing function: Color_range_Slide
        function Color_range_SlideValueChanging(app, event)
            changingValue = event.Value;
          % value = app.Color_range_Slide.Value;
            try
             app.TIME_FREQUENCY.CLim = changingValue;
            catch
             % msg = ('请重新选择范围');
             % uialert(app.Phase_Value_analisis,msg,'提示','Icon','warning')
            end
        end

        % Button pushed function: Beta_Peakfind
        function Beta_PeakfindButtonPushed(app, event)
        % Display progress dialog for correlation calculation
        app.waitting_now = uiprogressdlg(app.MainFigure,'Title','计算相关性中',...
            'Indeterminate','on');
        drawnow
        
        % Execute PSD plotting function
        Plot_PSDButtonPushed(app, event);
        
        % Calculate frequency resolution (Hz per bin)
        Resolu = str2double(app.FFT_Number.Value)/str2double(app.Powercalcu_frequency.Value);
        
        try
            % Perform simple beta peak identification
            peakss = [];
            
            % Process each selected channel
            for i = 1:length(app.chi_calcu.Value)
                % Extract frequency data in 5-40Hz range
                x = app.RESULT_DATA_PSD_FREQ(round(Resolu*5):round(Resolu*40)); % Independent variable (frequency)
                
                % Extract power data based on plot type (logarithmic or linear)
                if app.Is_Log_Plot.Value == 1
                    y = 10*log10(app.RESULT_DATA_PSD_POWER(round(Resolu*5):round(Resolu*40),i)); % Log scale power data
                elseif app.Is_Log_Plot.Value == 0
                    y = (app.RESULT_DATA_PSD_POWER(round(Resolu*5):round(Resolu*40),i)); % Linear scale power data
                end
                
                % Note: Number of bins per 1Hz = sampling_frequency / FFT_points
                
                % Define custom fitting function: a + b/(c+x) for 1/f background removal
                fitType = fittype(@(a,b,c,x) a + b./(c+x), ...
                    'independent', 'x', ...
                    'dependent', 'y');
                
                % Perform curve fitting with initial parameter guess
                [fitResult, ~] = fit(x, y, fitType, 'StartPoint', [1,1,1]);
                
                % Extract fitted parameters
                a = fitResult.a;
                b = fitResult.b;
                c = fitResult.c;
                
                % Calculate fitted curve values
                xs = x;
                ys = a+b./(c+xs);
                
                % Calculate residuals (Original - Curve Index, OCI)
                OCI = y-ys;
                
                % Focus on beta frequency range (13-35 Hz) for peak detection
                idx_freq = (x >= 13 & x <= 35);
                OCI_FIND = OCI(idx_freq);        % Residuals in beta range
                OCI_XDATA = x(idx_freq);         % Frequency values in beta range
                ORI_OCI = y(idx_freq);           % Original power values in beta range
                
                % Find peaks in the residual signal
                [PKS,LOCS] = findpeaks(OCI_FIND);
                [~,IDX] = max(PKS);              % Find the highest peak
                
                % Handle case when no peaks are found
                if isempty(PKS)
                    [~,IDX] = max(y);            % Use maximum of original signal
                    LOCS = x(IDX+13);            % Adjust location index
                end
                
                % Extract peak location and amplitude
                maxpeak_locs = OCI_XDATA(LOCS(IDX));  % Peak frequency
                maxpeak_pks = ORI_OCI(LOCS(IDX));     % Peak power value
                
                % Plot peak marker on PSD figure
                app.PSD_figure.NextPlot = 'add';
                plot(app.PSD_figure, maxpeak_locs, maxpeak_pks, 'o', ...
                    'MarkerSize', 8, 'MarkerEdgeColor', 'b', 'MarkerFaceColor', 'b', ...
                    'HandleVisibility', 'off');
                app.PSD_figure.NextPlot = 'add';
                
                % Store peak information for display
                peakss = [peakss, [app.chi_calcu.Items{str2double(app.chi_calcu.Value(i))}, ':', ...
                          num2str(maxpeak_locs), 'Hz ']];
            end
            
            % Close progress dialog
            close(app.waitting_now);
            
            % Display results message
            message = sprintf(['β震荡峰值频率识别：\n', num2str(peakss), ...
                              ' \n 注： 结果由 1/f 以上部分拟合决定。']);
            uiconfirm(app.MainFigure, message, 'β震荡峰值识别结果', 'Icon', 'success');
            
        catch
            % Error handling: display alert and close progress dialog
            uialert(app.MainFigure, '未能识别beta峰值，请检查PSD绘图结果', '错误', 'Icon', 'info');
            close(app.waitting_now);
        end
        end

        % Double-clicked callback: UITable
        function UITableDoubleClicked(app, event)
            Row = event.InteractionInformation.DisplayRow;
            % displayColumn = event.InteractionInformation.DisplayColumn;
            % item = app.UITable.Data(displayRow);
            % assignin("base","UITABLE",app.UITable.Data)
            % assignin("base","Row",Row)
            if Row <= height(app.UITable.Data)
            % app.filename.Value = 
               if istable(app.UITable.Data)
                  app.First_Filename = cell2mat(app.UITable.Data{Row,1});
               else
                  app.First_Filename = cell2mat(app.UITable.Data);
               end
            data_configPushed(app, event)
            else
            end

        end

        % Clicked callback: UITable
        function UITableClicked(app, event)
            Row = event.InteractionInformation.DisplayRow;
            % assignin("base","datas",app.UITable.Data)
            if Row <= height(app.UITable.Data)
               if istable(app.UITable.Data)
                  app.First_Filename = cell2mat(app.UITable.Data{Row,1});
               else
                  app.First_Filename = cell2mat(app.UITable.Data);
               end
            else
            end
        end

        % Button pushed function: More_info
        function More_infoButtonPushed(app, event)
            % assignin('base','Dealtiled_para',app.Dealtiled_para)
            try
            uialert(app.MainFigure,app.Dealtiled_para,'详细信息','Icon','none')
            catch
                uialert(app.MainFigure,'未正确获取信息','详细信息','Icon','none')
            end
            % confirm_i = uiconfirm(app.MainFigure,'确认伪迹已去除','确认对话框','Icon','warning');
        end

        % Value changing function: PSD_YSlider
        function PSD_YSliderValueChanging(app, event)
            value = event.Value;
            % value = event.Value;
            try
            app.PSD_figure.YLim = value;
            catch
               % msg = ('请重新选择范围');
               % uialert(app.Phase_Value_analisis,msg,'提示','Icon','warning')
               % msgbox(msg)
            end
        end

        % Value changing function: Range_freq
        function Range_freqValueChanging(app, event)
               % changingValue = event.Value;
               value = event.Value;
            try
               app.DATA_PSD.XLim = value;
            catch
               % msg = ('请重新选择范围');
               % uialert(app.Phase_Value_analisis,msg,'提示','Icon','warning')
               % msgbox(msg)
            end
        end

        % Button pushed function: Comparation_TOOL
        function Comparation_TOOLButtonPushed(app, event)
            
            % 打开App B
            app_anylysis_result_compare = APP_anylysis_result_compare;
            app_anylysis_result_compare.APP_anylysis_CHI_0_1 = app; % 将App A传递给App B以便回调
            
            % 显示模态对话框
            uialert(app.MainFigure,{'正在打开特征对比工具...';'请在对比工具中继续进行'},'提示','Icon','none')
            % 将对话框句柄保存为属性或全局变量
            % app.Dialog = d;

        end

        % Menu selected function: Menu_data_Compare
        function Menu_data_CompareSelected(app, event)
            app.Comparation_TOOLButtonPushed
        end

        % Value changed function: PSD_method
        function PSD_methodValueChanged(app, event)
            value = app.PSD_method.Value;
            if value == "1"
                app.FFT_Number.Enable = "on";
                app.overlap.Enable = "on";
                app.Powercalcu_frequency.Enable = "on";
                app.Window_length.Enable = "on";
                app.FFT_Number.Value = num2str(2*(app.data_fs));
               if length(app.Data_now) <= 60*app.data_fs*5
                   app.overlap.Value = num2str(round(app.data_fs*0.75));
               else
                   app.overlap.Value = num2str(round(app.data_fs*0.25));
               end
               app.Powercalcu_frequency.Value = num2str(app.data_fs);
               app.Window_length.Value = num2str(app.data_fs);
            else
               app.Window_length.Enable = "on";
               app.Window_length.Value = num2str(length(app.Data_now(1,:)));
               app.overlap.Value = num2str(0);
               app.overlap.Enable = "off";
               app.FFT_Number.Enable = "on";
               app.FFT_Number.Value = num2str(2^nextpow2(length(app.Data_now(1,:))));
               app.Powercalcu_frequency.Value = num2str(app.data_fs);
            end
        end

        % Menu selected function: Menu_batch_Processing
        function Menu_batch_ProcessingSelected(app, event)
            % app.Batch_Proceccing.Enable = 'off';
            filter1 = app.filter_design_highpass;
            filter2 = app.filter_design_artifact;

            if exist('filter1', 'var') ~= 1
                filter1 = []; % 或者为其分配默认值
            end
            if exist('filter2', 'var') ~= 1
                filter2 = []; % 或者为其分配默认值
            end

            app.Dialog = uiprogressdlg(app.MainFigure,'Title','请在裁剪工具箱中继续操作',...
            'Indeterminate','on');

            app.app_anylysis_Data_batch_CHI = APP_anylysis_Data_batch_CHI(app,filter1,filter2);
            close(app.waitting_now)
        end

        % Button pushed function: Comparation_TOOL_2
        function Comparation_TOOL_2ButtonPushed(app, event)
            % 打开App B
            app_anylysis_result_compare = APP_anylysis_result_compare;
            app_anylysis_result_compare.APP_anylysis_CHI_0_1 = app; % 将App A传递给App B以便回调
            uialert(app.MainFigure,{'正在打开特征对比工具...';'请在对比工具中继续进行'},'提示','Icon','none')
            % 显示模态对话框
            % d = dialog('Position',[300 300 250 150],'Name','My Dialog');
            % txt = uicontrol('Parent',d,...
                % 'Style','text',...
                % 'Position',[20 80 210 40],...
                % 'String','在对照工具箱中继续操作...');
            % app.Dialog = uiprogressdlg(app.MainFigure,'Title','请在对照工具箱中继续操作',...
            % 'Indeterminate','on');
            % 将对话框句柄保存为属性或全局变量
            % app.Dialog = d;
        end

        % Button pushed function: Batch_Procecces
        function Batch_ProceccesButtonPushed(app, event)
            
            filter1 = app.filter_design_highpass;
            filter2 = app.filter_design_artifact;

            if exist('filter1', 'var') ~= 1
                filter1 = []; % 或者为其分配默认值
            end
            if exist('filter2', 'var') ~= 1
                filter2 = []; % 或者为其分配默认值
            end

            % app.Dialog = uiprogressdlg(app.MainFigure,'Title','请在批处理工具箱中继续操作',...
            % 'Indeterminate','on');

            uialert(app.MainFigure,{'正在打开批处理工具...';'请在批处理工具中继续进行'},'提示','Icon','none')
            app.app_anylysis_Data_batch_CHI = APP_anylysis_Data_batch_CHI(app,filter1,filter2);

            % close(app.waitting_now)

        end

        % Value changed function: Is_Log_Plot
        function Is_Log_PlotValueChanged(app, event)
            value = app.Is_Log_Plot.Value;
            if value == 1
               app.DATA_PSD.YLabel.String = "能量(dB/Hz)";
               % app.PSD_figure.YLabel.String = "能量(dB/Hz)";
               app.PSD_figure.YLabel.String = "Power(dB/Hz)";
               app.PSD_YSlider.Limits = [-60,60];
            elseif value == 0
                app.DATA_PSD.YLabel.String = "能量(uv^{2}/Hz)";
                app.PSD_figure.YLabel.String = "Power(uv^{2}/Hz)";
                app.PSD_YSlider.Limits = [0,1];
            end
        end

        % Menu selected function: Data_copy
        function Data_copySelected(app, event)
            % Display progress dialog for folder selection
            app.waitting_now = uiprogressdlg(app.MainFigure,'Title','请选择检索文件夹与目标文件夹',...
                'Indeterminate','on');
            drawnow
            
            % Source folder selection dialog
            source_folder = uigetdir('', '选择目标文件夹1');
            if isequal(source_folder, 0)
                close(app.waitting_now)
                return; % Exit if user cancels selection
            end
            
            % Target folder selection dialog
            target_folder = uigetdir('', '选择目标文件夹2');
            if isequal(target_folder, 0)
                close(app.waitting_now)
                return; % Exit if user cancels selection
            end
            
            % Create confirmation window
            % Get screen dimensions for centering
            screenSize = get(0, 'ScreenSize'); % [left, bottom, width, height]
            
            % Define confirmation window dimensions
            windowWidth = 700;
            windowHeight = 200;
            
            % Calculate center position coordinates
            xPos = (screenSize(3) - windowWidth) / 2;
            yPos = (screenSize(4) - windowHeight) / 2;
            
            % Create centered confirmation dialog window
            confirmDlg = uifigure('Name', '确认文件夹与格式', ...
                'Position', [xPos, yPos, windowWidth, windowHeight]);
            
            % Display source folder path label
            uilabel(confirmDlg, 'Position', [20 160 600 30], ...
                'Text', ['检索文件夹: ', source_folder]);
            
            % Display target folder path label
            uilabel(confirmDlg, 'Position', [20 130 600 30], ...
                'Text', ['目标文件夹: ', target_folder]);
            
            % Create file format dropdown menu with .data as default
            fileFormatDropDown = uidropdown(confirmDlg, 'Position', [20 90 300 30], ...
                'Items', {'.data', '.dat', '.txt', '.csv', '自定义'}, ...
                'Value', '.data'); % Default format set to .data
            
            % Create custom format input field (initially hidden)
            customFormatEditField = uieditfield(confirmDlg, 'text', ...
                'Position', [180 100 100 30], 'Visible', 'off');
            
            % Set callback function for dropdown selection changes
            % Shows custom input field when "自定义" (Custom) is selected
            fileFormatDropDown.ValueChangedFcn = @(src, event) P02_handleCustomFormatSelection(app, ...
                fileFormatDropDown, customFormatEditField);
            
            % Create confirmation button with callback
            confirmButton = uibutton(confirmDlg, 'push', ...
                'Position', [20 50 150 30], 'Text', '确认', ...
                'ButtonPushedFcn', @(btn, event) P02_confirmSelection(app, confirmDlg, ...
                source_folder, target_folder, fileFormatDropDown, customFormatEditField));
            
            % Close progress dialog
            close(app.waitting_now)
        end

        % Button pushed function: Plot_Changes
        function Plot_ChangesButtonPushed(app, event)
            try
                % Extract frequency and power data from time-frequency analysis results
                freq = app.RESULT_DATA_TIME_FREQUENCY_POWER_f;
                logpower = 10*log10(abs(app.RESULT_DATA_TIME_FREQUENCY_POWER));
                
                % Define frequency band ranges (Hz)
                theta_range = [4 8];    % Theta band (4-8 Hz)
                alpha_range = [8 12];   % Alpha band (8-12 Hz)
                beta_range = [13 30];   % Beta band (13-30 Hz)
                gamma_range = [30 70];  % Gamma band (30-70 Hz)
                
                % Find frequency indices for each band
                theta_idx = find(freq >= theta_range(1) & freq <= theta_range(2));
                alpha_idx = find(freq >= alpha_range(1) & freq <= alpha_range(2));
                beta_idx = find(freq >= beta_range(1) & freq <= beta_range(2));
                gamma_idx = find(freq >= gamma_range(1) & freq <= gamma_range(2));
                
                % Calculate average power for each frequency band across time
                theta_power = mean(logpower(theta_idx, :), 1);
                alpha_power = mean(logpower(alpha_idx, :), 1);
                beta_power = mean(logpower(beta_idx, :), 1);
                gamma_power = mean(logpower(gamma_idx, :), 1);
                
                % Data normalization using min-max scaling (0-1 range)
                theta_power_norm = (theta_power - min(theta_power)) / (max(theta_power) - min(theta_power));
                alpha_power_norm = (alpha_power - min(alpha_power)) / (max(alpha_power) - min(alpha_power));
                beta_power_norm = (beta_power - min(beta_power)) / (max(beta_power) - min(beta_power));
                gamma_power_norm = (gamma_power - min(gamma_power)) / (max(gamma_power) - min(gamma_power));
                
                % Apply smoothing filter (span = 8) to reduce noise
                theta_power_smooth = smooth(theta_power_norm, 8);
                alpha_power_smooth = smooth(alpha_power_norm, 8);
                beta_power_smooth = smooth(beta_power_norm, 8);
                gamma_power_smooth = smooth(gamma_power_norm, 8);
                
                % Prepare time axis for plotting
                time_points = app.RESULT_DATA_TIME_FREQUENCY_POWER_t;
                
                % Create new figure for frequency band visualization
                figure;
                
                % Set figure background color
                set(gcf, 'Color', [0.93 0.93 0.96]);
                
                % Set axes background color
                set(gca, 'Color', [0.93 0.93 0.96]);
                
                % Plot Beta and Gamma bands first (using MATLAB default colors, opaque)
                plot(time_points, beta_power_smooth, 'LineWidth', 2, 'DisplayName', 'Beta (13-30 Hz)');
                hold on;
                plot(time_points, gamma_power_smooth, 'LineWidth', 2, 'DisplayName', 'Gamma (30-70 Hz)');
                
                % Plot Theta and Alpha bands with transparency (alpha = 0.3)
                plot(time_points, theta_power_smooth, 'g-', 'LineWidth', 2, ...
                     'DisplayName', 'Theta (4-8 Hz)', 'Color', [0 1 0 0.3]);
                plot(time_points, alpha_power_smooth, 'y-', 'LineWidth', 2, ...
                     'DisplayName', 'Alpha (8-12 Hz)', 'Color', [1 1 0 0.3]);
                hold off;
                
                % Configure plot appearance
                % Add legend and labels
                legend('Location', 'northeast', 'FontSize', 8);
                xlabel('时间 (s)');
                ylabel('能量 (a.u.)');
                title('Smoothed and Normalized Energy Fluctuations of Beta, Gamma, Theta, and Alpha Bands');
                
                % Customize axes appearance
                set(gca, 'XTick', []);              % Remove X-axis tick marks
                set(gca, 'Box', 'off');             % Remove outer border lines
                set(gca, 'FontSize', 12);           % Set font size
                grid off;                           % Turn off grid lines
                
                % Ensure consistent background colors
                set(gca, 'Color', [0.93, 0.93, 0.96]); % Set axes background color
                set(gcf, 'Color', [0.93, 0.93, 0.96]); % Set figure background color
                
            end
        end

        % Menu selected function: Defult
        function DefultMenuSelected(app, event)
            app.Set_Theme_Color.Text = "设置主题颜色：默认";
            if app.Set_Theme_Color.Text == "设置主题颜色：默认" 
               INPUT = 1;
            end
            P02_change_color_platform(app,INPUT)
        end

        % Menu selected function: White
        function WhiteMenuSelected(app, event)
            app.Set_Theme_Color.Text = "设置主题颜色：白色";
            if app.Set_Theme_Color.Text == "设置主题颜色：白色" 
               INPUT = 2;
            end
            P02_change_color_platform(app,INPUT)
        end

        % Menu selected function: Black
        function BlackMenuSelected(app, event)
            app.Set_Theme_Color.Text = "设置主题颜色：黑色";
            if app.Set_Theme_Color.Text == "设置主题颜色：黑色" 
               INPUT = 3;
            end
            P02_change_color_platform(app,INPUT)
        end

        % Menu selected function: Menu_current_INFO
        function Menu_current_INFOSelected(app, event)
            app.More_infoButtonPushed
        end

        % Menu selected function: Clear_all_set
        function Clear_all_setMenuSelected(app, event)
            % Check if primary settings file exists
            primaryFileExists = ~isempty(app.SettingsFile) && exist(app.SettingsFile, 'file');
            
            % Define backup settings file path in user Documents folder
            userDocsDir = fullfile(getenv('USERPROFILE'), 'Documents');
            backupFileName = 'YourAppName_settings_backup.mat'; % Assumed backup filename
            potentialBackupFile = fullfile(userDocsDir, backupFileName);
            backupFileExists = exist(potentialBackupFile, 'file');
            
            % Reset all related controls to their default values
            applyDefaultSettings(app);
            
            % Early return if no settings files exist
            if ~primaryFileExists && ~backupFileExists
                uialert(app.MainFigure, '已恢复预设项，未检测到预设文件。', '提示', 'Icon', 'info');
                return;
            end
            
            % Show confirmation dialog for destructive operation
            response = uiconfirm(app.MainFigure, ...
                '您确定要清除所有已保存的预设并恢复到默认设置吗？此操作不可撤销。', ...
                '确认清除预设', ...
                'Options', {'是，清除预设', '否，取消'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');
            
            % Process user confirmation
            if strcmp(response, '是，清除预设')
                filesDeleted = false;
                
                % Delete primary settings file
                if primaryFileExists
                    try
                        delete(app.SettingsFile);
                        if ~isdeployed
                            disp(['预设文件已删除: ' app.SettingsFile]);
                        end
                        filesDeleted = true;
                    catch
                        warning('删除主预设文件 "%s" 失败: %s');
                        uialert(app.MainFigure, '删除预设文件 "%s" 失败: %s', '错误', 'Icon', 'error');
                        % Continue execution even if primary file deletion fails
                    end
                end
                
                % Delete backup settings file (if applicable)
                if backupFileExists
                    try
                        delete(potentialBackupFile);
                        if ~isdeployed
                            disp(['备用预设文件已删除: ' potentialBackupFile]);
                        end
                        filesDeleted = true;
                    catch
                        warning('删除备用预设文件 "%s" 失败: %s');
                        % Continue execution even if backup file deletion fails
                    end
                end
                
                % Display operation result to user
                if filesDeleted
                    uialert(app.MainFigure, '所有已保存的预设已清除，相关设置已恢复为默认值。', ...
                           '操作成功', 'Icon', 'success');
                else
                    % Files didn't exist but user still clicked clear, and we executed applyDefaultSettings
                    uialert(app.MainFigure, '设置已恢复为默认值。未找到预设文件。', ...
                           '操作完成', 'Icon', 'info');
                end
                
            else
                % User cancelled the operation
                if ~isdeployed
                    disp('清除预设操作已取消。');
                end
            end
        end

        % Menu selected function: PercptPCMenu
        function PercptPCMenuSelected(app, event)
            % Converts Medtronic JSON files to MAT format compatible with specific system
            % Data format: [channels x samples]
            
            try
                % 1. File selection dialog
                [filename, pathname] = uigetfile({'*.json', 'JSON Files (*.json)'}, ...
                    'Select Medtronic JSON File');
                
                % Check if user cancelled file selection
                if isequal(filename, 0)
                    return; % User cancelled, return directly
                end
                
                input_json_file = fullfile(pathname, filename);
                
                % 2. Select output folder
                output_folder = uigetdir(pathname, 'Select Output Folder');
                if isequal(output_folder, 0)
                    return; % User cancelled, return directly
                end
                
                % 3. Show progress dialog
                progress_dlg = uiprogressdlg(app.MainFigure, 'Title', 'Conversion Progress', ...
                    'Message', 'Reading JSON file...', 'Indeterminate', 'on');
                
                % 4. Start conversion processing
                % Default fill values
                default_stim_info = 0;
                default_stim_onoff = 0;
                default_stim_electrode = 0;
                default_stim_frequency = 0;
                
                % Update progress
                progress_dlg.Message = 'Reading JSON file...';
                pause(0.1); % Allow UI update
                
                % Read JSON file
                fid = fopen(input_json_file, 'r');
                if fid == -1
                    close(progress_dlg);
                    uialert(app.MainFigure, ['Cannot open file: ', input_json_file], 'File Error', 'Icon', 'error');
                    return;
                end
                raw = fread(fid, inf);
                fclose(fid);
                jsonStr = char(raw');
                jsonData = jsondecode(jsonStr);
                
                % Check data structure
                progress_dlg.Message = 'Checking data structure...';
                pause(0.1);
                
                if ~isfield(jsonData, 'BrainSenseTimeDomain') || isempty(jsonData.BrainSenseTimeDomain)
                    close(progress_dlg);
                    uialert(app.MainFigure, 'BrainSenseTimeDomain data not found', 'Data Error', 'Icon', 'error');
                    return;
                end
                
                senseData = jsonData.BrainSenseTimeDomain;
                
                % Organize data by timestamp
                progress_dlg.Message = 'Organizing data...';
                pause(0.1);
                
                timestamps = unique({senseData.FirstPacketDateTime});
                
                % Create output folder
                if ~exist(output_folder, 'dir')
                    mkdir(output_folder);
                end
                
                % Process each timestamp data
                [~, base_name, ~] = fileparts(input_json_file);
                processed_files = {};
                total_channels = 0;
                
                % Set deterministic progress
                progress_dlg.Indeterminate = 'off';
                progress_dlg.Value = 0;
                
                for t = 1:length(timestamps)
                    current_timestamp = timestamps{t};
                    progress_value = t / length(timestamps);
                    progress_dlg.Value = progress_value;
                    progress_dlg.Message = sprintf('Processing timestamp %d/%d: %s', t, length(timestamps), current_timestamp);
                    pause(0.1);
                    
                    % Filter current timestamp data
                    mask = strcmp({senseData.FirstPacketDateTime}, current_timestamp);
                    current_entries = senseData(mask);
                    
                    % Extract data
                    channels = {current_entries.Channel};
                    sample_rates = [current_entries.SampleRateInHz];
                    
                    % Build data matrix
                    all_data = {};
                    for i = 1:length(current_entries)
                        all_data{i} = current_entries(i).TimeDomainData(:); % Ensure column vector
                    end
                    
                    % Unify data length (take shortest)
                    data_lengths = cellfun(@length, all_data);
                    min_length = min(data_lengths);
                    
                    % Assemble data matrix [channels x samples] - match system format
                    DATA_ori = zeros(length(all_data), min_length);
                    for i = 1:length(all_data)
                        DATA_ori(i, :) = all_data{i}(1:min_length)';
                    end
                    
                    Data_Processed = DATA_ori; % Assume processed data same as original
                    samplerate = sample_rates(1); % Assume all channels have same sample rate
                    
                    % Build save data structure
                    data_saved_time = datestr(now, 'yyyy-mm-dd_HH-MM-SS');
                    
                    if length(channels) >= 2
                        % Full format
                        chi_info = {'DATA_ori','Data_Processed','samplerate','stim_info','acq_info',...
                                   'Stim_onoff_info','stim_electrode_info','stim_frequency_info',...
                                   'recoding_parameter_info','Record_Channel','ori_dataname',...
                                   'processdate','save_type'};
                        
                        % Build acq_info
                        acq_info_struct = struct();
                        acq_info_struct.channels = channels;
                        acq_info_struct.samplerate = samplerate;
                        acq_info_struct.timestamp = current_timestamp;
                        acq_info_struct.data_length = min_length;
                        
                        % Build recording parameter info (channel information)
                        rec_param_info = channels; % Use channel name list directly, match your system
                        
                        data = {DATA_ori, Data_Processed, samplerate, default_stim_info, acq_info_struct,...
                               default_stim_onoff, default_stim_electrode, default_stim_frequency,...
                               rec_param_info, channels, base_name, data_saved_time, 'full'};
                    else
                        % Simplified format
                        chi_info = {'DATA_ori','Data_Processed','samplerate','save_time','save_type'};
                        data = {DATA_ori, Data_Processed, samplerate, data_saved_time, 'only_data'};
                    end
                    
                    % Save file - only save data variable to match your reading function
                    timestamp_clean = regexprep(current_timestamp, '[: ]', '_');
                    output_filename = sprintf('%s_timestamp_%d_%s.mat', base_name, t, timestamp_clean);
                    output_path = fullfile(output_folder, output_filename);
                    save(output_path, 'data'); % Only save data variable, match your system reading method
                    
                    % Record processing results
                    processed_files{end+1} = struct(...
                        'filename', output_filename, ...
                        'channels', length(channels), ...
                        'samplerate', samplerate, ...
                        'data_length', min_length, ...
                        'channel_names', {channels});
                    
                    total_channels = total_channels + length(channels);
                end
                
                % 5. Close progress dialog
                close(progress_dlg);
                
                % 6. Show conversion results
                % Create result display string
                result_text = sprintf(['转换成功！\n\n' ...
                    'Input File: %s\n' ...
                    'Output Folder: %s\n\n' ...
                    'Processing Statistics:\n' ...
                    '• Number of Timestamps: %d\n' ...
                    '• Generated Files: %d\n' ...
                    '• Total Channels: %d\n\n' ...
                    'Generated Files:\n'], ...
                    input_json_file, ...
                    output_folder, ...
                    length(timestamps), ...
                    length(processed_files), ...
                    total_channels);
                
                % Add file details
                for i = 1:min(length(processed_files), 5) % Show max 5 files
                    file_info = processed_files{i};
                    result_text = sprintf('%s• %s (%d channels, %dHz, %d samples)\n', ...
                        result_text, file_info.filename, file_info.channels, ...
                        file_info.samplerate, file_info.data_length);
                end
                
                if length(processed_files) > 5
                    result_text = sprintf('%s... and %d more files\n', ...
                        result_text, length(processed_files) - 5);
                end
                
                % Show result dialog
                selection = uiconfirm(app.MainFigure, result_text, 'Conversion Successful', ...
                    'Options', {'Open Output Folder', 'OK'}, ...
                    'DefaultOption', 2, ...
                    'Icon', 'success');
                
                % If user chooses to open folder
                if strcmp(selection, 'Open Output Folder')
                    if ispc
                        winopen(output_folder);
                    elseif ismac
                        system(['open "' output_folder '"']);
                    else
                        system(['xdg-open "' output_folder '"']);
                    end
                end
                
            catch ME
                % Exception handling
                if exist('progress_dlg', 'var') && isvalid(progress_dlg)
                    close(progress_dlg);
                end
                uialert(app.Mainfigure, ['Error during conversion: ', ME.message], 'Error', 'Icon', 'error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MainFigure = uifigure('Visible', 'off');
            app.MainFigure.Name = 'NERCN_TOOLBOX App';
            app.MainFigure.Icon = fullfile(pathToMLAPP, 'icon_48.png');
            app.MainFigure.CloseRequestFcn = createCallbackFcn(app, @MainFigureCloseRequest, true);
            app.Menu_main = uimenu(app.MainFigure);
            app.Menu_main.Text = '菜单/Menu';
            app.Menu_7 = uimenu(app.Menu_main);
            app.Menu_7.MenuSelectedFcn = createCallbackFcn(app, @Menu_7Selected, true);
            app.Menu_7.Text = '启动菜单';
            app.RSMenu = uimenu(app.Menu_main);
            app.RSMenu.MenuSelectedFcn = createCallbackFcn(app, @RSMenuSelected, true);
            app.RSMenu.Text = '106RS在线分析系统';
            app.LFPMenu = uimenu(app.Menu_main);
            app.LFPMenu.Enable = 'off';
            app.LFPMenu.Text = 'LFP离线分析平台';
            app.allage = uimenu(app.Menu_main);
            app.allage.Enable = 'off';
            app.allage.Text = 'LFP临床研究分析工具';
            app.AUTO_RESIZE = uimenu(app.Menu_main);
            app.AUTO_RESIZE.MenuSelectedFcn = createCallbackFcn(app, @AUTO_RESIZESelected, true);
            app.AUTO_RESIZE.Text = '控件大小自适应：关';
            app.Menu_exit = uimenu(app.Menu_main);
            app.Menu_exit.MenuSelectedFcn = createCallbackFcn(app, @Menu_exitSelected, true);
            app.Menu_exit.Text = '退出';
            app.Menu_functions = uimenu(app.MainFigure);
            app.Menu_functions.Text = '功能/Func';
            app.Menu_current_INFO = uimenu(app.Menu_functions);
            app.Menu_current_INFO.MenuSelectedFcn = createCallbackFcn(app, @Menu_current_INFOSelected, true);
            app.Menu_current_INFO.Text = '当前数据信息';
            app.Menu_data_CUT = uimenu(app.Menu_functions);
            app.Menu_data_CUT.Enable = 'off';
            app.Menu_data_CUT.Text = '数据裁剪工具';
            app.Menu_batch_Processing = uimenu(app.Menu_functions);
            app.Menu_batch_Processing.MenuSelectedFcn = createCallbackFcn(app, @Menu_batch_ProcessingSelected, true);
            app.Menu_batch_Processing.Text = '批处理工具';
            app.Menu_data_Compare = uimenu(app.Menu_functions);
            app.Menu_data_Compare.MenuSelectedFcn = createCallbackFcn(app, @Menu_data_CompareSelected, true);
            app.Menu_data_Compare.Text = '数据对照工具';
            app.Data_copy = uimenu(app.Menu_functions);
            app.Data_copy.MenuSelectedFcn = createCallbackFcn(app, @Data_copySelected, true);
            app.Data_copy.Text = '文件拷贝整理';
            app.Clear_all_set = uimenu(app.Menu_functions);
            app.Clear_all_set.MenuSelectedFcn = createCallbackFcn(app, @Clear_all_setMenuSelected, true);
            app.Clear_all_set.Text = '清除预设';
            app.Set_Theme_Color = uimenu(app.Menu_functions);
            app.Set_Theme_Color.Text = '设置主题颜色：默认';
            app.Defult = uimenu(app.Set_Theme_Color);
            app.Defult.MenuSelectedFcn = createCallbackFcn(app, @DefultMenuSelected, true);
            app.Defult.Text = '默认';
            app.White = uimenu(app.Set_Theme_Color);
            app.White.MenuSelectedFcn = createCallbackFcn(app, @WhiteMenuSelected, true);
            app.White.Text = '白色';
            app.Black = uimenu(app.Set_Theme_Color);
            app.Black.MenuSelectedFcn = createCallbackFcn(app, @BlackMenuSelected, true);
            app.Black.Text = '黑色';
            app.PercptPCMenu = uimenu(app.Menu_functions);
            app.PercptPCMenu.MenuSelectedFcn = createCallbackFcn(app, @PercptPCMenuSelected, true);
            app.PercptPCMenu.Text = 'Percpt PC数据转换';
            app.Menu_help = uimenu(app.MainFigure);
            app.Menu_help.Text = '帮助/Help';
            app.illustrate = uimenu(app.Menu_help);
            app.illustrate.MenuSelectedFcn = createCallbackFcn(app, @illustrateMenuSelected, true);
            app.illustrate.Text = '说明';
            app.Language = uimenu(app.MainFigure);
            app.Language.Text = 'Language';
            app.ChineseMenu = uimenu(app.Language);
            app.ChineseMenu.Text = 'Chinese';
            app.EniglisMenu = uimenu(app.Language);
            app.EniglisMenu.Text = 'Eniglis';
            app.NNSRLabel = uilabel(app.MainFigure);
            app.NNSRLabel.Text = 'NNSR 离线分析平台';
            app.data_load_pannel = uipanel(app.MainFigure);
            app.data_load_pannel.Title = 'LFP导入';
            app.axis_pre = uiaxes(app.data_load_pannel);
            zlabel(app.axis_pre, 'Z')
            app.axis_pre.XTick = [];
            app.axis_pre.YTick = [];
            app.axis_pre.YTickLabel = '';
            app.File_selection = uibutton(app.data_load_pannel, 'push');
            app.File_selection.ButtonPushedFcn = createCallbackFcn(app, @File_selectionButtonPushed, true);
            app.File_selection.Text = 'LFP数据选择';
            app.infoSTIM = uilabel(app.data_load_pannel);
            app.infoSTIM.Visible = 'off';
            app.infoSTIM.Text = 'no info detected';
            app.Label_3 = uilabel(app.data_load_pannel);
            app.Label_3.Visible = 'off';
            app.Label_3.Text = '数据刺激参数：';
            app.Label = uilabel(app.data_load_pannel);
            app.Label.Text = '数据采集参数：';
            app.infoACQ = uilabel(app.data_load_pannel);
            app.infoACQ.Text = 'no info detected';
            app.Image = uiimage(app.data_load_pannel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Electrode_ORI.jpg');
            app.Label_5 = uilabel(app.data_load_pannel);
            app.Label_5.Text = '快速预览：';
            app.View_chi_DropDown = uidropdown(app.data_load_pannel);
            app.View_chi_DropDown.Items = {'1+2-', '3+4-'};
            app.View_chi_DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.View_chi_DropDown.ValueChangedFcn = createCallbackFcn(app, @View_chi_DropDownValueChanged, true);
            app.View_chi_DropDown.Enable = 'off';
            app.View_chi_DropDown.Value = '1';
            app.UITable = uitable(app.data_load_pannel);
            app.UITable.ColumnName = {'已载入'; 'LFP'; 'ACC'; 'CEEG'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.DoubleClickedFcn = createCallbackFcn(app, @UITableDoubleClicked, true);
            app.UITable.ClickedFcn = createCallbackFcn(app, @UITableClicked, true);
            app.UITable.Multiselect = 'off';
            app.InseartNAN = uicheckbox(app.data_load_pannel);
            app.InseartNAN.ValueChangedFcn = createCallbackFcn(app, @InseartNANValueChanged, true);
            app.InseartNAN.Text = '长度补偿';
            app.data_config = uibutton(app.data_load_pannel, 'push');
            app.data_config.ButtonPushedFcn = createCallbackFcn(app, @data_configPushed, true);
            app.data_config.Enable = 'off';
            app.data_config.Text = '数据解析';
            app.More_info = uibutton(app.data_load_pannel, 'push');
            app.More_info.ButtonPushedFcn = createCallbackFcn(app, @More_infoButtonPushed, true);
            app.More_info.Text = '数据信息';
            app.IS_OLD_VERSON = uicheckbox(app.data_load_pannel);
            app.IS_OLD_VERSON.Text = '旧版解析';
            app.processing_pannel = uitabgroup(app.MainFigure);
            app.PreProcess_Tool = uitab(app.processing_pannel);
            app.PreProcess_Tool.Title = '一. 数据预处理';
            app.axis_preprocessing_now = uiaxes(app.PreProcess_Tool);
            title(app.axis_preprocessing_now, '时域信息')
            xlabel(app.axis_preprocessing_now, '采样点')
            ylabel(app.axis_preprocessing_now, '振幅/uv')
            zlabel(app.axis_preprocessing_now, 'Z')
            app.DATA_PSD = uiaxes(app.PreProcess_Tool);
            title(app.DATA_PSD, '频域信息')
            xlabel(app.DATA_PSD, '频率/Hz')
            ylabel(app.DATA_PSD, '能量(dB/Hz)')
            zlabel(app.DATA_PSD, 'Z')
            app.Data_config = uibutton(app.PreProcess_Tool, 'push');
            app.Data_config.ButtonPushedFcn = createCallbackFcn(app, @Data_configPushed, true);
            app.Data_config.Enable = 'off';
            app.Data_config.Text = '数据确认';
            app.chi_choose = uidropdown(app.PreProcess_Tool);
            app.chi_choose.Items = {'1+3-', '2+4-'};
            app.chi_choose.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.chi_choose.ValueChangedFcn = createCallbackFcn(app, @chi_chooseValueChanged, true);
            app.chi_choose.Enable = 'off';
            app.chi_choose.Value = '1';
            app.data_cut_tool = uibutton(app.PreProcess_Tool, 'push');
            app.data_cut_tool.ButtonPushedFcn = createCallbackFcn(app, @data_cut_toolButtonPushed, true);
            app.data_cut_tool.Enable = 'off';
            app.data_cut_tool.Text = '④数据裁剪工具';
            app.all_chi = uicheckbox(app.PreProcess_Tool);
            app.all_chi.Enable = 'off';
            app.all_chi.Text = '所有通道执行相同操作';
            app.all_chi.Value = true;
            app.Panel_Removal = uipanel(app.PreProcess_Tool);
            app.Panel_Removal.Title = '伪迹去除';
            app.Panel_Removal_in = uitabgroup(app.Panel_Removal);
            app.Tab_for_detrend = uitab(app.Panel_Removal_in);
            app.Tab_for_detrend.Title = '①基线漂移修正';
            app.filter_simptom1 = uiaxes(app.Tab_for_detrend);
            title(app.filter_simptom1, '幅频特征')
            xlabel(app.filter_simptom1, '频率(Hz)')
            ylabel(app.filter_simptom1, '幅值特性(dB)')
            zlabel(app.filter_simptom1, 'Z')
            app.filter_simptom1.Visible = 'off';
            app.filter_simptom2 = uiaxes(app.Tab_for_detrend);
            title(app.filter_simptom2, '相频特征')
            xlabel(app.filter_simptom2, '频率(Hz)')
            ylabel(app.filter_simptom2, '角度(rad)')
            zlabel(app.filter_simptom2, 'Z')
            app.filter_simptom2.Visible = 'off';
            app.DropDown_6Label = uilabel(app.Tab_for_detrend);
            app.DropDown_6Label.Text = '方法选择';
            app.detrend_function_chiose = uidropdown(app.Tab_for_detrend);
            app.detrend_function_chiose.Items = {'高通滤波', '多项式拟合'};
            app.detrend_function_chiose.ItemsData = {'1', '2'};
            app.detrend_function_chiose.ValueChangedFcn = createCallbackFcn(app, @detrend_function_chioseValueChanged, true);
            app.detrend_function_chiose.Value = '1';
            app.correction_detrend_Panel = uipanel(app.Tab_for_detrend);
            app.correction_detrend_Panel.Title = '多项式拟合';
            app.Label_13 = uilabel(app.correction_detrend_Panel);
            app.Label_13.Enable = 'off';
            app.Label_13.Text = '多项式次数';
            app.Num_of_order = uidropdown(app.correction_detrend_Panel);
            app.Num_of_order.Items = {'constant', 'linear', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.Num_of_order.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.Num_of_order.Enable = 'off';
            app.Num_of_order.Value = '2';
            app.NaNDropDownLabel = uilabel(app.correction_detrend_Panel);
            app.NaNDropDownLabel.Enable = 'off';
            app.NaNDropDownLabel.Text = '包含NaN';
            app.NaNDropDown = uidropdown(app.correction_detrend_Panel);
            app.NaNDropDown.Items = {'包含NaN', '不含NaN'};
            app.NaNDropDown.ItemsData = {'includenan', 'omitnan'};
            app.NaNDropDown.Enable = 'off';
            app.NaNDropDown.Value = 'includenan';
            app.Label_14 = uilabel(app.correction_detrend_Panel);
            app.Label_14.Enable = 'off';
            app.Label_14.Text = '趋势连续性';
            app.continuous = uidropdown(app.correction_detrend_Panel);
            app.continuous.Items = {'连续', '不连续'};
            app.continuous.ItemsData = {'1', '0'};
            app.continuous.Enable = 'off';
            app.continuous.Value = '1';
            app.apply_detrend1 = uibutton(app.correction_detrend_Panel, 'push');
            app.apply_detrend1.ButtonPushedFcn = createCallbackFcn(app, @apply_detrend1Pushed, true);
            app.apply_detrend1.Enable = 'off';
            app.apply_detrend1.Text = '应用';
            app.detrend_confirm = uibutton(app.correction_detrend_Panel, 'push');
            app.detrend_confirm.ButtonPushedFcn = createCallbackFcn(app, @detrend_confirmButtonPushed, true);
            app.detrend_confirm.Enable = 'off';
            app.detrend_confirm.Text = '确认';
            app.correction_filterdesign_Panel = uipanel(app.Tab_for_detrend);
            app.correction_filterdesign_Panel.Title = '高通滤波';
            app.Label_15 = uilabel(app.correction_filterdesign_Panel);
            app.Label_15.Text = '设计方式';
            app.filter_methed_1 = uidropdown(app.correction_filterdesign_Panel);
            app.filter_methed_1.Items = {'巴特沃斯', '切比雪夫1型'};
            app.filter_methed_1.ItemsData = {'butter', 'cheby1'};
            app.filter_methed_1.ValueChangedFcn = createCallbackFcn(app, @filter_methed_1ValueChanged, true);
            app.filter_methed_1.Enable = 'off';
            app.filter_methed_1.Value = 'butter';
            app.IIRCheckBox = uicheckbox(app.correction_filterdesign_Panel);
            app.IIRCheckBox.ValueChangedFcn = createCallbackFcn(app, @IIRCheckBoxValueChanged, true);
            app.IIRCheckBox.Enable = 'off';
            app.IIRCheckBox.Text = 'IIR';
            app.IIRCheckBox.Value = true;
            app.FIRCheckBox = uicheckbox(app.correction_filterdesign_Panel);
            app.FIRCheckBox.ValueChangedFcn = createCallbackFcn(app, @FIRCheckBoxValueChanged, true);
            app.FIRCheckBox.Enable = 'off';
            app.FIRCheckBox.Text = 'FIR';
            app.filter_methed_2 = uidropdown(app.correction_filterdesign_Panel);
            app.filter_methed_2.Items = {'等波纹', '最小二乘法'};
            app.filter_methed_2.ItemsData = {'equiripple', 'ls'};
            app.filter_methed_2.ValueChangedFcn = createCallbackFcn(app, @filter_methed_2ValueChanged, true);
            app.filter_methed_2.Enable = 'off';
            app.filter_methed_2.Value = 'equiripple';
            app.filterorder = uieditfield(app.correction_filterdesign_Panel, 'text');
            app.filterorder.Enable = 'off';
            app.filterorder.Value = '4';
            app.filter_sample_rate = uieditfield(app.correction_filterdesign_Panel, 'text');
            app.filter_sample_rate.Enable = 'off';
            app.fs_1 = uieditfield(app.correction_filterdesign_Panel, 'text');
            app.fs_1.Enable = 'off';
            app.fs_1.Value = '1';
            app.highpass_apply = uibutton(app.correction_filterdesign_Panel, 'push');
            app.highpass_apply.ButtonPushedFcn = createCallbackFcn(app, @highpass_applyButtonPushed, true);
            app.highpass_apply.Enable = 'off';
            app.highpass_apply.Text = '应用';
            app.comfirm_detrend2 = uibutton(app.correction_filterdesign_Panel, 'push');
            app.comfirm_detrend2.ButtonPushedFcn = createCallbackFcn(app, @comfirm_detrend2ButtonPushed2, true);
            app.comfirm_detrend2.Enable = 'off';
            app.comfirm_detrend2.Text = '确认';
            app.design_high_pass = uibutton(app.correction_filterdesign_Panel, 'push');
            app.design_high_pass.ButtonPushedFcn = createCallbackFcn(app, @design_high_passPushed, true);
            app.design_high_pass.Enable = 'off';
            app.design_high_pass.Text = '快速设计';
            app.Label_filterorder = uilabel(app.correction_filterdesign_Panel);
            app.Label_filterorder.Text = '滤波器阶次';
            app.Label_samplerate = uilabel(app.correction_filterdesign_Panel);
            app.Label_samplerate.Text = '采样率';
            app.Label_fs_1 = uilabel(app.correction_filterdesign_Panel);
            app.Label_fs_1.Text = '截止频率';
            app.Label_fs_2 = uilabel(app.correction_filterdesign_Panel);
            app.Label_fs_2.Visible = 'off';
            app.Label_fs_2.Text = '通带波动';
            app.fs_2 = uieditfield(app.correction_filterdesign_Panel, 'text');
            app.fs_2.Visible = 'off';
            app.fs_2.Value = '1';
            app.Tab_for_stim_artifacts = uitab(app.Panel_Removal_in);
            app.Tab_for_stim_artifacts.Tooltip = {'如存在开机的刺激伪迹，请运行此模块方法进行刺激伪迹去除'};
            app.Tab_for_stim_artifacts.Title = '②刺激伪迹去除';
            app.filter_simptom1_2 = uiaxes(app.Tab_for_stim_artifacts);
            title(app.filter_simptom1_2, '幅频特征')
            xlabel(app.filter_simptom1_2, '频率(Hz)')
            ylabel(app.filter_simptom1_2, '幅值(dB)')
            zlabel(app.filter_simptom1_2, 'Z')
            app.filter_simptom1_2.Visible = 'off';
            app.filter_simptom2_2 = uiaxes(app.Tab_for_stim_artifacts);
            title(app.filter_simptom2_2, '相频特征')
            xlabel(app.filter_simptom2_2, '频率(Hz)')
            ylabel(app.filter_simptom2_2, '角度(rad)')
            zlabel(app.filter_simptom2_2, 'Z')
            app.filter_simptom2_2.Visible = 'off';
            app.Label_12 = uilabel(app.Tab_for_stim_artifacts);
            app.Label_12.Text = '方法选择';
            app.artifact_denoise = uidropdown(app.Tab_for_stim_artifacts);
            app.artifact_denoise.Items = {'滤波器去除', '模板去除'};
            app.artifact_denoise.ItemsData = {'1', '2'};
            app.artifact_denoise.ValueChangedFcn = createCallbackFcn(app, @artifact_denoiseValueChanged, true);
            app.artifact_denoise.Value = '1';
            app.STMartifactremove_stract = uipanel(app.Tab_for_stim_artifacts);
            app.STMartifactremove_stract.Title = '模板去除';
            app.STMartifactremove_confirm_2 = uibutton(app.STMartifactremove_stract, 'push');
            app.STMartifactremove_confirm_2.ButtonPushedFcn = createCallbackFcn(app, @STMartifactremove_confirm_2ButtonPushed, true);
            app.STMartifactremove_confirm_2.Enable = 'off';
            app.STMartifactremove_confirm_2.Text = '确认结果';
            app.Label_32 = uilabel(app.STMartifactremove_stract);
            app.Label_32.Text = '分频数';
            app.STMartifact_remove = uibutton(app.STMartifactremove_stract, 'push');
            app.STMartifact_remove.ButtonPushedFcn = createCallbackFcn(app, @STMartifact_removePushed, true);
            app.STMartifact_remove.Enable = 'off';
            app.STMartifact_remove.Text = '去除';
            app.STMartifact_num_select = uieditfield(app.STMartifactremove_stract, 'numeric');
            app.STMartifact_num_select.Limits = [0 20];
            app.STMartifact_num_select.Value = 1;
            app.Label_42 = uilabel(app.STMartifactremove_stract);
            app.Label_42.Text = '窗长';
            app.STMartifact_re_length = uieditfield(app.STMartifactremove_stract, 'text');
            app.STMartifact_re_length.Value = '2000';
            app.STMartifactremove_filter = uipanel(app.Tab_for_stim_artifacts);
            app.STMartifactremove_filter.Title = '滤波器去除';
            app.Label_7 = uilabel(app.STMartifactremove_filter);
            app.Label_7.Text = '响应类型';
            app.filter_method_dropdown = uidropdown(app.STMartifactremove_filter);
            app.filter_method_dropdown.Items = {'低通'};
            app.filter_method_dropdown.ItemsData = {'1', '2', '3', '4'};
            app.filter_method_dropdown.ValueChangedFcn = createCallbackFcn(app, @filter_method_dropdownValueChanged, true);
            app.filter_method_dropdown.Value = '1';
            app.IIRCheckBox_iir = uicheckbox(app.STMartifactremove_filter);
            app.IIRCheckBox_iir.ValueChangedFcn = createCallbackFcn(app, @IIRCheckBox_iirValueChanged, true);
            app.IIRCheckBox_iir.Enable = 'off';
            app.IIRCheckBox_iir.Text = 'IIR';
            app.IIRCheckBox_iir.Value = true;
            app.FIRCheckBox_fir = uicheckbox(app.STMartifactremove_filter);
            app.FIRCheckBox_fir.ValueChangedFcn = createCallbackFcn(app, @FIRCheckBox_firValueChanged, true);
            app.FIRCheckBox_fir.Enable = 'off';
            app.FIRCheckBox_fir.Text = 'FIR';
            app.filter_methed_IIR = uidropdown(app.STMartifactremove_filter);
            app.filter_methed_IIR.Items = {'巴特沃斯', '切比雪夫1型', '切比雪夫2型'};
            app.filter_methed_IIR.ItemsData = {'1', '2', '3'};
            app.filter_methed_IIR.ValueChangedFcn = createCallbackFcn(app, @filter_methed_IIRValueChanged, true);
            app.filter_methed_IIR.Enable = 'off';
            app.filter_methed_IIR.Value = '1';
            app.filter_methed_FIR = uidropdown(app.STMartifactremove_filter);
            app.filter_methed_FIR.Items = {'等波纹', '最小二乘法'};
            app.filter_methed_FIR.ItemsData = {'1', '2'};
            app.filter_methed_FIR.ValueChangedFcn = createCallbackFcn(app, @filter_methed_FIRValueChanged, true);
            app.filter_methed_FIR.Enable = 'off';
            app.filter_methed_FIR.Value = '1';
            app.spicified_design = uibutton(app.STMartifactremove_filter, 'push');
            app.spicified_design.ButtonPushedFcn = createCallbackFcn(app, @spicified_designPushed, true);
            app.spicified_design.Visible = 'off';
            app.spicified_design.Text = '精细化滤波器设计';
            app.filter_file_choose = uibutton(app.STMartifactremove_filter, 'push');
            app.filter_file_choose.ButtonPushedFcn = createCallbackFcn(app, @filter_file_chooseButtonPushed, true);
            app.filter_file_choose.Visible = 'off';
            app.filter_file_choose.Text = '选择滤波器文件';
            app.apply_lowpass_filter = uibutton(app.STMartifactremove_filter, 'push');
            app.apply_lowpass_filter.ButtonPushedFcn = createCallbackFcn(app, @apply_lowpass_filterPushed, true);
            app.apply_lowpass_filter.Enable = 'off';
            app.apply_lowpass_filter.Text = '应用';
            app.STMartifactremove_confirm = uibutton(app.STMartifactremove_filter, 'push');
            app.STMartifactremove_confirm.ButtonPushedFcn = createCallbackFcn(app, @STMartifactremove_confirmPushed, true);
            app.STMartifactremove_confirm.Enable = 'off';
            app.STMartifactremove_confirm.Text = '确认结果';
            app.Label_9 = uilabel(app.STMartifactremove_filter);
            app.Label_9.Text = '滤波器阶次';
            app.filter_order_STM = uieditfield(app.STMartifactremove_filter, 'numeric');
            app.filter_order_STM.Value = 6;
            app.filter_design_function = uibutton(app.STMartifactremove_filter, 'push');
            app.filter_design_function.ButtonPushedFcn = createCallbackFcn(app, @filter_design_functionButtonPushed, true);
            app.filter_design_function.Enable = 'off';
            app.filter_design_function.Text = '快速设计';
            app.EditField_7Label = uilabel(app.STMartifactremove_filter);
            app.EditField_7Label.Text = '采样率';
            app.samplefrequency_STM1 = uieditfield(app.STMartifactremove_filter, 'numeric');
            app.samplefrequency_STM1.Value = 500;
            app.frequency4_text = uilabel(app.STMartifactremove_filter);
            app.frequency4_text.Enable = 'off';
            app.frequency4_text.Visible = 'off';
            app.frequency4_text.Text = 'FC2';
            app.frequency3_text = uilabel(app.STMartifactremove_filter);
            app.frequency3_text.Enable = 'off';
            app.frequency3_text.Visible = 'off';
            app.frequency3_text.Text = 'FC2';
            app.frequency1_text = uilabel(app.STMartifactremove_filter);
            app.frequency1_text.Text = '截止频率';
            app.frequency2_text = uilabel(app.STMartifactremove_filter);
            app.frequency2_text.Visible = 'off';
            app.frequency2_text.Text = 'FC2';
            app.frequency4 = uieditfield(app.STMartifactremove_filter, 'numeric');
            app.frequency4.Visible = 'off';
            app.frequency4.Value = 3;
            app.frequency3 = uieditfield(app.STMartifactremove_filter, 'numeric');
            app.frequency3.Visible = 'off';
            app.frequency3.Value = 3;
            app.frequency2 = uieditfield(app.STMartifactremove_filter, 'numeric');
            app.frequency2.Visible = 'off';
            app.frequency2.Value = 3;
            app.frequency1 = uieditfield(app.STMartifactremove_filter, 'numeric');
            app.frequency1.Value = 80;
            app.Tab_for_ECG_artifacts = uitab(app.Panel_Removal_in);
            app.Tab_for_ECG_artifacts.Tooltip = {'如数据具有周期性心电伪迹，请使用此模块进行心电伪迹去除'};
            app.Tab_for_ECG_artifacts.Title = '③心电伪迹去除';
            app.Temp_figure = uiaxes(app.Tab_for_ECG_artifacts);
            title(app.Temp_figure, '模板波形')
            zlabel(app.Temp_figure, 'Z')
            app.Temp_figure.Visible = 'off';
            app.DropDown_7Label = uilabel(app.Tab_for_ECG_artifacts);
            app.DropDown_7Label.Text = '方法选择';
            app.DropDown_7 = uidropdown(app.Tab_for_ECG_artifacts);
            app.DropDown_7.Items = {'EAS'};
            app.DropDown_7.Value = 'EAS';
            app.EASPanel = uipanel(app.Tab_for_ECG_artifacts);
            app.EASPanel.Title = 'EAS';
            app.Label_27 = uilabel(app.EASPanel);
            app.Label_27.Text = ' 处理步长';
            app.K_length = uieditfield(app.EASPanel, 'text');
            app.K_length.Value = '0';
            app.apply_EAS = uibutton(app.EASPanel, 'push');
            app.apply_EAS.ButtonPushedFcn = createCallbackFcn(app, @apply_EASButtonPushed, true);
            app.apply_EAS.Enable = 'off';
            app.apply_EAS.Text = '应用';
            app.confirm_EAS = uibutton(app.EASPanel, 'push');
            app.confirm_EAS.ButtonPushedFcn = createCallbackFcn(app, @confirm_EASButtonPushed, true);
            app.confirm_EAS.Enable = 'off';
            app.confirm_EAS.Text = '确认结果';
            app.Label_43 = uilabel(app.EASPanel);
            app.Label_43.Text = '参考心电';
            app.is_ECG_avi = uidropdown(app.EASPanel);
            app.is_ECG_avi.Items = {'无参考心电', '有参考心电'};
            app.is_ECG_avi.ItemsData = {'1', '2'};
            app.is_ECG_avi.ValueChangedFcn = createCallbackFcn(app, @is_ECG_aviValueChanged, true);
            app.is_ECG_avi.Value = '1';
            app.Load_ECG = uibutton(app.EASPanel, 'push');
            app.Load_ECG.ButtonPushedFcn = createCallbackFcn(app, @Load_ECGButtonPushed, true);
            app.Load_ECG.Enable = 'off';
            app.Load_ECG.Text = '导入同步参考心电';
            app.Label_44 = uilabel(app.EASPanel);
            app.Label_44.Enable = 'off';
            app.Label_44.Text = '（输入0为自动选择）';
            app.Label_46 = uilabel(app.EASPanel);
            app.Label_46.Text = '去除分段(s)';
            app.EAS_set_pice = uidropdown(app.EASPanel);
            app.EAS_set_pice.Items = {'10', '20', '30'};
            app.EAS_set_pice.Editable = 'on';
            app.EAS_set_pice.Value = '20';
            app.IS_ALL_CHANNEL = uicheckbox(app.EASPanel);
            app.IS_ALL_CHANNEL.Enable = 'off';
            app.IS_ALL_CHANNEL.Text = '所有通道';
            app.IS_ALL_CHANNEL.Value = true;
            app.Data_re_finish = uibutton(app.PreProcess_Tool, 'push');
            app.Data_re_finish.ButtonPushedFcn = createCallbackFcn(app, @Data_re_finishPushed, true);
            app.Data_re_finish.Text = '预处理完成确认（转跳特征分析）';
            app.back = uibutton(app.PreProcess_Tool, 'push');
            app.back.ButtonPushedFcn = createCallbackFcn(app, @backButtonPushed, true);
            app.back.Text = '清空';
            app.Label_50 = uilabel(app.PreProcess_Tool);
            app.Label_50.Text = '查看范围';
            app.Slider_Plot = uislider(app.PreProcess_Tool, 'range');
            app.Slider_Plot.ValueChangingFcn = createCallbackFcn(app, @Slider_PlotValueChanging, true);
            app.currentdata_txt = uitextarea(app.PreProcess_Tool);
            app.currentdata_txt.Editable = 'off';
            app.currentdata_txt.Value = {'请进行"数据确认"'};
            app.Current_data = uilabel(app.PreProcess_Tool);
            app.Current_data.Text = '数据';
            app.Label_54 = uilabel(app.PreProcess_Tool);
            app.Label_54.Text = '范围：';
            app.Range_freq = uislider(app.PreProcess_Tool, 'range');
            app.Range_freq.ValueChangingFcn = createCallbackFcn(app, @Range_freqValueChanging, true);
            app.Comparation_TOOL_2 = uibutton(app.PreProcess_Tool, 'push');
            app.Comparation_TOOL_2.ButtonPushedFcn = createCallbackFcn(app, @Comparation_TOOL_2ButtonPushed, true);
            app.Comparation_TOOL_2.Text = '特征对比工具';
            app.Batch_Procecces = uibutton(app.PreProcess_Tool, 'push');
            app.Batch_Procecces.ButtonPushedFcn = createCallbackFcn(app, @Batch_ProceccesButtonPushed, true);
            app.Batch_Procecces.Text = '批处理工具';
            app.IPGLabel = uilabel(app.PreProcess_Tool);
            app.IPGLabel.Text = 'IPG编号：';
            app.current_IPGfile = uitextarea(app.PreProcess_Tool);
            app.current_IPGfile.Editable = 'off';
            app.current_IPGfile.Value = {'导入数据以获取'};
            app.FeatureProcess_Tool = uitab(app.processing_pannel);
            app.FeatureProcess_Tool.Title = '二. 数据特征分析';
            app.FeatureProcess_Tool.ButtonDownFcn = createCallbackFcn(app, @FeatureProcess_ToolButtonDown, true);
            app.Panel_4 = uipanel(app.FeatureProcess_Tool);
            app.Panel_4.Title = '时频分析';
            app.TIME_FREQUENCY = uiaxes(app.Panel_4);
            xlabel(app.TIME_FREQUENCY, 'Time (s)')
            ylabel(app.TIME_FREQUENCY, 'Frequency (Hz)')
            zlabel(app.TIME_FREQUENCY, 'Z')
            colormap(app.TIME_FREQUENCY, 'jet')
            app.Label_19 = uilabel(app.Panel_4);
            app.Label_19.Text = '方法';
            app.TIME_FREQ_meth = uidropdown(app.Panel_4);
            app.TIME_FREQ_meth.Items = {'短时傅里叶', '小波变换'};
            app.TIME_FREQ_meth.ItemsData = {'1', '2', '3'};
            app.TIME_FREQ_meth.ValueChangedFcn = createCallbackFcn(app, @TIME_FREQ_methValueChanged, true);
            app.TIME_FREQ_meth.Enable = 'off';
            app.TIME_FREQ_meth.Value = '1';
            app.T_M_plot = uibutton(app.Panel_4, 'push');
            app.T_M_plot.ButtonPushedFcn = createCallbackFcn(app, @T_M_plotButtonPushed, true);
            app.T_M_plot.Enable = 'off';
            app.T_M_plot.Text = '绘制';
            app.DropDown_15Label_2 = uilabel(app.Panel_4);
            app.DropDown_15Label_2.Text = '通道';
            app.T_M_chi = uidropdown(app.Panel_4);
            app.T_M_chi.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.T_M_chi.ValueChangedFcn = createCallbackFcn(app, @T_M_chiValueChanged, true);
            app.T_M_chi.Enable = 'off';
            app.T_M_chi.Value = '1';
            app.T_M_Save = uibutton(app.Panel_4, 'push');
            app.T_M_Save.ButtonPushedFcn = createCallbackFcn(app, @T_M_SaveButtonPushed, true);
            app.T_M_Save.Enable = 'off';
            app.T_M_Save.Text = '存储图片';
            app.Label_39 = uilabel(app.Panel_4);
            app.Label_39.Text = '重叠区域';
            app.T_M_overlap = uieditfield(app.Panel_4, 'text');
            app.T_M_overlap.Enable = 'off';
            app.Label_40 = uilabel(app.Panel_4);
            app.Label_40.Text = '采样率';
            app.samplarate = uieditfield(app.Panel_4, 'text');
            app.samplarate.Enable = 'off';
            app.Label_41 = uilabel(app.Panel_4);
            app.Label_41.Text = '窗长';
            app.T_M_WindowsLength = uieditfield(app.Panel_4, 'text');
            app.T_M_WindowsLength.Enable = 'off';
            app.FFTEditField_2Label = uilabel(app.Panel_4);
            app.FFTEditField_2Label.Text = 'FFT点数';
            app.T_M_NFFT = uieditfield(app.Panel_4, 'text');
            app.T_M_NFFT.Enable = 'off';
            app.T_M_NFFT.Value = 'NaN';
            app.TO_4 = uilabel(app.Panel_4);
            app.TO_4.Text = '存为';
            app.save_T_F_MAP = uibutton(app.Panel_4, 'push');
            app.save_T_F_MAP.ButtonPushedFcn = createCallbackFcn(app, @save_T_F_MAPButtonPushed, true);
            app.save_T_F_MAP.Enable = 'off';
            app.save_T_F_MAP.Text = '文件';
            app.save_T_F_MAP_Workspace = uibutton(app.Panel_4, 'push');
            app.save_T_F_MAP_Workspace.ButtonPushedFcn = createCallbackFcn(app, @save_T_F_MAP_WorkspaceButtonPushed, true);
            app.save_T_F_MAP_Workspace.Enable = 'off';
            app.save_T_F_MAP_Workspace.Text = '工作区';
            app.Label_49 = uilabel(app.Panel_4);
            app.Label_49.Text = 'C轴';
            app.Color_range_Slide = uislider(app.Panel_4, 'range');
            app.Color_range_Slide.Limits = [-80 80];
            app.Color_range_Slide.ValueChangingFcn = createCallbackFcn(app, @Color_range_SlideValueChanging, true);
            app.Color_range_Slide.Value = [-50 60];
            app.Label_52 = uilabel(app.Panel_4);
            app.Label_52.Text = '时间范围';
            app.Time_range_Slide = uislider(app.Panel_4, 'range');
            app.Time_range_Slide.ValueChangingFcn = createCallbackFcn(app, @Time_range_SlideValueChanging, true);
            app.SliderLabel = uilabel(app.Panel_4);
            app.SliderLabel.Text = '频率轴';
            app.Y_range_Slide = uislider(app.Panel_4, 'range');
            app.Y_range_Slide.ValueChangingFcn = createCallbackFcn(app, @Y_range_SlideValueChanging, true);
            app.out_put_select3 = uidropdown(app.Panel_4);
            app.out_put_select3.Items = {'Matlab', 'Excel', 'TXT'};
            app.out_put_select3.ItemsData = {'1', '2', '3'};
            app.out_put_select3.Value = '1';
            app.Plot_Changes = uibutton(app.Panel_4, 'push');
            app.Plot_Changes.ButtonPushedFcn = createCallbackFcn(app, @Plot_ChangesButtonPushed, true);
            app.Plot_Changes.Enable = 'off';
            app.Plot_Changes.Text = '绘制波动';
            app.TabGroup3 = uitabgroup(app.FeatureProcess_Tool);
            app.PSD_analisis = uitab(app.TabGroup3);
            app.PSD_analisis.Title = '功率谱分析';
            app.PSD_figure = uiaxes(app.PSD_analisis);
            title(app.PSD_figure, '功率谱密度')
            xlabel(app.PSD_figure, 'Frequency (Hz)')
            ylabel(app.PSD_figure, 'Power (dB/Hz)')
            zlabel(app.PSD_figure, 'Z')
            app.Label_20 = uilabel(app.PSD_analisis);
            app.Label_20.Text = '方法';
            app.PSD_method = uidropdown(app.PSD_analisis);
            app.PSD_method.Items = {'welch', 'FFT'};
            app.PSD_method.ItemsData = {'1', '2'};
            app.PSD_method.ValueChangedFcn = createCallbackFcn(app, @PSD_methodValueChanged, true);
            app.PSD_method.Enable = 'off';
            app.PSD_method.Value = '1';
            app.Label_22 = uilabel(app.PSD_analisis);
            app.Label_22.Text = 'FFT点数';
            app.FFT_Number = uieditfield(app.PSD_analisis, 'text');
            app.FFT_Number.Enable = 'off';
            app.FFT_Number.Value = '250';
            app.Label_23 = uilabel(app.PSD_analisis);
            app.Label_23.Text = '重叠';
            app.overlap = uieditfield(app.PSD_analisis, 'text');
            app.overlap.Enable = 'off';
            app.overlap.Value = '125';
            app.Plot_PSD = uibutton(app.PSD_analisis, 'push');
            app.Plot_PSD.ButtonPushedFcn = createCallbackFcn(app, @Plot_PSDButtonPushed, true);
            app.Plot_PSD.Enable = 'off';
            app.Plot_PSD.Text = '绘制';
            app.save_PSD_Fig = uibutton(app.PSD_analisis, 'push');
            app.save_PSD_Fig.ButtonPushedFcn = createCallbackFcn(app, @save_PSD_FigButtonPushed, true);
            app.save_PSD_Fig.Enable = 'off';
            app.save_PSD_Fig.Text = '保存图片';
            app.save_PSD_2Work_space = uibutton(app.PSD_analisis, 'push');
            app.save_PSD_2Work_space.ButtonPushedFcn = createCallbackFcn(app, @save_PSD_2Work_spaceButtonPushed, true);
            app.save_PSD_2Work_space.Enable = 'off';
            app.save_PSD_2Work_space.Text = '工作区';
            app.Label_33 = uilabel(app.PSD_analisis);
            app.Label_33.Text = '计算通道';
            app.chi_calcu = uilistbox(app.PSD_analisis);
            app.chi_calcu.Items = {'1+3-', '2+4-', '3+2-'};
            app.chi_calcu.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.chi_calcu.Multiselect = 'on';
            app.chi_calcu.Enable = 'off';
            app.chi_calcu.Value = {'1'};
            app.Label_25 = uilabel(app.PSD_analisis);
            app.Label_25.Enable = 'off';
            app.Label_25.Text = '分段置信';
            app.welch_overlap_2 = uieditfield(app.PSD_analisis, 'text');
            app.welch_overlap_2.Editable = 'off';
            app.welch_overlap_2.Enable = 'off';
            app.welch_overlap_2.Value = '0';
            app.EditField_7Label_2 = uilabel(app.PSD_analisis);
            app.EditField_7Label_2.Text = '绘制范围';
            app.plot_freq_down = uieditfield(app.PSD_analisis, 'numeric');
            app.plot_freq_down.Enable = 'off';
            app.plot_freq_down.Value = 2;
            app.TO = uilabel(app.PSD_analisis);
            app.TO.Text = '至';
            app.plot_freq_up = uieditfield(app.PSD_analisis, 'numeric');
            app.plot_freq_up.Enable = 'off';
            app.plot_freq_up.Value = 65;
            app.Label_21 = uilabel(app.PSD_analisis);
            app.Label_21.Text = '采样率';
            app.Powercalcu_frequency = uieditfield(app.PSD_analisis, 'text');
            app.Powercalcu_frequency.Enable = 'off';
            app.Label_47 = uilabel(app.PSD_analisis);
            app.Label_47.Text = '窗长';
            app.Window_length = uieditfield(app.PSD_analisis, 'text');
            app.Window_length.Enable = 'off';
            app.CtrlLabel = uilabel(app.PSD_analisis);
            app.CtrlLabel.Text = '（"Ctrl"键多选）';
            app.TO_2 = uilabel(app.PSD_analisis);
            app.TO_2.Text = 'Hz';
            app.TO_3 = uilabel(app.PSD_analisis);
            app.TO_3.Text = '数值存为';
            app.save_PSD_2File = uibutton(app.PSD_analisis, 'push');
            app.save_PSD_2File.ButtonPushedFcn = createCallbackFcn(app, @save_PSD_2FileButtonPushed, true);
            app.save_PSD_2File.Enable = 'off';
            app.save_PSD_2File.Text = '文件';
            app.Label_51 = uilabel(app.PSD_analisis);
            app.Label_51.Text = '频率轴';
            app.Slider_PSD = uislider(app.PSD_analisis, 'range');
            app.Slider_PSD.Limits = [0 130];
            app.Slider_PSD.ValueChangingFcn = createCallbackFcn(app, @Slider_PSDValueChanging, true);
            app.Slider_PSD.Value = [2 79.95];
            app.Beta_Peakfind = uibutton(app.PSD_analisis, 'push');
            app.Beta_Peakfind.ButtonPushedFcn = createCallbackFcn(app, @Beta_PeakfindButtonPushed, true);
            app.Beta_Peakfind.Enable = 'off';
            app.Beta_Peakfind.Text = 'β峰值识别';
            app.out_put_select2 = uidropdown(app.PSD_analisis);
            app.out_put_select2.Items = {'Matlab', 'Excel', 'TXT'};
            app.out_put_select2.ItemsData = {'2', '1', '3'};
            app.out_put_select2.Value = '2';
            app.Is_shadow = uicheckbox(app.PSD_analisis);
            app.Is_shadow.Text = '频带指示';
            app.Is_shadow.Value = true;
            app.Label_53 = uilabel(app.PSD_analisis);
            app.Label_53.Text = '能量范围';
            app.PSD_YSlider = uislider(app.PSD_analisis, 'range');
            app.PSD_YSlider.Limits = [-60 60];
            app.PSD_YSlider.ValueChangingFcn = createCallbackFcn(app, @PSD_YSliderValueChanging, true);
            app.PSD_YSlider.Value = [0 30];
            app.Comparation_TOOL = uibutton(app.PSD_analisis, 'push');
            app.Comparation_TOOL.ButtonPushedFcn = createCallbackFcn(app, @Comparation_TOOLButtonPushed, true);
            app.Comparation_TOOL.Text = '特征对比工具';
            app.Phase_Value_analisis = uitab(app.TabGroup3);
            app.Phase_Value_analisis.Title = '相域特征分析';
            app.Phase_Value_analisis.ButtonDownFcn = createCallbackFcn(app, @Phase_Value_analisisButtonDown, true);
            app.Phase_feature = uiaxes(app.Phase_Value_analisis);
            title(app.Phase_feature, '相位谱')
            xlabel(app.Phase_feature, '频率')
            ylabel(app.Phase_feature, '相位角')
            zlabel(app.Phase_feature, 'Z')
            app.PLV_plot = uiaxes(app.Phase_Value_analisis);
            title(app.PLV_plot, '锁相特征')
            xlabel(app.PLV_plot, '分段')
            ylabel(app.PLV_plot, '相位锁定值')
            zlabel(app.PLV_plot, 'Z')
            app.Label_26 = uilabel(app.Phase_Value_analisis);
            app.Label_26.Text = '方法';
            app.Phase_method = uidropdown(app.Phase_Value_analisis);
            app.Phase_method.Items = {'傅里叶变换'};
            app.Phase_method.Enable = 'off';
            app.Phase_method.Value = '傅里叶变换';
            app.FFTEditFieldLabel = uilabel(app.Phase_Value_analisis);
            app.FFTEditFieldLabel.Text = 'FFT点数';
            app.FFTEditField = uieditfield(app.Phase_Value_analisis, 'numeric');
            app.FFTEditField.Enable = 'off';
            app.plot_Phase_spectrum = uibutton(app.Phase_Value_analisis, 'push');
            app.plot_Phase_spectrum.ButtonPushedFcn = createCallbackFcn(app, @plot_Phase_spectrumButtonPushed, true);
            app.plot_Phase_spectrum.Enable = 'off';
            app.plot_Phase_spectrum.Text = '绘制相位谱图';
            app.Label_37 = uilabel(app.Phase_Value_analisis);
            app.Label_37.Text = '通道';
            app.channel_Phase_spectrum = uidropdown(app.Phase_Value_analisis);
            app.channel_Phase_spectrum.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.channel_Phase_spectrum.Enable = 'off';
            app.channel_Phase_spectrum.Value = '1';
            app.EditField_3Label = uilabel(app.Phase_Value_analisis);
            app.EditField_3Label.Text = '采样率';
            app.samplerate_phase = uieditfield(app.Phase_Value_analisis, 'numeric');
            app.samplerate_phase.Enable = 'off';
            app.SAVE_PHASE_FIG = uibutton(app.Phase_Value_analisis, 'push');
            app.SAVE_PHASE_FIG.ButtonPushedFcn = createCallbackFcn(app, @SAVE_PHASE_FIGButtonPushed, true);
            app.SAVE_PHASE_FIG.Enable = 'off';
            app.SAVE_PHASE_FIG.Text = '存储图像';
            app.DropDown_15Label = uilabel(app.Phase_Value_analisis);
            app.DropDown_15Label.Text = '选择通道1';
            app.channel_choose_1 = uidropdown(app.Phase_Value_analisis);
            app.channel_choose_1.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.channel_choose_1.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.channel_choose_1.Enable = 'off';
            app.channel_choose_1.Value = '1';
            app.Label_31 = uilabel(app.Phase_Value_analisis);
            app.Label_31.Text = '选择通道2';
            app.channel_choose_2 = uidropdown(app.Phase_Value_analisis);
            app.channel_choose_2.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.channel_choose_2.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.channel_choose_2.Enable = 'off';
            app.channel_choose_2.Value = '1';
            app.PLV_calcu = uibutton(app.Phase_Value_analisis, 'push');
            app.PLV_calcu.ButtonPushedFcn = createCallbackFcn(app, @PLV_calcuButtonPushed, true);
            app.PLV_calcu.Enable = 'off';
            app.PLV_calcu.Text = '计算相位锁定';
            app.result = uieditfield(app.Phase_Value_analisis, 'numeric');
            app.result.Enable = 'off';
            app.Label_45 = uilabel(app.Phase_Value_analisis);
            app.Label_45.Text = '分段长度(s)';
            app.PLV_length = uieditfield(app.Phase_Value_analisis, 'text');
            app.PLV_length.Enable = 'off';
            app.PLV_length.Value = '4';
            app.SAVE_PLV_FIG = uibutton(app.Phase_Value_analisis, 'push');
            app.SAVE_PLV_FIG.ButtonPushedFcn = createCallbackFcn(app, @SAVE_PLV_FIGButtonPushed, true);
            app.SAVE_PLV_FIG.Enable = 'off';
            app.SAVE_PLV_FIG.Text = '存储图像';
            app.Label_55 = uilabel(app.Phase_Value_analisis);
            app.Label_55.Text = '频段范围';
            app.BandPowerSelect = uidropdown(app.Phase_Value_analisis);
            app.BandPowerSelect.Items = {'θ(4-8)', 'δ(1-3)', 'β(13-35)', 'α(9-12)', 'γ(35-50)'};
            app.BandPowerSelect.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.BandPowerSelect.Enable = 'off';
            app.BandPowerSelect.Value = '1';
            app.data_management = uipanel(app.MainFigure);
            app.data_management.Title = '数据管理';
            app.Current_location = uilabel(app.data_management);
            app.Current_location.Text = '当前存储路径：';
            app.Save_location = uibutton(app.data_management, 'push');
            app.Save_location.ButtonPushedFcn = createCallbackFcn(app, @Save_locationPushed, true);
            app.Save_location.Text = '修改存储路径';
            app.Save_Path = uitextarea(app.data_management);
            app.save_data2workspace = uibutton(app.data_management, 'push');
            app.save_data2workspace.ButtonPushedFcn = createCallbackFcn(app, @save_data2workspacePushed, true);
            app.save_data2workspace.Enable = 'off';
            app.save_data2workspace.Text = '工作区';
            app.Save_Data = uibutton(app.data_management, 'push');
            app.Save_Data.ButtonPushedFcn = createCallbackFcn(app, @Save_DataButtonPushed, true);
            app.Save_Data.Enable = 'off';
            app.Save_Data.Text = '保存至文件';
            app.out_put_select = uidropdown(app.data_management);
            app.out_put_select.Items = {'.m(Simple)', '.txt(格式)', '.EDF(格式)'};
            app.out_put_select.ItemsData = {'1', '3', '2'};
            app.out_put_select.Value = '1';
            app.Open_File = uibutton(app.data_management, 'push');
            app.Open_File.ButtonPushedFcn = createCallbackFcn(app, @Open_FileButtonPushed, true);
            app.Open_File.Text = '打开路径';
            app.Is_Log_Plot = uicheckbox(app.MainFigure);
            app.Is_Log_Plot.ValueChangedFcn = createCallbackFcn(app, @Is_Log_PlotValueChanged, true);
            app.Is_Log_Plot.Text = '对数绘制';
            app.Is_Log_Plot.Value = true;
            % Show the figure after all components are created
            app.MainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_anylysis_CHI_0_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFigure)
        end
    end
end