classdef APP_anylysis_Data_batch_CHI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFigure_batch      matlab.ui.Figure
        AUTO_SIZEs            matlab.ui.control.StateButton
        TabGroup              matlab.ui.container.TabGroup
        batch_Process         matlab.ui.container.Tab
        Data_chack            matlab.ui.container.Panel
        LFP_FS                matlab.ui.control.Label
        LFP_FS_SET            matlab.ui.control.NumericEditField
        IS_unpack_ACC         matlab.ui.control.CheckBox
        Defult_acc_FS         matlab.ui.control.Label
        Defults_accfs         matlab.ui.control.NumericEditField
        Draw_data             matlab.ui.container.Panel
        IPG_ID                matlab.ui.control.TextArea
        Current_data          matlab.ui.control.Label
        currentdata_txt       matlab.ui.control.TextArea
        Data_ACC_PLOT         matlab.ui.control.UIAxes
        Data_TF_PLOT          matlab.ui.control.UIAxes
        Data_time_PLOT        matlab.ui.control.UIAxes
        Clear_all             matlab.ui.control.Button
        Slider_TIME           matlab.ui.control.RangeSlider
        Label_12              matlab.ui.control.Label
        Channel_Check         matlab.ui.control.DropDown
        Label_9               matlab.ui.control.Label
        Data_Cut_Tool         matlab.ui.control.Button
        Decline               matlab.ui.control.Button
        Confirm               matlab.ui.control.Button
        Panel_2               matlab.ui.container.Panel
        Save_fig              matlab.ui.control.CheckBox
        Check_Every_Seg       matlab.ui.control.CheckBox
        Record_State          matlab.ui.control.Button
        Record_Unpack         matlab.ui.control.Button
        Open_dir              matlab.ui.control.Button
        Identify_Parameter    matlab.ui.control.Label
        IS_OLD_VERSON         matlab.ui.control.CheckBox
        Chose_output          matlab.ui.control.DiscreteKnob
        data_conpensate       matlab.ui.control.CheckBox
        Start_TASK            matlab.ui.control.Button
        Save_Dir              matlab.ui.control.Button
        Label_6               matlab.ui.control.Label
        out_put_select        matlab.ui.control.DropDown
        Label_5               matlab.ui.control.Label
        Save_path             matlab.ui.control.TextArea
        Proceed               matlab.ui.control.Label
        Preprocess            matlab.ui.control.Label
        Load_dir              matlab.ui.control.Label
        ECG                   matlab.ui.control.CheckBox
        LowPass               matlab.ui.control.CheckBox
        High_Pass             matlab.ui.control.CheckBox
        Continus_Process      matlab.ui.container.Tab
        ACC_UNPACK_CHACK_2    matlab.ui.control.CheckBox
        ACCLabel              matlab.ui.control.Label
        Defults_acc_chack     matlab.ui.control.NumericEditField
        SAVE_type             matlab.ui.control.DropDown
        Y_range_Slide         matlab.ui.control.RangeSlider
        SliderLabel           matlab.ui.control.Label
        Color_range_Slide     matlab.ui.control.RangeSlider
        Label_17              matlab.ui.control.Label
        CHACK_LARGEMAT        matlab.ui.control.DropDown
        Label_16              matlab.ui.control.Label
        Fixed_SamplingRate    matlab.ui.control.NumericEditField
        Label_15              matlab.ui.control.Label
        Save_Data             matlab.ui.control.Button
        Get_Info              matlab.ui.control.Button
        Channel_number_chack  matlab.ui.control.NumericEditField
        Label_14              matlab.ui.control.Label
        Samplerate_Chack      matlab.ui.control.NumericEditField
        Label_13              matlab.ui.control.Label
        Switch                matlab.ui.control.Switch
        PC18hLabel            matlab.ui.control.Label
        Unpack_ALL            matlab.ui.control.Button
        TIME_ACC              matlab.ui.control.UIAxes
        TIME_POW              matlab.ui.control.UIAxes
        TIME_AMP              matlab.ui.control.UIAxes
        Panel_3               matlab.ui.container.Panel
        Data_trans_copy       matlab.ui.control.Button
        format_select         matlab.ui.control.DropDown
        Label_8               matlab.ui.control.Label
        Label_7               matlab.ui.control.Label
        selected_dir          matlab.ui.control.TextArea
        delete_all            matlab.ui.control.Button
        delete_file           matlab.ui.control.Button
        data_table            matlab.ui.control.Table
        File_selection        matlab.ui.control.Button
        Label                 matlab.ui.control.Label
        NNSRTOOLBOXLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        waitting_NOW % waiting figure 
        
        % File Management
        Filename_Processed % processed filename
        displays
        First_Filenames % First_filename
        Filename_NEW % Description
        Filename_delete_NOW % 
        Num_of_unpack_ALL % Unpack_num
        Num_of_unpack_NOW
        Path_NOW % load_paths
        Path_NOW_new % load_new_path
        Data_NOW_UNPACK % data

        data_fs
        Stim_Info
        Acq_Info
        Stim_ONOFF_Info
        Stim_Emode_Info
        Freq_StiM
        Polar_Info
        Data_NOW_Preprocessed % 
        data_LosT % 
        UNPACKED_NOW % 
        acc_data % 加速度数据
        Save_processinG % 
        IS_UNPACK_OK % 
        Specified_Unpack_Info % 
        IS_Para_Identy % 是否识别导入的滤波器？
        mark % Description
        Dialog % Description
        large_mat_LFP % 存储LFP拼接整体矩阵
        large_mat_ACC % 
        Time_seq % 整体矩阵的时间变量
        setFS % 
        starttime % 
        Datetime % 

        First_Filename;
        Rec_Parameters_Info; 
        IPG_NUMBER
        
        defult_tab_color = [1 1 1]
        highlight_tab_color = [1 0.8 0.8] 
        DBS_DATA_TOSAVE = struct(); % save_struct
    end

    properties (Access = public)
        highPassFilter % high pass filter
        lowPassFilter % low pass filter
        APP_anylysis_CHI_0_1 % main app
        app_anylisis_Data_CUT 
        % Description
    end
    
    methods (Access = private)

    % %% %%%% %%%%% function 【1】, 102RS unpack %%%%
    % function[Data_out,Data_fs,Stim_info,acq_info,Stim_ONOFF_info,Stim_Emode_info,Freq_Stim,Polar_info,Rec_Parameters_info,Lost] = P01_F1a_102_unpack_dir(~,bit_data) 

    function [result, processedResult] = ProcessingFilename(app,fileList)
        % 输入:
        %   fileList: 1*n 的 cell 数组，存储文件名称
        % 输出:
        %   result: 每一行对应时间戳的 LFP 和 ACC 文件，第四列为 EEG 数据，目前固定为 'N'
        %   processedResult: 时间信息 + LFP/ACC 标识 + 第四列为 'N'
    
        % 初始化分类
        accFiles = {};  % 存储 ACC 数据文件
        lfpFiles = {};  % 存储 LFP 数据文件
        accTimes = {};  % 存储 ACC 文件的时间戳
        lfpTimes = {};  % 存储 LFP 文件的时间戳
    
        % 分类文件，并提取时间戳
        for i = 1:length(fileList)
            fileName = fileList{i};
            [dateTime, fileType] = extractDateTimeAndType(app,fileName); % 提取时间戳和文件类型
    
            % 判断文件类型并分类
            if strcmp(fileType, 'ACC')
                accFiles{end+1} = fileName; % 将 ACC 文件放入 accFiles
                accTimes{end+1} = dateTime; % 保存对应时间戳
            elseif strcmp(fileType, 'LFP') || strcmp(fileType, 'EEG') % 兼容 LFP 和新的 EEG 数据
                lfpFiles{end+1} = fileName; % 将 LFP 文件放入 lfpFiles
                lfpTimes{end+1} = dateTime; % 保存对应时间戳
            end
        end

        % 获取所有唯一时间戳
        validTimes = unique([accTimes, lfpTimes]);
    
        % 如果没有文件，直接返回空
        if isempty(validTimes)
            result = {};
            processedResult = {};
            return;
        end
    
        % 创建结果矩阵
        result = {};
        processedResult = {};
        for i = 1:length(validTimes)
            currentTime = validTimes{i};
    
            % 找到当前时间戳的文件
            lfpForTime = lfpFiles(strcmp(lfpTimes, currentTime));
            accForTime = accFiles(strcmp(accTimes, currentTime));
    
            % 如果是仅有 LFP 数据的时间戳，补空
            if isempty(accForTime) && ~isempty(lfpForTime)
                accForTime = {''}; % 没有 ACC 数据时补空字符串
            end
    
            % 确保文件数量一致（如果需要填充，补空字符串）
            maxFiles = max(length(lfpForTime), length(accForTime));
            lfpForTime = [lfpForTime, repmat({''}, 1, maxFiles - length(lfpForTime))];
            accForTime = [accForTime, repmat({''}, 1, maxFiles - length(accForTime))];
    
            % 只保留第一个有效文件
            if ~isempty(lfpForTime)
                lfpFile = lfpForTime{1};
            else
                lfpFile = '';
            end
    
            if ~isempty(accForTime)
                accFile = accForTime{1};
            else
                accFile = '';
            end
    
            % 构建 result 和 processedResult
            result = [result; {currentTime, lfpFile, accFile, 'N'}]; %#ok<AGROW>
            hasLfpStr = 'Y';
            hasAccStr = 'Y';
            if isempty(lfpFile)
                hasLfpStr = 'N';
            end
            if isempty(accFile)
                hasAccStr = 'N';
            end
            processedResult = [processedResult; {currentTime, hasLfpStr, hasAccStr, 'N'}]; %#ok<AGROW>
        end
    end

    function [dateTimeStr, fileType] = extractDateTimeAndType(~,fileName)
        % 从文件名中提取日期时间信息和文件类型
        % 假设文件类型为 ACC 或 LFP，时间格式因数据类型不同而不同
    
        % 初始化默认值
        dateTimeStr = '';
        fileType = '';
    
        % 第一类数据 (ACCRealTime 和 EEGRealTime)
        if contains(fileName, 'ACCRealTime', 'IgnoreCase', true)
            fileType = 'ACC';
            tokens = regexp(fileName, '\d{4}-\d{2}-\d{2}-\d{2}-\d{2}-\d{2}', 'match'); % 时间格式: YYYY-MM-DD-HH-MM-SS
            if ~isempty(tokens)
                rawDateTime = tokens{1};
                parts = split(rawDateTime, '-'); % 分割时间
                dateTimeStr = sprintf('%s-%s-%s %s:%s:%s', parts{1}, parts{2}, parts{3}, parts{4}, parts{5}, parts{6});
            end
    
        elseif contains(fileName, 'EEGRealTime', 'IgnoreCase', true)
            fileType = 'LFP'; % 兼容 EEG 和 LFP 数据
            tokens = regexp(fileName, '\d{4}-\d{2}-\d{2}-\d{2}-\d{2}-\d{2}', 'match'); % 时间格式: YYYY-MM-DD-HH-MM-SS
            if ~isempty(tokens)
                rawDateTime = tokens{1};
                parts = split(rawDateTime, '-');
                dateTimeStr = sprintf('%s-%s-%s %s:%s:%s', parts{1}, parts{2}, parts{3}, parts{4}, parts{5}, parts{6});
            end
    
        % 第二类数据 (SourceData 和 SourceSpeedData)
        elseif startsWith(fileName, 'SourceData', 'IgnoreCase', true)
            fileType = 'LFP';
            tokens = regexp(fileName, 'SourceData_(\d{8})(\d{6})', 'tokens'); % 时间格式: YYYYMMDDHHMMSS
            if ~isempty(tokens)
                rawStr = [tokens{1}{1}, tokens{1}{2}];
                dateTimeStr = sprintf('%s-%s-%s %s:%s:%s', ...
                    rawStr(1:4), rawStr(5:6), rawStr(7:8), rawStr(9:10), rawStr(11:12), rawStr(13:14));
            end
        elseif startsWith(fileName, 'SourceSpeedData', 'IgnoreCase', true)
            fileType = 'ACC';
            tokens = regexp(fileName, 'SourceSpeedData_(\d{8})(\d{6})', 'tokens'); % 时间格式: YYYYMMDDHHMMSS
            if ~isempty(tokens)
                rawStr = [tokens{1}{1}, tokens{1}{2}];
                dateTimeStr = sprintf('%s-%s-%s %s:%s:%s', ...
                    rawStr(1:4), rawStr(5:6), rawStr(7:8), rawStr(9:10), rawStr(11:12), rawStr(13:14));
            end
    
        % 第三类数据 (head 和 accelerator)
        elseif contains(fileName, 'head', 'IgnoreCase', true) || contains(fileName, 'noacc', 'IgnoreCase', true)
            fileType = 'LFP';
            tokens = regexp(fileName, '\d{4}-\d{2}-\d{2} \d{2}_\d{2}_\d{2}', 'match'); % 时间格式: YYYY-MM-DD HH_MM_SS
            if ~isempty(tokens)
                dateTimeStr = strrep(tokens{1}, '_', ':'); % 替换 "_" 为 ":"
            end
        elseif contains(fileName, 'accelerator', 'IgnoreCase', true)
            fileType = 'ACC';
            tokens = regexp(fileName, '\d{4}-\d{2}-\d{2} \d{2}_\d{2}_\d{2}', 'match'); % 时间格式: YYYY-MM-DD HH_MM_SS
            if ~isempty(tokens)
                dateTimeStr = strrep(tokens{1}, '_', ':');
            end
    
        % 第四类数据 (2016-05-20 10:31:37 058037.dat 格式)
        elseif regexp(fileName, '\d{4}-\d{2}-\d{2} \d{2}：\d{2}：\d{2} .*\.dat', 'once')
            fileType = 'LFP'; % 此格式仅有 LFP 数据
            tokens = regexp(fileName, '\d{4}-\d{2}-\d{2} \d{2}：\d{2}：\d{2}', 'match'); % 时间格式: YYYY-MM-DD HH:MM:SS
            if ~isempty(tokens)
                dateTimeStr = tokens{1};
            end
        end
    end

     % function file_count = P02_collect_files(~,source_folder, target_folder, file_format)

     function confirmSelection(app,confirmDlg, source_folder, target_folder, fileFormatDropDown, customFormatEditField)
            % 获取最终的文件格式
            app.waitting_NOW = uiprogressdlg(app.MainFigure_batch,'Title','请选择检索文件夹与目标文件夹',...
            'Indeterminate','on');
            drawnow
            
            if strcmp(fileFormatDropDown.Value, '自定义')
                file_format = customFormatEditField.Value;
            else
                file_format = fileFormatDropDown.Value;
            end
        
            % 关闭确认对话框
            close(confirmDlg);
        
            % 调用文件复制函数，并返回复制的文件数量
            file_count = P02_collect_files(app,source_folder, target_folder, file_format);
        
            % 显示复制完成和文件数量的消息
            msgbox(['复制完成！共复制了 ', num2str(file_count), ' 个文件。'], '复制结果');

            close(app.waitting_NOW)
      end

     % 回调函数：当选择“自定义”时显示自定义输入框
     function handleCustomFormatSelection(~,fileFormatDropDown, customFormatEditField)
            if strcmp(fileFormatDropDown.Value, '自定义')
                customFormatEditField.Visible = 'on';
            else
                customFormatEditField.Visible = 'off';
            end
        end

     % %% %%%% %%%%% function 【2】, 106RS OLD dat unpack %%%%
     % function [Data_out,Data_fs,Stim_info,acq_info,Stim_ONOFF_info,Stim_Emode_info,Freq_Stim,Polar_info,Rec_Parameters_info,Lost] = P01_F1a_106_unpack_dir(~,bit_data,IsaddNans,FILENAME) 

     % %% %%%% %%%%% function 【3】, 106RS OLD data unpack %%%%
     % function [Data_out,Data_fs,Stim_info,acq_info,Stim_ONOFF_info,Stim_Emode_info,Freq_Stim,Polar_info,Rec_Parameters_info,Lost] = P01_F2a_106_unpack_dir(~,bit_data,IsaddNans,FILENAME) 

     % %% %%%% %%%%% function 【4】, 106RS NEW unpack %%%%
     % function [Data_out,Data_fs,Stim_info,acq_info,Stim_ONOFF_info,Stim_Emode_info,Freq_Stim,Polar_info,Rec_Parameters_info,other_INFO,Lost,IPG_ID] = P01_F2b_106_unpack_dir_new(~,bit_data,IsaddNans,FILENAME) 
  
     % Unpack_all data from all
     function [data_unpackeded,data_frequency,data_stim_info,data_acq_info,data_stim_ONOFF_info,data_stim_Emode_info,data_Freq_Stim_info,data_polar_info,data_Rec_Parameters_info,data_Lost,Dealtiled_para,UNPACK_IS_OK,DATA_DATE,DATA_TIME,IPG_ID] = Unpack_all_Kind_Ori(app,data,filenames,IsaddNans)
          data_unpackeded = [];
          DATA_DATE = 'NaN';
          DATA_TIME = 'NaN';
          data_frequency = 'NaN';
          data_stim_info = 'NaN';
          data_acq_info = 'NaN';
          data_stim_ONOFF_info = 'NaN';
          data_stim_Emode_info = 'NaN';
          data_Freq_Stim_info = 'NaN';
          data_polar_info = 'NaN';
          data_Rec_Parameters_info = 'NaN';
          data_Lost = [];

          UNPACK_IS_OK = '解析成功' ;
          istimeset = 0;
          Dealtiled_para = '解析失败，无信息';
          IPG_ID = 'S_106RS';
           
         if filenames((end-3):end) == ".dat" % unpack 102RS data and OLD 106RS data
                     if length(data) < 1000
                         % uialert(app.MainFigure_batch,'数据长度过短或识别失败','提示','Icon','warning');
                         UNPACK_IS_OK = '解析失败 数据长度过短';
                         % msgbox('文件长度过短','提示','warn')
                         set(app.MainFigure_batch,'Pointer','arrow'); drawnow;
                         close(app.waitting_NOW)
                         return
                     end

                      try
                             try
                                [data_unpackeded,data_frequency,data_stim_info,data_acq_info,data_stim_ONOFF_info,data_stim_Emode_info,data_Freq_Stim_info,data_polar_info,data_Rec_Parameters_info,data_Lost] = P01_F1a_102_unpack_dir(app,data);
                                    if data_Lost < 4
                                       data_Lost = 0;
                                    end

                                if istimeset ~= 1
                                   try
                                        pattern = '(\d{4})-(\d{2})-(\d{2}) (\d{2})[:：](\d{2})[:：](\d{2})';
                                        tokens = regexp(filenames, pattern, 'tokens');
                                        year = tokens{1}{1};
                                        month = tokens{1}{2};
                                        day = tokens{1}{3};      DATA_DATE = [year,'年',month,'月',day,'日'];
                                        hour = tokens{1}{4};
                                        minute = tokens{1}{5};
                                        second = tokens{1}{6};   DATA_TIME = [hour,'时',minute,'分',second,'秒'];
                                        istimeset = 1;
                                   catch
                                        DATA_DATE = 'NaN';
                                        DATA_TIME = 'NaN';
                                   end
                                   try
                                     Dealtiled_para = {'※ 数据采集：102RS';['※ 刺激模式：',data_stim_info];['※ 采集模式：',strjoin(data_acq_info,' ')];['※ 丢包参考数: ',num2str(data_Lost)];['* 采集时间',DATA_DATE,DATA_TIME]};
                                   catch
                                     Dealtiled_para = {'※ 数据采集：102RS';['※ 刺激模式：',data_stim_info];['※ 采集模式：',data_acq_info,' '];['※ 丢包参考数: ',num2str(data_Lost)];['* 采集时间',DATA_DATE,DATA_TIME]};
                                   end
                                     IPG_ID = 'S_102RS';
                                end
                             catch
                                try
                                [data_unpackeded,data_frequency,data_stim_info,data_acq_info,data_stim_ONOFF_info,data_stim_Emode_info,data_Freq_Stim_info,data_polar_info,data_Rec_Parameters_info,data_Lost] = P01_F1a_106_unpack_dir(app,data,IsaddNans,filenames);
                                    if data_Lost < 4
                                       data_Lost = 0;
                                    end
                                if istimeset ~= 1
                                   try
                                        pattern = 'SourceData_(\d{4})(\d{2})(\d{2})(\d{2})(\d{2})(\d{2})';
                                        tokens = regexp(filenames, pattern, 'tokens');
                                        year = tokens{1}{1};
                                        month = tokens{1}{2};
                                        day = tokens{1}{3};      DATA_DATE = [year,'年',month,'月',day,'日'];
                                        hour = tokens{1}{4};
                                        minute = tokens{1}{5};
                                        second = tokens{1}{6};   DATA_TIME = [hour,'时',minute,'分',second,'秒'];
                                        istimeset = 1;
                                   catch
                                        DATA_DATE = 'NaN';
                                        DATA_TIME = 'NaN';
                                   end
                                   try
                                   Dealtiled_para = {'※ 数据采集：106RS (code:02)';['※ 刺激模式：',data_stim_info];['※  采集模式：',strjoin(data_acq_info,' ')];['※ 丢包参考数: ',num2str(data_Lost)];['* 采集时间',DATA_DATE,DATA_TIME]};    
                                   catch
                                   Dealtiled_para = {'※ 数据采集：106RS (code:02)';['※ 刺激模式：',data_stim_info];['※  采集模式：',data_acq_info,' '];['※ 丢包参考数: ',num2str(data_Lost)];['* 采集时间',DATA_DATE,DATA_TIME]};    
                                   end
                                   IPG_ID = 'S_106RS';
                                end
                                catch
                                      close(app.waitting_NOW)
                                      UNPACK_IS_OK = '解析失败 （.dat 1002）';
                                      Dealtiled_para = '解析失败，无信息 (code 02)';
                                      set(app.MainFigure_batch,'Pointer','arrow'); drawnow;
                                      return
                                end
                             end
                      catch
                          close(app.waitting_NOW)
                          set(app.MainFigure_batch,'Pointer','arrow'); drawnow;
                          UNPACK_IS_OK = '解析失败 （.dat 1002）';
                      end
                            
                            if length(data_unpackeded(1,:)) < 4*(data_frequency)
                                UNPACK_IS_OK = '解析失败 .dat数据过短，小于4个采样周期';
                                Dealtiled_para = '解析失败，数据过短，小于4个采样周期';
                                close(app.waitting_NOW)
                                return
                            end
                            
          elseif filenames((end-4):end) == ".data"
                                     if length(data) < 7000
                                         uialert(app.MainFigure_batch,'数据长度过短，可能无法进行后续分析，请选择其他数据','提示','Icon','warning');
                                         UNPACK_IS_OK = '解析失败 （.dat 1006）';
                                         set(app.MainFigure_batch,'Pointer','arrow'); drawnow;
                                         close(app.waitting_NOW)
                                         return
                                     end

                                        try
                                           if app.IS_OLD_VERSON.Value  == 1
                                            % try
                                                 % assignin("base","data",data)
                                                [data_unpackeded,data_frequency,data_stim_info,data_acq_info,data_stim_ONOFF_info,data_stim_Emode_info,data_Freq_Stim_info,data_polar_info,data_Rec_Parameters_info,data_Lost] = P01_F2a_106_unpack_dir(app,data,IsaddNans,filenames);
                                                % data_unpackeded,data_frequency,data_stim_info,data_acq_info,data_stim_ONOFF_info,data_stim_Emode_info,data_Freq_Stim_info,data_polar_info,data_Rec_Parameters_info,data_Lostl
                                                % assignin("base","stim_info",app.stim_info)
                                                 % assignin("base","acq_info",app.acq_info)
                                                    % if app.Lost_index < 4
                                                    %    app.Lost_index = 0;
                                                    % end
                                                    data_Lost = data_Lost -5 ;
                                                    if data_Lost < 0
                                                       data_Lost = 0;
                                                    end
                                                    % uialert(app.MainFigure_batch,{'106RS数据解包成功！';[app.stim_info,app.acq_info];['丢包数：',num2str(app.Lost_index)]},'(已加载.data文件) 数据信息确认','Icon','success')
                                                    % msgbox(,'提示','warn');
                                                    % app.IS_106RS = 2;

                                                   try
                                                        pattern = '(\d{4})-(\d{2})-(\d{2}) (\d{2})_(\d{2})_(\d{2})';
                                                        tokens = regexp(filenames, pattern, 'tokens');
                                                        year = tokens{1}{1};
                                                        month = tokens{1}{2};
                                                        day = tokens{1}{3};      DATA_DATE = [year,'年',month,'月',day,'日'];
                                                        hour = tokens{1}{4};
                                                        minute = tokens{1}{5};
                                                        second = tokens{1}{6};   DATA_TIME = [hour,'时',minute,'分',second,'秒'];
                                                        istimeset = 1;
                                                   catch
                                                        DATA_DATE = 'NaN';
                                                        DATA_TIME = 'NaN';
                                                   end
                                                       IPG_ID = 'S_106RS';
                                                   try
                                                       Dealtiled_para = {'※ 数据采集：106RS (code:04)';['※ 刺激参数：',data_stim_info];['※ 采集参数：',strjoin(data_acq_info,' ')];['※ 丢包参考数: ',num2str(data_Lost)];['* 采集时间',DATA_DATE,DATA_TIME];['※ IPG型号: ',IPG_ID]};
                                                   catch
                                                       Dealtiled_para = {'※ 数据采集：106RS (code:04)';['※ 刺激参数：',data_stim_info];['※ 采集参数：',(data_acq_info)];['※ 丢包参考数: ',num2str(data_Lost)];['* 采集时间',DATA_DATE,DATA_TIME];['※ IPG型号: ',IPG_ID]};
                                                   end

                                                   % 判断数据
                                                        if length(data_unpackeded(1,:)) < 4*(data_frequency)
                                                            % uialert(app.MainFigure_batch,'数据长度过短，无法进行后续分析，请选择其他数据','提示','Icon','warning');
                                                            UNPACK_IS_OK = '解析失败 .data数据过短，小于4个采样周期';
                                                            % cla(app.axis_pre)
                                                            close(app.waitting_NOW)
                                                            return
                                                        end
                                            else
                                                 % assignin("base","data",data)
                                                try
                                                [data_unpackeded,data_frequency,data_stim_info,data_acq_info,data_stim_ONOFF_info,data_stim_Emode_info,data_Freq_Stim_info,data_polar_info,data_Rec_Parameters_info,otherINFO,data_Lost,IPG_ID] = P01_F2b_106_unpack_dir_new(app,data,IsaddNans,filenames);
                                                % data_unpackeded,data_frequency,data_stim_info,data_acq_info,data_stim_ONOFF_info,data_stim_Emode_info,data_Freq_Stim_info,data_polar_info,data_Rec_Parameters_info,data_Lost
                                                % assignin("base","stim_info",app.stim_info)
                                                % assignin("base","acq_info",app.acq_info)
                                                    % if app.Lost_index < 4
                                                    %    app.Lost_index = 0;
                                                    % end
                                                    % assignin("base","lost",app.Lost_index)
                                                    data_Lost = data_Lost -2;
                                                    if data_Lost < 0
                                                       data_Lost = 0;
                                                    end
                                                    % uialert(app.MainFigure_batch,['106RS数据解包成功！',app.stim_info,app.acq_info,'丢包数：',num2str(app.Lost_index)],'(已加载.data文件)数据信息确认','Icon','success')
                                                    % uialert(app.MainFigure_batch,{'106RS数据解包成功！';[data_stim_info,data_acq_info];['丢包数：',num2str(data_Lost)]},'(已加载.data文件) 数据信息确认','Icon','success')

                                                    % app.IS_106RS = 2;
                                                    try
                                                        pattern = '(\d+)年(\d{1,2})月(\d{1,2})日(\d{1,2})时(\d{1,2})分(\d{1,2})秒';tokens = regexp(otherINFO, pattern, 'tokens');
                                                        year = tokens{1}{1};
                                                        month = tokens{1}{2};
                                                        day = tokens{1}{3};       DATA_DATE = [year,'年',month,'月',day,'日'];
                                                        hour = tokens{1}{4};
                                                        minute = tokens{1}{5};
                                                        second = tokens{1}{6};     DATA_TIME = [hour,'时',minute,'分',second,'秒'];
                                                        istimeset = 1;
                                                    catch
                                                        DATA_DATE = 'NaN';
                                                        DATA_TIME = 'NaN';
                                                    end

                                                    try
                                                    Dealtiled_para = {'※ 数据采集：106RS (NEW)';['※ 刺激参数：',data_stim_info];['※ 采集参数：',strjoin(data_acq_info,' ')];['※ 丢包参考数: ',num2str(data_Lost)];['※ 其他信息: ',otherINFO];['※ IPG编号: ',IPG_ID]};
                                                    catch
                                                    Dealtiled_para = {'※ 数据采集：106RS (NEW)';['※ 刺激参数：',data_stim_info];['※ 采集参数：',data_acq_info,' '];['※ 丢包参考数: ',num2str(data_Lost)];['※ 其他信息: ',otherINFO];['※ IPG编号: ',IPG_ID]};
                                                    end
                                                 
                                                 catch
                                                      close(app.waitting_NOW)
                                                      % uialert(app.MainFigure_batch,'.data文件解包失败 （提示：如为旧版软件采集，请手动勾选“旧版解析”）','提示','Icon','warning');
                                                      UNPACK_IS_OK = '解析失败：请检查是否是旧版data文件';
                                                      Dealtiled_para = '解析失败，请检查是否为旧版格式';
                                                      return
                                                 end
                                            end
                                            % end
                                            % 判断数据
                                            if length(data_unpackeded(1,:)) < 4*(data_frequency)
                                                % uialert(app.MainFigure_batch,'数据长度过短，无法进行后续分析，请选择其他数据','提示','Icon','warning');
                                                UNPACK_IS_OK = '解析失败 .data数据过短，小于4个采样周期';
                                                Dealtiled_para = '解析失败，数据小于4个采样周期';
                                                % app.Data_config.Enable = "off";
                                                % cla(app.axis_pre)
                                                close(app.waitting_NOW)
                                                return
                                            end

                                        catch
                                             close(app.waitting_NOW)
                                             UNPACK_IS_OK = '解析失败：.data文件解析失败 请检查数据与版本 -004';
                                             Dealtiled_para = '解析失败，请检查版本';
                                             % uialert(app.MainFigure_batch,'.data文件解析失败 请检查数据与版本 code:1004','提示','Icon','warning');
                                             return
                                             msgbox('.dat文件解包错误 -004','提示','warn')
                                        end
                                        
          elseif filenames((end-3):end) == ".mat"
                 try
                    U = string(app.First_Filenames);
                   % assignin('base','U',U)
                    app.mat_data = load(U);
                 catch
                    close(app.waitting_NOW)
                    % msgbox('文件访问错误','提示','warn')
                    uialert(app.MainFigure_batch,'文件访问失败','提示','Icon','warning')
                 end
    
                 try
                    mat_data_in = app.mat_data.data;
                    % assignin("base","mat_data_in",mat_data_in)
                    [app.Data_ori,app.Data_now,app.data_fs,app.Stim_Info,app.Acq_Info,app.Stim_ONOFF_Info,app.Stim_Emode_info,app.Freq_Stim,app.Polar_info,app.Rec_Parameters_Info] = P02_F1b_load_mat(app,mat_data_in);
                    % msgbox([app.stim_info,app.acq_info],'(已加载.mat文件)数据信息确认','提示','warn');
                    uialert(app.MainFigure_batch,[app.Stim_Info,app.Acq_Info],'(已加载.mat文件)数据信息确认','Icon','success')
                 catch
                    close(app.waitting_NOW)
                    uialert(app.MainFigure_batch,'.mat文件内容残缺','提示','Icon','warning')
                    % msgbox('.mat文件内容残缺','提示','warn')
                 end
                 
                 % app.infoSTIM.Text = [app.Stim_ONOFF_info,app.Stim_Emode_info,app.Freq_Stim];
                 app.infoACQ.Text = cell2mat([app.Polar_info,app.Rec_Parameters_Info]);
                 % assignin('base','Data',app.Data_now);
                 % app.Data_ori = app.Data_now;
                 % enable
                 app.View_chi_DropDown.Enable = "on";
                 app.Data_config.Enable = 'on';
                 app.filter_sample_rate.Value = num2str(app.data_fs);
                 app.samplefrequency_STM1.Value = app.data_fs;
                 app.Powercalcu_frequency.Value = num2str(app.data_fs);
                 app.save_data2workspace.Enable = "on";
                 app.Save_Data.Enable = "on";

                 app.defult_date = 'NaN';
                 app.defult_time = 'NaN';

          elseif filenames((end-3):end) == ".txt"
                   samplingRate = 1;
                 try
                    filesssname = string(app.First_Filenames);

                    fileID = fopen(filesssname, 'r');
                    if fileID == -1
                        uialert(app.MainFigure_batch,'文件访问失败','提示','Icon','warning')
                    end

                    firstLine1 = fgetl(fileID); % READ 第一行
                    firstLine2 = fgetl(fileID); % READ 第二行
                    trysss = [firstLine1,firstLine2];
                    pattern_fs = '采样率：(\d+)';
                    match_fs = regexp(trysss, pattern_fs, 'tokens');

                    pattern_channel = '_(\w+\+\w+-)';
                    matche_channel = regexp(trysss, pattern_channel, 'tokens');


                    if ~isempty(match_fs)
                        samplingRate = str2double(match_fs{1}{1});
                    else
                        try
                            str = app.First_Filenames;
                            pattern = '(\d+)Hz';
                            match_fs = regexp(str, pattern, 'tokens');
                            % frequency 
                            samplingRate = str2double(match_fs{1}{1}); % 将找到的字符串转换为数字
                        catch
                            answer = inputdlg({'请手动输入采样率：'}, '输入', [1 30],{'500'});
                            samplingRate =  str2double(answer); % 如果没有匹配到，返回NaN
                        end
                    end

                    app.data_fs = samplingRate;

                    if isempty(matche_channel)
                       lineCounter = 0;
                        % 指定要读取的行号
                        targetLineNum = 3; % 以读取第3行为例
                        % 循环遍历文件直到到达目标行
                        while ~feof(fileID)
                            lineCounter = lineCounter + 1;
                            line = fgetl(fileID);
                            if lineCounter == targetLineNum
                                break;
                            end
                        end
                        numbers = str2num(line);
                        n = length(numbers); 
                        cells = cell(1, length(numbers)); % 创建一个1xn的cell数组
                        % 使用循环为每个单元赋值
                        for i = 1:n
                           cells{i} = num2str(i); % 将索引转换为字符串并赋值给相应的cell单元
                        end
                       app.Rec_Parameters_Info = cells;
                    else
                       app.Rec_Parameters_Info = [matche_channel{:}];
                    end

                       app.Data_ori = readmatrix(filesssname, 'NumHeaderLines', 6,'Delimiter', ',')';
                       assignin("base",'dataorri',app.Data_ori);

                       try
                           uniqueValues = unique(app.Data_ori(2,:));
                           if numel(uniqueValues)<3
                              app.Data_ori(1:2,:) = [];
                           end
                       end
                       
                       % app.Data_ori = app.Data_ori(~isnan(app.Data_ori));
                       fclose(fileID);
                       app.Data_now = app.Data_ori;
                       app.Dealtiled_para = {'※ 数据来源：TXT文档';['※ 采样率: ',num2str(samplingRate)];['※ 采集通道: ',cell2mat(app.Rec_Parameters_Info)]};
                       
                       % assignin("base",'Data_ori',app.Data_ori);
                      % 判断数据
                            if length(app.Data_now(1,:)) < 4*(app.data_fs)
                                uialert(app.MainFigure_batch,'数据长度过短，无法进行后续分析，请选择其他数据','提示','Icon','warning');
                                app.infoACQ.Text = cell2mat([app.Polar_info,app.Rec_Parameters_Info]);
                                app.Data_config.Enable = "off";
                                cla(app.axis_pre)
                                close(app.waitting_NOW)
                                return
                            end
                       uialert(app.MainFigure_batch,[app.Dealtiled_para],'(已加载.txt文件) 数据信息确认','Icon','success')

                       app.Stim_ONOFF_Info = ' ';
                       app.Stim_Emode_info = ' ';
                       app.Freq_Stim = ' ';
                       % app.Rec_Parameters_info
                       app.Polar_info = ' ';
                     iii = app.View_chi_DropDown.Value;
                     % assignin("base",'datanow',app.Data_now);
                     plot(app.axis_pre,app.Data_now(str2double(iii),500:end));
                     % 
                     app.Data_ori = app.Data_now;
                     % enable
                     app.View_chi_DropDown.Enable = "on";
                     app.Data_config.Enable = 'on';
                     app.filter_sample_rate.Value = num2str(app.data_fs);
                     app.samplefrequency_STM1.Value = app.data_fs;
                     app.Powercalcu_frequency.Value = num2str(app.data_fs);
                     app.save_data2workspace.Enable = "on";
                     app.Save_Data.Enable = "on";
                 catch
                    % close(app.waitting_NOW)
                    % msgbox('文件访问错误','提示','warn')
                    uialert(app.MainFigure_batch,'文件访问失败','提示','Icon','warning')
                    close(app.waitting_NOW)
                 end
         end
    end

     % function [acc] = T01_Unpack_ACC_data(~,data)

     function DBS_Data = Save_NERCN_Format(app,DBS_Data_In, para, Starttime, Fs_LFP, Data_LFP, Processed_LFP, Channel_LFP, ...
                                            Fs_ACC, Data_ACC, Processed_ACC, Channel_ACC, ...
                                            Fs_EcoG, Data_EcoG, Processed_EcoG, Channel_EcoG, Savetime, appendMode)
                % Save_NERCN_Format - 保存或追加解析后的数据到标准格式的结构体中
                %
                % 功能：
                % 该函数用于将解析后的数据保存为特定格式的结构体(DBS_Data)，支持初始化数据或以追加模式存储。
                % 在追加模式下，支持检查新数据的 IPG 编号是否与已有数据一致，并根据用户选择进行处理。
                %
                % 输入参数：
                %   DBS_Data_In  - 已存在的 DBS_Data 结构体，若时设置为 0
                %   para         - 包含元信息的结构体，需包含以下字段：
                %                  * ori_dataname：原始文件名
                %                  * IPG_NUM：IPG 编号
                %   Starttime    - 数据的起始时间 (datetime)
                %   Fs_LFP       - LFP 数据采样频率
                %   Data_LFP     - LFP 原始数据
                %   Processed_LFP - LFP 处理后的数据
                %   Channel_LFP  - LFP 通道信息
                %   Fs_ACC       - 加速度计数据采样频率
                %   Data_ACC     - 加速度计原始数据
                %   Processed_ACC - 加速度计处理后的数据
                %   Channel_ACC  - 加速度计通道信息
                %   Fs_C_EEG     - EEG 数据采样频率
                %   Data_C_EEG   - EEG 原始数据
                %   Processed_C_EEG - EEG 处理后的数据
                %   Channel_C_EEG - EEG 通道信息
                %   Savetime     - 数据保存时间 (datetime)
                %   appendMode   - 是否以追加模式存储 (0 = 非追加模式，1 = 追加模式)
                %
                % 输出结果：
                %   DBS_Data - 存储解析后数据的结构体，包括以下字段：
                %              * Data_LFP：LFP 数据表格
                %              * Data_ACC：加速度计数据表格
                %              * Data_C_EEG：EEG 数据表格
                %              * INFO：元信息表格
                %              * AppendLog：追加记录表格，包含追加索引和保存时间
                %              * IPG_NUM：IPG 编号
                %              * Savetime：保存时间
                %
                % 注意事项：
                % 1. 如果 appendMode 为 1（追加模式），将检查新数据的 IPG_NUM 是否与已有数据一致。
                %    若不一致，将弹出对话框，用户可选择以下三种操作：
                %      a. 合并并覆盖：覆盖已有数据的 IPG_NUM，并继续追加。
                %      b. 不合并：取消追加模式，仅存储当前数据为新数据集。
                %      c. 退出：终止操作，不存储数据。
                % 2. 在初始化模式下 (appendMode = 0)，会清空已有数据并重新创建结构体。
                % 3. 追加模式下每种数据类型 (LFP, ACC, EEG) 的 Index 独立递增。
                if appendMode == 0 || isempty(fieldnames(DBS_Data_In))
                        DBS_Data = struct();
                        DBS_Data.Data_LFP = table();
                        DBS_Data.Data_ACC = table();
                        DBS_Data.Data_C_EEG = table();
                        DBS_Data.INFO = table();
                        DBS_Data.AppendLog = table(); % 用于存储追加记录的索引和保存时间
                        DBS_Data.Savetime = []; % 初始化保存时间
                        DBS_Data.AppendMode = false; % 初始化为非追加模式
                        DBS_Data.IPG_NUM = para.IPG_NUM; % 初始化 IPG_NUM
                        currentIndex_LFP = 1; % 初始化 Data_LFP 索引
                        currentIndex_ACC = 1; % 初始化 Data_ACC 索引
                        currentIndex_ECoG = 1; % 初始化 Data_C_EEG 索引
                        currentIndex_INFO = 1; % 初始化 INFO 索引
                
                else
                    
                        DBS_Data = DBS_Data_In; % 使用已有数据结构
                        DBS_Data.AppendMode = appendMode; % 设置当前模式
            
                    % 检查 IPG_NUM 是否一致
                        if isfield(DBS_Data, 'IPG_NUM') && ~strcmp(DBS_Data.IPG_NUM, para.IPG_NUM)
                        % 弹出对话框，提示用户选择操作
                        choice = questdlg(...
                            sprintf('当前 IPG 编号 (%s) 与已有数据的 IPG 编号 (%s) 不一致，是否继续？', para.IPG_NUM, DBS_Data.IPG_NUM), ...
                            'IPG 编号冲突', ...
                            '合并并覆盖', '不合并', '退出', '合并并覆盖');
                        
                            switch choice
                            case '合并并覆盖'
                                DBS_Data.IPG_NUM = para.IPG_NUM; % 覆盖 IPG_NUM
                            case '不合并'
                                appendMode = 0; % 取消追加模式，重新初始化数据
                                DBS_Data = Save_NERCN_Format(app,0, para, Starttime, Fs_LFP, Data_LFP, Processed_LFP, Channel_LFP, ...
                                                             Fs_ACC, Data_ACC, Processed_ACC, Channel_ACC, ...
                                                             Fs_EcoG, Data_EcoG, Processed_EcoG, Channel_EcoG, Savetime, appendMode);
                                return; % 退出函数，存储当前数据
                            case '退出'
                               
                            end
                         end
    
                    % 如果是追加模式，从每个表中获取当前最大索引值
                    try
                        currentIndex_LFP = max(cell2mat(DBS_Data.Data_LFP.Index)) + 1;
                    catch
                        currentIndex_LFP = 1;
                    end
            
                    try
                        currentIndex_ACC = max(cell2mat(DBS_Data.Data_ACC.Index)) + 1;
                    catch
                        currentIndex_ACC = 1; % 初始化索引
                    end
                    
                    try
                        currentIndex_ECoG = max(cell2mat(DBS_Data.Data_C_EEG.Index)) + 1;
                    catch
                        currentIndex_ECoG = 1; % 初始化索引
                    end
            
                    try
                        currentIndex_INFO = max(cell2mat(DBS_Data.INFO.Index)) + 1;
                    catch
                        currentIndex_INFO = 1; % 初始化索引
                    end
                end
            
                DBS_Data.IPG_NUM = para.IPG_NUM;
            
            
                % 更新保存时间
                DBS_Data.Savetime = Savetime;
            
                % 获取文件名
                OriginalFileName = validateInput(app,para.ori_dataname, 'Unknown');
            
                % 添加 Data_LFP 数据
                newRowLFP = table(...
                    {currentIndex_LFP}, ... % 数据索引
                    {'Realtime acquisition'}, ... % 数据类型
                    {Starttime}, ... % 开始时间
                    {validateInput(app,Fs_LFP, [])}, ... % 采样频率
                    {validateInput(app,Channel_LFP, [])}, ... % 通道
                    {OriginalFileName}, ... % 原始文件名
                    {validateInput(app,Data_LFP, [])}, ... % 原始数据
                    {validateInput(app,Processed_LFP, [])}, ... % 处理后的数据
                    'VariableNames', {'Index', 'Type', 'StartTime', 'Fs', 'Channel', 'OriginalFileName', 'RawData', 'ProcessedData'} ...
                );
                DBS_Data.Data_LFP = [DBS_Data.Data_LFP; newRowLFP];
            
                % 添加 Data_ACC 数据
                newRowACC = table(...
                    {currentIndex_ACC}, ... % 数据索引
                    {'Realtime acquisition'}, ... % 数据类型
                    {Starttime}, ... % 开始时间
                    {validateInput(app,Fs_ACC, 'No data')}, ... % 采样频率
                    {validateInput(app,Channel_ACC, 'No data')}, ... % 通道
                    {OriginalFileName}, ... % 原始文件名
                    {validateInput(app,Data_ACC, 'No data')}, ... % 原始数据
                    {validateInput(app,Processed_ACC, 'No data')}, ... % 处理后的数据
                    'VariableNames', {'Index', 'Type', 'StartTime', 'Fs', 'Channel', 'OriginalFileName', 'RawData', 'ProcessedData'} ...
                );
                DBS_Data.Data_ACC = [DBS_Data.Data_ACC; newRowACC];
            
                % 添加 Data_C_EEG 数据
                newRowEEG = table(...
                    {currentIndex_ECoG}, ... % 数据索引
                    {'Realtime acquisition'}, ... % 数据类型
                    {Starttime}, ... % 开始时间
                    {validateInput(app,Fs_EcoG, 'No data')}, ... % 采样频率
                    {validateInput(app,Channel_EcoG, 'No data')}, ... % 通道
                    {OriginalFileName}, ... % 原始文件名
                    {validateInput(app,Data_EcoG, 'No data')}, ... % 原始数据
                    {validateInput(app,Processed_EcoG, 'No data')}, ... % 处理后的数据
                    'VariableNames', {'Index', 'Type', 'StartTime', 'Fs', 'Channel', 'OriginalFileName', 'RawData', 'ProcessedData'} ...
                );
                DBS_Data.Data_C_EEG = [DBS_Data.Data_C_EEG; newRowEEG];
            
                % 添加 INFO 数据
                infoFields = {'Index','acq_info1', 'acq_info2', 'Stim_onoff_info', 'ori_dataname', 'save_type', 'IPG_NUM'};
                newRowINFO = table(...
                    {currentIndex_INFO}, ... % 数据索引
                    {para.acq_info1},...
                    {para.acq_info2},...
                    {para.Stim_onoff_info},...
                    {para.ori_dataname},...
                    {para.save_type},...
                    {para.IPG_NUM},...
                    'VariableNames', infoFields ... % 动态设置表头
                );
                DBS_Data.INFO = [DBS_Data.INFO; newRowINFO];
            
                % 如果是追加模式，记录保存时间和追加的索引到 AppendLog 表格
                if appendMode
                    newLogEntry = table(... % 存储追加记录
                        {currentIndex_LFP}, ... % 记录 LFP 的索引
                        {Savetime}, ... % 保存时间
                        'VariableNames', {'Index', 'Savetime'} ...
                    );
                    DBS_Data.AppendLog = [DBS_Data.AppendLog; newLogEntry];
                end
            end

      function value = validateInput(~,inputValue, defaultValue)
            if nargin < 1 || isempty(inputValue)
                value = defaultValue;
            else
                value = inputValue;
            end
      end

      function value = convertToString(~,inputValue)
            if ischar(inputValue) % 如果是字符向量
                value = string(inputValue); % 转为字符串数组
            elseif isstring(inputValue) % 如果是字符串数组
                value = inputValue;
            else % 其他类型，转换为字符串
                value = string(inputValue);
            end
      end

    end
    
    methods (Access = public)
        
        function app = APP_analysis_Data_batch_CHI(app,highPassFilter, lowPassFilter)
        % 存储传入的数据
        app.highPassFilter = highPassFilter;
        app.lowPassFilter = lowPassFilter;
        end

        function  update_cut_data_batch(app, Data_cutted)
            channel_num = length(app.Channel_Check.Items);
            app.Data_NOW_UNPACK = Data_cutted(channel_num+1:end,:);
            app.Data_NOW_Preprocessed = Data_cutted(1:channel_num,:);

            value = app.Channel_Check.Value;
            i = value;
            plot(app.Data_time_PLOT,(1:1:length(app.Data_NOW_Preprocessed(str2double(i),300:end)))/app.data_fs,app.Data_NOW_Preprocessed(str2double(i),300:end));
            
            Data1 = app.Data_NOW_Preprocessed(str2double(i),300:end);
            
            [B,f,t] = specgram(Data1,round(app.data_fs),round(app.data_fs),round(app.data_fs),round(app.data_fs*0.75));
            imagesc(app.Data_TF_PLOT,t,f,20*log10(abs(B)));
            app.Data_TF_PLOT.YDir = "normal";
            app.Data_TF_PLOT.Colormap = jet;
            app.Data_TF_PLOT.YLim = [0 180];
            app.Data_TF_PLOT.XLim = [t(1) t(end)];
            app.Slider_TIME.Limits = [1,length(Data1)/app.data_fs];
            app.Slider_TIME.Value = [1,length(Data1)/app.data_fs];
        end
         
        function closeDialog_batch(app)
            % 关闭对话框的方法
            close(app.Dialog);
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, APP_anylysis_CHI_0_1, highPassFilter, lowPassFilter)
            appDir = fileparts(mfilename('fullpath'));
            addpath(appDir);
            
            try
            app.APP_anylysis_CHI_0_1 = APP_anylysis_CHI_0_1;
            end
            app.IS_Para_Identy = 0;

            try
            if isempty(highPassFilter) &&  isempty(lowPassFilter) 
                app.Identify_Parameter.Text = '未识别到相关配置信息';
                app.Identify_Parameter.FontColor = [1.00,0.41,0.16];
                app.LowPass.Enable = "off";
                app.High_Pass.Enable = "off";
                app.IS_Para_Identy = 0;
            elseif ~isempty(highPassFilter) && isempty(lowPassFilter) 
                app.Identify_Parameter.Text = '识别到配置信息';
                app.Identify_Parameter.FontColor = [0.53,0.64,0.51];
                app.High_Pass.Enable = "on";
                app.highPassFilter = highPassFilter;
                app.IS_Para_Identy = 1;
            elseif isempty(highPassFilter) && ~isempty(lowPassFilter) 
                app.Identify_Parameter.Text = '识别到配置信息';
                app.Identify_Parameter.FontColor = [0.53,0.64,0.51];
                app.LowPass.Enable = "on";
                app.lowPassFilter = lowPassFilter;
                app.IS_Para_Identy = 1;
            elseif ~isempty(highPassFilter) && ~isempty(lowPassFilter) 
                app.Identify_Parameter.Text = '识别到配置信息';
                app.Identify_Parameter.FontColor = [0.53,0.64,0.51];
                app.High_Pass.Enable = "on";
                app.highPassFilter = highPassFilter;
                app.LowPass.Enable = "on";
                app.lowPassFilter = lowPassFilter;
                app.IS_Para_Identy = 1;
            end

            catch
                app.Identify_Parameter.Text = '未识别到相关配置信息';
                app.Identify_Parameter.FontColor = [1.00,0.41,0.16];
                app.LowPass.Enable = "off";
                app.High_Pass.Enable = "off";
                app.IS_Para_Identy = 0;
            end

            
            screenSize = get(groot, 'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            appWidth = app.MainFigure_batch.Position(3);
            appHeight = app.MainFigure_batch.Position(4);
            horizontalCenter = (screenWidth - appWidth) / 2;
            verticalCenter = (screenHeight - appHeight) / 2;
            app.MainFigure_batch.Position(1:2) = [horizontalCenter, verticalCenter];

            colorbacks = [0.76,0.73,0.82];
            text(app.Data_time_PLOT,0.25,0.5,'脑电数据离线分析系统','Color',[0.98,0.98,0.98],"BackgroundColor",colorbacks,"FontSize",24,"FontSmoothing","on","FontWeight","bold")
            app.Data_time_PLOT.Color = [0.76,0.73,0.82];

            colorbacks = [0.76,0.73,0.82];
            text(app.Data_ACC_PLOT,0.25,0.5,'脑电数据离线分析系统','Color',[0.98,0.98,0.98],"BackgroundColor",colorbacks,"FontSize",24,"FontSmoothing","on","FontWeight","bold")
            app.Data_ACC_PLOT.Color = [0.76,0.73,0.82];

            colorbacks = [0.76,0.73,0.82];
            text(app.TIME_ACC,0.25,0.5,'脑电数据离线分析系统','Color',[0.98,0.98,0.98],"BackgroundColor",colorbacks,"FontSize",24,"FontSmoothing","on","FontWeight","bold")
            app.TIME_ACC.Color = [0.76,0.73,0.82];

            colorbacks = [0.76,0.73,0.82];
            text(app.TIME_AMP,0.25,0.5,'脑电数据离线分析系统','Color',[0.98,0.98,0.98],"BackgroundColor",colorbacks,"FontSize",34,"FontSmoothing","on","FontWeight","bold")
            app.TIME_AMP.Color = [0.76,0.73,0.82];

            colorbacks = [0.76,0.73,0.82];
            text(app.TIME_POW,0.25,0.5,'脑电数据离线分析系统','Color',[0.98,0.98,0.98],"BackgroundColor",colorbacks,"FontSize",34,"FontSmoothing","on","FontWeight","bold")
            app.TIME_POW.Color = [0.76,0.73,0.82];

            colorbacks = [0.76,0.73,0.82];
            text(app.Data_TF_PLOT,0.25,0.5,'脑电数据离线分析系统','Color',[0.98,0.98,0.98],"BackgroundColor",colorbacks,"FontSize",24,"FontSmoothing","on","FontWeight","bold")
            app.Data_TF_PLOT.Color = [0.76,0.73,0.82];
            app.MainFigure_batch.Resize = "off";
            app.Num_of_unpack_NOW = 1;
            % linkaxes([app.TIME_AMP,app.TIME_POW],'x')
            linkaxes([app.Data_time_PLOT,app.Data_TF_PLOT,app.TIME_ACC],'x')
            set(app.MainFigure_batch,'Icon','icon_48.png');
            app.MainFigure_batch.Name = 'NNSR 批处理工具';

        end

        % Button pushed function: File_selection
        function File_selectionButtonPushed(app, event)
            app.waitting_NOW = uiprogressdlg(app.MainFigure_batch,'Title','正在获取文件夹',...
            'Indeterminate','on');
            drawnow
            Filename_NOW = [];
            app.Num_of_unpack_NOW = 1; % 重置当前
            app.Path_NOW = [];
            if app.format_select.Value == "1"
               [Filename_NOW,app.Path_NOW] = uigetfile({'*.data'},'打开LFP文件','MultiSelect','on');
            elseif app.format_select.Value == "2"
               [Filename_NOW,app.Path_NOW] = uigetfile({'*.dat'},'打开LFP文件','MultiSelect','on');
            elseif app.format_select.Value == "3"
               [Filename_NOW,app.Path_NOW] = uigetfile({'*.txt'},'打开LFP文件','MultiSelect','on');
            elseif app.format_select.Value == "4"
               [Filename_NOW,app.Path_NOW] = uigetfile({'*.mat'},'打开LFP文件','MultiSelect','on');
            end
            
            % [app.Filename_NOW,path] = uigetfile({'*.dat;*.mat;*.data;*.txt'},'打开LFP文件','MultiSelect','on');
            if isequal(Filename_NOW,0) || isequal(app.Path_NOW,0)
               uialert(app.MainFigure_batch,'用户取消了文件选择','提示','Icon','info');
            else
                % 重新梳理文件名称
                [app.Filename_Processed,app.displays] = ProcessingFilename(app,Filename_NOW);
                app.First_Filename = app.Filename_Processed(1,:);
                try
                    app.data_table.Data = cell2table(app.displays, 'VariableNames', {'Strings'});
                catch
                    % assignin('base','filename',app.Filename)
                    app.Filename_Processed = cellstr(app.Filename_Processed(:,:));
                    app.data_table.Data = app.displays;
                end
                    app.First_Filenames = app.Filename_Processed(:,:);
    
                if length(size(app.First_Filenames{1,2})) <= 1 % Filename return
                    uialert(app.MainFigure_batch,'请选择有效文件','提示','Icon','warning');
                    % app.data_config.Enable = "off";
                else
                    % app.SaVE_path.Value = path;
                    % app.data_config.Enable = "on";
                    % app.add_file.Enable = 'on';
                    app.delete_all.Enable = "on";
                    cd(app.Path_NOW)
                    % addpath(app.Path_NOW);
                    app.selected_dir.Value = app.Path_NOW;
                    app.Save_path.Value = [app.Path_NOW,'Processing\'];
                    app.Num_of_unpack_ALL = size(app.data_table.Data,1); %多少列数据
                    app.Start_TASK.Enable = "on";
                    app.Get_Info.Enable = "on";

                end
            end
            
            try
                filena = app.First_Filenames{1,2};
                if filena(1:4) == "head" % unpack 102RS data and OLD 106RS data
                   app.IS_OLD_VERSON.Value = 1;
                elseif filena(1:5) == "noacc"
                   app.IS_OLD_VERSON.Value = 1;
                else
                   app.IS_OLD_VERSON.Value = 0;
                end
            end
            
            % Process ACC data
            
            close(app.waitting_NOW)
            
             % assignin('base','pathsss',app.data_table.Data)
        end

        % Cell selection callback: data_table
        function data_tableCellSelection(app, event)
            
            indices = event.Indices;
            % assignin("base","indices",indices)
            try
                indi_loop = indices(1,1) ;
                app.Filename_delete_NOW = indi_loop;
                app.delete_file.Enable = "on";
            catch
                app.delete_file.Enable = "off";
            end

        end

        % Button pushed function: delete_all
        function delete_allButtonPushed(app, event)

            try
                % app.Filename_NOW = {};
                app.Filename_Processed = {};
                app.Filename_NEW = {};
                app.data_table.Data = {};
                % app.add_file.Enable = "off";
                app.delete_file.Enable = "off";
                app.delete_all.Enable = "off";
                uialert(app.MainFigure_batch,'已清空列表','提示','Icon','info');

            end

        end

        % Button pushed function: delete_file
        function delete_fileButtonPushed(app, event)
               
           try
               arry_table_all = app.data_table.Data;
               arry_table_all(app.Filename_delete_NOW,:) = [];
               app.data_table.Data = arry_table_all;
               
               app.Filename_Processed(app.Filename_delete_NOW,:) = [];
               
               app.Filename_Processed= cellstr(app.Filename_Processed);
           catch

               app.delete_allButtonPushed(app)
               app.Filename_Processed= cellstr(app.Filename_Processed);
           end

        end

        % Button pushed function: Save_Dir
        function Save_DirButtonPushed(app, event)
            
            try
            fild = uigetdir;
            app.Save_path.Value = [fild,'\'];
            % app.current_Path = path;
            catch
                uialert(app.MainFigure_batch,'取消路径选择','提示','Icon','warning')
                % msgbox('请选择路径','提示','warn')
            end

        end

        % Button pushed function: Start_TASK
        function Start_TASKButtonPushed(app, event)
           loopINDEX = 1;
           if app.Num_of_unpack_NOW == 1 % 初始化参数
              app.Save_processinG = {};
              app.IS_UNPACK_OK = {};
              app.Specified_Unpack_Info = {};
              app.DBS_DATA_TOSAVE = struct();
               if app.Check_Every_Seg.Value == 1 % 根据模式设置不同循环次数
                   loopINDEX = length(1);
                   app.Start_TASK.Enable = "off";
               elseif app.Check_Every_Seg.Value == 0
                   loopINDEX = length(app.Filename_Processed(:,1))+1;
               end
           end

           for  P = 1:1:loopINDEX
                if app.data_conpensate.Value == 1
                    IsaddNans = 1;
                elseif  app.data_conpensate.Value == 0
                    IsaddNans = 0;
                end
           
                % 初始化
                app.Data_NOW_UNPACK = [];
                app.data_fs = [];
                app.Rec_Parameters_Info = {1;2;3;4};
                data_Lost = 0;
                Dealtiled_para =  {' '};
                UNPACK_IS_OK = ' ';
                DATA_DATE = ' ';
                DATA_TIME = ' ';
                 % app.Num_of_unpack_NOW
               if app.Num_of_unpack_NOW <= length(app.Filename_Processed(:,1))
                  % unpack
                % try
                % assignin('base','NUMNOW',app.Num_of_unpack_NOW)
                % app.Num_of_unpack_NOW
                Filename_ONE = app.Filename_Processed(app.Num_of_unpack_NOW,:);

                 app.waitting_NOW = uiprogressdlg(app.MainFigure_batch,'Title',['正在获取文件',Filename_ONE{1,1}],...
                'Indeterminate','on');
                    drawnow

                    try
                        list1=dir([cell2mat(app.selected_dir.Value),Filename_ONE{1,2}]);
                        % list3=dir([cell2mat(app.selected_dir.Value),Filename_ONE{1,4}]);
                        % List 多个List单元分别存储不同的数据输入
                    catch
                        list1=dir(Filename_ONE{1,2});
                    end
                    
                    app.UNPACKED_NOW = app.Filename_Processed(app.Num_of_unpack_NOW,:);
                    
                   % 循环中定义Table颜色标识
                   numRows = size(app.displays, 1);
                   backgroundColors = repmat(app.defult_tab_color, numRows, 1);
                   highlightRow = app.Num_of_unpack_NOW;
                   backgroundColors(highlightRow, :) = app.highlight_tab_color;
                   app.data_table.BackgroundColor = backgroundColors;
                   %  定义Table颜色标识
                  
                    app.Num_of_unpack_NOW = app.Num_of_unpack_NOW+1; % 当前计数+1
                    file_id = fopen(list1(1).name);
                   
                       try
                           data= fread(file_id); 
                           fclose(file_id);
                       catch
                           uialert(app.MainFigure_batch,'文件访问失败','提示','Icon','warning');
                           % 提示存储失败
                           if app.Check_Every_Seg.Value == 0
                               IS_save_pro_suc = '数据保存失败'; % 默认值
                               app.Save_processinG = [app.Save_processinG;[Filename_ONE{1,1},IS_save_pro_suc]];
                           end
                           close(app.waitting_NOW)
                       end
                       % 解析 lfp数据
                        try
                          [app.Data_NOW_UNPACK,app.data_fs,app.Stim_Info,app.Acq_Info,app.Stim_ONOFF_Info,app.Stim_Emode_Info,app.Freq_StiM,app.Polar_Info,app.Rec_Parameters_Info,app.data_LosT,Dealtiled_para,UNPACK_IS_OK,DATA_DATE,DATA_TIME,app.IPG_NUMBER] = Unpack_all_Kind_Ori(app,data,Filename_ONE{1,2},IsaddNans);
                          app.IPG_ID.Value = app.IPG_NUMBER;

                          app.LFP_FS_SET.Value = app.data_fs;
                          % [app.Data_NOW_UNPACK,app.data_fs,app.stim_info,app.acq_info,app.Stim_ONOFF_info,app.Stim_Emode_info,app.Freq_Stim,app.Polar_info,app.Rec_Parameters_info,app.Lost_index] = F1A_102_unpack_dir(app,data);
                          app.Channel_Check.Enable = "on";
                          app.currentdata_txt.Value = Filename_ONE{1,1};
                          iii = app.Channel_Check.Value;
                         app.Channel_Check.Items = app.Rec_Parameters_Info;
                         % app.channel_choose_1.Items = app.Rec_Parameters_info;
                         app.Data_NOW_Preprocessed = app.Data_NOW_UNPACK;
                         Data_To_Process = app.Data_NOW_UNPACK;
                         
                         app.mark = 0;
  
                        if app.LowPass.Value == 1 
                             Data_in = Data_To_Process';
                             % assignin("base",'filter',app.lowPassFilter)
                             Data_out = filtfilt(app.lowPassFilter,Data_in);
                             Data_To_Process = Data_out';
                             app.mark = 1;
                             app.Data_NOW_Preprocessed = Data_To_Process;
                        end
                        
                        if app.High_Pass.Value == 1 
                             Data_in = Data_To_Process';
                             Data_out = filtfilt(app.highPassFilter,Data_in);
                             Data_To_Process = Data_out';
                             app.mark = 1;
                             app.Data_NOW_Preprocessed = Data_To_Process;
                        end
                        
                        if app.mark == 1
                            plot(app.Data_time_PLOT,(1:1:length(app.Data_NOW_UNPACK(str2double(iii), 1:end)))/app.data_fs ,app.Data_NOW_UNPACK(str2double(iii), 1:end));
                            hold(app.Data_time_PLOT,"on")
                            plot(app.Data_time_PLOT,(1:1:length(app.Data_NOW_Preprocessed(str2double(iii), 1:end)))/app.data_fs ,app.Data_NOW_Preprocessed(str2double(iii), 1:end));
                            legend(app.Data_time_PLOT,{'before','after'})
                            [B,f,t] = specgram(app.Data_NOW_Preprocessed(1,300:end),round(app.data_fs),round(app.data_fs),round(app.data_fs),round(app.data_fs*0.75));
                        else

                            for loopss = 1:length(app.Channel_Check.Items)
                                plot(app.Data_time_PLOT,(1:1:length(app.Data_NOW_UNPACK(loopss, 1:end)))/app.data_fs ,app.Data_NOW_UNPACK(loopss, 1:end));
                                hold(app.Data_time_PLOT,"on")
                            end
                                legend(app.Data_time_PLOT,app.Channel_Check.Items)
                                hold(app.Data_time_PLOT,"off")
                                [B,f,t] = specgram(app.Data_NOW_UNPACK(1,300:end),round(app.data_fs),round(app.data_fs),round(app.data_fs),round(app.data_fs*0.75));
                                
                        end
                            hold(app.Data_time_PLOT,"off")
                            imagesc(app.Data_TF_PLOT,t,f,20*log10(abs(B)));
                            app.Data_TF_PLOT.YDir = "normal";
                            app.Data_TF_PLOT.Colormap = jet;
                            app.Data_TF_PLOT.YLim = [0 180];
                            app.Data_TF_PLOT.XLim = [t(1) t(end)];
                            app.Confirm.Enable = "on";
                            app.Decline.Enable = "on";
                            app.Slider_TIME.Limits = [1,round(length(Data_To_Process(1,:))/app.data_fs)];
                            app.Slider_TIME.Value = [1,round(length(Data_To_Process(1,:))/app.data_fs)];
                            app.Data_Cut_Tool.Enable = "on";
                        catch
                              uialert(app.MainFigure_batch,[Filename_ONE{1,1},'解析失败，请继续'],'提示','Icon','warning');
                                        % 提示存储失败
                                       if app.Check_Every_Seg.Value == 0
                                           IS_save_pro_suc = '数据保存失败'; % 默认值
                                           app.Save_processinG = [app.Save_processinG;[Filename_ONE{1,1},IS_save_pro_suc]];
                                       end
                              app.Data_Cut_Tool.Enable = "off";
                              app.Confirm.Enable = "off";
                              app.Decline.Enable = "on";
                        end
                        
                        if app.IS_unpack_ACC.Value == 1
                            % 解析ACC数据
                            app.acc_data = [];
                            cla(app.Data_ACC_PLOT)
                            try
                                try
                                    list2 = dir([cell2mat(app.selected_dir.Value),Filename_ONE{1,3}]);
                                    % list3=dir([cell2mat(app.selected_dir.Value),Filename_ONE{1,4}]);
                                    % List 多个List单元分别存储不同的数据输入
                                catch
                                    list2=dir(Filename_ONE{1,3});
                                end
                                file_id = fopen(list2(1).name);
                            
                                try
                                    dataacc= fread(file_id); 
                                    fclose(file_id);
                                catch
                                    % uialert(app.MainFigure_batch,'无ACC/ACC访问失败','提示','Icon','warning');
                                    close(app.waitting_NOW)
                                end
        
                                try
                                    [app.acc_data] = T01_Unpack_ACC_data(app,dataacc);
                                    
                                    for loopss = 1:3
                                        plot(app.Data_ACC_PLOT,(1:1:length(app.acc_data(loopss, 1:end)))/app.Defults_accfs.Value ,app.acc_data(loopss, 1:end));
                                        hold(app.Data_ACC_PLOT,"on")
                                    end
                                        legend(app.Data_ACC_PLOT, 'x', 'y', 'z');
                                        hold(app.Data_ACC_PLOT,"off")
                                catch
        
                                end
                            end
                        end

                    IS_save_pro_suc = '数据保存失败'; % 默认值
                 if app.Check_Every_Seg.Value == 0 && ~isempty(app.Data_NOW_UNPACK)% 循环内保存数据
                                data_saved_time = datestr(now);
                                saved_time = datestr(now,30);
                                % 处理数据
                                   app.data_conpensate.Enable = "off";
                                   app.IS_OLD_VERSON.Enable = "off";
                                   app.Check_Every_Seg.Enable = "off";
                                   app.Save_fig.Enable = "off";

                                  if app.Chose_output.Value == "1"
                                      app.Data_NOW_Preprocessed = nan;
                                  elseif app.Chose_output.Value == "2"
                                      % 执行Preprocessing操作
                                      % 直接存储：app.Data_NOW_Preprocessed，不用额外操作
                                  elseif app.Chose_output.Value == "3"
                                      
                                  end

                                if length(app.Acq_Info) >= 2
                                   chi_info = {'DATA_ori','Data_Processed','samplerate','stim_info','acq_info','Stim_onoff_info','stim_electrode_info','stim_frequency_info','recoding_parameter_info','Record_Channel','ori_dataname','processdate','save_type','lost','ACC_data'}; % data saved order
                                   data = {app.Data_NOW_UNPACK,app.Data_NOW_Preprocessed,app.data_fs,app.Stim_Info,app.Acq_Info,app.Stim_ONOFF_Info,app.Stim_Emode_Info,app.Freq_StiM,app.Polar_Info,app.Rec_Parameters_Info,Filename_ONE,data_saved_time,'full',app.data_LosT,app.acc_data}; % save data mat
                                   % assignin("base","data_save",data)
                                   % assignin("base","data_save_info",chi_info)
                                elseif length(app.Acq_Info) < 2
                                   chi_info = {'DATA_ori','Data_Processed','samplerate','save_time','save_type','lost','ACC_data'};
                                   data = {app.Data_NOW_UNPACK,app.Data_NOW_Preprocessed,app.data_fs,data_saved_time,'only_data',app.data_LosT,app.acc_data};
                                end
                                   
                                   % IS_save_pro_suc = '数据保存失败'; % 默认值
                                if app.Chose_output.Value == "1"
                                    if isempty(chi_info) == 1 && isempty(data) == 1
                                       close(app.waitting_now)
                                       % msgbox('请计算后再存储')
                                       % uialert(app.MainFigure_batch,'请计算后再存储','错误','Icon','warning');
                                    else
                                       
                                       if app.out_put_select.Value == "1"
                                           % Save Nercn_FORMAT
                                           % dirs = app.Save_path.Value{1};
                                           % mkdir(dirs);
                                           % filename = [dirs,'/',Filename_ONE(1:end-5),'_',saved_time, '.mat'];
                                            if isempty(chi_info) == 1 && isempty(data) == 1
                                               close(app.waitting_NOW)
                                            else
                                               dirs = app.Save_path.Value{1};
                                               mkdir(dirs);
                                             % DBS_Data_In = app.DBS_DATA_TOSAVE;
                                             para.acq_info1 = app.Stim_Info;
                                             para.acq_info2 = app.Acq_Info;
                                             para.Stim_onoff_info = app.Stim_ONOFF_Info;
                                             para.ori_dataname = Filename_ONE{1,2};
                                             para.save_type = 'Realtime'; % 1\Realtime;2\PostBack;3\Merging
                                             para.IPG_NUM = app.IPG_NUMBER;
                                             Starttime = Filename_ONE{1,1};
                                             % Data_LFP = app.Data_ori;
                                             Processed_LFP = [];
                                             Channel_LFP = app.Rec_Parameters_Info;
                                             Fs_ACC = app.Defults_accfs.Value;
                                             Data_ACC = ['No data'];
                                             if ~isempty(app.acc_data)
                                             Data_ACC = app.acc_data;
                                             end
                                             Processed_ACC = ['No data'];
                                             Channel_ACC = {'x';'y';'z'};
                                             Fs_EcoG = ['No data'];
                                             Data_EcoG = ['No data'];
                                             Processed_EcoG = ['No data'];
                                             Channel_EcoG = ['No data'];
                                             appdendMode = 1;
                                             saved_time = datestr(now, 'yyyy-mm-dd_HH-MM-SS'); % 
                                             [DBS_Data] = Save_NERCN_Format(app,app.DBS_DATA_TOSAVE,para, Starttime, app.data_fs, app.Data_NOW_UNPACK , Processed_LFP, Channel_LFP, ...
                                                                            Fs_ACC, Data_ACC, Processed_ACC, Channel_ACC, ...
                                                                            Fs_EcoG, Data_EcoG, Processed_EcoG, Channel_EcoG, saved_time,appdendMode);
                                             app.Datetime = app.First_Filename{1,1}(1:10);
                                             % 循环最后一段再存储
                                             if app.Num_of_unpack_NOW >= length(app.Filename_Processed(:,1))
                                             fileName = [dirs,sprintf('DBS_Data_%s_%s.mat', DBS_Data.IPG_NUM, app.Datetime)];
                                             save(fileName,'DBS_Data'); % 'DBS_Data' 是变量名，'DBS_Data.mat' 是默认文件名
                                             end
                                             app.DBS_DATA_TOSAVE = DBS_Data;
                                               % 存储数据绘图结果
                                                   if app.Save_fig.Value == 1
                                                      frame = getframe(app.MainFigure_batch);
                                                      panelImage = imcrop(frame.cdata, [281+233+4,62,518,382]);
                                                      imwrite(panelImage, [dirs,Filename_ONE{1,2}(1:end-5), '.png']);
                                                   end
                                               IS_save_pro_suc = '数据成功存储';
                                               close(app.waitting_NOW)
                                            end
                                           
                                       elseif app.out_put_select.Value == "2"
                                           dirs = app.Save_path.Value{1};
                                           mkdir(dirs);
                                           filename = [dirs,'/',Filename_ONE{1,1}(1:end-5),'_',saved_time, '.mat'];
                                           % filename = [[Filename_ONE,saved_time{1}], '.mat'];
                                            % 使用 save 函数直接保存变量到指定的文件
                                            % assignin("base","filename",filename)
                                            % 存储数据绘图结果
                                                   if app.Save_fig.Value == 1
                                                      frame = getframe(app.MainFigure_batch);
                                                      panelImage = imcrop(frame.cdata, [281+233+4,62,518,382]);
                                                      imwrite(panelImage, [dirs,Filename_ONE{1,2}(1:end-5), '.png']);
                                                   end
                                           save(filename, 'data', 'chi_info');
                                           IS_save_pro_suc = '数据成功保存';
                                       elseif app.out_put_select.Value == "3"
                                           
                                       end
                                       
                                       if app.Save_fig.Value == 1
                                            frame = getframe(app.MainFigure_batch);
                                            panelImage = imcrop(frame.cdata, [281+233+4,62,518,382]);
                                            imwrite(panelImage, [dirs,Filename_ONE{1,1}(1:end-5), '.png']);
                                       end

                                       close(app.waitting_NOW)
                                       % uialert(app.MainFigure_batch,['数据存储成功，位置：',app.Save_Path.Value],'保存提示','Icon','success');
                                    end
                                elseif app.Chose_output.Value == "2"
                                       
                                    if isempty(chi_info) == 1 && isempty(data) == 1
                                       close(app.waitting_NOW)
                                       % msgbox('请计算后再存储')
                                       % uialert(app.MainFigure_batch,'请计算后再存储','错误','Icon','warning');
                                    else
                                       % assignin("base","saved_time",saved_time)
                                       % assignin("base","Filename_ONE",Filename_ONE)
                                       dirs = app.Save_path.Value{1};
                                       mkdir(dirs);
                                       filename = [dirs,Filename_ONE{1,1}(1:end-5),'_',saved_time, '.mat'];
                                        % 使用 save 函数直接保存变量到指定的文件
                                       save(filename, 'data', 'chi_info');

                                       IS_save_pro_suc = '数据成功存储';
                                       if app.Save_fig.Value == 1
                                            frame = getframe(app.MainFigure_batch);
                                            panelImage = imcrop(frame.cdata, [281+233+4,62,518,382]);
                                            imwrite(panelImage, [dirs,Filename_ONE{1,1}(1:end-5), '.png']);  
                                       end
                                       close(app.waitting_NOW)

                                    end
                                    % IS_save_pro_suc = '数据成功保存';
                                elseif app.Chose_output.Value == "3"
                                       dirs = app.Save_path.Value{1};
                                       mkdir(dirs);
                                       origin_names = [Filename_ONE{1,1}(1:end-5),'_',saved_time, '.mat'];
                                       validFileName = regexprep(origin_names, '[\.\-\\/:*?"<>|]', '');
                                       Filename_ONE{1,1}(1:end-5)
                                       saved_name = [dirs,validFileName,'_PSD'];
                                        % assignin('base','saved_name',saved_name)
                                       nfft = app.data_fs; % number of fft
                                       fs = app.data_fs;
                                       noverlap = round(0.75*app.data_fs); % number of overlap
                                       chinum = length(app.Channel_Check.Items); 
                                       
                                       % assignin('base','Rec_Parameters_info',app.Rec_Parameters_info)
                                       window = hanning(app.data_fs);
                                       PSD_Power = [];
                                       for num_cy = 1:chinum 
                                       [PXX22,f] = pwelch(app.Data_NOW_Preprocessed(num_cy,400:end-400),window,noverlap,nfft,fs); % use pwelch method
                                       PSD_Power = [PSD_Power,PXX22];
                                       end
                        
                                        power = PSD_Power;
                                        logpower = 10*log10(power);
                                        freq = f;
                                        channel = app.Channel_Check.Items;
                        
                                        meth = 'Welch';
                                        FFT_Number = app.data_fs;
                                        overlap = noverlap;
                        
                                        Info = {'FFT_num_PSD:',FFT_Number,'Overlap_PSD',overlap,'Sample_Rate_PSD',app.data_fs,'Meth_PSD',meth};
                                        save(saved_name, 'power','logpower','freq','channel','Info');
                                        
                                        IS_save_pro_suc = '特征成功存储';
                                        % IS_save_pro_suc = '数据成功存储';
                                       if app.Save_fig.Value == 1
                                            frame = getframe(app.MainFigure_batch);
                                            panelImage = imcrop(frame.cdata, [281+233+4,62,518,382]);
                                            imwrite(panelImage, [dirs,Filename_ONE{1,1}(1:end-5), '.png']);
                                       end
                                       close(app.waitting_NOW)
                                end
                                
                                % 存储完成直接进行下一次计算
                                app.Save_processinG = [app.Save_processinG;[Filename_ONE{1,1},IS_save_pro_suc]];
                 end
                 app.IS_UNPACK_OK = [app.IS_UNPACK_OK;UNPACK_IS_OK];
                 % assignin("base","Specified_Unpack_Info",app.Specified_Unpack_Info);
                 % assignin("base","Dealtiled_para",Dealtiled_para);
                
                 try
                     app.Specified_Unpack_Info = [app.Specified_Unpack_Info;cell2mat(Dealtiled_para')];
                 catch
                     app.Specified_Unpack_Info = [app.Specified_Unpack_Info;(Dealtiled_para)];
                 end
                 close(app.waitting_NOW)
               else

                   uialert(app.MainFigure_batch,['已完成，执行结果：';app.Save_processinG],'提示','Icon','success') 
                   app.data_conpensate.Enable = "on";
                   app.IS_OLD_VERSON.Enable = "on";
                   app.Check_Every_Seg.Enable = "on";
                   app.Save_fig.Enable = "on";
                   app.Data_Cut_Tool.Enable = "off";
                   name_time = cellstr(datetime('now', 'Format', 'MM_dd_HH_mm_ss'));
                   name_times = cellstr(datetime('now', 'Format', 'yyyy_MM_dd_HH_mm_ss'));
                   mkdir(app.Save_path.Value{1});
                   fid = fopen([app.Save_path.Value{1},'/',name_time{1},'Unpack_Log.txt'], 'wt');

                   fprintf(fid, '%s\n', ['Unpacking Log date:',name_times{1}]);
                   % fprintf(fid, '%s\n', 'Unpack log: ');
                   %  for i = 1:length(app.IS_UNPACK_OK)
                   %      fprintf(fid, '%s\n', app.IS_UNPACK_OK{i});
                   %  end
                   % assignin("base","Save_processinG",app.Save_processinG);
                   % assignin("base","Specified_Unpack_Info",app.Specified_Unpack_Info);
                   try
                   record_all = cellfun(@(x,y) [x,y], app.Save_processinG, app.Specified_Unpack_Info, 'UniformOutput', false);
                   fprintf(fid, '%s\n', '-----------------------Data Para Log: ');
                   fprintf(fid, '%s\n', '--------------------------------------');
                    for i = 1:length(app.IS_UNPACK_OK)
                        fprintf(fid, '%s\n', record_all{i});
                        fprintf(fid, '%s\n', '--------------------------------------');
                    end
                   fclose(fid);
                   end
                   app.Confirm.Enable = 'off';
                   app.Decline.Enable = "off";
                   app.Start_TASK.Enable ="on";
                   app.Num_of_unpack_NOW = 1;
                   close(app.waitting_NOW)
               end
                app.Start_TASK.Enable = "on";
           end
        end

        % Button pushed function: Confirm
        function ConfirmButtonPushed(app, event)
            % 存储，写入TXT或者.Mat
            Filename_ONE = app.UNPACKED_NOW;
            app.data_fs = app.LFP_FS_SET.Value;
            app.waitting_NOW = uiprogressdlg(app.MainFigure_batch,'Title',['正在存储',Filename_ONE{1,1}],...
            'Indeterminate','on');
             IS_save_pro_suc = '存储失败';
            data_saved_time = datestr(now);
            saved_time = datestr(now,30);
            % 处理数据
              if app.Chose_output.Value == "1"
                  app.Data_NOW_Preprocessed = nan;
              elseif app.Chose_output.Value == "2"
                  % 执行Preprocessing操作
                  app.Data_NOW_Preprocessed = app.Data_NOW_UNPACK;
       
              elseif app.Chose_output.Value == "3"
                  if app.mark == 0
                  app.Data_NOW_Preprocessed = app.Data_NOW_UNPACK;
                  end
              end

            if length(app.Acq_Info) >= 2
               chi_info = {'DATA_ori','Data_Processed','samplerate','stim_info','acq_info','Stim_onoff_info','stim_electrode_info','stim_frequency_info','recoding_parameter_info','Record_Channel','ori_dataname','processdate','save_type','lost','ACC_data'}; % data saved order
               data = {app.Data_NOW_UNPACK,app.Data_NOW_Preprocessed,app.data_fs,app.Stim_Info,app.Acq_Info,app.Stim_ONOFF_Info,app.Stim_Emode_Info,app.Freq_StiM,app.Polar_Info,app.Rec_Parameters_Info,Filename_ONE,data_saved_time,'full',app.data_LosT,app.acc_data}; % save data mat
               % assignin("base","data_save",data)
               % assignin("base","data_save_info",chi_info)
            elseif length(app.Acq_Info) < 2
               chi_info = {'DATA_ori','Data_Processed','samplerate','save_time','save_type','lost','ACC_data'};
               data = {app.Data_NOW_UNPACK,app.Data_NOW_Preprocessed,app.data_fs,data_saved_time,'only_data',app.data_LosT,app.acc_data};
            end

            if app.out_put_select.Value == "1"
                if isempty(chi_info) == 1 && isempty(data) == 1
                   close(app.waitting_NOW)
                else
                   dirs = app.Save_path.Value{1};
                   mkdir(dirs);
                 % DBS_Data_In = app.DBS_DATA_TOSAVE;
                 para.acq_info1 = app.Stim_Info;
                 para.acq_info2 = app.Acq_Info;
                 para.Stim_onoff_info = app.Stim_ONOFF_Info;
                 para.ori_dataname = Filename_ONE{1,2};
                 para.save_type = 'Realtime'; % 1\Realtime;2\PostBack;3\Merging
                 para.IPG_NUM = app.IPG_NUMBER;
                 Starttime = Filename_ONE{1,1};
                 Fs_LFP = app.data_fs;
                 Data_LFP = app.Data_NOW_UNPACK;
                 Processed_LFP = app.Data_NOW_Preprocessed;
                 Channel_LFP = app.Rec_Parameters_Info;
                 Fs_ACC = app.Defults_accfs.Value;
                 Data_ACC = ['No data'];
                 if ~isempty(app.acc_data)
                 Data_ACC = app.acc_data;
                 end
                 Processed_ACC = ['No data'];
                 Channel_ACC = {'x';'y';'z'};
                 Fs_EcoG = ['No data'];
                 Data_EcoG = ['No data'];
                 Processed_EcoG = ['No data'];
                 Channel_EcoG = ['No data'];
                 appdendMode = 1;
                 saved_time = datestr(now, 'yyyy-mm-dd_HH-MM-SS'); % 
                 [DBS_Data] = Save_NERCN_Format(app,app.DBS_DATA_TOSAVE,para, Starttime, Fs_LFP, Data_LFP , Processed_LFP, Channel_LFP, ...
                                                  Fs_ACC, Data_ACC, Processed_ACC, Channel_ACC, ...
                                                  Fs_EcoG, Data_EcoG, Processed_EcoG, Channel_EcoG, saved_time,appdendMode);
                 app.Datetime = app.First_Filename{1,1}(1:10);
                 fileName = [dirs,sprintf('DBS_Data_%s_%s.mat', DBS_Data.IPG_NUM, app.Datetime)];
                 save(fileName,'DBS_Data'); % 'DBS_Data' 是变量名，'DBS_Data.mat' 是默认文件名
                 app.DBS_DATA_TOSAVE = DBS_Data;
                   % 存储数据绘图结果
                       if app.Save_fig.Value == 1
                          frame = getframe(app.MainFigure_batch);
                          panelImage = imcrop(frame.cdata, [281+233+4,62,518,382]);
                          imwrite(panelImage, [dirs,Filename_ONE{1,2}(1:end-5), '.png']);
                       end
                   IS_save_pro_suc = '数据成功存储';
                   close(app.waitting_NOW)
                end
            elseif app.out_put_select.Value == "2"
                if isempty(chi_info) == 1 && isempty(data) == 1
                   close(app.waitting_NOW)
                   % msgbox('请计算后再存储')
                   % uialert(app.MainFigure_batch,'请计算后再存储','错误','Icon','warning');
                else
                   % assignin("base","saved_time",saved_time)
                   % assignin("base","Filename_ONE",Filename_ONE)
                   dirs = app.Save_path.Value{1};
                   mkdir(dirs);
                   filename = [dirs,Filename_ONE{1,2}(1:end-5),'_',saved_time, '.mat'];
                    % 使用 save 函数直接保存变量到指定的文件
                   save(filename, 'data', 'chi_info');
                   IS_save_pro_suc = '数据成功存储';
                   if app.Save_fig.Value == 1
                        frame = getframe(app.MainFigure_batch);
                        panelImage = imcrop(frame.cdata, [281+233+4,62,518,382]);
                        imwrite(panelImage, [dirs,Filename_ONE{1,2}(1:end-5), '.png']);
                   end

                   close(app.waitting_NOW)
                   % uialert(app.MainFigure_batch,['数据存储成功，位置：',app.Save_Path.Value],'保存提示','Icon','success');
                end
            elseif app.out_put_select.Value == "3"
                % 
                   dirs = app.Save_path.Value{1};
                   mkdir(dirs);
                   origin_names = [Filename_ONE{1,2}(1:end-5),'_',saved_time, '.mat'];
                   validFileName = regexprep(origin_names, '[\.\-\\/:*?"<>|]', '');
                   saved_name = [dirs,validFileName,'_PSD'];
                   % assignin('base','saved_name',saved_name)
                   nfft = app.data_fs; % number of fft
                   fs = app.data_fs;
                   noverlap = round(0.75*app.data_fs); % number of overlap
                   chinum = str2double(app.Channel_Check.Items); 
                   
                   % assignin('base','chinum',chinum)
                   window = hanning(app.data_fs);
                   PSD_Power = [];
                   for num_cy = 1:length(chinum) 
                        frame = getframe(app.MainFigure_batch);
                        panelImage = imcrop(frame.cdata, [281+233+4,62,518,382]);
                        imwrite(panelImage, [dirs,Filename_ONE{1,2}(1:end-5), '.png']);
                   end
                   power = PSD_Power;
                    logpower = 10*log10(power);
                    freq = app.data_fs;
                    channel = app.Channel_Check.Items;
                    meth = 'Welch';
                    FFT_Number = app.data_fs;
                    overlap = noverlap;
                    Info = {'FFT_num_PSD:',FFT_Number,'Overlap_PSD',overlap,'Sample_Rate_PSD',app.data_fs,'Meth_PSD',meth};
                    save(saved_name, 'power','logpower','freq','channel','Info');
                   if app.Save_fig.Value == 1
                        frame = getframe(app.MainFigure_batch);
                        panelImage = imcrop(frame.cdata, [281+233+4,62,518,382]);
                        imwrite(panelImage, [dirs,Filename_ONE{1,2}(1:end-5), '.png']);
                   end
                    IS_save_pro_suc = '特征成功存储';
            end
            
            app.Save_processinG= [app.Save_processinG;[Filename_ONE{1,1},IS_save_pro_suc]];
            % 存储完成直接进行下一次计算
            app.Start_TASKButtonPushed
            
        end

        % Button pushed function: Decline
        function DeclineButtonPushed(app, event)
            IS_save_pro_suc = '数据保存失败';
            Filename_ONE = app.UNPACKED_NOW;
            app.Save_processinG= [app.Save_processinG;[Filename_ONE{1,1},IS_save_pro_suc]];                           % 提示存储失败
            % IS_save_pro_suc = '数据保存失败'; % 默认值
            % app.Save_processinG = [app.Save_processinG;[Filename_ONE{1,1},IS_save_pro_suc]];
            app.Start_TASKButtonPushed
        end

        % Button pushed function: Open_dir
        function Open_dirPushed(app, event)
            try
            path = app.Save_path.Value;
            mkdir(path{1});
            % assignin("base","path",path)
            winopen(path{1,1});
            end
        end

        % Button pushed function: Record_Unpack
        function Record_UnpackButtonPushed(app, event)
            try
                record_all = cellfun(@(x,y) [x,y], app.Save_processinG, app.Specified_Unpack_Info, 'UniformOutput', false);
                uialert(app.MainFigure_batch,['已完成，解包记录：';record_all],'提示','Icon','info') 
            catch
                 uialert(app.MainFigure_batch,'未查询到解析记录，历史解析信息请参考log文件。','提示','Icon','info') 
            end
        end

        % Button pushed function: Record_State
        function Record_StateButtonPushed(app, event)
            try
                 uialert(app.MainFigure_batch,['已完成，存储记录：';app.Save_processinG;['存储位置：',app.Save_path.Value{1}]],'提示','Icon','info') 
            catch
                 uialert(app.MainFigure_batch,'未查询到存储记录，历史解析信息请参考log文件。','提示','Icon','info') 
            end
        end

        % Value changing function: Slider_TIME
        function Slider_TIMEValueChanging(app, event)

            changingValue = event.Value;
            try
            app.Data_TF_PLOT.XLim = changingValue;
            app.Data_time_PLOT.XLim = changingValue;
            app.Data_ACC_PLOT.XLim = changingValue;
            catch
               % uialert(app.Phase_Value_analisis,msg,'提示','Icon','warning')
            end
        end

        % Value changed function: Channel_Check
        function Channel_CheckValueChanged(app, event)
            try
            value = app.Channel_Check.Value;
            % set(app.MainFigure_batch,'Pointer','watch'); drawnow;
            % app.channel_choose_1.Items = app.Rec_Parameters_info;
            % if app.mark == 1
            iii = value;
            if app.mark == 1
                plot(app.Data_time_PLOT,(1:1:length(app.Data_NOW_UNPACK(str2double(iii), 300:end)))/app.data_fs ,app.Data_NOW_UNPACK(str2double(iii), 300:end));
                hold(app.Data_time_PLOT,"on")
                plot(app.Data_time_PLOT,(1:1:length(app.Data_NOW_Preprocessed(str2double(iii), 300:end)))/app.data_fs ,app.Data_NOW_Preprocessed(str2double(iii), 300:end));
                legend(app.Data_time_PLOT,{'before','after'})
                % plot(app.Data_time_PLOT,(1:1:length(app.Data_NOW_UNPACK(str2double(iii), 300:end)))/app.data_fs ,app.Data_NOW_UNPACK(str2double(iii), 300:end));
                hold(app.Data_time_PLOT,"off")
                [B,f,t] = specgram(app.Data_NOW_Preprocessed(str2double(iii),300:end),round(app.data_fs),round(app.data_fs),round(app.data_fs),round(app.data_fs*0.75));
            else
                plot(app.Data_time_PLOT,(1:1:length(app.Data_NOW_UNPACK(str2double(iii), 300:end)))/app.data_fs ,app.Data_NOW_UNPACK(str2double(iii), 300:end));
                [B,f,t] = specgram(app.Data_NOW_UNPACK(str2double(iii),300:end),round(app.data_fs),round(app.data_fs),round(app.data_fs),round(app.data_fs*0.75));
            end
            % end
            % [B,f,t] = specgram(app.Data_NOW_UNPACK(str2double(value),300:end),round(app.data_fs),round(app.data_fs),round(app.data_fs),round(app.data_fs*0.75));
            imagesc(app.Data_TF_PLOT,t,f,20*log10(abs(B)));
            app.Data_TF_PLOT.YDir = "normal";
            app.Data_TF_PLOT.Colormap=jet;
            app.Data_TF_PLOT.YLim = [0 130];
            app.Data_TF_PLOT.XLim = [t(1) t(end)];
            app.Confirm.Enable = "on";
            app.Slider_TIME.Limits = [1,length(app.Data_NOW_UNPACK(1,300:end))/app.data_fs];
            app.Slider_TIME.Value = [1,length(app.Data_NOW_UNPACK(1,300:end))/app.data_fs];
            end
            % 
        end

        % Close request function: MainFigure_batch
        function MainFigure_batchCloseRequest(app, event)
            % button=questdlg('您确定退出吗？（注意！未储存的数据将被删除）','退出离线分析工具？','是','否','是'); %内容，标题，选项，默认选项
            selection = uiconfirm(app.MainFigure_batch, '您确定退出吗？（注意！未储存的数据将被删除）', '退出批处理工具？','Options',{'直接退出', '返回主菜单' ,'取消'},'DefaultOption', '取消', 'CancelOption', '直接退出');
            if strcmp(selection,'直接退出')
               % closeDialog_batch(app);
                delete(app);

            elseif strcmp(selection,'返回主菜单')
               APP_to_start_0_1
               delete(app);
            end
        end

        % Value changed function: Chose_output
        function Chose_outputValueChanged(app, event)
            value = app.Chose_output.Value;
            if value == "2"
                if app.IS_Para_Identy == 0
                   uialert(app.MainFigure_batch,'请从离线分析工具中确认预处理滤波信息。','提示','Icon','info') 
                   app.Chose_output.Value = "1";
                end
            end
        end

        % Value changed function: LowPass
        function LowPassValueChanged(app, event)
            value = app.LowPass.Value;
            if value == 1
                app.Chose_output.Value = "2";
            end
        end

        % Value changed function: High_Pass
        function High_PassValueChanged(app, event)
            value = app.High_Pass.Value;
            % value = app.LowPass.Value;
            if value == 1
                app.Chose_output.Value = "2";
            end
        end

        % Value changed function: ECG
        function ECGValueChanged(app, event)
            value = app.ECG.Value;
            if value == 1
                app.Chose_output.Value = "2";
            end
        end

        % Button pushed function: Clear_all
        function Clear_allPushed(app, event)
           button = uiconfirm(app.MainFigure_batch, '您确定清空并重置界面吗？（注意！未储存的数据将被删除）', '重置界面？','Options',{'是', '否'},'DefaultOption', '否', 'CancelOption', '是');
           set(app.MainFigure_batch,'Pointer','watch'); drawnow;
        if strcmp(button,'是')
            app.DBS_DATA_TOSAVE = struct();
            cla(app.Data_time_PLOT);
            cla(app.Data_TF_PLOT);
            cla(app.Data_ACC_PLOT);
            colorbacks = [0.76,0.73,0.82];
            text(app.Data_time_PLOT,0.25,0.5,'脑电数据离线分析系统','Color',[0.98,0.98,0.98],"BackgroundColor",colorbacks,"FontSize",24,"FontSmoothing","on","FontWeight","bold")
            app.Data_time_PLOT.Color = [0.76,0.73,0.82];
            colorbacks = [0.76,0.73,0.82];
            text(app.Data_TF_PLOT,0.25,0.5,'脑电数据离线分析系统','Color',[0.98,0.98,0.98],"BackgroundColor",colorbacks,"FontSize",24,"FontSmoothing","on","FontWeight","bold")
            app.Data_TF_PLOT.Color = [0.76,0.73,0.82];
            text(app.Data_ACC_PLOT,0.25,0.5,'脑电数据离线分析系统','Color',[0.98,0.98,0.98],"BackgroundColor",colorbacks,"FontSize",24,"FontSmoothing","on","FontWeight","bold")
            app.Data_ACC_PLOT.Color = [0.76,0.73,0.82];

            app.Num_of_unpack_NOW = 1;% Set batch processing form 1
            linkaxes([app.Data_time_PLOT,app.Data_TF_PLOT,app.Data_ACC_PLOT],'x')
            set(app.MainFigure_batch,'Icon','icon_48.png');
            app.MainFigure_batch.Name = 'NNSR 批处理工具';
            app.selected_dir.Value = '提示：请点击"数据选择”以导入数据。';
            app.Save_path.Value = ' ';
            app.currentdata_txt.Value = '请进行"数据确认"';
            app.Channel_Check.Enable = "off";
            app.Confirm.Enable = "off";
            app.Decline.Enable = "off";
            app.delete_allButtonPushed;
        end
           set(app.MainFigure_batch,'Pointer','arrow'); drawnow;
        end

        % Value changed function: AUTO_SIZEs
        function AUTO_SIZEsValueChanged(app, event)
            value = app.AUTO_SIZEs.Value;
            if value == 1
                app.AUTO_SIZEs.Text = 'autosize off';
                app.MainFigure_batch.Resize = "off";
            elseif value == 0
                app.AUTO_SIZEs.Text = 'autosize on';      
                app.MainFigure_batch.Resize = "on";
            end

        end

        % Button pushed function: Data_Cut_Tool
        function Data_Cut_ToolButtonPushed(app, event)
            % app.Data_Cut_Tool.Enable = 'off';
            % app.Menu_data_CUT.Enable = "off";
            %  app.waitting_now = uiprogressdlg(app.MainFigure_batch,'Title','请在新界面中操作',...
            % 'Indeterminate','on');
            % drawnow
            app.Dialog = uiprogressdlg(app.MainFigure_batch,'Title','请在裁剪工具箱中继续操作',...
            'Indeterminate','on');
            DATA = [app.Data_NOW_Preprocessed;app.Data_NOW_UNPACK];
            fs = app.data_fs;
            chi_input = app.Channel_Check.Items; 
            app.app_anylisis_Data_CUT = APP_anylisis_Data_CUT(app,DATA,fs,chi_input,2); % 1:Main;2:cut
            % app.data_cut_tool.Enable = 'on';
            % app.Menu_data_CUT.Enable = "on";
            % close(app.waitting_now)
        end

        % Button pushed function: Unpack_ALL
        function Unpack_ALLButtonPushed(app, event)
            % Waiting dialog
            app.waitting_NOW = uiprogressdlg(app.MainFigure_batch, 'Title', 'Parsing and executing data merging');
            app.CHACK_LARGEMAT.Enable = "off";
            
            % Initialize variables
            app.large_mat_LFP = []; % Define large LFP matrix
            app.large_mat_ACC = []; % Define large ACC matrix
            date_time_format = 'yyyy年MM月dd日 HH时mm分ss秒';
            app.Save_processinG = {}; 
            length_filenames = length(app.Filename_Processed(:,1));
            app.waitting_NOW.Value = 1/length_filenames;
            [sorted_filename, ~] = sortrows(app.Filename_Processed, 1);
            
            IsaddNans = 1; % Compensation flag
            DATA_ALL_ARRAY_LFP = {};
            DATA_ALL_ARRAY_ACC = {};
            p = 0; % Actually unpacked file counter
            i = 0; % File iteration counter
            
            % Start processing files
            for i = 1:length_filenames
                app.waitting_NOW = uiprogressdlg(app.MainFigure_batch, 'Title', 'Parsing and executing data merging');
                try
                    app.waitting_NOW.Value = i/length_filenames;
                catch
                    % Continue if progress update fails
                end
            
                try
                    temp_filename = sorted_filename(i,:);
                    try
                        list = dir([cell2mat(app.selected_dir.Value), temp_filename{1,2}]);
                    catch
                        list = dir(temp_filename{1,2});
                    end
            
                    % Read file data
                    file_id = fopen(list(1).name);
                    if file_id == -1
                        app.Save_processinG = [app.Save_processinG; [temp_filename{1,1}, 'File access failed']];
                        continue;
                    end
                    
                    try
                        data = fread(file_id); 
                        fclose(file_id);
                        
                        % Parse LFP data using the unpacking function
                        [data_unpackeded, data_frequency, data_stim_info, data_acq_info, ...
                         data_stim_ONOFF_info, data_stim_Emode_info, data_Freq_Stim_info, ...
                         data_polar_info, data_Rec_Parameters_info, data_Lost, Dealtiled_para, ...
                         UNPACK_IS_OK, DATA_DATE, DATA_TIME, IPG_ID] = ...
                         Unpack_all_Kind_Ori(app, data, temp_filename{1,2}, IsaddNans);
                        
                        % Check if unpacking was successful
                        if contains(UNPACK_IS_OK, '失败') || contains(UNPACK_IS_OK, 'failed')
                            app.Save_processinG = [app.Save_processinG; [temp_filename{1,1}, UNPACK_IS_OK]];
                            continue;
                        end
                        
                        % Assign unpacked data to output variables
                        LFP_Data_OUT = data_unpackeded;
                        Data_FSS = data_frequency;
                        app.Stim_Info = data_stim_info;
                        app.Acq_Info = data_acq_info;
                        app.Stim_ONOFF_Info = data_stim_ONOFF_info;
                        app.Stim_Emode_Info = data_stim_Emode_info;
                        app.Freq_StiM = data_Freq_Stim_info;
                        app.Polar_Info = data_polar_info;
                        app.Rec_Parameters_Info = data_Rec_Parameters_info;
                        app.data_LosT = data_Lost;
                        app.IPG_NUMBER = IPG_ID;
                        
                        app.Save_processinG = [app.Save_processinG; [temp_filename{1,1}, UNPACK_IS_OK]];
                        full_date_time_str = [DATA_DATE, ' ', DATA_TIME];    
                        
                    catch ME
                        fclose(file_id);
                        app.Save_processinG = [app.Save_processinG; [temp_filename{1,1}, 'Unpacking failed: ', ME.message]];
                        continue;
                    end
                    
                    try
                        % Validate channel number
                        if isempty(LFP_Data_OUT) || size(LFP_Data_OUT, 1) ~= app.Channel_number_chack.Value
                            app.Save_processinG = [app.Save_processinG; [temp_filename{1,1}, 'Data does not match set channels']];
                            continue;
                        end
                        
                        % Validate sampling rate
                        if Data_FSS ~= app.Samplerate_Chack.Value
                            app.Save_processinG = [app.Save_processinG; [temp_filename{1,1}, 'Data does not match set sampling rate']];
                            continue;
                        end
                        
                        p = p + 1;
                        
                        % Initialize time reference for first file
                        if p == 1
                            try
                                data_ori_array = datetime(full_date_time_str, 'InputFormat', date_time_format);  % t0
                                data_Last_array = data_ori_array;
                                lastlengthssecond_LFP = seconds(0);
                                lastlengthssecond_ACC = seconds(0);
                            catch
                                % If date parsing fails, use current time as reference
                                data_ori_array = datetime('now');
                                data_Last_array = data_ori_array;
                                lastlengthssecond_LFP = seconds(0);
                                lastlengthssecond_ACC = seconds(0);
                            end
                        end
                        
                    catch ME
                        app.Save_processinG = [app.Save_processinG; [temp_filename{1,1}, 'Data validation failed: ', ME.message]];
                        continue;
                    end
            
                    try
                        % Parse current file timestamp
                        data_Now_array = datetime(full_date_time_str, 'InputFormat', date_time_format);
                    catch
                        % If parsing fails, estimate time based on data length
                        data_Now_array = data_Last_array + seconds(size(LFP_Data_OUT, 2) / Data_FSS);
                    end
                    
                    DATA_ALL_ARRAY_LFP = [DATA_ALL_ARRAY_LFP, data_Now_array];
                    DATA_ALL_ARRAY_ACC = [DATA_ALL_ARRAY_ACC, data_Now_array];
                    
                    fs_LFP = app.Fixed_SamplingRate.Value;
                    fs_ACC = app.Defults_acc_chack.Value;
                    
                    % Calculate end time of last data segment
                    datatime_array_end_LFP = data_Last_array + lastlengthssecond_LFP;
                    datatime_array_end_ACC = data_Last_array + lastlengthssecond_ACC;
                    
                    DATA_ALL_ARRAY_LFP = [DATA_ALL_ARRAY_LFP, datatime_array_end_LFP];
                    DATA_ALL_ARRAY_ACC = [DATA_ALL_ARRAY_ACC, datatime_array_end_ACC];
            
                    % Calculate time gap between files
                    middleseconds_LFP = data_Now_array - datatime_array_end_LFP;
                    middleseconds_ACC = data_Now_array - datatime_array_end_ACC;
            
                    % Calculate number of samples needed to fill the gap
                    dataadd_length_LFP = max(0, round(seconds(middleseconds_LFP) * fs_LFP));
                    dataadd_length_ACC = max(0, round(seconds(middleseconds_ACC) * fs_ACC));
                    dataadd_width = app.Channel_number_chack.Value;
            
                    % Create NaN matrices to fill time gaps
                    if dataadd_length_LFP > 0
                        add_mat_LFP = nan(dataadd_width, dataadd_length_LFP);
                        app.large_mat_LFP = [app.large_mat_LFP, add_mat_LFP];
                    end
                    
                    if dataadd_length_ACC > 0
                        add_mat_ACC = nan(3, dataadd_length_ACC);
                        app.large_mat_ACC = [app.large_mat_ACC, add_mat_ACC];
                    end
            
                    % Process ACC data if available
                    Acc_Data_OUT = [];
                    if ~isempty(temp_filename{1,3})
                        try
                            try
                                list_acc = dir([cell2mat(app.selected_dir.Value), temp_filename{1,3}]);
                            catch
                                list_acc = dir(temp_filename{1,3});
                            end
            
                            file_id_acc = fopen(list_acc(1).name);
                            if file_id_acc ~= -1
                                data_acc = fread(file_id_acc); 
                                fclose(file_id_acc); 
                                [Acc_Data_OUT] = T01_Unpack_ACC_data(app, data_acc);
                            end
                        catch
                            Acc_Data_OUT = [];
                        end
                    end
            
                    % Append current data to large matrices
                    app.large_mat_LFP = [app.large_mat_LFP, LFP_Data_OUT];
                    if ~isempty(Acc_Data_OUT)
                        app.large_mat_ACC = [app.large_mat_ACC, Acc_Data_OUT];
                    end
            
                    % Update time tracking
                    data_Last_array = data_Now_array;
                    lastlengthssecond_LFP = seconds(size(LFP_Data_OUT, 2) / fs_LFP);
                    if ~isempty(Acc_Data_OUT)
                        lastlengthssecond_ACC = seconds(size(Acc_Data_OUT, 2) / fs_ACC);
                    end
            
                    % Check memory usage to prevent system overload
                    try
                        info_LFP = whos('app');
                        if info_LFP.bytes > 4e9  % 4GB limit
                            uialert(app.MainFigure_batch, 'Concatenated data exceeds 4GB, exiting early', 'Info', 'Icon', 'info');
                            break;
                        end
                    catch
                        % Continue if memory check fails
                    end
                    
                catch ME
                    app.Save_processinG = [app.Save_processinG; [temp_filename{1,1}, 'Processing failed: ', ME.message]];
                    continue;
                end
            end
            
            % Generate time sequences and plots if data was successfully processed
            if ~isempty(app.large_mat_LFP)
                try
                    % Generate time sequence
                    t0 = data_ori_array;
                    app.starttime = t0;
                
                    % Calculate time intervals
                    dt_LFP = seconds(1/fs_LFP);
                    dt_ACC = seconds(1/fs_ACC);
                
                    % Generate time point sequences
                    n_LFP = size(app.large_mat_LFP, 2);
                    n_ACC = size(app.large_mat_ACC, 2);
                    time_LFP = t0 + (0:n_LFP-1) * dt_LFP;
                    time_ACC = t0 + (0:n_ACC-1) * dt_ACC;
                    app.Time_seq = time_LFP;
                
                    % Plot 1: Time-amplitude plot
                    cla(app.TIME_AMP);
                    if n_LFP > 0
                        meanss = nanmean(app.large_mat_LFP(1,:));
                        stdss = nanstd(app.large_mat_LFP(1,:));
                        ymin = meanss - 2 * stdss;
                        ymax = meanss + 2 * stdss;
                    
                        % Downsample for plotting to improve performance
                        n_draw = max(1, round(n_LFP / 10000)); % Adaptive downsampling
                        sampled_datay_LFP = app.large_mat_LFP(1, 1:n_draw:end);
                        sampled_datax_LFP = time_LFP(1:n_draw:end);
                    
                        % Plot LFP
                        plot(app.TIME_AMP, sampled_datax_LFP, sampled_datay_LFP);
                        app.TIME_AMP.YLim = [ymin, ymax];
                        app.TIME_AMP.XLim = [sampled_datax_LFP(1), sampled_datax_LFP(end)];
                    end
                
                    % Plot ACC data if available
                    if n_ACC > 0
                        n_draw_acc = max(1, round(n_ACC / 10000));
                        sampled_datay_ACC = app.large_mat_ACC(1, 1:n_draw_acc:end);
                        sampled_datax_ACC = time_ACC(1:n_draw_acc:end);
                        plot(app.TIME_ACC, sampled_datax_ACC, sampled_datay_ACC);
                    end
                
                    % Generate spectrogram
                    cla(app.TIME_POW);
                    try
                        % Calculate power spectrum with adaptive parameters
                        window_size = min(round(app.Samplerate_Chack.Value), size(app.large_mat_LFP, 2));
                        overlap = round(window_size * 0.75);
                        
                        % Downsample if data is too large
                        if size(app.large_mat_LFP, 2) > 1e6
                            downsample_factor = ceil(size(app.large_mat_LFP, 2) / 1e6);
                            data_for_spec = downsample(app.large_mat_LFP(1,:), downsample_factor);
                            fs_for_spec = round(app.Samplerate_Chack.Value / downsample_factor);
                        else
                            data_for_spec = app.large_mat_LFP(1,:);
                            fs_for_spec = app.Samplerate_Chack.Value;
                        end
                        
                        [B, f, t] = specgram(data_for_spec, window_size, fs_for_spec, window_size, overlap);
                        
                        % Plot spectrogram
                        imagesc(app.TIME_POW, t, f, 10 * log10(abs(B)));
                        app.TIME_POW.YDir = "normal";
                        app.TIME_POW.Colormap = jet;
                        app.TIME_POW.YLim = [0, min(90, fs_for_spec/2)];
                        app.TIME_POW.XLim = [t(1), t(end)];
                        app.TIME_POW.XTick = [];
                        
                    catch ME
                        disp(['Spectrogram calculation failed: ', ME.message]);
                    end
                
                    % Enable save button
                    app.Save_Data.Enable = "on";
                
                    % Link X axes
                    try
                        linkaxes([app.TIME_AMP, app.TIME_ACC], 'x');
                    catch
                        disp('Failed to link X axes');
                    end
                
                    % Close waiting dialog and show success message
                    close(app.waitting_NOW);
                    uialert(app.MainFigure_batch, ...
                        ['Parsing successful, please click {Save Data} to store results'; ...
                         'Parsing information:'; app.Save_processinG], ...
                        'Info', 'Icon', 'success');
                    app.CHACK_LARGEMAT.Enable = "on";
                    
                catch ME
                    close(app.waitting_NOW);
                    uialert(app.MainFigure_batch, ['Error in post-processing: ', ME.message], 'Error', 'Icon', 'error');
                    app.CHACK_LARGEMAT.Enable = "on";
                end
            else
                close(app.waitting_NOW);
                uialert(app.MainFigure_batch, 'Failed to obtain data', 'Info', 'Icon', 'error');
                app.CHACK_LARGEMAT.Enable = "on";
            end
        end

        % Button pushed function: Get_Info
        function Get_InfoPushed(app, event)

                 app.First_Filename = app.Filename_Processed(1,:);
                 app.waitting_NOW = uiprogressdlg(app.MainFigure_batch,'Title',['正在解码文件信息',app.First_Filename{1,1}],...
                'Indeterminate','on');
                    drawnow
                    % assignin('base','Filename_NOW1',Filename_ONE)
                          try
                            try
                                list=dir([cell2mat(app.selected_dir.Value),app.First_Filename{1,2}]);
                            catch
                                list=dir(app.First_Filename{1,1});
                            end
                            app.Num_of_unpack_NOW = app.Num_of_unpack_NOW+1; % 当前计数+1
                            file_id = fopen(list(1).name);

                               try
                                   data= fread(file_id); 
                                   fclose(file_id);
                               catch
                                   close(app.waitting_NOW)    
                                   uialert(app.MainFigure_batch,'文件访问失败-1','提示','Icon','warning');
                                   close(app.waitting_NOW)
                               end

        [~,FS,app.Stim_Info,app.Acq_Info,app.Stim_ONOFF_Info,data_stim_Emode_info,data_Freq_Stim_info,data_polar_info,app.Rec_Parameters_Info,~,Dealtiled_para,~,Date,DATA_TIME,app.IPG_NUMBER] = Unpack_all_Kind_Ori(app,data,app.First_Filename{1,2},0);
        % [data_unpackeded,data_frequency,data_stim_info,data_acq_info,data_stim_ONOFF_info,data_stim_Emode_info,data_Freq_Stim_info,data_polar_info,data_Rec_Parameters_info,data_Lost,Dealtiled_para,UNPACK_IS_OK,DATA_DATE,DATA_TIME]
                          app.CHACK_LARGEMAT.Items = app.Rec_Parameters_Info;
                          app.Samplerate_Chack.Value = FS;
                          app.Channel_number_chack.Value = FS;
                          app.Fixed_SamplingRate.Value = FS;
                          app.setFS = app.Fixed_SamplingRate.Value;
                          app.Unpack_ALL.Enable = "on";
                          app.Channel_number_chack.Value = length(app.Rec_Parameters_Info);
                          uialert(app.MainFigure_batch,Dealtiled_para,'首个数据信息','Icon','success')
                          catch
                                app.First_Filename = app.Filename_Processed(2,:);
                               try
                                try
                                    list=dir([cell2mat(app.selected_dir.Value),app.First_Filename{1,2}]);
                                catch
                                    list=dir(app.First_Filename{1,2});
                                end
                                app.Num_of_unpack_NOW = app.Num_of_unpack_NOW+1; % 当前计数+1
                                file_id = fopen(list(1).name);
                               try
                                   data= fread(file_id); 
                                   fclose(file_id);
                               catch
                                   close(app.waitting_NOW)    
                                   uialert(app.MainFigure_batch,'文件访问失败-2','提示','Icon','warning');
                                   close(app.waitting_NOW)
                               end
                              Tempsfile = app.Filename_Processed(2,:);
                              [~,FS,app.Stim_Info,data_acq_info,data_stim_ONOFF_info,data_stim_Emode_info,data_Freq_Stim_info,data_polar_info,app.Rec_Parameters_Info,~,Dealtiled_para,~,Date,DATA_TIME,app.IPG_NUMBER] = Unpack_all_Kind_Ori(app,data,Tempsfile{1,2},0);
                               % [app.Data_NOW_UNPACK,app.data_fs,app.stim_info,app.acq_info,app.Stim_ONOFF_info,app.Stim_Emode_info,app.Freq_Stim,app.Polar_info,app.Rec_Parameters_info,app.Lost_index] = F1A_102_unpack_dir(app,data);
                              app.CHACK_LARGEMAT.Items = app.Rec_Parameters_Info;
                               app.Samplerate_Chack.Value = FS;
                              app.Channel_number_chack.Value = FS;
                              app.Fixed_SamplingRate.Value = FS;
                              app.setFS = app.Fixed_SamplingRate.Value;
                              app.Channel_number_chack.Value = length(app.Rec_Parameters_Info);
                              app.Unpack_ALL.Enable = "on";
                              uialert(app.MainFigure_batch,Dealtiled_para,'首个数据信息','Icon','success')
                               catch
                              uialert(app.MainFigure_batch,'文件访问失败-3','提示','Icon','warning');
                               end

                          end
                          % assignin("base","Date",Date)
                          % app.Datetime = regexprep(Date, '[\u4e00-\u9fa5]', '');
                          try
                             app.Datetime = regexprep(Date, '[^\u4e00-\u9fa5]', '');
                          end
                          
                          close(app.waitting_NOW)
        end

        % Button pushed function: Save_Data
        function Save_DataButtonPushed(app, event)
                 app.waitting_NOW = uiprogressdlg(app.MainFigure_batch,'Title','正在进行数据存储，将需要一定时间',...
                'Indeterminate','on');

                 if app.SAVE_type.Value == "1"

                     Full_mat = app.large_mat_LFP;
                     Time_Seq = app.Time_seq;
                     fs = app.setFS;
                     Starttime = app.starttime;
                     uisave({'Full_mat','Time_Seq','fs','Starttime'},[app.Datetime,'_LFP'])
                     close(app.waitting_NOW)

                 elseif app.SAVE_type.Value == "2"

                     DBS_Data_In = 0;
                     para.acq_info1 = app.Stim_Info;
                     para.acq_info2 = app.Acq_Info;
                     para.Stim_onoff_info = app.Stim_ONOFF_Info;
                     para.ori_dataname = app.First_Filename;
                     para.save_type = 'Merging'; % 1\Realtime;2\PostBack;3\Merging
                     para.IPG_NUM = app.IPG_NUMBER;
                     Starttime = app.Time_seq(1);
                     % Data_LFP = app.Data_ori;
                     Processed_LFP = [];
                     Channel_LFP = app.Rec_Parameters_Info;
                     Fs_ACC = app.Defults_acc_chack.Value;
                     Data_ACC = ['No data'];
                     if ~isempty(app.large_mat_ACC)
                     Data_ACC = app.large_mat_ACC;
                     end

                     Processed_ACC = ['No data'];
                     Channel_ACC = ['x','y','z'];
                     Fs_C_EEG = ['No data'];
                     Data_C_EEG = ['No data'];
                     Processed_C_EEG = ['No data'];
                     Channel_C_EEG = ['No data'];
                     appdendMode = 0;
                     saved_time = datestr(now, 'yyyy-mm-dd_HH-MM-SS'); % 
                     [DBS_Data] = Save_NERCN_Format(app,DBS_Data_In,para, Starttime, app.setFS, app.large_mat_LFP , Processed_LFP, Channel_LFP, ...
                                                      Fs_ACC, Data_ACC, Processed_ACC, Channel_ACC, ...
                                                      Fs_C_EEG, Data_C_EEG, Processed_C_EEG, Channel_C_EEG, saved_time,appdendMode);
                     fileName = sprintf('DBS_Data_%s_%s.mat', DBS_Data.IPG_NUM, app.Datetime);
                     uisave('DBS_Data', fileName); % 'DBS_Data' 是变量名，'DBS_Data.mat' 是默认文件名
                     close(app.waitting_NOW)
                     
                 end
                 
        end

        % Value changed function: Fixed_SamplingRate
        function Fixed_SamplingRateValueChanged(app, event)
                value = app.Fixed_SamplingRate.Value;
                app.setFS = value;
        end

        % Value changed function: CHACK_LARGEMAT
        function CHACK_LARGEMATValueChanged(app, event)
            try
                app.waitting_NOW = uiprogressdlg(app.MainFigure_batch,'Title','正在绘制','Indeterminate','on');
                value = app.CHACK_LARGEMAT.Value;
                % set(app.MainFigure_batch,'Pointer','watch'); drawnow;
                % app.channel_choose_1.Items = app.Rec_Parameters_info;
                % if app.mark == 1
                iii = str2double(value);
                time = app.Time_seq;
                % 绘图1
                cla(app.TIME_AMP,'reset');
                meanss = nanmean(app.large_mat_LFP(iii,:));
                ymin = meanss - 2*nanstd(app.large_mat_LFP(iii,:));
                ymax = meanss + 2*nanstd(app.large_mat_LFP(iii,:));
                n_draw = 10;
                sampled_datay = app.large_mat_LFP(iii,1:n_draw:end); % 以n为间隔抽样
                sampled_datax = time(iii:n_draw:end);
                plot(app.TIME_AMP,sampled_datax,sampled_datay);
                app.TIME_AMP.YLim = [ymin,ymax];
                cla(app.TIME_POW,'reset');
                [B,f,t] = specgram(app.large_mat_LFP(iii,:),round(app.Samplerate_Chack.Value),round(app.Samplerate_Chack.Value),round(app.Samplerate_Chack.Value),round(app.Samplerate_Chack.Value*0.75));
                tnew = time(1:round(app.Samplerate_Chack.Value/4):end);
               
                try
                    imagesc(app.TIME_POW,tnew,f,10*log10(abs(B(:,1:length(tnew)))));
                catch
                    imagesc(app.TIME_POW,tnew(1:1:length(B(1,:))),f,10*log10(abs(B)));
                end

                app.TIME_POW.YDir = "normal";
                app.TIME_POW.Colormap=jet;
                app.TIME_POW.YLim = [0 90];
                app.TIME_POW.XLim = [tnew(1) tnew(end)];
                app.Save_Data.Enable = "on";
                linkaxes([app.TIME_AMP,app.TIME_POW,app.TIME_ACC],'x')
                close(app.waitting_NOW);
            end
        end

        % Value changing function: Color_range_Slide
        function Color_range_SlideValueChanging(app, event)

            changingValue = event.Value;
          % value = app.Color_range_Slide.Value;
            try
             app.TIME_POW.CLim = changingValue;
            catch
             % msg = ('请重新选择范围');
             % uialert(app.Phase_Value_analisis,msg,'提示','Icon','warning')
            end
        end

        % Value changing function: Y_range_Slide
        function Y_range_SlideValueChanging(app, event)
            changingValue = event.Value;
            try
            app.TIME_POW.YLim = changingValue;
            catch
               % msg = ('请重新选择范围');
               % uialert(app.Phase_Value_analisis,msg,'提示','Icon','warning')
            end
        end

        % Button pushed function: Data_trans_copy
        function Data_trans_copyButtonPushed(app, event)
            app.waitting_NOW = uiprogressdlg(app.MainFigure_batch,'Title','请选择检索文件夹与目标文件夹',...
            'Indeterminate','on');
            drawnow
            
            % 弹出对话框，选择目标文件夹1
            source_folder = uigetdir('', '选择目标文件夹1');
            if isequal(source_folder, 0)
                close(app.waitting_NOW)
                return; % 如果用户取消选择，则返回
            end
        
            % 弹出对话框，选择目标文件夹2
            target_folder = uigetdir('', '选择目标文件夹2');
            if isequal(target_folder, 0)
                close(app.waitting_NOW)
                return; % 如果用户取消选择，则返回
            end
        
            % 创建确认窗口
            % 获取屏幕尺寸
            screenSize = get(0, 'ScreenSize');  % [left, bottom, width, height]
            
            % 窗口的宽度和高度
            windowWidth = 700;  % 你所设置的宽度
            windowHeight = 200;  % 你所设置的高度
            
            % 计算窗口的x和y位置，使窗口居中
            xPos = (screenSize(3) - windowWidth) / 2;
            yPos = (screenSize(4) - windowHeight) / 2;
            
            % 创建确认窗口，并设置居中位置
            confirmDlg = uifigure('Name', '确认文件夹与格式', 'Position', [xPos, yPos, windowWidth, windowHeight]);
            % 创建文件夹1显示标签
            uilabel(confirmDlg, 'Position', [20 160 600 30], 'Text', ['检索文件夹: ', source_folder]);
        
            % 创建文件夹2显示标签
            uilabel(confirmDlg, 'Position', [20 130 600 30], 'Text', ['目标文件夹: ', target_folder]);
        
            % 创建下拉菜单用于选择文件格式，默认值设为 .data
            fileFormatDropDown = uidropdown(confirmDlg, 'Position', [20 90 300 30], ...
                'Items', {'.data', '.dat', '.txt', '.csv', '自定义'}, ...
                'Value', '.data');  % 默认格式改为 .data
        
            % 创建自定义输入框（默认隐藏）
            customFormatEditField = uieditfield(confirmDlg, 'text', ...
                'Position', [180 100 100 30], 'Visible', 'off');
        
            % 回调函数，当选择“自定义”时显示自定义输入框
            fileFormatDropDown.ValueChangedFcn = @(src, event) handleCustomFormatSelection(app,fileFormatDropDown, customFormatEditField);
        
            % 创建确认按钮
            confirmButton = uibutton(confirmDlg, 'push', ...
            'Position', [20 50 150 30], 'Text', '确认', ...
            'ButtonPushedFcn', @(btn, event) confirmSelection(app,confirmDlg, source_folder, target_folder, fileFormatDropDown, customFormatEditField));
             
            close(app.waitting_NOW)

        end

        % Value changed function: IS_unpack_ACC
        function IS_unpack_ACCValueChanged(app, event)
            value = app.IS_unpack_ACC.Value;
            if value == 1
               app.Data_ACC_PLOT.Visible = "on";
            else
               cla(app.Data_ACC_PLOT);
               app.Data_ACC_PLOT.Visible = "off";
            end
        end

        % Value changed function: ACC_UNPACK_CHACK_2
        function ACC_UNPACK_CHACK_2ValueChanged(app, event)
            value = app.ACC_UNPACK_CHACK_2.Value;
            if value == 1
               app.TIME_ACC.Visible = "on";
            else
               cla(app.TIME_ACC);
               app.TIME_ACC.Visible = "off";
            end
            
        end

        % Value changed function: LFP_FS_SET
        function LFP_FS_SETValueChanged(app, event)
            value = app.LFP_FS_SET.Value;
            app.data_fs = value;
            iii = app.View_chi_DropDown.Value;
            try
                if app.mark == 1
                    plot(app.Data_time_PLOT,(1:1:length(app.Data_NOW_UNPACK(str2double(iii), 1:end)))/app.data_fs ,app.Data_NOW_UNPACK(str2double(iii), 1:end));
                    hold(app.Data_time_PLOT,"on")
                    plot(app.Data_time_PLOT,(1:1:length(app.Data_NOW_Preprocessed(str2double(iii), 1:end)))/app.data_fs ,app.Data_NOW_Preprocessed(str2double(iii), 1:end));
                    legend(app.Data_time_PLOT,{'before','after'})
                    [B,f,t] = specgram(app.Data_NOW_Preprocessed(1,300:end),round(app.data_fs),round(app.data_fs),round(app.data_fs),round(app.data_fs*0.75));
                else
            
                    for loopss = 1:length(app.Channel_Check.Items)
                        plot(app.Data_time_PLOT,(1:1:length(app.Data_NOW_UNPACK(loopss, 1:end)))/app.data_fs ,app.Data_NOW_UNPACK(loopss, 1:end));
                        hold(app.Data_time_PLOT,"on")
                    end
                        legend(app.Data_time_PLOT,app.Channel_Check.Items)
                        hold(app.Data_time_PLOT,"off")
                        [B,f,t] = specgram(app.Data_NOW_UNPACK(1,300:end),round(app.data_fs),round(app.data_fs),round(app.data_fs),round(app.data_fs*0.75));
                        
                end
                    hold(app.Data_time_PLOT,"off")
                    imagesc(app.Data_TF_PLOT,t,f,20*log10(abs(B)));
                    app.Data_TF_PLOT.YDir = "normal";
                    app.Data_TF_PLOT.Colormap = jet;
                    app.Data_TF_PLOT.YLim = [0 180];
                    app.Data_TF_PLOT.XLim = [t(1) t(end)];
                    app.Confirm.Enable = "on";
                    app.Decline.Enable = "on";
                    app.Slider_TIME.Limits = [1,round(length(app.Data_NOW_UNPACK(1,:))/app.data_fs)];
                    app.Slider_TIME.Value = [1,round(length(app.Data_NOW_UNPACK(1,:))/app.data_fs)];
                    app.Data_Cut_Tool.Enable = "on";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFigure_batch = uifigure('Visible', 'off');
            app.MainFigure_batch.Name = 'MATLAB App';
            app.MainFigure_batch.CloseRequestFcn = createCallbackFcn(app, @MainFigure_batchCloseRequest, true);
            app.NNSRTOOLBOXLabel = uilabel(app.MainFigure_batch);
            app.NNSRTOOLBOXLabel.Text = 'NNSR TOOLBOX';
            app.Label = uilabel(app.MainFigure_batch);
            app.Label.Text = '-  批处理工具';
            app.Panel_3 = uipanel(app.MainFigure_batch);
            app.Panel_3.Title = '数据管理';
            app.File_selection = uibutton(app.Panel_3, 'push');
            app.File_selection.ButtonPushedFcn = createCallbackFcn(app, @File_selectionButtonPushed, true);
            app.File_selection.Text = '数据选择';
            app.data_table = uitable(app.Panel_3);
            app.data_table.ColumnName = {'已载入'; 'LFP'; 'ACC'; 'EcoG'};
            app.data_table.RowName = {};
            app.data_table.CellSelectionCallback = createCallbackFcn(app, @data_tableCellSelection, true);
            app.delete_file = uibutton(app.Panel_3, 'push');
            app.delete_file.ButtonPushedFcn = createCallbackFcn(app, @delete_fileButtonPushed, true);
            app.delete_file.Enable = 'off';
            app.delete_file.Text = '删除';
            app.delete_all = uibutton(app.Panel_3, 'push');
            app.delete_all.ButtonPushedFcn = createCallbackFcn(app, @delete_allButtonPushed, true);
            app.delete_all.Enable = 'off';
            app.delete_all.Text = '清空';
            app.selected_dir = uitextarea(app.Panel_3);
            app.selected_dir.Editable = 'off';
            app.selected_dir.Value = {'提示：请点击“数据选择”以导入数据。'};
            app.Label_7 = uilabel(app.Panel_3);
            app.Label_7.Text = '位置：';
            app.Label_8 = uilabel(app.Panel_3);
            app.Label_8.Text = '格式：';
            app.format_select = uidropdown(app.Panel_3);
            app.format_select.Items = {'.data', '.dat', '.txt'};
            app.format_select.ItemsData = {'1', '2', '3', '4'};
            app.format_select.Value = '1';
            app.Data_trans_copy = uibutton(app.Panel_3, 'push');
            app.Data_trans_copy.ButtonPushedFcn = createCallbackFcn(app, @Data_trans_copyButtonPushed, true);
            app.Data_trans_copy.Text = '数据拷贝';
            app.TabGroup = uitabgroup(app.MainFigure_batch);
            app.batch_Process = uitab(app.TabGroup);
            app.batch_Process.Title = '批处理';
            app.Panel_2 = uipanel(app.batch_Process);
            app.Panel_2.Title = '批量处理';
            app.High_Pass = uicheckbox(app.Panel_2);
            app.High_Pass.ValueChangedFcn = createCallbackFcn(app, @High_PassValueChanged, true);
            app.High_Pass.Enable = 'off';
            app.High_Pass.Text = '基线漂移去除';
            app.LowPass = uicheckbox(app.Panel_2);
            app.LowPass.ValueChangedFcn = createCallbackFcn(app, @LowPassValueChanged, true);
            app.LowPass.Enable = 'off';
            app.LowPass.Text = '刺激伪迹去除';
            app.ECG = uicheckbox(app.Panel_2);
            app.ECG.ValueChangedFcn = createCallbackFcn(app, @ECGValueChanged, true);
            app.ECG.Enable = 'off';
            app.ECG.Text = '心电去除 (单独选择)';
            app.Load_dir = uilabel(app.Panel_2);
            app.Load_dir.Text = '-  数据解析设置';
            app.Preprocess = uilabel(app.Panel_2);
            app.Preprocess.Text = '-  预处理设置（配合离线工具箱）';
            app.Proceed = uilabel(app.Panel_2);
            app.Proceed.Text = '-  执行内容';
            app.Save_path = uitextarea(app.Panel_2);
            app.Save_path.Editable = 'off';
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = '存储地址：';
            app.out_put_select = uidropdown(app.Panel_2);
            app.out_put_select.Items = {'.mat(NNSF)', '.mat(简化格式)', '.txt'};
            app.out_put_select.ItemsData = {'1', '2', '3'};
            app.out_put_select.Value = '1';
            app.Label_6 = uilabel(app.Panel_2);
            app.Label_6.Text = '输出格式选择：';
            app.Save_Dir = uibutton(app.Panel_2, 'push');
            app.Save_Dir.ButtonPushedFcn = createCallbackFcn(app, @Save_DirButtonPushed, true);
            app.Save_Dir.Text = '修改存储位置';
            app.Start_TASK = uibutton(app.Panel_2, 'push');
            app.Start_TASK.ButtonPushedFcn = createCallbackFcn(app, @Start_TASKButtonPushed, true);
            app.Start_TASK.Text = '开始执行';
            app.data_conpensate = uicheckbox(app.Panel_2);
            app.data_conpensate.Text = '丢包补偿';
            app.data_conpensate.Value = true;
            app.Chose_output = uiknob(app.Panel_2, 'discrete');
            app.Chose_output.Items = {'解析', '解析与预处理', 'PSD计算'};
            app.Chose_output.ItemsData = {'1', '2', '3'};
            app.Chose_output.ValueChangedFcn = createCallbackFcn(app, @Chose_outputValueChanged, true);
            app.Chose_output.Value = '1';
            app.IS_OLD_VERSON = uicheckbox(app.Panel_2);
            app.IS_OLD_VERSON.Text = '旧版协议';
            app.Identify_Parameter = uilabel(app.Panel_2);
            app.Identify_Parameter.Text = '未识别到相关配置信息';
            app.Open_dir = uibutton(app.Panel_2, 'push');
            app.Open_dir.ButtonPushedFcn = createCallbackFcn(app, @Open_dirPushed, true);
            app.Open_dir.Text = '打开';
            app.Record_Unpack = uibutton(app.Panel_2, 'push');
            app.Record_Unpack.ButtonPushedFcn = createCallbackFcn(app, @Record_UnpackButtonPushed, true);
            app.Record_Unpack.Text = '解析记录';
            app.Record_State = uibutton(app.Panel_2, 'push');
            app.Record_State.ButtonPushedFcn = createCallbackFcn(app, @Record_StateButtonPushed, true);
            app.Record_State.Text = '存储记录';
            app.Check_Every_Seg = uicheckbox(app.Panel_2);
            app.Check_Every_Seg.Text = '逐段检查';
            app.Check_Every_Seg.Value = true;
            app.Save_fig = uicheckbox(app.Panel_2);
            app.Save_fig.Text = '存储预览图像';
            app.Data_chack = uipanel(app.batch_Process);
            app.Data_chack.Title = '数据查看';
            app.Confirm = uibutton(app.Data_chack, 'push');
            app.Confirm.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.Confirm.Enable = 'off';
            app.Confirm.Text = '确认/继续';
            app.Decline = uibutton(app.Data_chack, 'push');
            app.Decline.ButtonPushedFcn = createCallbackFcn(app, @DeclineButtonPushed, true);
            app.Decline.Enable = 'off';
            app.Decline.Text = '跳过/继续';
            app.Data_Cut_Tool = uibutton(app.Data_chack, 'push');
            app.Data_Cut_Tool.ButtonPushedFcn = createCallbackFcn(app, @Data_Cut_ToolButtonPushed, true);
            app.Data_Cut_Tool.Enable = 'off';
            app.Data_Cut_Tool.Text = '剪裁工具';
            app.Label_9 = uilabel(app.Data_chack);
            app.Label_9.Text = '查看通道：';
            app.Channel_Check = uidropdown(app.Data_chack);
            app.Channel_Check.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.Channel_Check.ValueChangedFcn = createCallbackFcn(app, @Channel_CheckValueChanged, true);
            app.Channel_Check.Enable = 'off';
            app.Channel_Check.Value = '1';
            app.Label_12 = uilabel(app.Data_chack);
            app.Label_12.Text = '时间轴';
            app.Slider_TIME = uislider(app.Data_chack, 'range');
            app.Slider_TIME.ValueChangingFcn = createCallbackFcn(app, @Slider_TIMEValueChanging, true);
            app.Clear_all = uibutton(app.Data_chack, 'push');
            app.Clear_all.ButtonPushedFcn = createCallbackFcn(app, @Clear_allPushed, true);
            app.Clear_all.Text = '清空';
            app.Draw_data = uipanel(app.Data_chack);
            app.Data_time_PLOT = uiaxes(app.Draw_data);
            title(app.Data_time_PLOT, 'LFP')
            ylabel(app.Data_time_PLOT, '振幅 (μV)')
            zlabel(app.Data_time_PLOT, 'Z')
            app.Data_TF_PLOT = uiaxes(app.Draw_data);
            title(app.Data_TF_PLOT, '时频图')
            xlabel(app.Data_TF_PLOT, '时间（s）')
            ylabel(app.Data_TF_PLOT, '频率 (Hz)')
            zlabel(app.Data_TF_PLOT, 'Z')
            app.Data_ACC_PLOT = uiaxes(app.Draw_data);
            title(app.Data_ACC_PLOT, 'ACC')
            ylabel(app.Data_ACC_PLOT, '加速度（g）')
            app.currentdata_txt = uitextarea(app.Draw_data);
            app.currentdata_txt.Editable = 'off';
            app.currentdata_txt.Value = {'请进行"数据确认"'};
            app.Current_data = uilabel(app.Draw_data);
            app.Current_data.Text = '当前：';
            app.IPG_ID = uitextarea(app.Draw_data);
            app.IPG_ID.Editable = 'off';
            app.IPG_ID.Value = {'IPG编号'};
            app.Defults_accfs = uieditfield(app.Data_chack, 'numeric');
            app.Defults_accfs.Value = 10;
            app.Defult_acc_FS = uilabel(app.Data_chack);
            app.Defult_acc_FS.Text = '设定ACC采样率';
            app.IS_unpack_ACC = uicheckbox(app.Data_chack);
            app.IS_unpack_ACC.ValueChangedFcn = createCallbackFcn(app, @IS_unpack_ACCValueChanged, true);
            app.IS_unpack_ACC.Text = '解析ACC';
            app.IS_unpack_ACC.Value = true;
            app.LFP_FS_SET = uieditfield(app.Data_chack, 'numeric');
            app.LFP_FS_SET.ValueChangedFcn = createCallbackFcn(app, @LFP_FS_SETValueChanged, true);
            app.LFP_FS = uilabel(app.Data_chack);
            app.LFP_FS.Text = 'LFP采样率';
            app.Continus_Process = uitab(app.TabGroup);
            app.Continus_Process.Title = '连续数据处理';
            app.TIME_AMP = uiaxes(app.Continus_Process);
            title(app.TIME_AMP, 'LFP')
            ylabel(app.TIME_AMP, '振幅')
            zlabel(app.TIME_AMP, 'Z')
            app.TIME_POW = uiaxes(app.Continus_Process);
            title(app.TIME_POW, '时频图')
            ylabel(app.TIME_POW, '频率')
            zlabel(app.TIME_POW, 'Z')
            app.TIME_ACC = uiaxes(app.Continus_Process);
            title(app.TIME_ACC, 'ACC')
            xlabel(app.TIME_ACC, '时间')
            ylabel(app.TIME_ACC, '加速度')
            zlabel(app.TIME_ACC, 'Z')
            app.Unpack_ALL = uibutton(app.Continus_Process, 'push');
            app.Unpack_ALL.ButtonPushedFcn = createCallbackFcn(app, @Unpack_ALLButtonPushed, true);
            app.Unpack_ALL.Enable = 'off';
            app.Unpack_ALL.Text = '开始执行数据拼接';
            app.PC18hLabel = uilabel(app.Continus_Process);
            app.PC18hLabel.Text = {'注：连续数据解析对PC设备内存具有一定要求。'; '       连续数据解析时间跨度不超过18h'};
            app.Switch = uiswitch(app.Continus_Process, 'slider');
            app.Switch.Items = {'连续数据解析', '长时波动提取'};
            app.Switch.Enable = 'off';
            app.Switch.Value = '连续数据解析';
            app.Label_13 = uilabel(app.Continus_Process);
            app.Label_13.Text = 'LFP解析采样率';
            app.Samplerate_Chack = uieditfield(app.Continus_Process, 'numeric');
            app.Label_14 = uilabel(app.Continus_Process);
            app.Label_14.Text = '通道数';
            app.Channel_number_chack = uieditfield(app.Continus_Process, 'numeric');
            app.Get_Info = uibutton(app.Continus_Process, 'push');
            app.Get_Info.ButtonPushedFcn = createCallbackFcn(app, @Get_InfoPushed, true);
            app.Get_Info.Enable = 'off';
            app.Get_Info.Text = '获取信息';
            app.Save_Data = uibutton(app.Continus_Process, 'push');
            app.Save_Data.ButtonPushedFcn = createCallbackFcn(app, @Save_DataButtonPushed, true);
            app.Save_Data.Enable = 'off';
            app.Save_Data.Text = '存储为：';
            app.Label_15 = uilabel(app.Continus_Process);
            app.Label_15.Text = '解析采样率修正';
            app.Fixed_SamplingRate = uieditfield(app.Continus_Process, 'numeric');
            app.Fixed_SamplingRate.ValueChangedFcn = createCallbackFcn(app, @Fixed_SamplingRateValueChanged, true);
            app.Label_16 = uilabel(app.Continus_Process);
            app.Label_16.Enable = 'off';
            app.Label_16.Text = '通道';
            app.CHACK_LARGEMAT = uidropdown(app.Continus_Process);
            app.CHACK_LARGEMAT.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.CHACK_LARGEMAT.ValueChangedFcn = createCallbackFcn(app, @CHACK_LARGEMATValueChanged, true);
            app.CHACK_LARGEMAT.Enable = 'off';
            app.CHACK_LARGEMAT.Value = '1';
            app.Label_17 = uilabel(app.Continus_Process);
            app.Label_17.Text = 'C轴';
            app.Color_range_Slide = uislider(app.Continus_Process, 'range');
            app.Color_range_Slide.Limits = [-80 80];
            app.Color_range_Slide.ValueChangingFcn = createCallbackFcn(app, @Color_range_SlideValueChanging, true);
            app.Color_range_Slide.Value = [-50 60];
            app.SliderLabel = uilabel(app.Continus_Process);
            app.SliderLabel.Text = '频率轴';
            app.Y_range_Slide = uislider(app.Continus_Process, 'range');
            app.Y_range_Slide.ValueChangingFcn = createCallbackFcn(app, @Y_range_SlideValueChanging, true);
            app.SAVE_type = uidropdown(app.Continus_Process);
            app.SAVE_type.Items = {'.m(简化格式)', '.m(NNSF)'};
            app.SAVE_type.ItemsData = {'1', '2'};
            app.SAVE_type.Value = '2';
            app.Defults_acc_chack = uieditfield(app.Continus_Process, 'numeric');
            app.Defults_acc_chack.Value = 10;
            app.ACCLabel = uilabel(app.Continus_Process);
            app.ACCLabel.Text = 'ACC采样率';
            app.ACC_UNPACK_CHACK_2 = uicheckbox(app.Continus_Process);
            app.ACC_UNPACK_CHACK_2.ValueChangedFcn = createCallbackFcn(app, @ACC_UNPACK_CHACK_2ValueChanged, true);
            app.ACC_UNPACK_CHACK_2.Text = '自动解析ACC';
            app.ACC_UNPACK_CHACK_2.Value = true;
            app.AUTO_SIZEs = uibutton(app.MainFigure_batch, 'state');
            app.AUTO_SIZEs.ValueChangedFcn = createCallbackFcn(app, @AUTO_SIZEsValueChanged, true);
            app.AUTO_SIZEs.Text = 'autosize off';
            app.AUTO_SIZEs.Value = true;
            % Show the figure after all components are created
            app.MainFigure_batch.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_anylysis_Data_batch_CHI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFigure_batch)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFigure_batch)
        end
    end
end