classdef APP_anylysis_result_compare < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFigure           matlab.ui.Figure
        matLabel             matlab.ui.control.Label
        Data_load            matlab.ui.container.Panel
        infoButton           matlab.ui.control.Button
        Load_data            matlab.ui.control.Button
        DropDown             matlab.ui.control.DropDown
        Data_list            matlab.ui.control.Table
        data_loading         matlab.ui.control.Button
        Plot_tab             matlab.ui.container.TabGroup
        PSD_compare          matlab.ui.container.Tab
        Clear                matlab.ui.control.Button
        IS_shadow            matlab.ui.control.CheckBox
        TO_5                 matlab.ui.control.Label
        PowerOrDB            matlab.ui.control.CheckBox
        plot_freq_down_2     matlab.ui.control.NumericEditField
        TO_4                 matlab.ui.control.Label
        plot_freq_up_2       matlab.ui.control.NumericEditField
        TO_3                 matlab.ui.control.Label
        Is_normalize         matlab.ui.control.CheckBox
        Lable_changes        matlab.ui.control.Button
        beta_peak_detection  matlab.ui.control.Button
        Save_figure          matlab.ui.control.Button
        plot_freq_down       matlab.ui.control.NumericEditField
        EditField_7Label     matlab.ui.control.Label
        TO_2                 matlab.ui.control.Label
        plot_freq_up         matlab.ui.control.NumericEditField
        TO                   matlab.ui.control.Label
        Plot                 matlab.ui.control.Button
        Channel_dir          matlab.ui.control.DropDown
        Label_4              matlab.ui.control.Label
        frequency_t          matlab.ui.control.Label
        Slider_PSD           matlab.ui.control.RangeSlider
        range_t              matlab.ui.control.Label
        PSD_YSlider          matlab.ui.control.RangeSlider
        PSD_figure           matlab.ui.control.UIAxes
        compare              matlab.ui.control.Label
        NNSRLabel            matlab.ui.control.Label
    end

    properties (Access = private)
        Filename % filenames
        waitting_now % wait
        data_path % data_pathss
        channels % ALL_CHANNELS
        freqs % All freq
        powers % ALL power
        channel_uniq % uniq channel
        Is_Load_Ok % Load OK Mark
        show_load_info
        % Waitting_now % waitting
        Legends % Description
        Plotted % Description
    end
    
    properties (Access = public)
        % UIFigure % Description
        APP_anylysis_CHI_0_1
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            msg = {'提示/Note：', 
                   '该工具支持不同数据段功率谱密度的对比分析。', 
                   '请先使用手动分析工具保存多段功率谱密度数据（.mat格式）以便导入分析。', 
                   'The tool supports comparison of power spectral density across different data segments.', 
                   'Please first use the manual analysis tool to save the power spectral density data from multiple segments in .mat format for import and analysis.'};

            % 显示消息对话框
            uialert(app.MainFigure, msg, '提示 / Notification', 'Icon', 'info');


            screenSize = get(groot, 'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            appWidth = app.MainFigure.Position(3);
            appHeight = app.MainFigure.Position(4);
            horizontalCenter = (screenWidth - appWidth) / 2;
            verticalCenter = (screenHeight - appHeight) / 2;
            app.MainFigure.Position(1:2) = [horizontalCenter, verticalCenter];
            set(app.MainFigure,'Icon','icon_48.png');
            app.MainFigure.Name = 'NERCN脑电平台';

            colorbacks = [0.76,0.73,0.82];
            text(app.PSD_figure,0.1,0.5,'脑电数据离线分析系统','Color',[0.98,0.98,0.98],"BackgroundColor",colorbacks,"FontSize",24,"FontSmoothing","on","FontWeight","bold")
            app.PSD_figure.Color = [0.76,0.73,0.82];
            app.show_load_info = {'无数据信息，请先载入数据'};

        end

        % Button pushed function: data_loading
        function data_loadingButtonPushed(app, event)
            
            app.waitting_now = uiprogressdlg(app.MainFigure,'Title','正在获取文件夹',...
            'Indeterminate','on');
            drawnow
            app.Filename = [];

            [app.Filename,path] = uigetfile({'*.mat'},'打开特征结果文件(.mat)','MultiSelect','on');
            if isequal(app.Filename,0) || isequal(path,0)
             uialert(app.MainFigure,'用户取消了文件选择','提示','Icon','info');
             app.Load_data.Enable = "off";
             close(app.waitting_now)
             return
            else
                try
                    app.Data_list.Data = cell2table(app.Filename', 'VariableNames', {'Strings'});
                catch
                  % assignin('base','filename',app.Filename)
                    app.Filename = cellstr(app.Filename);
                    app.Data_list.Data = app.Filename;
                end
                    app.data_path = path;
            end
            uialert(app.MainFigure,['已成功选择 【',num2str(length(app.Filename)),'】个.mat数据',' 可继续执行 "载入数据" 操作'],'提示','Icon','success');
            app.Load_data.Enable = "on";
            close(app.waitting_now)
        end

        % Button pushed function: Load_data
        function Load_dataButtonPushed(app, event)
            % [filename,path] = uigetfile({'*.mat'},'打开PSD能量文件(.mat)','MultiSelect','on');
            addpath(app.data_path)
            file_number = length(app.Filename);
            app.channels = cell(file_number,1);
            app.freqs = cell(file_number,1);
            % infos = cell(file_number,1);
            app.powers = cell(file_number,1);
            is_loaded = [];

            for i = 1:file_number
                try
                    Loadeddata = load(app.Filename{i});
                    app.channels{i} = Loadeddata.channel;
                    % eval(['channel_',num2str(i),' = channel;'])
                    % assignin('base','Loadeddata',Loadeddata)
                    app.freqs{i} = Loadeddata.freq;
                    % assignin('base','powers',power)
                    app.powers{i} = Loadeddata.power;
                    is_loaded = [is_loaded,1];
                catch
                    is_loaded = [is_loaded,0];
                end
            end

            app.Is_Load_Ok = is_loaded;
            rmpath(app.data_path) % 删除添加的路径
           % 初始化一个空的cell数组来存放结果
            channel_all = {};

            for i = 1:length(app.channels)
                if iscell(app.channels{i})
                    for j = 1:length(app.channels{i})
                        channel_all{end+1} = app.channels{i}{j}; % 将内层cell的元素添加到结果数组
                    end
                else
                    % 如果C{i}不是cell（这在此示例中不会发生，但为了通用性而检查）
                    channel_all{end+1} = app.channels{i};
                end
            end
            
            app.channel_uniq =  unique(channel_all);
            app.Channel_dir.Items = app.channel_uniq;
            show_load = cell(1,length(is_loaded));
            for i =1:length(is_loaded)
                if is_loaded(i) == 1
                    show_load{i} = ['列表第',num2str(i),'个数据',' 成功载入 共',num2str(length(app.channels{i})),' 个通道'];
                    app.Channel_dir.Enable = "on";
                    app.Lable_changes.Enable = "on";
                    app.plot_freq_down.Enable = "on";
                    app.plot_freq_up.Enable = "on";
                    app.Plot.Enable = "on";
                    app.IS_shadow.Enable = "on";
                    app.PowerOrDB.Enable = "on";
                    app.Is_normalize.Enable = "on";
                    app.plot_freq_down_2.Enable ="on";
                    app.plot_freq_up_2.Enable = "on";
                elseif is_loaded(i) == 0
                    show_load{i} = ['列表第',num2str(i),'个数据 失败'];
                    app.Channel_dir.Enable = "off";
                    app.Lable_changes.Enable = "off";
                    app.plot_freq_down.Enable = "off";
                    app.plot_freq_up.Enable = "off";
                    app.Plot.Enable = "off";
                    app.IS_shadow.Enable = "off";
                    app.PowerOrDB.Enable = "off";
                    app.Is_normalize.Enable = "off";
                    app.plot_freq_down_2.Enable ="off";
                    app.plot_freq_up_2.Enable = "off";
                end

            end

            logical_index = (app.Is_Load_Ok == 1);
            app.Legends = app.Filename(logical_index);
            % assignin("base","show_load",show_load)
            % app.Legends = app.Filename;
            uialert(app.MainFigure,show_load,'数据载入情况','Icon','success');
            app.show_load_info = show_load;
        end

        % Button pushed function: Plot
        function PlotButtonPushed(app, event)
            app.waitting_now = uiprogressdlg(app.MainFigure,'Title','绘制中',...
            'Indeterminate','on');
            drawnow
            cla(app.PSD_figure,'reset');
            channel_choose = app.Channel_dir.Value;
            % 设置无法绘制的通道？
            Legends_new = {};
            logical_index = (app.Is_Load_Ok == 1);
            % 结构体的new version供绘制
            app.Plotted = 1;
            
            for i = find(logical_index) % 非0数number 按照index
                
                matches = cellfun(@(x) strcmp(x, channel_choose), app.channels{i});
                % assignin("base","matches",matches)
                if sum(matches) > 0 
                    Legends_new = [Legends_new,app.Legends(i)];
                    index = matches; % find 去掉了
                    
                    line_x_toplot = app.freqs{i}(:,1);
                    line_y_toplot = app.powers{i}(:,index);
                    
                    if app.Is_normalize.Value == 1
                    indexssss =  line_x_toplot < app.plot_freq_up_2.Value & line_x_toplot > app.plot_freq_down_2.Value;
                    line_y_toplot = line_y_toplot./mean(line_y_toplot(indexssss));
                    else
                    line_y_toplot = line_y_toplot./1;
                    end
    
                    if app.PowerOrDB.Value == 1
                    plot(app.PSD_figure,line_x_toplot,10*log10(line_y_toplot),"LineWidth",1.5);
                    else
                    plot(app.PSD_figure,line_x_toplot,(line_y_toplot),"LineWidth",1.5);
                    end
                    app.PSD_figure.NextPlot = 'add';
                    
                end
                
            end

               try
               app.PSD_figure.XLim = [app.plot_freq_down.Value,app.plot_freq_up.Value];
               end

               try
               app.Slider_PSD.Value = [app.plot_freq_down.Value,app.plot_freq_up.Value];
               app.Slider_PSD.Limits = [0,app.freqs{i}(end)];
               end

            if app.IS_shadow.Value == 1
            colorList_band = [245 117 71; 214 064 078;158 3 66;78 98 171;70 158 180;135 207 164]./256; % 各频段Color
            YLM = get(app.PSD_figure,'ylim');
            app.PSD_figure.NextPlot = 'add';
            x = [1 4 4 1];
            y = [YLM(1) YLM(1) YLM(2) YLM(2)];
            H1 = fill(app.PSD_figure,x,y,colorList_band(1,:),'EdgeColor','none','FaceAlpha',0.28,'HandleVisibility', 'off');
            uistack(H1, 'bottom')
            app.PSD_figure.NextPlot = 'add';
            % YLM = get(app.PSD_figure,'ylim');
            x = [5 8 8 5];
            % y = [YLM(1)-30 YLM(1)-30 YLM(2)+30 YLM(2)+30];
            H2 = fill(app.PSD_figure,x,y,colorList_band(2,:),'EdgeColor','none','FaceAlpha',0.28,'HandleVisibility', 'off');
            uistack(H2, 'bottom')
            app.PSD_figure.NextPlot = 'add';
            % YLM = get(app.PSD_figure,'ylim');
            x = [9 13 13 9];
            % y = [YLM(1)-30 YLM(1)-30 YLM(2)+30 YLM(2)+30];
            H3 = fill(app.PSD_figure,x,y,colorList_band(3,:),'EdgeColor','none','FaceAlpha',0.28,'HandleVisibility', 'off');
            uistack(H3, 'bottom')
            app.PSD_figure.NextPlot = 'add';
            % YLM = get(app.PSD_figure,'ylim');
            x = [14 20 20 14];
            % y = [YLM(1)-30 YLM(1)-30 YLM(2)+30 YLM(2)+30];
            H4 = fill(app.PSD_figure,x,y,colorList_band(4,:),'EdgeColor','none','FaceAlpha',0.28,'HandleVisibility', 'off');
            uistack(H4, 'bottom')
            app.PSD_figure.NextPlot = 'add';
            % YLM = get(app.PSD_figure,'ylim');
            x = [21 35 35 21];
            % y = [YLM(1)-30 YLM(1)-30 YLM(2)+30 YLM(2)+30];
            H5 = fill(app.PSD_figure,x,y,colorList_band(5,:),'EdgeColor','none','FaceAlpha',0.28,'HandleVisibility', 'off');
            uistack(H5, 'bottom')
            app.PSD_figure.NextPlot = 'add';
            % YLM = get(app.PSD_figure,'ylim');
            x = [36 100 100 36];
            % y = [YLM(1)-30 YLM(1)-30 YLM(2)+30 YLM(2)+30];
            H6 = fill(app.PSD_figure,x,y,colorList_band(6,:),'EdgeColor','none','FaceAlpha',0.28,'HandleVisibility', 'off');
            uistack(H6, 'bottom')
            end
            
            if app.Is_normalize.Value == 0
                if app.PowerOrDB.Value == 1
                    app.PSD_figure.YLabel.String = 'Power (dB/Hz)';
                elseif app.PowerOrDB.Value == 0
                    app.PSD_figure.YLabel.String = 'Power (μV^2/Hz)';
                end
            elseif app.Is_normalize.Value == 1

                app.PSD_figure.YLabel.String = 'Power (a.u.)';
            end
            app.PSD_figure.XLabel.String = 'Frequency (Hz)';
            app.PSD_figure.FontSize = 10;
            % app.Legends = app.Filename(logical_index);
            legend(app.PSD_figure,Legends_new)

            % xlim([0,50])
            % assignin("base",'logical_index',logical_index)
             % assignin("base",'Filename',app.Filename)
             % assignin("base",'legend_index',find(logical_index))
            app.PSD_figure.NextPlot = 'replacechildren';
            app.Save_figure.Enable = "on";
            app.beta_peak_detection.Enable = "on";
            close(app.waitting_now)
        end

        % Button pushed function: infoButton
        function infoButtonPushed(app, event)
           uialert(app.MainFigure,['当前列表数据载入情况：',app.show_load_info],'数据载入情况','Icon','success');
        end

        % Button pushed function: Clear
        function ClearButtonPushed(app, event)
            app.Plotted = 0;
            app.Save_figure.Enable = "off";
            app.beta_peak_detection.Enable = "off";
            app.Plot.Enable = "off";
            app.plot_freq_down_2.Enable = "off";
            app.plot_freq_up_2.Enable = "off";
            app.Is_normalize.Enable = "off";
            app.PowerOrDB.Enable = "off";
            app.IS_shadow.Enable = "off";
            app.plot_freq_down.Enable = "off";
            app.plot_freq_up.Enable = "off";
            app.Lable_changes.Enable = "off";
            app.Channel_dir.Enable = "off";
            app.Load_data.Enable = "off";
            
            app.Data_list.Data = [];
            cla(app.PSD_figure,'reset');
            colorbacks = [0.76,0.73,0.82];
            text(app.PSD_figure,0.1,0.5,'脑电数据离线分析系统','Color',[0.98,0.98,0.98],"BackgroundColor",colorbacks,"FontSize",24,"FontSmoothing","on","FontWeight","bold")
            app.PSD_figure.Color = [0.76,0.73,0.82];
            app.show_load_info = {'无数据信息，请先载入数据'};
            
        end

        % Button pushed function: Save_figure
        function Save_figureButtonPushed(app, event)
                
                new_f_handle=figure('visible','off');
                new_axes=copyobj(app.PSD_figure,new_f_handle); %picture是GUI界面绘图的坐标系句柄
                set(new_axes,'units','default','position','default');
                [filen,pathname,fileindex]=uiputfile({'*.jpg';'*.bmp'},'save picture as','PSD_Compare_figure');
                if ~filen
                     return
                else
                      file=strcat(pathname,filen);
                      switch fileindex %根据不同的选择保存为不同的类型
                          case 1  % JPG格式
                                exportgraphics(new_f_handle, file, 'Format', 'jpeg');
                          case 2  % BMP格式
                                exportgraphics(new_f_handle, file, 'Format', 'bmp');
                      end
                end
                delete(new_f_handle);     

        end

        % Button pushed function: Lable_changes
        function Lable_changesButtonPushed(app, event)
            
            numInputs = length(app.Legends);
            prompt = cell(numInputs, 1);
            for i = 1:numInputs
                prompt{i} = sprintf('请输入标签 #%d:', i);
            end
            dlgTitle = '输入Legend标签';
            defaultAnswers = app.Legends; % 默认答案为空
            inputStrings = inputdlg(prompt, dlgTitle, [1, 50], defaultAnswers);
            assignin("base","inputStrings",inputStrings)
            % 检查用户是否点击了取消
            if isempty(inputStrings)
                uialert(app.MainFigure,'用户取消输入','提示','Icon','info');
            else
                uialert(app.MainFigure,['输入成功，对应新标签为：';inputStrings],'提示','Icon','success');
                app.Legends = inputStrings;
                % disp(inputStrings);
            end
            if app.Plotted == 1
                try
                PlotButtonPushed(app, event)
                end
            end
        end

        % Button pushed function: beta_peak_detection
        function beta_peak_detectionButtonPushed(app, event)
            app.waitting_now = uiprogressdlg(app.MainFigure,'Title','计算相关性中',...
            'Indeterminate','on');
            drawnow
            app.PowerOrDB.Value = 1;
            
            PlotButtonPushed(app, event);
            numInputs = length(app.Legends);

            channel_choose = app.Channel_dir.Value;

            try
            % 做简单的beta峰值识别
            peakss = [];
            % assignin("base",'Value',app.chi_calcu.Value)

            for i = 1:numInputs

                matches = cellfun(@(x) strcmp(x, channel_choose), app.channels{i});
                if sum(matches) > 0 
                    index = matches; % find 去掉了
                    if app.Is_normalize.Value == 1 
                        x = app.freqs{i}(:,1);
                        indexssss =  x < app.plot_freq_up_2.Value & x > app.plot_freq_down_2.Value;
                        y_line = app.powers{i}(:,index);
                        y = 10*log10(y_line./mean(y_line(indexssss)));
                    elseif app.Is_normalize.Value == 0 
                        x = app.freqs{i}(:,1);
                        % line_y_toplot = line_y_toplot./1;
                        y = 10*log10(app.powers{i}(:,index));
                    end
                    % y = 10*log10(app.RESULT_DATA_PSD_POWER(1:app.plot_freq_up.Value,i)); % 因变量数据
                    % 定义拟合类型，使用自定义匿名函数
                    fitType = fittype(@(a,b,c,x) a + b./(c+x), ...
                                       'independent', 'x', ...
                                       'dependent', 'y');
                    [fitResult, ~] = fit(x, y, fitType, 'StartPoint', [1,1,1]);
                    % 提取拟合参数
                    a = fitResult.a;
                    b = fitResult.b;
                    c = fitResult.c;
                    % 绘制数据和拟合曲线
                    % plot(fitResult, x, y);
                    xs = x;
                    ys = a+b./(c+xs);

                    OCI = y-ys;
                    idx_freq = (x >= 13 & x <= 35);
                    OCI_FIND = OCI(idx_freq);
                    OCI_XDATA = x(idx_freq);
                    ORI_OCI = y(idx_freq);
                    
                    [PKS,LOCS] = findpeaks(OCI_FIND);
                    [~,IDX] = max(PKS);
                    maxpeak_locs = OCI_XDATA(LOCS(IDX));
                    maxpeak_pks = ORI_OCI(LOCS(IDX));
                    
                    app.PSD_figure.NextPlot = 'add';
                    plot(app.PSD_figure,maxpeak_locs, maxpeak_pks, 'o', 'MarkerSize', 8, 'MarkerEdgeColor', 'b', 'MarkerFaceColor', 'b','HandleVisibility', 'off');
                    % hold on;
                    app.PSD_figure.NextPlot = 'add';
                    peakss = [peakss,[app.Legends(i),'为:【' , num2str(maxpeak_locs),'Hz】 ']];
                end
            end
                close(app.waitting_now);
                assignin("base","peakss",peakss)
                message = sprintf(['β震荡峰值频率识别：\n',cell2mat(peakss), '  \n 注： 结果由 1/f 以上部分拟合决定。']);
                % msgbox(message,'β震荡峰值','Icon','success')
                uiconfirm(app.MainFigure,message,'β震荡峰值识别结果','Icon','success');
            catch
                uialert(app.MainFigure,'未能识别beta峰值，请检查PSD绘图结果','错误','Icon','info');
                % msgbox('','','warn')
            end
            
        end

        % Value changed function: Is_normalize
        function Is_normalizeValueChanged(app, event)
            
            value = app.Is_normalize.Value;
            if value == 1
               app.plot_freq_down_2.Enable = "on";
               app.plot_freq_up_2.Enable = "on";
            else
               app.plot_freq_down_2.Enable = "off";
               app.plot_freq_up_2.Enable = "off";
            end
            if app.Plotted == 1
                try
                PlotButtonPushed(app, event)
                end
            end
        end

        % Value changing function: Slider_PSD
        function Slider_PSDValueChanging(app, event)
            value = event.Value;
            try
            app.PSD_figure.XLim = value;
            catch
               % msg = ('请重新选择范围');
               % uialert(app.Phase_Value_analisis,msg,'提示','Icon','warning')
               % msgbox(msg)
            end
        end

        % Value changing function: PSD_YSlider
        function PSD_YSliderValueChanging(app, event)
            value = event.Value;
            try
            app.PSD_figure.YLim = value;
            catch
               % msg = ('请重新选择范围');
               % uialert(app.Phase_Value_analisis,msg,'提示','Icon','warning')
               % msgbox(msg)
            end
        end

        % Value changed function: IS_shadow
        function IS_shadowValueChanged(app, event)
            if app.Plotted == 1
                try
                PlotButtonPushed(app, event)
                end
            end
        end

        % Value changed function: PowerOrDB
        function PowerOrDBValueChanged(app, event)
            if app.Plotted == 1
                try
                PlotButtonPushed(app, event)
                end
            end
        end

        % Value changed function: Channel_dir
        function Channel_dirValueChanged(app, event)
            
            if app.Plotted == 1
                try
                PlotButtonPushed(app, event)
                end
            end

        end

        % Close request function: MainFigure
        function MainFigureCloseRequest(app, event)
            
            % button=questdlg('您确定退出吗？（注意！未储存的数据将被删除）','退出离线分析工具？','是','否','是'); %内容，标题，选项，默认选项
            selection = uiconfirm(app.MainFigure, '您确定退出吗？（注意！未储存的数据将被删除）', '退出数据对比工具？','Options',{'直接退出', '返回主菜单' ,'取消'},'DefaultOption', '取消', 'CancelOption', '直接退出');
            if strcmp(selection,'直接退出')
               delete(app);
            elseif strcmp(selection,'返回主菜单')
               APP_to_start_0_1
               delete(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFigure = uifigure('Visible', 'off');
            app.MainFigure.Name = 'MATLAB App';
            app.MainFigure.CloseRequestFcn = createCallbackFcn(app, @MainFigureCloseRequest, true);
            app.NNSRLabel = uilabel(app.MainFigure);
            app.NNSRLabel.Text = 'NNSR 离线分析平台';
            app.compare = uilabel(app.MainFigure);
            app.compare.Text = '-  结果对照工具';
            app.Plot_tab = uitabgroup(app.MainFigure);
            app.PSD_compare = uitab(app.Plot_tab);
            app.PSD_compare.Title = '功率谱特征对比';
            app.PSD_figure = uiaxes(app.PSD_compare);
            title(app.PSD_figure, '功率谱密度')
            xlabel(app.PSD_figure, 'Frequency (Hz)')
            ylabel(app.PSD_figure, 'Power (dB/Hz)')
            zlabel(app.PSD_figure, 'Z')
            app.PSD_YSlider = uislider(app.PSD_compare, 'range');
            app.PSD_YSlider.Limits = [-30 40];
            app.PSD_YSlider.ValueChangingFcn = createCallbackFcn(app, @PSD_YSliderValueChanging, true);
            app.PSD_YSlider.Value = [0 29.99];
            app.range_t = uilabel(app.PSD_compare);
            app.range_t.Text = '范围';
            app.Slider_PSD = uislider(app.PSD_compare, 'range');
            app.Slider_PSD.Limits = [0 130];
            app.Slider_PSD.ValueChangingFcn = createCallbackFcn(app, @Slider_PSDValueChanging, true);
            app.Slider_PSD.Value = [2 79.95];
            app.frequency_t = uilabel(app.PSD_compare);
            app.frequency_t.Text = '频率轴';
            app.Label_4 = uilabel(app.PSD_compare);
            app.Label_4.Text = '对比通道选择';
            app.Channel_dir = uidropdown(app.PSD_compare);
            app.Channel_dir.Items = {'请载入数据'};
            app.Channel_dir.ValueChangedFcn = createCallbackFcn(app, @Channel_dirValueChanged, true);
            app.Channel_dir.Enable = 'off';
            app.Channel_dir.Value = '请载入数据';
            app.Plot = uibutton(app.PSD_compare, 'push');
            app.Plot.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.Plot.Enable = 'off';
            app.Plot.Text = '绘制';
            app.TO = uilabel(app.PSD_compare);
            app.TO.Text = '至';
            app.plot_freq_up = uieditfield(app.PSD_compare, 'numeric');
            app.plot_freq_up.Enable = 'off';
            app.plot_freq_up.Value = 60;
            app.TO_2 = uilabel(app.PSD_compare);
            app.TO_2.Text = 'Hz';
            app.EditField_7Label = uilabel(app.PSD_compare);
            app.EditField_7Label.Enable = 'off';
            app.EditField_7Label.Text = '绘制范围';
            app.plot_freq_down = uieditfield(app.PSD_compare, 'numeric');
            app.plot_freq_down.Enable = 'off';
            app.plot_freq_down.Value = 2;
            app.Save_figure = uibutton(app.PSD_compare, 'push');
            app.Save_figure.ButtonPushedFcn = createCallbackFcn(app, @Save_figureButtonPushed, true);
            app.Save_figure.Enable = 'off';
            app.Save_figure.Text = '保存图片';
            app.beta_peak_detection = uibutton(app.PSD_compare, 'push');
            app.beta_peak_detection.ButtonPushedFcn = createCallbackFcn(app, @beta_peak_detectionButtonPushed, true);
            app.beta_peak_detection.Enable = 'off';
            app.beta_peak_detection.Text = 'β 峰值识别';
            app.Lable_changes = uibutton(app.PSD_compare, 'push');
            app.Lable_changes.ButtonPushedFcn = createCallbackFcn(app, @Lable_changesButtonPushed, true);
            app.Lable_changes.Enable = 'off';
            app.Lable_changes.Text = '手动标签设置';
            app.Is_normalize = uicheckbox(app.PSD_compare);
            app.Is_normalize.ValueChangedFcn = createCallbackFcn(app, @Is_normalizeValueChanged, true);
            app.Is_normalize.Enable = 'off';
            app.Is_normalize.Text = '数据归一化：';
            app.Is_normalize.Value = true;
            app.TO_3 = uilabel(app.PSD_compare);
            app.TO_3.Text = '至';
            app.plot_freq_up_2 = uieditfield(app.PSD_compare, 'numeric');
            app.plot_freq_up_2.Enable = 'off';
            app.plot_freq_up_2.Value = 40;
            app.TO_4 = uilabel(app.PSD_compare);
            app.TO_4.Text = 'Hz';
            app.plot_freq_down_2 = uieditfield(app.PSD_compare, 'numeric');
            app.plot_freq_down_2.Enable = 'off';
            app.plot_freq_down_2.Value = 2;
            app.PowerOrDB = uicheckbox(app.PSD_compare);
            app.PowerOrDB.ValueChangedFcn = createCallbackFcn(app, @PowerOrDBValueChanged, true);
            app.PowerOrDB.Enable = 'off';
            app.PowerOrDB.Text = '以分贝绘制';
            app.PowerOrDB.Value = true;
            app.TO_5 = uilabel(app.PSD_compare);
            app.TO_5.Text = '范围';
            app.IS_shadow = uicheckbox(app.PSD_compare);
            app.IS_shadow.ValueChangedFcn = createCallbackFcn(app, @IS_shadowValueChanged, true);
            app.IS_shadow.Enable = 'off';
            app.IS_shadow.Text = '频段指示';
            app.IS_shadow.Value = true;
            app.Clear = uibutton(app.PSD_compare, 'push');
            app.Clear.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.Clear.Text = '清空';
            app.Data_load = uipanel(app.MainFigure);
            app.Data_load.Title = '数据管理';
            app.data_loading = uibutton(app.Data_load, 'push');
            app.data_loading.ButtonPushedFcn = createCallbackFcn(app, @data_loadingButtonPushed, true);
            app.data_loading.Text = '特征文件选择 ';
            app.Data_list = uitable(app.Data_load);
            app.Data_list.ColumnName = {'数据名称'};
            app.Data_list.RowName = {};
            app.DropDown = uidropdown(app.Data_load);
            app.DropDown.Items = {'.MAT'};
            app.DropDown.Enable = 'off';
            app.DropDown.Value = '.MAT';
            app.Load_data = uibutton(app.Data_load, 'push');
            app.Load_data.ButtonPushedFcn = createCallbackFcn(app, @Load_dataButtonPushed, true);
            app.Load_data.Enable = 'off';
            app.Load_data.Text = '载入数据';
            app.infoButton = uibutton(app.Data_load, 'push');
            app.infoButton.ButtonPushedFcn = createCallbackFcn(app, @infoButtonPushed, true);
            app.infoButton.Text = 'info';
            app.matLabel = uilabel(app.MainFigure);
            app.matLabel.Text = {'      模块功能：将不同数据段的'; '功率谱密度计算结果进行对比；'; '      请先将不同数据段特征存储'; '为.mat格式再导入绘制。'};
            % Show the figure after all components are created
            app.MainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_anylysis_result_compare

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFigure)
        end
    end
end