classdef APP_to_start_0_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFigure         matlab.ui.Figure
        Panel2             matlab.ui.container.Panel
        Lab2               matlab.ui.control.Label
        Feature_Conpare    matlab.ui.control.Image
        Lab1               matlab.ui.control.Label
        Batch_Process      matlab.ui.control.Image
        Lab4               matlab.ui.control.Label
        Lab3               matlab.ui.control.Label
        Data_Crop          matlab.ui.control.Image
        Close_LOOP         matlab.ui.control.Image
        Panel1             matlab.ui.container.Panel
        Label_5            matlab.ui.control.Label
        CloseLoopToolbox   matlab.ui.control.Image
        Label_3            matlab.ui.control.Label
        Label_2            matlab.ui.control.Label
        Label              matlab.ui.control.Label
        Clinical           matlab.ui.control.Image
        LFPToolbox         matlab.ui.control.Image
        OnlineToolbox      matlab.ui.control.Image
        DropDown           matlab.ui.control.DropDown
        Label_4            matlab.ui.control.Label
        LanguageLabel      matlab.ui.control.Label
        Switch             matlab.ui.control.Switch
        v020Label          matlab.ui.control.Label
        byNATIONALENGINEERINGRESEARCHCENTEROFNEUROMODULATIONLabel  matlab.ui.control.Label
        ClicktoenterLabel  matlab.ui.control.Label
        WelcometoNERCNNeuroSignalResearchNNSRToolboxLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        waitting_NOW % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.waitting_NOW = uiprogressdlg(app.MainFigure,'Title','正在载入界面',...
            'Indeterminate','on');
            drawnow
            
            app.MainFigure.Theme = 'light';  % 强制使用浅色主题

            screenSize = get(groot, 'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            appWidth = app.MainFigure.Position(3);
            appHeight = app.MainFigure.Position(4);
            horizontalCenter = (screenWidth - appWidth) / 2;
            verticalCenter = (screenHeight - appHeight) / 2;
            app.MainFigure.Position(1:2) = [horizontalCenter, verticalCenter];
           
           set(app.MainFigure,'Icon','icon_48.png');
           app.MainFigure.Name = 'NNSR TOOLBOX';
           app.Switch.Value = '1';
           app.WelcometoNERCNNeuroSignalResearchNNSRToolboxLabel.Text = '欢迎访问 NERCN NeuroSignal Research(NNSR) 工具箱!';
           app.byNATIONALENGINEERINGRESEARCHCENTEROFNEUROMODULATIONLabel.Text = '本工具由神经调控国家工程研究中心公开';
           app.ClicktoenterLabel.Text = '点击进入:';
           app.MainFigure.Name = 'NNSR TOOLBOX';
           app.Switch.Items = {'中文','英文'};
           close(app.waitting_NOW)
        end

        % Image clicked function: OnlineToolbox
        function OnlineToolboxClicked(app, event)
       
        if str2double(app.Switch.Value) == 2
            % button=questdlg('','warning','','NO','YES'); %内容，标题，选项，默认选项
            button = uiconfirm(app.MainFigure, 'Leave current page？', 'LEAVE?','Options',{'YES', 'NO'},'DefaultOption', 'NO', 'CancelOption', 'YES');
        else
            button = uiconfirm(app.MainFigure, '是否进入在线研究工具并离开当前页面？', '离开','Options',{'YES', 'NO'},'DefaultOption', 'NO', 'CancelOption', 'YES');
            % button=questdlg('','提醒','YES','NO','YES'); %内容，标题，选项，默认选项
        end

        if strcmp(button,'YES')
           if str2double(app.Switch.Value) == 1
            app.waitting_NOW = uiprogressdlg(app.MainFigure,'Title','正在加载中',...
            'Indeterminate','on');
            drawnow
               delete(app);
               APP_acq_CHI_0_1
           elseif str2double(app.Switch.Value) == 2
            app.waitting_NOW = uiprogressdlg(app.MainFigure,'Title','正在加载中',...
            'Indeterminate','on');
            drawnow
               delete(app);
               APP_acq_CHI_0_1
               % APP_acq_ENG_0_1
           end
        end
        end

        % Image clicked function: LFPToolbox
        function LFPToolboxClicked(app, event)
        if str2double(app.Switch.Value) == 2
            % button=questdlg('Leave current page？','warning','YES','NO','YES'); %内容，标题，选项，默认选项
            button = uiconfirm(app.MainFigure, 'Leave current page？', 'LEAVE?','Options',{'YES', 'NO'},'DefaultOption', 'NO', 'CancelOption', 'YES');
        else
            % button=questdlg('即将关闭当前页面？','提醒','YES','NO','YES'); %内容，标题，选项，默认选项
            button = uiconfirm(app.MainFigure, '是否进入手动分析工具并离开当前页面？', '离开','Options',{'YES', 'NO'},'DefaultOption', 'NO', 'CancelOption', 'YES');
        end

        if strcmp(button,'YES')
           if str2double(app.Switch.Value) == 1
            app.waitting_NOW = uiprogressdlg(app.MainFigure,'Title','正在加载中',...
            'Indeterminate','on');
            drawnow
               APP_anylysis_CHI_0_1
               delete(app);
           elseif str2double(app.Switch.Value) == 2
            app.waitting_NOW = uiprogressdlg(app.MainFigure,'Title','正在加载中',...
            'Indeterminate','on');
            drawnow
%                APP_anylysis_ENG_0_1
               APP_anylysis_CHI_0_1
               delete(app);
           end
        end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = str2double(app.Switch.Value);
            if value == 1
                app.WelcometoNERCNNeuroSignalResearchNNSRToolboxLabel.Text = '欢迎访问 NNSR 工具箱!';
                app.byNATIONALENGINEERINGRESEARCHCENTEROFNEUROMODULATIONLabel.Text = '本工具由神经调控国家工程研究中心公开';
                app.ClicktoenterLabel.Text = '点击进入:';
                app.MainFigure.Name = 'NNSR 工具箱';
                app.Switch.Items = {'中文','英文'};
            elseif value == 2
                app.WelcometoNERCNNeuroSignalResearchNNSRToolboxLabel.Text = 'Welcome to NERCN NeuroSignal Research (NNSR) Toolbox！';
                app.byNATIONALENGINEERINGRESEARCHCENTEROFNEUROMODULATIONLabel.Text = 'by  NATIONAL ENGINEERING RESEARCH CENTER OF NEUROMODULATION';
                app.ClicktoenterLabel.Text = 'Click to enter：';
                app.MainFigure.Name = 'NNSR TOOLBOX';
                app.Switch.Items = {'CHI','ENG'};
            end
        end

        % Close request function: MainFigure
        function CloseReturn(app, event)
        value = str2double(app.Switch.Value);
        if value == 1
                  % button=questdlg('您确定退出吗？','退出采集软件','是','否','是'); %内容，标题，选项，默认选项
                  button = uiconfirm(app.MainFigure, '您确定退出吗？', '退出软件','Options',{'是', '否'},'DefaultOption', '否', 'CancelOption', '是');
               if strcmp(button,'是')
                   delete(app);
               end
        elseif value == 2
                 button=questdlg('Sure to quit？','Quit','YES','NO','YES'); %内容，标题，选项，默认选项
              if strcmp(button,'YES')
                 delete(app);
              end
        end
%         delete(app);
        end

        % Image clicked function: Clinical
        function ClinicalClicked(app, event)
        if str2double(app.Switch.Value) == 2
            % button=questdlg('Leave current page？','warning','YES','NO','YES'); %内容，标题，选项，默认选项
            button = uiconfirm(app.MainFigure, 'Leave current page？', 'LEAVE?','Options',{'YES', 'NO'},'DefaultOption', 'NO', 'CancelOption', 'YES');
        else
            % button=questdlg('即将关闭当前页面？','提醒','YES','NO','YES'); %内容，标题，选项，默认选项
            button = uiconfirm(app.MainFigure, '是否进入自动分析工具，并离开当前页面？', '离开','Options',{'YES', 'NO'},'DefaultOption', 'NO', 'CancelOption', 'YES');
        end

        if strcmp(button,'YES')
           if str2double(app.Switch.Value) == 1
            app.waitting_NOW = uiprogressdlg(app.MainFigure,'Title','正在加载中',...
            'Indeterminate','on');
            drawnow
               APP_Clinical_Research_CHI
               delete(app);
           elseif str2double(app.Switch.Value) == 2
            app.waitting_NOW = uiprogressdlg(app.MainFigure,'Title','正在加载中',...
            'Indeterminate','on');
            drawnow
%                APP_anylysis_ENG_0_1
               APP_Clinical_Research_CHI
               delete(app);
           end
        end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if value == '2'
               app.Panel1.Enable = "off";
               app.Panel1.Visible = "off";
               app.Panel2.Enable = "on";
               app.Panel2.Visible = "on";
            elseif value == '1'
               app.Panel2.Enable = "off";
               app.Panel2.Visible = "off";
               app.Panel1.Enable = "on";
               app.Panel1.Visible = "on";
            end
            
        end

        % Image clicked function: Batch_Process
        function Batch_ProcessImageClicked(app, event)
        if str2double(app.Switch.Value) == 2
            % button=questdlg('Leave current page？','warning','YES','NO','YES'); %内容，标题，选项，默认选项
            button = uiconfirm(app.MainFigure, 'Leave current page？', 'LEAVE?','Options',{'YES', 'NO'},'DefaultOption', 'NO', 'CancelOption', 'YES');
        else
            % button=questdlg('即将关闭当前页面？','提醒','YES','NO','YES'); %内容，标题，选项，默认选项
            button = uiconfirm(app.MainFigure, '是否进入批处理工具并离开当前页面？', '离开','Options',{'YES', 'NO'},'DefaultOption', 'NO', 'CancelOption', 'YES');
        end

        if strcmp(button,'YES')
           if str2double(app.Switch.Value) == 1
            app.waitting_NOW = uiprogressdlg(app.MainFigure,'Title','正在加载中',...
            'Indeterminate','on');
            drawnow
               APP_anylysis_Data_batch_CHI
               delete(app);
           elseif str2double(app.Switch.Value) == 2
            app.waitting_NOW = uiprogressdlg(app.MainFigure,'Title','正在加载中',...
            'Indeterminate','on');
            drawnow
%                APP_anylysis_ENG_0_1
               APP_anylysis_Data_batch_CHI
               delete(app);
           end
        end
        end

        % Image clicked function: Feature_Conpare
        function Feature_ConpareImageClicked(app, event)
        if str2double(app.Switch.Value) == 2
            % button=questdlg('Leave current page？','warning','YES','NO','YES'); %内容，标题，选项，默认选项
            button = uiconfirm(app.MainFigure, 'Leave current page？', 'LEAVE?','Options',{'YES', 'NO'},'DefaultOption', 'NO', 'CancelOption', 'YES');
        else
            % button=questdlg('即将关闭当前页面？','提醒','YES','NO','YES'); %内容，标题，选项，默认选项
            button = uiconfirm(app.MainFigure, '是否进入数据对比工具并离开当前页面？', '离开','Options',{'YES', 'NO'},'DefaultOption', 'NO', 'CancelOption', 'YES');
        end

        if strcmp(button,'YES')
           if str2double(app.Switch.Value) == 1
            app.waitting_NOW = uiprogressdlg(app.MainFigure,'Title','正在加载中',...
            'Indeterminate','on');
            drawnow

               APP_anylysis_result_compare
               delete(app);
           elseif str2double(app.Switch.Value) == 2
            app.waitting_NOW = uiprogressdlg(app.MainFigure,'Title','正在加载中',...
            'Indeterminate','on');
            drawnow
%                APP_anylysis_ENG_0_1
               APP_anylysis_result_compare
               delete(app);
           end
        end
        end

        % Image clicked function: Data_Crop
        function Data_CropImageClicked(app, event)
        if str2double(app.Switch.Value) == 2
            % button=questdlg('Leave current page？','warning','YES','NO','YES'); %内容，标题，选项，默认选项
            button = uiconfirm(app.MainFigure, 'Leave current page？', 'LEAVE?','Options',{'YES', 'NO'},'DefaultOption', 'NO', 'CancelOption', 'YES');
        else
            % button=questdlg('即将关闭当前页面？','提醒','YES','NO','YES'); %内容，标题，选项，默认选项
            button = uiconfirm(app.MainFigure, '提示：请从离线分析工具中进入！是否继续进入离线分析工具并离开当前页面？', '离开','Options',{'YES', 'NO'},'DefaultOption', 'NO', 'CancelOption', 'YES');
        end

        if strcmp(button,'YES')
           if str2double(app.Switch.Value) == 1
            app.waitting_NOW = uiprogressdlg(app.MainFigure,'Title','正在加载中',...
            'Indeterminate','on');
            drawnow
               APP_anylysis_CHI_0_1
               APP_anylisis_Data_CUT
               delete(app);
           elseif str2double(app.Switch.Value) == 2
%                APP_anylysis_ENG_0_1
            app.waitting_NOW = uiprogressdlg(app.MainFigure,'Title','正在加载中',...
            'Indeterminate','on');
            drawnow
               APP_anylysis_CHI_0_1
               APP_anylisis_Data_CUT
               delete(app);
           end
        end
        end

        % Image clicked function: Close_LOOP
        function Close_LOOPImageClicked(app, event)
            % if str2double(app.Switch.Value) == 2
                % 弹出提示信息
                uialert(app.MainFigure, 'NNSR统计研究工具正在开发中，敬请期待。', '提示');
        end

        % Image clicked function: CloseLoopToolbox
        function CloseLoopToolboxImageClicked(app, event)
         if str2double(app.Switch.Value) == 2
            % button=questdlg('Leave current page？','warning','YES','NO','YES'); %内容，标题，选项，默认选项
            button = uiconfirm(app.MainFigure, 'Leave current page？', 'LEAVE?','Options',{'YES', 'NO'},'DefaultOption', 'NO', 'CancelOption', 'YES');
        else
            % button=questdlg('即将关闭当前页面？','提醒','YES','NO','YES'); %内容，标题，选项，默认选项
            button = uiconfirm(app.MainFigure, '是否进入闭环研究工具并离开当前页面？', '离开','Options',{'YES', 'NO'},'DefaultOption', 'NO', 'CancelOption', 'YES');
        end

        if strcmp(button,'YES')
           if str2double(app.Switch.Value) == 1
                app.waitting_NOW = uiprogressdlg(app.MainFigure,'Title','正在加载中',...
                'Indeterminate','on');
               drawnow
               APP_Close_Loop_CHI_0_1
               delete(app);
           elseif str2double(app.Switch.Value) == 2
                app.waitting_NOW = uiprogressdlg(app.MainFigure,'Title','正在加载中',...
                'Indeterminate','on');
               drawnow
               APP_Close_Loop_CHI_0_1
               delete(app);
           end
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MainFigure = uifigure('Visible', 'off');
            app.MainFigure.Name = 'MATLAB App';
            app.MainFigure.CloseRequestFcn = createCallbackFcn(app, @CloseReturn, true);
            app.WelcometoNERCNNeuroSignalResearchNNSRToolboxLabel = uilabel(app.MainFigure);
            app.WelcometoNERCNNeuroSignalResearchNNSRToolboxLabel.Text = 'Welcome to NERCN NeuroSignal Research(NNSR) Toolbox!';
            app.ClicktoenterLabel = uilabel(app.MainFigure);
            app.ClicktoenterLabel.Text = 'Click to enter：';
            app.byNATIONALENGINEERINGRESEARCHCENTEROFNEUROMODULATIONLabel = uilabel(app.MainFigure);
            app.byNATIONALENGINEERINGRESEARCHCENTEROFNEUROMODULATIONLabel.Text = 'by  NATIONAL ENGINEERING RESEARCH CENTER OF NEUROMODULATION';
            app.v020Label = uilabel(app.MainFigure);
            app.v020Label.Text = 'v 0.2.0';
            app.Switch = uiswitch(app.MainFigure, 'slider');
            app.Switch.Items = {'CHI', 'ENG'};
            app.Switch.ItemsData = {'1', '2'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '1';
            app.LanguageLabel = uilabel(app.MainFigure);
            app.LanguageLabel.Visible = 'off';
            app.LanguageLabel.Text = 'Language：';
            app.Label_4 = uilabel(app.MainFigure);
            app.Label_4.Text = '进入模块：';
            app.DropDown = uidropdown(app.MainFigure);
            app.DropDown.Items = {'主功能界面', '常用工具'};
            app.DropDown.ItemsData = {'1', '2'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '1';
            app.Panel1 = uipanel(app.MainFigure);
            app.OnlineToolbox = uiimage(app.Panel1);
            app.OnlineToolbox.ImageClickedFcn = createCallbackFcn(app, @OnlineToolboxClicked, true);
            app.OnlineToolbox.Tooltip = {'Click to enter ONLINE TOOLBOX：'; 'Online toolbox'};
            app.OnlineToolbox.ImageSource = fullfile(pathToMLAPP, 'Main_3.jpg');
            app.LFPToolbox = uiimage(app.Panel1);
            app.LFPToolbox.ImageClickedFcn = createCallbackFcn(app, @LFPToolboxClicked, true);
            app.LFPToolbox.Tooltip = {'Click to enter ANALYSiS TOOLBOX'; ': Full function Analysis Toolbox'};
            app.LFPToolbox.ImageSource = fullfile(pathToMLAPP, 'Main_2.jpg');
            app.Clinical = uiimage(app.Panel1);
            app.Clinical.ImageClickedFcn = createCallbackFcn(app, @ClinicalClicked, true);
            app.Clinical.Tooltip = {'Click to enter Clinical RESEARCH TOOLBOX'; ': Simplifyed Analysis Toolbox'};
            app.Clinical.ImageSource = fullfile(pathToMLAPP, 'Main_1.jpg');
            app.Label = uilabel(app.Panel1);
            app.Label.Text = '*简化研究工具';
            app.Label_2 = uilabel(app.Panel1);
            app.Label_2.Text = '*全功能离线工具';
            app.Label_3 = uilabel(app.Panel1);
            app.Label_3.Text = '*在线研究工具';
            app.CloseLoopToolbox = uiimage(app.Panel1);
            app.CloseLoopToolbox.ImageClickedFcn = createCallbackFcn(app, @CloseLoopToolboxImageClicked, true);
            app.CloseLoopToolbox.Tooltip = {'Click to enter ONLINE TOOLBOX：'; 'Online toolbox'};
            app.CloseLoopToolbox.ImageSource = fullfile(pathToMLAPP, 'Main_4.jpg');
            app.Label_5 = uilabel(app.Panel1);
            app.Label_5.Text = '*闭环研究工具';
            app.Panel2 = uipanel(app.MainFigure);
            app.Panel2.Enable = 'off';
            app.Panel2.Visible = 'off';
            app.Close_LOOP = uiimage(app.Panel2);
            app.Close_LOOP.ImageClickedFcn = createCallbackFcn(app, @Close_LOOPImageClicked, true);
            app.Close_LOOP.ImageSource = fullfile(pathToMLAPP, 'TOOL4.jpg');
            app.Data_Crop = uiimage(app.Panel2);
            app.Data_Crop.ImageClickedFcn = createCallbackFcn(app, @Data_CropImageClicked, true);
            app.Data_Crop.ImageSource = fullfile(pathToMLAPP, 'TOOL3.jpg');
            app.Lab3 = uilabel(app.Panel2);
            app.Lab3.Text = '*数据剪裁工具';
            app.Lab4 = uilabel(app.Panel2);
            app.Lab4.Text = '*统计研究工具';
            app.Batch_Process = uiimage(app.Panel2);
            app.Batch_Process.ImageClickedFcn = createCallbackFcn(app, @Batch_ProcessImageClicked, true);
            app.Batch_Process.ImageSource = fullfile(pathToMLAPP, 'TOOL1.jpg');
            app.Lab1 = uilabel(app.Panel2);
            app.Lab1.Text = '*批处理工具';
            app.Feature_Conpare = uiimage(app.Panel2);
            app.Feature_Conpare.ImageClickedFcn = createCallbackFcn(app, @Feature_ConpareImageClicked, true);
            app.Feature_Conpare.ImageSource = fullfile(pathToMLAPP, 'TOOL2.jpg');
            app.Lab2 = uilabel(app.Panel2);
            app.Lab2.Text = '*特征对比工具';
            % Show the figure after all components are created
            app.MainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_to_start_0_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFigure)
        end
    end
end